classdef BinMapCCMatrix < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ClustercrosscorrelationUIFigure  matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        CrosscorrelationTab             matlab.ui.container.Tab
        TimeShiftEF                     matlab.ui.control.Spinner
        NormalizeRangeButtonGroup       matlab.ui.container.ButtonGroup
        NormalizedRB                    matlab.ui.control.RadioButton
        NormalizeRangeNoneRB            matlab.ui.control.RadioButton
        TabGroup2                       matlab.ui.container.TabGroup
        DistributioncomparisonTab       matlab.ui.container.Tab
        DisplayCCDistributionsPanel     matlab.ui.container.Panel
        X0LineCB                        matlab.ui.control.CheckBox
        ButtonGroup                     matlab.ui.container.ButtonGroup
        QQorCdfCB                       matlab.ui.control.CheckBox
        QQRB                            matlab.ui.control.RadioButton
        CdfRB                           matlab.ui.control.RadioButton
        NormalityTestBG                 matlab.ui.container.ButtonGroup
        PearsonRB                       matlab.ui.control.RadioButton
        NormalityTestCB                 matlab.ui.control.CheckBox
        TTestRB                         matlab.ui.control.RadioButton
        KSRB                            matlab.ui.control.RadioButton
        DistributionBinEF               matlab.ui.control.NumericEditField
        BinLabel                        matlab.ui.control.Label
        DisplayCCDistributionsCB        matlab.ui.control.CheckBox
        LimitsButtonGroup               matlab.ui.container.ButtonGroup
        AutoFixedDibLimitsBT            matlab.ui.control.StateButton
        DistributionYMinEF              matlab.ui.control.NumericEditField
        SetLogCCDistributionCB          matlab.ui.control.CheckBox
        DistributionYMaxEF              matlab.ui.control.NumericEditField
        YlimitsLabel                    matlab.ui.control.Label
        DistributionXMaxEF              matlab.ui.control.NumericEditField
        DistributionXMinEF              matlab.ui.control.NumericEditField
        XlimitsLabel                    matlab.ui.control.Label
        DistributionFixedRB             matlab.ui.control.RadioButton
        DistributionAutoRB              matlab.ui.control.RadioButton
        GraphicsTab                     matlab.ui.container.Tab
        GraphicSettingsPanel            matlab.ui.container.Panel
        ButtonGroup_2                   matlab.ui.container.ButtonGroup
        SCEventsRB                      matlab.ui.control.RadioButton
        SCAreaRB                        matlab.ui.control.RadioButton
        SCDistanceRB                    matlab.ui.control.RadioButton
        CompareBT                       matlab.ui.control.Button
        ScatterSizeEF                   matlab.ui.control.NumericEditField
        ScatterSizeLabel                matlab.ui.control.Label
        ClDistGraphicCB                 matlab.ui.control.CheckBox
        ShowPermutedCB                  matlab.ui.control.CheckBox
        chooseColormapDD                matlab.ui.control.DropDown
        ColormapLabel                   matlab.ui.control.Label
        ShowColorbarCB                  matlab.ui.control.CheckBox
        AutoCLimsBT                     matlab.ui.control.Button
        RestoreDefaultBT                matlab.ui.control.Button
        ExportTab                       matlab.ui.container.Tab
        SaveStatisticalTestCB           matlab.ui.control.CheckBox
        ExportHistogramsBT              matlab.ui.control.Button
        ExportModeButtonGroup           matlab.ui.container.ButtonGroup
        ExportModeSaveRB                matlab.ui.control.RadioButton
        ExportModePreviewRB             matlab.ui.control.RadioButton
        SaveFiguresLegendCB             matlab.ui.control.CheckBox
        SelectFiguresPanel              matlab.ui.container.Panel
        BoundariesCheckBox              matlab.ui.control.CheckBox
        SaveFigureCCDistCB              matlab.ui.control.CheckBox
        SaveFigurePCB                   matlab.ui.control.CheckBox
        SaveFigure3CB                   matlab.ui.control.CheckBox
        SaveFigure2CB                   matlab.ui.control.CheckBox
        SaveFigure1CB                   matlab.ui.control.CheckBox
        SaveFiguresBT                   matlab.ui.control.Button
        CCSlotsPanel                    matlab.ui.container.Panel
        NormalScoreLabel3               matlab.ui.control.Label
        NormalScoreLabel1               matlab.ui.control.Label
        NormalScoreLabel2               matlab.ui.control.Label
        CLimsLabel3                     matlab.ui.control.Label
        CLimsLabel2                     matlab.ui.control.Label
        CLimsLabel1                     matlab.ui.control.Label
        CLims1MinSP                     matlab.ui.control.Spinner
        CLims3MaxSP                     matlab.ui.control.Spinner
        CLims3MinSP                     matlab.ui.control.Spinner
        CLims2MaxSP                     matlab.ui.control.Spinner
        CLims2MinSP                     matlab.ui.control.Spinner
        CLims1MaxSP                     matlab.ui.control.Spinner
        ComputeCCMatrix3BT              matlab.ui.control.Button
        ComputeCCMatrix2BT              matlab.ui.control.Button
        ComputeCCMatrix1BT              matlab.ui.control.Button
        SwitchCC3SW                     matlab.ui.control.RockerSwitch
        SwitchCC2SW                     matlab.ui.control.RockerSwitch
        SwitchCC1SW                     matlab.ui.control.RockerSwitch
        CCPlot1                         matlab.ui.control.UIAxes
        CCPlot2                         matlab.ui.control.UIAxes
        CCPlot3                         matlab.ui.control.UIAxes
        PermutationPanel                matlab.ui.container.Panel
        PermutationTrialsEF             matlab.ui.control.NumericEditField
        TrialsLabel_2                   matlab.ui.control.Label
        FlipTimeDirectionCB             matlab.ui.control.CheckBox
        NormalScoreLabelP               matlab.ui.control.Label
        SwitchCCPSW                     matlab.ui.control.RockerSwitch
        CLimsLabelP                     matlab.ui.control.Label
        CLimsPermMinSP                  matlab.ui.control.Spinner
        RandPermFixedOffsetsCB          matlab.ui.control.CheckBox
        CLimsPermMaxSP                  matlab.ui.control.Spinner
        RandomPermutationBT             matlab.ui.control.Button
        CCPlotRandomPermutation         matlab.ui.control.UIAxes
        ClDistPanel                     matlab.ui.container.Panel
        FixScatterAxesXMinEF            matlab.ui.control.NumericEditField
        FixScatterAxesYMaxEF            matlab.ui.control.NumericEditField
        FixScatterAxesYMinEF            matlab.ui.control.NumericEditField
        FixScatterAxesXMaxEF            matlab.ui.control.NumericEditField
        FixScatterAxesCB                matlab.ui.control.CheckBox
        FindPairLabel                   matlab.ui.control.Label
        FindPairBT                      matlab.ui.control.Button
        ComputeClDistBT                 matlab.ui.control.Button
        CCPlotClDist                    matlab.ui.control.UIAxes
        CCcalculationPanel              matlab.ui.container.Panel
        RotateTimeShiftCB               matlab.ui.control.CheckBox
        TimeShiftCB                     matlab.ui.control.CheckBox
        TimeshiftLabel                  matlab.ui.control.Label
        CenterLagCB                     matlab.ui.control.CheckBox
        CenterLagEF                     matlab.ui.control.NumericEditField
        CenterLagLabel                  matlab.ui.control.Label
        DeltaLagEF                      matlab.ui.control.NumericEditField
        DeltaLagLabel                   matlab.ui.control.Label
        TimeSeriesSourcePanel           matlab.ui.container.Panel
        sortingCriteriaRB               matlab.ui.container.ButtonGroup
        AreaButton                      matlab.ui.control.RadioButton
        nEventsButton                   matlab.ui.control.RadioButton
        NosortingButton                 matlab.ui.control.RadioButton
        TSselection                     matlab.ui.container.ButtonGroup
        DFUallButton                    matlab.ui.control.RadioButton
        DFIallButton                    matlab.ui.control.RadioButton
        DFunionButton                   matlab.ui.control.RadioButton
        DFintersectionButton            matlab.ui.control.RadioButton
        SynchronizationmeasurementbetaTab  matlab.ui.container.Tab
        TraceSP                         matlab.ui.control.Spinner
        TraceSpinnerLabel               matlab.ui.control.Label
        GenerateRandomPhasesBT          matlab.ui.control.Button
        NumberofClustersEF              matlab.ui.control.NumericEditField
        NumberofclustersofclustersEditFieldLabel  matlab.ui.control.Label
        GlobalSynchronizationIndexEF    matlab.ui.control.NumericEditField
        GlobalSynchronizationIndexLabel  matlab.ui.control.Label
        TrialsEF                        matlab.ui.control.NumericEditField
        TrialsLabel                     matlab.ui.control.Label
        KValueSP                        matlab.ui.control.Spinner
        KLabel                          matlab.ui.control.Label
        ComputeSynchronizationBT        matlab.ui.control.Button
        SynchronizationIndexLabel       matlab.ui.control.Label
        ParticipationIndexPlot          matlab.ui.control.UIAxes
        EigenvaluesPlot                 matlab.ui.control.UIAxes
        SynCCPlot                       matlab.ui.control.UIAxes
        ImportComparisonTab             matlab.ui.container.Tab
        ImportABNormalPanel             matlab.ui.container.Panel
        ImportAB2BT                     matlab.ui.control.Button
        ImportAB1BT                     matlab.ui.control.Button
        ImportABMergeAnalysisPanel      matlab.ui.container.Panel
        DisplaymergeofhighabnormalityButton  matlab.ui.control.Button
        ImportABSaveMaskBT              matlab.ui.control.Button
        ImportABSubtractEF              matlab.ui.control.NumericEditField
        BinthrEditFieldLabel            matlab.ui.control.Label
        ImportABSubtractNormValuesBT    matlab.ui.control.Button
        ImportABMergeBT                 matlab.ui.control.Button
        ImportAB1Plot                   matlab.ui.control.UIAxes
        ImportAB2Plot                   matlab.ui.control.UIAxes
        ImportedKSAnalysisPanel         matlab.ui.container.Panel
        ImportedKSNormalizeCB           matlab.ui.control.CheckBox
        ImportedKSAnalysisScreenshotBT  matlab.ui.control.Button
        ImportKSBT                      matlab.ui.control.Button
        ImportUseCustomCB               matlab.ui.control.CheckBox
        ImportKSAlphaEF                 matlab.ui.control.NumericEditField
        SignificanceLabel               matlab.ui.control.Label
        ImportKSTextArea                matlab.ui.control.TextArea
        KSResultsTextAreaLabel          matlab.ui.control.Label
        FixAutoImportedLimitsBT         matlab.ui.control.Button
        Import2BT                       matlab.ui.control.Button
        Import1BT                       matlab.ui.control.Button
        Import1Plot                     matlab.ui.control.UIAxes
        Import2Plot                     matlab.ui.control.UIAxes
        TimeshiftoverviewTab            matlab.ui.container.Tab
        Epoch                           matlab.ui.control.Image
    end

    
    properties (Access = public)
        
        par % Access to the parent app, Binary Map. See OpeningFcn
        
        ccm % This structure saves the output of the computeCCmatrix function for
        % each one of the three plots.
        % ccm.m1 % CC Matrix 1
        % ccm.m2 % CC Matrix 2
        % ccm.m3 % CC Matrix 3
        
        clims % This structure saves the original CLim data for each one of the
        % three plots.
        % clims.m1Min
        % clims.m1Max
        % clims.m2Min
        % clims.m2Max
        % clims.m3Min
        % clims.m3Max
        
        cMap % Colormap
        
        % Store in this child all randomly permuted CC data
        CCMatrixPerm % Permuted CC data
        ClDistancePerm % Cluster pairs distance matrix
        RandomOffsets % Store permutation offsets
        lastMatrix % This is just used to get if last matrix is permuted and how
        activeTitle % Store the title of active plot
        activeSubtitle % Do the same for subtitle. This will show minor things like deltalag
        
        % Compare original and permuted traces
        rasterPermutedSource
        rasterPermutedOutput
        rasterIsAll % Checks if DF I/U 'All' method was used. In that case, raster data will not be binarized
        
        ran % Phase randomized series
        
        PI % Participation Index traces
        
        SelectedFigures % Checked CBs for the 'Save figure' callback
        TiledExport % Invisible figure which will store all the selected subplots
        SingleExpTit % Title string of single-image export mode
        
        QQPlotLimits % X Limits of QQ plots
        CdfPlotLimits % X Limits of cumulative distribution functions
        RandPermDibValues % Values of permuted crosscorrelation. For statistic comparison.
        
        ExportHistogramLogic % It will be 0 if regular histogram display is selected.
        % Otherwise, it will be 1 when 'save matrices'
        % button is pressed.
        Imported1 % Imported matrices
        Imported2
        ImportedTmp % Temporary. Needed to quickly store the imported matrices
        % Do the same for title, obtained by getting file name
        ImportedTitle1
        ImportedTitle2
        ImportedTitleTmp
        
        % Import AB-Normal maps. Store matrices and titles
        ImportedAB1 % Imported matrices
        ImportedAB2
        ImportedABTmp % Temporary. Needed to quickly store the imported matrices
        % Do the same for title, obtained by getting file name
        ImportedABTitle1
        ImportedABTitle2
        ImportedABTitleTmp
        % AB Merge maps and masks
        NormSubtractedAB % Subtraction of positively-normalized values
        NormSubtractedBinAB % Binary output
        
        AreaSC
        EventsSC
    end
    
    methods (Access = public)
        
        %%********* Computation of CC Matrix *********%%
        function computeCCmatrix(app)
            
            % Rocco, January 11, 2021
            %
            %
            % The code that follows was originally written by GMR.
            % I just pasted it into this function. The major difference,
            % for the moment, is that all source data
            % from the parent is inherited as app.par property instead of app.
            % Although, the selection of the source occurs via RB handles
            % that are located in this child app.
            % In this application, three independent UIAxes can be used to plot different
            % cross correlation matrices coming from different sources. Anytime
            % you plot something, matrix data will be saved in this child as well,
            % by assigning it a property that keeps the identity of its UIaxes.
            %
            %
            %
            %
            % Happy New Year! December 30th, 2020
            % Computation of the CC matrix at lag -1 + 1 frames
            
            % Define parameters
            app.par.CC.deltaLag = app.DeltaLagEF.Value;        % Width of the CC peak expressed in frames
            app.par.CC.centerLag = app.CenterLagEF.Value; % Center of the CC integral. Implemented 27 Jan, 2021
            app.par.CC.CCmatrix = zeros(app.par.nClusters);        % Initialisation of the CC matrix
            %             app.par.CC.CCmatrixSorted = zeros(app.par.nClusters);  % CC matrix sorted by decreasing amplitude
            
            % The source of the data is determined by the app.TSselection
            % RB handles within this child app
            switch app.TSselection.SelectedObject.Text
                case 'DF intersection'
                    source = 'deltaFI';
                case 'DF union'
                    source = 'deltaFU';
                case 'DF I all'
                    source = 'deltaFIall';
                case 'DF U all'
                    source = 'deltaFUall';
            end
            
            % Check if a specific sortig criteria has been selected
            switch app.sortingCriteriaRB.SelectedObject.Text
                case 'No sorting'
                    sourceI = 1:app.par.nClusters;
                case 'Area'
                    sourceI = app.par.CC.sortIarea;
                case 'n Events'
                    sourceI = app.par.CC.sortInEvents;
            end
            
            % Crosscorrelation starts here
            for ClI1=1:app.par.nClusters
                track1 = app.par.CCregions.(source)(sourceI(ClI1),:);
                track1 = track1-mean(track1);
                
                % Jul 2021.
                % Shift time series!
                % First, cut the first time series on its last part (while, conversely,
                % track 2s will be cut on their first part). Doing so, they will
                % have the same size. Crosscorrelation matrix will be
                % asymmetric.
                if app.TimeShiftCB.Value
                    v = app.TimeShiftEF.Value;
                    track1 = track1(1:(length(track1)-v));
                end
                
                for ClI2=1:app.par.nClusters
                    if ClI1~=ClI2
                        % Compute the CC
                        track2 = app.par.CCregions.(source)(sourceI(ClI2),:);
                        track2 = track2 - mean(track2);
                        
                        % Jul 2021.
                        % Shift time series!
                        % Cut the time series on its first part...
                        if app.TimeShiftCB.Value
                            v = app.TimeShiftEF.Value;
                            track2 = track2(v:length(track2));
                        end
                        
                        % Jan 27, 2021.
                        % Shift the center of the integral of the crosscorrelation by a given
                        % value. I complicated a little bit the
                        % calculation because xcorr() wants deltaLag argument (which corresponds to
                        % the extremes of the integral region)  as a scalar value.
                        % So, basically you can do the whole
                        % crosscorrelation first. Then, sum() is made within the
                        % selected interval.
                        if app.CenterLagCB.Value && app.par.CC.centerLag ~= 0
                            tmp = xcorr(track1,track2);
                            % Also valid for negative values of Center Lag
                            newMinLag = - app.par.CC.deltaLag + app.par.CC.centerLag;
                            newMaxLag = app.par.CC.deltaLag + app.par.CC.centerLag;
                            % Old zero index is on the center of the (odd)
                            % array
                            zeroIndex = ceil(length(tmp)/2);
                            % Set boundaries and integrate within them
                            tmp = tmp((zeroIndex + newMinLag):(zeroIndex + newMaxLag));
                            app.par.CC.CCmatrix(ClI1,ClI2) = sum(tmp);
                            
                            
                        else % If CB is not selected, then compute using only deltaLag as argument of xcorr function
                            if app.NormalizedRB.Value % Normalize on autocorrelation
                                app.par.CC.CCmatrix(ClI1,ClI2) = sum(xcorr(track1,track2,app.par.CC.deltaLag,"normalized"));
                            else
                                app.par.CC.CCmatrix(ClI1,ClI2) = sum(xcorr(track1,track2,app.par.CC.deltaLag));
                            end
                            
                        end
                        
                        % 26 Jan, 2021
                        % Compute the distance between clusters. This code
                        % has been slightly modified in order to adapt it
                        % to the cases in which the sorting method is
                        % changed. In this way, as it should be, scatter
                        % plot doesn't change whenever you change the
                        % sorting method.
                        app.par.CC.clDistance(ClI1,ClI2) = hypot(app.par.CCregions.centroidU(sourceI(ClI1),1)-app.par.CCregions.centroidU(sourceI(ClI2),1),...
                            app.par.CCregions.centroidU(sourceI(ClI1),2)-app.par.CCregions.centroidU(sourceI(ClI2),2));
                        
                        % Jul 2021.
                        % Add surface of clusters' pairs. Computed as the
                        % mean of their surface
                        app.AreaSC(ClI1,ClI2) = (app.par.CCregions.areaU(sourceI(ClI1),1)+app.par.CCregions.areaU(sourceI(ClI2),1))/2;
                        % Add also sum of N Events. I prefer using sum
                        % instead of mean in order to obtain a wider interval of
                        % values
                        app.EventsSC(ClI1,ClI2) = (app.par.CCregions.eventN(sourceI(ClI1),1)+app.par.CCregions.eventN(sourceI(ClI2),1))/1;
                        
                    end
                end
            end
            
            %             % March 23, 2021. Normalize values.
            %             % I'm starting to compare before-after TBS
            %             % calcium data.
            %             % A form of normalization might be needed. Hence, with this
            %             % option you can range the computed values within [0,1]
            %             % interval. Hope this works in order to compare the means of the distributions.
            %             if ~ app.NormalizeRangeNoneRB.Value
            %                 % If normalization is selected, first compute [0,1] range
            %                 normA = app.par.CC.CCmatrix - min(app.par.CC.CCmatrix(:));
            %                 normA = normA ./ max(normA(:));
            %                 app.par.CC.CCmatrix = normA;
            %                 % If custom value is selected, compute the range between
            %                 % that absolute value
            %                 if app.NormalizeRangeValueRB.Value
            %                     range = app.NormalizeRangeValueEF.Value;
            %                     app.par.CC.CCmatrix = (2*range) * app.par.CC.CCmatrix - range;
            %                 end
            %             end
            
            % Jul 2021.
            % Shift time series!
            % Rotate 180° the CC matrix to compute, as distribution, the
            % correct side of the matrix (which with this parameter is
            % asymmetric). The correct side of the matrix is the one with positive
            % time shift.
            %
            % If time shift will turn out to be useful, I will
            % improve this code...
            if app.TimeShiftCB.Value
                if app.RotateTimeShiftCB.Value
                    app.par.CC.CCmatrix = rot90(rot90(app.par.CC.CCmatrix));
                    % Rotate also distance and surface...
                    app.par.CC.clDistance = rot90(rot90(app.par.CC.clDistance));
                    app.AreaSC = rot90(rot90(app.AreaSC));
                end
            end
            
            % For the moment, normalization occurs just here and no
            % callback associated to pressed CB is set.
            
            % March 15, 2021. Total CC score
            % I wanted to create a 'score' of crosscorrelation based on the
            % sum of the crosscorrelation with all the other clusters. A
            % new column has been added in app.par.CCregions.
            % This information is additional to the one provided by the
            % Nearest Neighbour. I would like to make a sort of heatmap out of
            % it.
            %
            % Sometimes, when using grid, some NaNs are found...
            tmpTotScore = app.par.CC.CCmatrix;
            tmpTotScore(isnan(tmpTotScore)) = 0;
            % Sum columns of each row and assign column vector to cluster table.
            app.par.CCregions.totCC = sum(tmpTotScore,2);
            
            % Jan 2021. NaN diagonal.
            % Since autocorrelation was not
            % computed, the diagonal was automatically set to 0. We are
            % going to replace these 0s with NaNs.
            % This edit is necessary for two major
            % reasons: 1) first, it allows the correct visualisation of the
            % colormap of the CC Matrix plot; 2) most importantly, it adjusts the
            % "0 correlation, 0 distance" bug which affected some sparse clusters along the
            % Binary Map app NN output. The bug impinged on those clusters that had only
            % anticorrelations (negative values) besides the diagonal (0), so
            % that at the end the diagonal was picked.
            % To summarize, replacing the 0s with NaNs, we are now able to
            % visualize the NN cluster in Binary Map App even if the NN is
            % not positively correlated.
            for i=1:app.par.nClusters
                app.par.CC.CCmatrix(i,i) = NaN;
            end
            
            % CC sorting. Implemented Jan 2021
            % Clusters might be sorted in terms of the integral of their CC.
            %             app.par.CCregions.intCC = sum (app.par.CC.CCmatrix,2);        % Column vector holding the CC summed on all clusters
            % Get sorting by the whole CC Matrix
            app.par.CCregions.intCC = max(app.par.CC.CCmatrix,[],2); % Find max value of each row
            [~,app.par.CC.sortIintCC] = sort(app.par.CCregions.intCC,'descend'); % Sort here the pairs
            % Initialize arrays
            app.par.CC.NNdistance = zeros(app.par.nClusters,1);
            app.par.CC.NNind = zeros(app.par.nClusters,1);
            % Compute the strongest link for each cluster
            for ClI1=1:app.par.nClusters
                tmp = app.par.CC.CCmatrix(ClI1,:);
                [app.par.CC.NNdistance(ClI1), app.par.CC.NNind(ClI1)] = max(tmp);
            end
            
            % Last matrix is not permuted
            app.lastMatrix = 0;
            
        end
        
        %%********* Plot Matrices and Colorbar *********%%
        % This function is needed to (re)plot things without computing
        % again the data.
        function plotMatrixAndColorbar(app,matrix,ax)
            % Plot and show Colorbar
            cla(ax,'reset');
            if app.ShowColorbarCB.Value
                imagesc(ax,matrix);
                colorbar(ax);
                ax.DataAspectRatio = [1 1 1];
                ax.PlotBoxAspectRatio = [1 1 1];
            else
                if ~isempty(colorbar(ax))
                    delete(colorbar(ax))
                end
                imagesc(ax,matrix);
                ax.DataAspectRatio = [1 1 1];
                ax.PlotBoxAspectRatio = [1 1 1];
            end
            
            title(ax,app.activeTitle) % Preserve title
            subtitle(ax,app.activeSubtitle) % Preserve subtitle
            % Feb 26. Just to be sure. If 'distribution' CB visualisation
            % is picked, then give it the priority
            if app.DisplayCCDistributionsCB.Value
                ShowCCDistribution(app); % Note that this is the callback and not the computeDistributions() function
            end
        end
        
        
        %%********* Fixed Random Circular Permutation function *********%%
        function computeRandomPermutation(app)
            %% Rocco, Jan 17, 2021
            % Random circular permutation has been added to
            % BinMapCCMatrix.
            
            %% March 7, 2021.
            % I have reorganized some things due to the recent changes made to
            % this app. Basically, I moved the 'if' statement for picking
            % one of the two methods inside of this function (before, it was
            % external, at the level of the callback).
            % In terms of code, the difference between fixed and non fixed methods
            % is very simple: it is just at the level of the 'while'
            % statement, which is present a bit below here,
            % which can be used when the quasi-randomic offsets are generated. Other little
            % differences are in terms of the title string, but obviously
            % that's not important at all.
            %
            % As opposite as thought before, the non-fixed method
            % will probably turn out to be the most reliable choice in the future,
            % since has an higher degree of randomness.
            
            %% Section 1: generation of fixed random offsets
            % Source data is got via the app.TSselection
            % RB handles within the child app.
            % Check if a specific sortig criteria has been selected
            switch app.sortingCriteriaRB.SelectedObject.Text
                case 'No sorting'
                    sourceI = 1:app.par.nClusters;
                case 'Area'
                    sourceI = app.par.CC.sortIarea;
                case 'n Events'
                    sourceI = app.par.CC.sortInEvents;
            end
            % Now, use this switch in order to keep ALWAYS the DF intersection or the
            % DF union even if you have selected the 'all' metric. This extra variable
            % will be needed when we will pick the fixed random offsets. In fact, we
            % are interested in making these offsets fall into the 'zero' regions of the
            % filtered time series - then, we will use these offsets for the
            % corresponding 'all' stack as well.
            switch app.TSselection.SelectedObject.Text
                case 'DF intersection'
                    sourceFix = 'deltaFI';
                case 'DF union'
                    sourceFix = 'deltaFU';
                case 'DF I all'
                    sourceFix = 'deltaFI'; % Pick corresponding filtered data
                case 'DF U all'
                    sourceFix = 'deltaFU'; % Pick corresponding filtered data
            end
            
            % March, 19 2021. Random permutation trials
            % This was needed a lot of time ago. The whole permutation
            % function is now inserted within a loop of n trials.
            % For each iteration, the resulting CC matrix is going to be inserted
            % into a 3D container.
            % Then, mean() along the 3D dimension will be computed.
            % We wonder if the resulting distribution will be more
            % normal-like. We will see...
            nTrials = app.PermutationTrialsEF.Value;
            % Preallocate 3D matrix
            permutationTrialsResults = zeros(app.par.nClusters,app.par.nClusters,nTrials);
            for trial = 1:nTrials
                
                % First, create a loop to generate the random offsets.
                time = app.par.parentApp.metadata.frames;
                domains = app.par.nClusters;
                fixedOffsets = zeros(1,domains); % There will be one offset for each cluster
                % I check that each random offset does not
                % fall into any event within the corresponding filtered data.
                for nCl = 1:domains
                    % This is the time series associated to the nCl cluster. Notice that
                    % I'm picking the sourceFix variable and not the source one
                    % (which I will use later,for the calculation of the CC matrix); this
                    % because I'm interested in considering the filtered data, which has no
                    % noise. (Or, better to say, has noise already conveniently set to 0).
                    trackFix = app.par.CCregions.(sourceFix)(sourceI(nCl),:); % I'm considering the stack row by row
                    % This is the first trial for the generation of one random offset
                    fixedOffsets(nCl) = randi(time,1);
                    % If offset does not fall within a point in which the
                    % corresponding trace is zero (thus falling within an event, and splitting it in half)
                    % then repeat its random generation...
                    if app.RandPermFixedOffsetsCB.Value % (March, 2021. Moved this statement down here).
                        while trackFix(fixedOffsets(nCl)) ~= 0 % ...until it does not satisfy the condition.
                            fixedOffsets(nCl) = randi(time,1);
                        end
                    end
                end
                % Now I have my array with my conveniently limited random
                % offsets.
                app.RandomOffsets = fixedOffsets;
                %% Section 2: Circular random permutation
                % Now, fixed random offsets have been generated. So, I'm gonna apply them to
                % permute all the corresponding time series. Notice that this
                % time I'm getting back the 'deltaFIall' and 'deltaFUall'
                % sources, which will inherit the same offsets of their
                % corresponding filtered counterpart (respectively, 'deltaFI'
                % and 'deltaFU')
                switch app.TSselection.SelectedObject.Text
                    case 'DF intersection'
                        source = 'deltaFI';
                    case 'DF union'
                        source = 'deltaFU';
                    case 'DF I all'
                        source = 'deltaFIall';
                    case 'DF U all'
                        source = 'deltaFUall';
                end
                %Preallocation of permuted tracks Stack
                permutedTracks = zeros(domains, time);
                
                % Apply random circular permutation (with the already generated random
                % offsets) to the whole source stack
                for nCl=1:domains
                    % Offsets are points in time in which the left part and the right part of
                    % the corresponding time series are swapped
                    
                    % Get each row of source track. Each row will be permuted
                    % and added to the overall matrix called permutedTracks
                    originalTimeSeriesRow = app.par.CCregions.(source)(sourceI(nCl),:);
                    
                    % permutedTracksRow will contain the permuted row. Clear it
                    % at each iteration
                    permutedTracksRow = zeros(1,time);
                    
                    % Compute permutation at given offset
                    permutedTracksRow(fixedOffsets(nCl):time) = originalTimeSeriesRow(1:(time-fixedOffsets(nCl)+1));
                    permutedTracksRow(1:(fixedOffsets(nCl)-1)) = originalTimeSeriesRow((time-fixedOffsets(nCl)+2):time);
                    
                    % Add permuted row to the preallocated permutedTracks
                    % matrix, which will be used for the CC matrix
                    permutedTracks(nCl,:) = permutedTracksRow;
                end
                
                % March 19, 2021. Flip time direction
                % Sometimes, due to calcium intrinsic kinetics,
                % permuted distribution seems to retain some positive
                % correlation. Avoid this by flipping randomically the
                % direction of the sources.
                % Probability to flip each source is 0.5.
                if app.FlipTimeDirectionCB.Value
                    % Keep a counter of flipped sources. Not important.
                     %isRevertedCounter = 0;
                    for i=1:app.par.nClusters
                        % Generate 0 or 1 with p = 0.5.
                        isReverted = randi([0 1]);
                        % If 1 was obtained, then flip the time series.
                        if isReverted == 1
                            permutedTracks(i,:) = flip(permutedTracks(i,:)); % Flip x
                            
                             %isRevertedCounter = isRevertedCounter + 1
                        end
                    end
                    % disp(isRevertedCounter);
                end
                
                % Jan 25, 2021. Save Original track and permuted tracks in order to plot them
                % in the parser plot callback
                app.rasterPermutedSource = app.par.CCregions.(source)(sourceI(:),:);
                app.rasterPermutedOutput = permutedTracks;
                % Binarization of parser plot will not be applied if source is
                % 'All' type
                if strcmp(source,'deltaFIall') == 1 || strcmp(source,'deltaFUall') == 1
                    app.rasterIsAll = 1;
                else
                    app.rasterIsAll = 0;
                end
                
                %% Section 3: Computation of the CC matrix of permutedTracks
                % The code which follows is basically the same used for the classic CC
                % matrix. I'm repeating it here (instead of using the function) just to be
                % sure that nothing goes wrong. Just a few differences will be present,
                % since the original 'track1' variable will be substituted here with the
                % 'permutedTracks' variable
                % Define parameters
                app.par.CC.deltaLag = app.DeltaLagEF.Value;        % Width of the CC peak expressed in frames
                app.par.CC.centerLag = app.CenterLagEF.Value; % Center of the CC integral. Implemented 27 Jan, 2021
                app.par.CC.CCmatrix = zeros(app.par.nClusters);        % Initialisation of the CC matrix
                %             app.par.CC.CCmatrixSorted = zeros(app.par.nClusters);  % CC matrix sorted by decreasing amplitude
                % CC matrix
                for ClI1=1:app.par.nClusters
                    track1 =  permutedTracks(ClI1,:); % Use permutedTracks
                    track1 = track1 - mean(track1);
                    for ClI2=1:app.par.nClusters
                        if ClI1~=ClI2
                            % Compute the CC
                            track2 = permutedTracks(ClI2,:);
                            track2 = track2 - mean(track2);
                            
                            % Jan 27, 2021.
                            % Shift the center of the integral of the crosscorrelation by a given
                            % value. I complicated a little bit the
                            % calculation because xcorr() wants deltalag argument (which corresponds to
                            % the extremes of the integral region)  as a scalar value.
                            % So, basically you can do the whole
                            % crosscorrelation first. Then, sum is made within the
                            % selected interval.
                            if app.CenterLagCB.Value && app.par.CC.centerLag ~= 0
                                tmp = xcorr(track1,track2);
                                % Also valid for negative values of Center Lag
                                newMinLag = - app.par.CC.deltaLag + app.par.CC.centerLag;
                                newMaxLag = app.par.CC.deltaLag + app.par.CC.centerLag;
                                % Old zero index is on the center of the (odd)
                                % array
                                zeroIndex = ceil(length(tmp)/2);
                                % Set boundaries and integrate within them
                                tmp = tmp((zeroIndex + newMinLag):(zeroIndex + newMaxLag));
                                app.par.CC.CCmatrix(ClI1,ClI2) = sum(tmp);
                            else % If CB is not selected, then compute using only deltaLag as argument of xcorr function
                                if app.NormalizedRB.Value % Normalize on autocorrelation
                                    app.par.CC.CCmatrix(ClI1,ClI2) = sum(xcorr(track1,track2,app.par.CC.deltaLag,"normalized"));
                                else
                                    app.par.CC.CCmatrix(ClI1,ClI2) = sum(xcorr(track1,track2,app.par.CC.deltaLag));
                                end
                                
                            end
                            
                            % 26 Jan, 2021
                            % Compute the distance between clusters. This code
                            % has been slightly modified in order to adapt it
                            % to the cases in which the sorting method is
                            % changed. In this way, as it should be, scatter
                            % plot doesn't change whenever you change the
                            % sorting method.
                            app.par.CC.clDistance(ClI1,ClI2) = hypot(app.par.CCregions.centroidU(sourceI(ClI1),1)-app.par.CCregions.centroidU(sourceI(ClI2),1),...
                                app.par.CCregions.centroidU(sourceI(ClI1),2)-app.par.CCregions.centroidU(sourceI(ClI2),2));
                        end
                    end
                end
                
                % March 19, 2021. Permutation trials
                % At this point, permutation is concluded. If multiple
                % trials are selected, then store the computed matrices
                % within a 3D container.
                % I'm not going to touch pair distance matrix at all, since
                % it would make no sense.
                permutationTrialsResults(:,:,trial) = app.par.CC.CCmatrix;
                
            end % End of trials
            % Finally, compute the mean along 3rd dimension if multiple trials were selected.
            if nTrials > 1
                
                
                %                 app.par.CC.CCmatrix = mean(permutationTrialsResults,3);
                
                % Tmp. Sum permuted values. This is not suitable to be plotted as CCdistance scatter
                tmpTot=[];
                for iii =1:app.PermutationTrialsEF.Value
                    tmpP = permutationTrialsResults(:,:,iii);
                    n=numel(tmpP);
                    tmpP = reshape(tmpP, [1,n]); % Vectorize
                    tmpTot = [tmpTot, tmpP];
                end
                figure
                histogram(tmpTot,'Normalization',"probability","BinWidth",50);
                
                app.par.CC.CCmatrix = tmpTot;
            end
            
%             % March 23, 2021. Normalize values.
%             % I'm starting to compare before-after TBS
%             % calcium data.
%             % A form of normalization might be needed. Hence, with this
%             % option you can range the computed values within [0,1]
%             % interval. Hope this works in order to compare the means of the distributions.
%             if ~ app.NormalizeRangeNoneRB.Value
%                 % If normalization is selected, first compute [0,1] range
%                 normA = app.par.CC.CCmatrix - min(app.par.CC.CCmatrix(:));
%                 normA = normA ./ max(normA(:));
%                 app.par.CC.CCmatrix = normA;
%                 % If custom value is selected, compute the range between
%                 % that absolute value
%                 if app.NormalizeRangeValueRB.Value
%                     range = app.NormalizeRangeValueEF.Value;
%                     app.par.CC.CCmatrix = (2*range) * app.par.CC.CCmatrix - range;
%                 end
%             end
            % For the moment, normalization occurs just here and no
            % callback associated to pressed CB is set.
            
            % March 15, 2021. Total CC score
            %
            % The score is calculated even here, upon permutation, for
            % debug reasons only!
            %
            % Sometimes, when using grid, some NaNs are found...
            tmpTotScore = app.par.CC.CCmatrix;
            tmpTotScore(isnan(tmpTotScore)) = 0;
            % Sum columns of each row and assign column vector to cluster table.
            app.par.CCregions.totCC = sum(tmpTotScore,2);
            
            % Set diagonal NaN
            for i=1:app.par.nClusters
                app.par.CC.CCmatrix(i,i) = NaN;
            end
            
            % CC sorting. Of course this makes no sense because matrix is
            % permuted. Just keeping it here...
            % Clusters might be sorted in terms of the integral of their CC.
            %             intCC = sum (app.par.CC.CCmatrix,2);        % Column vector holding the CC summed on all clusters
            app.par.CCregions.intCC = max(app.par.CC.CCmatrix,[],2); % Find max value of each row
            [~,app.par.CC.sortIintCC] = sort(app.par.CCregions.intCC,'descend'); % Sort here the pairs
            % Initialize arrays
            app.par.CC.NNdistance = zeros(app.par.nClusters,1);
            app.par.CC.NNind = zeros(app.par.nClusters,1);
            % Compute the strongest link for each cluster
            for ClI1=1:app.par.nClusters
                tmp = app.par.CC.CCmatrix(ClI1,:);
                [app.par.CC.NNdistance(ClI1), app.par.CC.NNind(ClI1)] = max(tmp);
            end
            
            % Store properties in the CCMatrix app
            app.CCMatrixPerm = app.par.CC.CCmatrix;
            app.ClDistancePerm = app.par.CC.clDistance;
            
            % Plot Matrix and Colorbar
            matrix = app.CCMatrixPerm;
            ax = app.CCPlotRandomPermutation;
            plotMatrixAndColorbar(app,matrix,ax);
            
            % Change title
            tss = app.TSselection.SelectedObject.Text;
            sc = app.sortingCriteriaRB.SelectedObject.Text;
            SI = string(app.par.SIthr.Value);
            tit = append('FIX Rand. Perm.: ', tss, ', ' , sc, ', ' , SI); % Combine strings
            title(app.CCPlotRandomPermutation, tit,"FontSize",9);
            % Get plot title string
            app.activeTitle = tit;
            
            % Insert also subtitle
            dl = string(app.DeltaLagEF.Value);
            if app.CenterLagCB.Value % Check if CenterLag was used
                cl = string(app.CenterLagEF.Value);
            else
                cl = '0';
            end
            app.activeSubtitle = append ('DeltaLag = ', dl,'; CenterLag = ', cl);
            subtitle(app.CCPlotRandomPermutation, app.activeSubtitle);
            
            % Save CLims and refresh EFs
            app.clims.PermMin = min(matrix,[],'all');
            app.clims.PermMax = max(matrix,[],'all');
            app.CLimsPermMinSP.Value = min(matrix,[],'all');
            app.CLimsPermMaxSP.Value = max(matrix,[],'all');
            
            % Refresh track to inherit in parent app
            ShowPermutedInParent(app)
            
            % Last matrix is simple permuted
            app.lastMatrix = 2;
            
            % Refresh switch graphics
            currentSwitch = app.SwitchCCPSW;
            switchMatrix(app,currentSwitch)
        end
        
        %%********* Switch matrices *********%%
        function switchMatrix(app,currentSwitch)
            
            % Turn down all other active switches
            g = findall(app.ClustercrosscorrelationUIFigure,'Tag','SW','Enable','On');
            set(g(:),'Value','Off');
            
            % Turn up current switch
            currentSwitch.Value = 'On';
            currentSwitch.Enable = 'On';
        end
        
        %%********* Display distributions *********%%
        % Feb 26, 2021. This function will be applied to all existing plots
        % in order to show the correspondent histogram (i.e. the
        % distribution of the CrossCorrelation.
        
        function computeDistributions(app, ax, matrix, titleStr, subtitleStr, statLabel)
            cla(ax,'reset');
            if app.DisplayCCDistributionsCB.Value
                % Plot histogram. Y axes is normalized, therefore the max
                % peak will be 1. This histogram will have the same shape
                % of the pdf, i.e. probability density function.
                b = app.DistributionBinEF.Value;
                
                % March 6, 2021. For the computation of the histograms, it
                % is better to not take into account the whole CC matrix,
                % but just the values which are beneath the NaN diagonal.
                % In order to do so, first create a NaN matrix with the same size
                % of the original one. Fill it with the interesting
                % (non-repeating) values; then, delete NaNs and reshape
                % into a vector. This steps are not so necessary, and they will
                % make the process a bit slower, nevertheless it's better to be
                % cautious.
                tmp = nan(app.par.nClusters);
                % Important: from this moment on, we are going
                % to take into account only the points which
                % are beneath the diagonal of a square matrix. So, the
                % bottom side of the matrix. This will allow to not
                % double the number of observation.
                for i = 1:(app.par.nClusters-1)
                    tmp((1+i),(1:i)) = matrix((1+i),(1:i));
                end
                tmp = reshape(tmp,1,[]);
                matrix = tmp(~isnan(tmp));
                matrix = sort(matrix);
                % Store RP values for statistical comparison...
                if ax == app.CCPlotRandomPermutation
                    app.RandPermDibValues = matrix;
                end
                
                % Now, matrix is made of fewer elements, and this hopefully
                % will make easier and faster the computation of the
                % statistical tests...
                
                % March 6, 2021
                % Statistical tests to assess the similarity to a normal
                % distribution. % Work in progress!
                if app.NormalityTestCB.Value
                    
                    if app.KSRB.Value % Kolmogorov-Smirnov Normality test.
                        % KS test can be used to assess the similarity with
                        % any distribution, you just need to specify the
                        % parameters.
                        %
                        % I'm performing here a two-pair test, in which the
                        % sample CC data is compared to the permuted data.
                        % So, in order to use this test, make sure that
                        % permutation was already computed.
                        % This test could be used as a confirmation of the
                        % skewness/abnormality of real data.
                        cv=[];
                        if ~isempty(app.RandPermDibValues)
                            [h, p, stat] = kstest2(matrix,app.RandPermDibValues,'Alpha',0.01);
                        else
                            return
                        end
                    elseif app.TTestRB.Value % Student's one sample t-test.
                        % It returns a test decision for the null hypothesis that
                        % the data in x comes from a normal distribution with mean
                        % equal to zero and unknown variance.
                        % Significance is set at .01.
                        [h,p,ci,stat] = ttest(matrix,0,'Alpha',0.01);
                        % ci is the confidence interval.
                        mCI = num2str(ci(1),'%0.2f');
                        MCI = num2str(ci(2),'%0.2f');
                        cv = append(mCI,',',MCI); % No threshold is given here, so clear it
                        stat = stat.tstat;
                    else % Pearson's Chi-square g.o.f. test.
                        % For the moment, chi-square test
                        % seems useful since it assigns a reliable statistic
                        % score of "general" abnormality, without assuming mean = 0,
                        % as it happens in Student's t-test.
                        % Intriguingly, the result of this test will
                        % be most of the times h == 1 (abnormal) for most
                        % histograms.
                        % First, a fitted distribution is modelled from
                        % data. Note that I'm using tLocationScale. It
                        % seems to be more reliable for our data than
                        % 'Normal' -- probably because of the high kurtosis.
                        pd = fitdist(matrix','tLocationScale');
                        [h,p,tmpstats] =  chi2gof(matrix,'CDF',pd,"Alpha",0.05,"NBins",5000);
                        % Alternatively:
                        % [h,p,tmpstats] =  chi2gof(matrix,'cdf',{@normcdf,0,std(matrix),(1:length(matrix))},"NBins",5000);
                        cv = []; % Don't show
                        stat = tmpstats.chi2stat;
                        
                    end
                    % Label string
                    nString=append('n: ', num2str(app.par.nClusters),'; ');
                    muString=append("x̄: ", num2str(mean(matrix),'%0.1f'),'; ');
                    mdString=append('Md: ', num2str(median(matrix),'%0.1f'),'; ');
                    stdString=append('Std: ', num2str(std(matrix),'%0.1f'),';');
                    statTmpString = append(...
                        'Abnormal: ',num2str(h),'; ', ...
                        'Stat.: ',num2str(stat, '%0.3f'),'; ');
                    % If p-value is less than 0.001, print it
                    if p < 0.001
                        statTmpString = append(statTmpString, 'P-Value: <0.001');
                    else
                        statTmpString = append(statTmpString, 'P-Value: ',num2str(p,'%0.4f'));
                    end
                    if ~isempty(cv)
                        % KS also displays critical value
                        statTmpString = append(statTmpString,'; CV: ', num2str(cv,'%0.3f'));
                    end
                    statString = {append(nString,muString,mdString,stdString);statTmpString};
                    statString = string(statString);
                    % Add string to each label. If CB is not selected, then
                    % hide them.
                    statLabel.Visible = 'on';
                    statLabel.Text = statString;
                else
                    statLabel.Visible = 'off';
                end
                
                % Now, compute graphical outputs
                % March 6, 2021. Added the possibility to show
                % quantile-quantile (QQ) plot and cumulative distribution
                % function of the data. These can be used as a further
                % graphical prove to test 'normality' of the distribution.
                if app.QQorCdfCB.Value
                    if app.QQRB.Value
                        % I could also have used qqplot() function, which
                        % is more generic. normplot() is specific for the
                        % comparison with normal distribution.
                        qq = normplot(ax, matrix);
                        % I want to set the same limits for all QQ plots.
                        % I'm taking the highest X extreme among all active
                        % plots, and I'm storing it into app.QQPlotLimits
                        % property. This property is cleared at the end of
                        % the ShowCCDistribution callback, which is
                        % downstream this function.
                        qq_MX = max(abs(qq(length(qq)).XData)); % Maximum absolute value of XData
                        if isempty(app.QQPlotLimits)
                            app.QQPlotLimits = qq_MX; % Take max X of first plot...
                        else % ...and then do the same for all the other active plots.
                            if qq_MX > app.QQPlotLimits
                                app.QQPlotLimits = qq_MK;
                            end
                        end
                        % Limits are imposed to all plots at each iteration
                        QQxlims = [-app.QQPlotLimits,app.QQPlotLimits];
                        if ~isempty(app.ccm.m1)
                            app.CCPlot1.XLim = QQxlims;
                        end
                        if ~isempty(app.ccm.m2)
                            app.CCPlot2.XLim = QQxlims;
                        end
                        if ~isempty(app.ccm.m3)
                            app.CCPlot3.XLim = QQxlims;
                        end
                        if ~isempty(app.CCMatrixPerm)
                            app.CCPlotRandomPermutation.XLim = QQxlims;
                        end
                    else % If Cumulative distribution function is selected
                        % First, standardize data
                        x = (matrix-mean(matrix))/std(matrix);
                        % Plot cdf. I couldn't inherit directly cdfplot
                        % output, so cdfdata structure was created.
                        [cdfdata,~] = cdfplot(x);
                        plot(ax, cdfdata.XData,cdfdata.YData);
                        tmp_cdfdata = cdfdata.XData(~isinf(cdfdata.XData));
                        close(gcf);
                        % And merge with cdf of standard normal
                        hold(ax,"on")
                        x_values = linspace(min(x),max(x));
                        plot(ax, x_values,normcdf(x_values,0,1),'r-')
                        legend(ax, 'Empirical CDF','Standard Normal CDF','Location','best')
                        hold(ax,'off')
                        % In order to impose equal X limits to all the existing
                        % Cdfs, I'm using the same approach as for the QQ
                        % plots. See above for more details. Not elegant...
                        cdf_MX = max(abs(tmp_cdfdata)); % Maximum absolute value of XData
                        if isempty(app.CdfPlotLimits)
                            app.CdfPlotLimits = cdf_MX; % Take max X of first plot...
                        else % ...and then do the same for all the other active plots.
                            if cdf_MX > app.QQPlotLimits
                                app.CdfPlotLimits = cdf_MK;
                            end
                        end
                        % Limits are imposed to all plots at each iteration
                        Cdfxlims = [-app.CdfPlotLimits,app.CdfPlotLimits];
                        if ~isempty(app.ccm.m1)
                            app.CCPlot1.XLim = Cdfxlims;
                        end
                        if ~isempty(app.ccm.m2)
                            app.CCPlot2.XLim = Cdfxlims;
                        end
                        if ~isempty(app.ccm.m3)
                            app.CCPlot3.XLim = Cdfxlims;
                        end
                        if ~isempty(app.CCMatrixPerm)
                            app.CCPlotRandomPermutation.XLim = Cdfxlims;
                        end
                    end
                    % Preserve title and subtitle
                    title(ax, titleStr);
                    subtitle(ax, subtitleStr)
                    % Just to make sure plotbox is preserved (after Save feature e.g.)
                    ax.PlotBoxAspectRatio = [1 1 1];
                    return % End here
                end
                
                % If no other mutually-exclusive functions were selected,
                % then plot simply the histograms.
                %                 histogram(ax,matrix, b,"Normalization","probability");
                histogram(ax,matrix, "BinWidth",b,"Normalization","probability"); % Oct 2021
                % Set limits
                if app.DistributionAutoRB.Value % Auto
                    % If auto is selected, center the histogram at 0.
                    % Limits are selected by taking into account the
                    % absolute highest value of the corresponding CC
                    % matrix. Most likely, it will be a positive value
                    % (positive correlation).
                    M = max(matrix,[],'all');
                    m = min(matrix,[],'all');
                    lim = max(abs([M,m]));
                    ax.XLim = [-lim, lim];
                    % With YLimMode auto, max peak of the histogram will be
                    % around (less than) 1. Note that log scale might or
                    % not be applied.
                    ax.YLimMode = 'auto';
                else
                    % Otherwise, utilize EFs' values
                    ax.XLim = [app.DistributionXMinEF.Value,app.DistributionXMaxEF.Value];
                    ax.YLim = [app.DistributionYMinEF.Value,app.DistributionYMaxEF.Value];
                end
                
                % Log scale
                if app.SetLogCCDistributionCB.Value
                    ax.YScale = 'log';
                else
                    ax.YScale = 'linear';
                end
                
                % Add also vertical line at 0
                if app.X0LineCB.Value
                    hold(ax, 'on');
                    xline(ax,0,'--r','LineWidth', 2)
                    hold(ax, 'off');
                end
                
            else
                plotMatrixAndColorbar(app,matrix,ax) % If CB is unchecked, display the CC matrices
                statLabel.Visible = 'off'; % Hide stats
            end
            % Preserve title and subtitle
            title(ax, titleStr);
            subtitle(ax, subtitleStr)
            % Just to make sure plotbox is preserved (after Save feature e.g.)
            ax.PlotBoxAspectRatio = [1 1 1];
            
            % March, 31. Save histogram matrices.
            % If button is pressed, save histogram
            % matrices as .m files. They will be compared in a third
            % app. A temporary property is needed to start this
            % statement, and at the end of it it is cleared.
            if app.ExportHistogramLogic == 1
                fname = sprintf(app.par.parentApp.fileIn);
                fname = strrep(fname,'.',''); % Remove '.'
                fname = strrep(fname,' ',''); % Remove spaces ' '
                %% Edit for CodeOcean format. Save results in appropriate folder.

                outputFolderTmp1=pwd;
                outputFolderTmp2=fileparts(outputFolderTmp1);

                outputFolderParent=append(outputFolderTmp2,filesep,'results',filesep);
                if exist(outputFolderParent, 'dir') ~= 7
                    mkdir(outputFolderParent);
                end
                outputFolder=append(outputFolderParent,'Cross-correlation',filesep);
                if exist(outputFolder, 'dir') ~= 7
                    mkdir(outputFolder);
                end
                % fld = 'Cross-correlation\';
                fld=outputFolder;
                expTit = append(fld,fname,'-DIB.mat');

                % Function save() automatically replaces files with same
                % name. This is a temporary workaround, and it is very nasty because
                % doesn't cancel previus numbers. E.g., if you have three copies,
                % instead of being named (1)...(2)...(3), they will be named (1)...(1)(2)...(1)(2)(3).
                % Not that important.
                whileCounter = 0;
                while isfile(expTit) == 1 % While same-named file exists
                    % Remove last part of the string, including the format
                    whileCounter = whileCounter + 1;
                    expTitTmp = strrep(expTit,'-DIB.mat','');
                    % Add reiteration number string inside the file title
                    % and restore format. Not nice.
                    expTit = append(expTitTmp,append('-(',num2str(whileCounter),')'),'-DIB.mat');
                end
                
                % Save matrix
                save(expTit, 'matrix');
                %                 % No other thing is needed, so return
                %                 return
            end
            
        end
        
        %%********* Parameters for saving figures *********%%
        function exportMultipleFigures(app,ax,climits,matrix)
            % Add to tiled
            nexttile
            
            % If distribution visualisation is selected, then it's easier
            % to re-plot the histograms... part of the
            % computeDistributions() function is pasted here
            if app.DisplayCCDistributionsCB.Value
                b = app.DistributionBinEF.Value;
                tmp = nan(app.par.nClusters);
                % This loop takes into account only the points which
                % are beneath the diagonal of a square matrix.
                for i = 1:(app.par.nClusters-1)
                    tmp((1+i),(1:i)) = matrix((1+i),(1:i));
                end
                tmp = reshape(tmp,1,[]);
                matrix = tmp(~isnan(tmp));
                matrix = sort(matrix);
                % Store RP values for statistical comparison...
                if ax == app.CCPlotRandomPermutation
                    app.RandPermDibValues = matrix;
                end
                histogram(matrix, b,"Normalization","probability");
                % Set limits
                if app.DistributionAutoRB.Value % Auto
                    % If auto is selected, center the histogram at 0.
                    % Limits are selected by taking into account the
                    % absolute highest value of the corresponding CC
                    % matrix. Most likely, it will be a positive value
                    % (positive correlation).
                    M = max(matrix,[],'all');
                    m = min(matrix,[],'all');
                    lim = max(abs([M,m]));
                    xlim([-lim, lim]);
                    % With YLimMode auto, max peak of the histogram will be
                    % around (less than) 1. Note that log scale might or
                    % not be applied.
                    set(gca,'YLimMode','auto');
                else
                    % Otherwise, utilize EFs' values
                    xlim([app.DistributionXMinEF.Value,app.DistributionXMaxEF.Value]);
                    ylim([app.DistributionYMinEF.Value,app.DistributionYMaxEF.Value]);
                end
                % Log scale
                if app.SetLogCCDistributionCB.Value
                    set(gca,'YScale','log');
                else
                    set(gca,'YScale','linear');
                end
                % Add also vertical line at 0
                if app.X0LineCB.Value
                    hold(gca, 'on');
                    xline(gca,0,'--r','LineWidth', 2)
                    hold(gca, 'off');
                end
                % Also, preserve graphics, title and subtitle
                title(ax.Title.String);
                subtitle(ax.Subtitle.String);
                set(gca,'PlotBoxAspectRatio', [1 1 1]);
                
            else % In the case histograms visualisation is not selected, then imagesc() CC matrices
                if ~isempty(ax.Children) % Children data is used; this should make things a bit faster
                    lastChildren = length(ax.Children); % Different images (childrens) might belong to the axes
                    tmp = ax.Children(lastChildren).CData; % Take the last one (first ones are 'default' images of MATLAB
                    imagesc(tmp); % Plot it (automatically within tiled layout)
                    set(gca, 'DataAspectRatio', [1 1 1]);
                    set(gca,'PlotBoxAspectRatio',[1 1 1]);
                    set(gca,'CLim',climits) % Unfortunately, making it automatic is not easy. So, selected climits are argument of function
                    % Also, preserve title and subtitle
                    title(ax.Title.String);
                    subtitle(ax.Subtitle.String);
                    % Always show colorbar.
                    colorbar
                end
            end
        end
        
        %%********* Import .mat files for histogram comparison *********%%
        % March 31, 2021.
        function importMatrix(app,ax)
            
            % Get .mat file and load it
            [file,path] = uigetfile;
            filepath = append(path,file);
            load(filepath,'matrix');
            
            % Plot
            histogram(ax, matrix,app.DistributionBinEF.Value,'Normalization','probability')
            title(ax, file)
            set(ax,'PlotBoxAspectRatio',[1 1 1]);
            
            % Store matrix in Import1 or Import2 properties. To do so, use
            % a temporary property
            app.ImportedTmp = matrix;
            % Store titles as well
            app.ImportedTitleTmp = file;
            
        end
        
        %%********* Import .mat files for AB-Normal / DFF maps comparison *********%%
        % March 31, 2021.
        % This function is quite similar to the previous one
        function importABMatrix(app,ax)
            
            % Get .mat file and load it
            [file,path] = uigetfile;
            filepath = append(path,file);
            load(filepath,'ab');
            
            % Plot
            imagesc(ax, ab)
            title(ax, file)
            set(ax,'PlotBoxAspectRatio',[1 1 1]);
            set(ax,'DataAspectRatio',[1 1 1]);
            
            % Store AB in Importab1 or Importab2 properties. To do so, use
            % a temporary property
            app.ImportedABTmp = ab;
            % Store titles as well
            app.ImportedABTitleTmp = file;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function BinaryMapCCMatrix_OpeningFcn(app, parentBinaryApp)
            app.par = parentBinaryApp; %Initialisation and link with Binary Map
            % ...
            % Should I add some other arguments? I don't think so.
            % The name of the argument parentBinaryApp was arbitrary and
            % can be changed.
            
            % Initialize structure properties
            % Store matrices and pair distances
            app.ccm = struct('m1',[],'m2',[],'m3',[],'pd1',[],'pd2',[],'pd3',[]);
            % Store CLims (no substructures are used)
            app.clims = struct('m1Min',[],'m2Min',[],'m3Min',[],'m1Max',[],'m2Max',[],'m3Max',[],'PermMin',[],'PermMax',[]);
            
        end

        % Button pushed function: ComputeCCMatrix1BT
        function plotCC1(app, event)
            app.ComputeCCMatrix1BT.BackgroundColor = [1 0 0];
            drawnow
            
            % Compute CC matrix by taking parameters from Binary Map app GUI.
            computeCCmatrix(app);
            
            % Store data
            app.ccm.m1 = app.par.CC.CCmatrix;
            app.ccm.pd1 = app.par.CC.clDistance;
            
            % Plot Matrix and Colorbar
            matrix = app.ccm.m1;
            ax = app.CCPlot1;
            plotMatrixAndColorbar(app,matrix,ax);
            
            % Change title
            tss = app.TSselection.SelectedObject.Text;
            sc = app.sortingCriteriaRB.SelectedObject.Text;
            SI = string(app.par.SIthr.Value);
            tit = append(tss, ', ' , sc, ', ' ,'SI: ', SI); % Combine strings
            title(app.CCPlot1 , tit);
            % Get plot title string
            app.activeTitle = tit;
            
            % Insert also subtitle
            dl = string(app.DeltaLagEF.Value);
            if app.CenterLagCB.Value % Check if CenterLag was used
                cl = string(app.CenterLagEF.Value);
            else
                cl = '0';
            end
            app.activeSubtitle = append ('DeltaLag = ', dl,'; CenterLag = ', cl);
            subtitle(app.CCPlot1, app.activeSubtitle);
            
            % Save CLims and refresh EFs
            app.clims.m1Min = min(matrix,[],'all');
            app.clims.m1Max = max(matrix,[],'all');
            app.CLims1MinSP.Value = min(matrix,[],'all');
            app.CLims1MaxSP.Value = max(matrix,[],'all');
            
            % Refresh switch graphics
            currentSwitch = app.SwitchCC1SW;
            switchMatrix(app,currentSwitch)
            
            % Refresh forced auto limits. March 2021
            AutoFixedDibLimits(app)
            
            app.ComputeCCMatrix1BT.BackgroundColor = [0.99,0.92,0.80];
            drawnow
        end

        % Button pushed function: ComputeCCMatrix2BT
        function plotCC2(app, event)
            app.ComputeCCMatrix2BT.BackgroundColor = [1 0 0];
            drawnow
            
            % Compute CC matrix by taking parameters from Binary Map app GUI.
            computeCCmatrix(app);
            
            % Store data
            app.ccm.m2 = app.par.CC.CCmatrix;
            app.ccm.pd2 = app.par.CC.clDistance;
            
            % Plot Matrix and Colorbar
            matrix = app.ccm.m2;
            ax = app.CCPlot2;
            plotMatrixAndColorbar(app,matrix,ax);
            
            % Change title
            tss = app.TSselection.SelectedObject.Text;
            sc = app.sortingCriteriaRB.SelectedObject.Text;
            SI = string(app.par.SIthr.Value);
            tit = append(tss, ', ' , sc, ', ' ,'SI: ', SI); % Combine strings
            title(app.CCPlot2 , tit);
            % Get plot title string
            app.activeTitle = tit;
            
            % Insert also subtitle
            dl = string(app.DeltaLagEF.Value);
            if app.CenterLagCB.Value % Check if CenterLag was used
                cl = string(app.CenterLagEF.Value);
            else
                cl = '0';
            end
            app.activeSubtitle = append ('DeltaLag = ', dl,'; CenterLag = ', cl);
            subtitle(app.CCPlot2, app.activeSubtitle);
            
            % Save CLims and refresh EFs
            app.clims.m2Min = min(matrix,[],'all');
            app.clims.m2Max = max(matrix,[],'all');
            app.CLims2MinSP.Value = min(matrix,[],'all');
            app.CLims2MaxSP.Value = max(matrix,[],'all');
            
            % Refresh switch graphics
            currentSwitch = app.SwitchCC2SW;
            switchMatrix(app,currentSwitch)
            
            % Refresh forced auto limits. March 2021
            AutoFixedDibLimits(app)
            
            app.ComputeCCMatrix2BT.BackgroundColor = [0.99,0.92,0.80];
            drawnow
        end

        % Button pushed function: ComputeCCMatrix3BT
        function plotCC3(app, event)
            app.ComputeCCMatrix3BT.BackgroundColor = [1 0 0];
            drawnow
            
            % Compute CC matrix by taking parameters from Binary Map app GUI.
            computeCCmatrix(app);
            
            % Store data
            app.ccm.m3 = app.par.CC.CCmatrix;
            app.ccm.pd3 = app.par.CC.clDistance;
            
            % Plot Matrix and Colorbar
            matrix = app.ccm.m3;
            ax = app.CCPlot3;
            plotMatrixAndColorbar(app,matrix,ax);
            
            % Change title
            tss = app.TSselection.SelectedObject.Text;
            sc = app.sortingCriteriaRB.SelectedObject.Text;
            SI = string(app.par.SIthr.Value);
            tit = append(tss, ', ' , sc, ', ' ,'SI: ', SI); % Combine strings
            title(app.CCPlot3 , tit);
            % Get plot title string
            app.activeTitle = tit;
            
            % Insert also subtitle
            dl = string(app.DeltaLagEF.Value);
            if app.CenterLagCB.Value % Check if CenterLag was used
                cl = string(app.CenterLagEF.Value);
            else
                cl = '0';
            end
            app.activeSubtitle = append ('DeltaLag = ', dl,'; CenterLag = ', cl);
            subtitle(app.CCPlot3, app.activeSubtitle);
            
            % Save CLims and refresh EFs
            app.clims.m3Min = min(matrix,[],'all');
            app.clims.m3Max = max(matrix,[],'all');
            app.CLims3MinSP.Value = min(matrix,[],'all');
            app.CLims3MaxSP.Value = max(matrix,[],'all');
            
            % Refresh switch graphics
            currentSwitch = app.SwitchCC3SW;
            switchMatrix(app,currentSwitch)
            
            % Refresh forced auto limits. March 2021
            AutoFixedDibLimits(app)
            
            app.ComputeCCMatrix3BT.BackgroundColor = [0.99,0.92,0.80];
            drawnow
        end

        % Button pushed function: RandomPermutationBT
        function RandomPermutation(app, event)
            %% Computation of the CC Matrix upon Random circular permutation of the time series.
            % Rocco, January 13 2021
            % The aim is to validate how the cross-correlation changes whether the
            % original source is permuted at randomly imposed offsets.
            % There are two methods.
            % The first one imposes totally random offsets.
            % The second ('fixed') one makes instead sure that every single event
            % within the 'DF U' and 'DF I' traces is never splitted in half (so
            % that these events will be still counted as single events).
            %
            %
            %
            % March 7, 2021. Some things changed.
            % Since we understood that the non-'all'
            % methods were biased, the 'fixed' permutation method - which allows to
            % avoid splitting single time-related events - seems not to be that useful
            % anymore.
            % Now, all the information relative to the permutation process are
            % stored inside the computeRandomPermutation() function,
            % which also comprehends non-fixed method. The non-fixed method
            % simply differs for the lack of a 'while' statement at the
            % start of the function.
            app.RandomPermutationBT.BackgroundColor = [1,0,0];
            drawnow
            
            computeRandomPermutation(app)
            
            % If classic modality is chosen, make sure that the title tells
            % it.
            if ~app.RandPermFixedOffsetsCB.Value
                % Change title
                tss = app.TSselection.SelectedObject.Text;
                sc = app.sortingCriteriaRB.SelectedObject.Text;
                SI = string(app.par.SIthr.Value);
                tit = append('Random permuted: ', tss, ', ' , sc, ', ' , SI); % Combine strings
                title(app.CCPlotRandomPermutation, tit,"FontSize",9);
                % Get plot title string
                app.activeTitle = tit;
                
                % Last matrix is simple permuted
                app.lastMatrix = 1;
            end
            
            % Refresh forced auto limits. March 2021
            AutoFixedDibLimits(app)
            
            % Restore button color
            app.RandomPermutationBT.BackgroundColor = [0.99,0.92,0.80];
            drawnow
        end

        % Value changed function: chooseColormapDD
        function AppyColormap(app, event)
            % Take the value from DD. I decided to not use directly string
            % because that gave problems with creating variable names. This
            % solution is longer but cleaner.
            
            % Pick the numeric value of DD. Each one is associated to a
            % different array of Matlab colormaps.
            v = str2double(app.chooseColormapDD.Value);
            % CMap property has already been defined as equal to hot. See openBTN_Callback
            if    v == 2
                app.cMap = gray;
            elseif   v == 3
                app.cMap = bone;
            elseif   v == 4
                app.cMap = copper;
            elseif   v == 5
                app.cMap = jet;
            elseif   v == 6
                app.cMap = parula;
            elseif   v == 7
                load ('unionjack.mat', "unionjack"); % Just to make sure you can use it on other pcs
                app.cMap = unionjack;
            else % I prefer to keep default map as 'else', just in case of errors within the previous statements
                app.cMap = hot;
            end
            
            % Apply colormap
            set(app.CCPlot1,'Colormap',app.cMap);
            set(app.CCPlot2,'Colormap',app.cMap);
            set(app.CCPlot3,'Colormap',app.cMap);
            set(app.CCPlotRandomPermutation,'Colormap',app.cMap);
        end

        % Value changed function: CLims1MaxSP, CLims1MinSP
        function ApplyCLims1(app, event)
            min = app.CLims1MinSP.Value;
            max = app.CLims1MaxSP.Value;
            if ~isempty(app.ccm.m1)
                app.CCPlot1.CLim = [min max];
            end
        end

        % Value changed function: CLims2MaxSP, CLims2MinSP
        function ApplyCLims2(app, event)
            min = app.CLims2MinSP.Value;
            max = app.CLims2MaxSP.Value;
            if ~isempty(app.ccm.m2)
                app.CCPlot2.CLim = [min max];
            end
        end

        % Value changed function: CLims3MaxSP, CLims3MinSP
        function ApplyCLims3(app, event)
            min = app.CLims3MinSP.Value;
            max = app.CLims3MaxSP.Value;
            if ~isempty(app.ccm.m3)
                app.CCPlot3.CLim = [min max];
            end
        end

        % Value changed function: CLimsPermMaxSP, CLimsPermMinSP
        function ApplyCLimsPerm(app, event)
            min = app.CLimsPermMinSP.Value;
            max = app.CLimsPermMaxSP.Value;
            if ~isempty(app.CCMatrixPerm)
                app.CCPlotRandomPermutation.CLim = [min max];
            end
        end

        % Button pushed function: RestoreDefaultBT
        function RestoreDefault(app, event)
            % Restore the original CLim values
            
            if ~isempty(app.clims.m1Min)
                app.CLims1MinSP.Value = app.clims.m1Min;
                app.CLims1MaxSP.Value = app.clims.m1Max;
                ApplyCLims1(app);
            end
            
            if ~isempty(app.clims.m2Min)
                app.CLims2MinSP.Value = app.clims.m2Min;
                app.CLims2MaxSP.Value = app.clims.m2Max;
                ApplyCLims2(app);
            end
            
            if ~isempty(app.clims.m3Min)
                app.CLims3MinSP.Value = app.clims.m3Min;
                app.CLims3MaxSP.Value = app.clims.m3Max;
                ApplyCLims3(app);
            end
            
            if ~isempty(app.clims.PermMin)
                app.CLimsPermMinSP.Value = app.clims.PermMin;
                app.CLimsPermMaxSP.Value = app.clims.PermMax;
                ApplyCLimsPerm(app);
            end
            
            % Restore default colormap
            app.cMap = parula;
            %app.chooseColormapDD = 6;
            set(app.CCPlot1,'Colormap',app.cMap);
            set(app.CCPlot2,'Colormap',app.cMap);
            set(app.CCPlot3,'Colormap',app.cMap);
            set(app.CCPlotRandomPermutation,'Colormap',app.cMap);
            
        end

        % Button pushed function: AutoCLimsBT
        function UnionjackCLims(app, event)
            % Set symmetric limits for the colormap such that the white color
            % corresponds to the value zero.
            
            % Plot1
            if ~isempty(app.ccm.m1)
                r = app.ccm.m1;
                M = max(abs(r),[],'all');
                app.CCPlot1.CLim = [-M, M];
                app.CLims1MinSP.Value = -M;
                app.CLims1MaxSP.Value = M;
                
            end
            % Plot2
            if ~isempty(app.ccm.m2)
                r = app.ccm.m2;
                M = max(abs(r),[],'all');
                app.CCPlot2.CLim = [-M, M];
                app.CLims2MinSP.Value = -M;
                app.CLims2MaxSP.Value = M;
            end
            % Plot3
            if ~isempty(app.ccm.m3)
                r = app.ccm.m3;
                M = max(abs(r),[],'all');
                app.CCPlot3.CLim = [-M, M];
                app.CLims3MinSP.Value = -M;
                app.CLims3MaxSP.Value = M;
            end
            % Random Perm Plot
            if ~isempty(app.CCMatrixPerm)
                r = app.CCMatrixPerm;
                M = max(abs(r),[],'all');
                app.CCPlotRandomPermutation.CLim = [-M, M];
                app.CLimsPermMinSP.Value = -M;
                app.CLimsPermMaxSP.Value = M;
            end
        end

        % Button pushed function: ComputeClDistBT
        function ComputeClDist(app, event)
            % This plot will show on the x axes the distance between each
            % pair of clusters, and on the y axes the value of the
            % crosscorrelation for each pair.
            % This plot will be generated in relation to the last CC matrix
            % that has been computed, thus relying on the
            % computeCCmatrix(app) OR permute function values.
            
            app.ComputeClDistBT.BackgroundColor = [1,0,0];
            drawnow
            
            % Size of scatter dots
            sz = app.ScatterSizeEF.Value;
            
            if ~isempty(app.par.CC.CCmatrix)
                % Get matrices from the computeCCmatrix(app) function.
                CC = app.par.CC.CCmatrix;
                
                % Jul 2021. Surface and event number were added.
                if app.SCAreaRB.Value
                    ClDist = app.AreaSC;
                    app.CCPlotClDist.XLabel.String = {'Clusters surface (mean)'};
                elseif app.SCEventsRB.Value
                    ClDist =  app.EventsSC;
                    app.CCPlotClDist.XLabel.String = {'Clusters events (sum)'};
                else
                    ClDist =  app.par.CC.clDistance;
                    app.CCPlotClDist.XLabel.String = {'Clusters distance'};
                end
                
                % Erase last plot
                cla(app.CCPlotClDist);
                
                
                % Jan 18, 2021
                % This code computes the scatter plot between the distance and the crosscorrelation of each pair of clusters.
                % I complicated a little bit the following code for two reasons:
                % 1) scatter() doesn't like matrices, but needs vectors, thus I'm plotting one row for each iteration;
                % 2) Most importantly, you want to plot the information obtained from two crosscorrelation matrices.
                % These matrices are of course symmetric, thus it is not nice to plot twice the same scatter dot.
                % The scatter plot will consider only the bottom/left side
                % of the CC matrix.
                % Furthermore, the central diagonal is useless for the computation, because the points within it
                % have null value for both CC and Distance matrices. So I'm keeping it out of the computation.
                l=app.par.nClusters-1; % I need to reduce length by 1 because I'm not considering at all the central diagonal
                if app.ClDistGraphicCB.Value
                    for i = 1:l
                        % Scatter plot needs to be plotted vector by vector.
                        % Here, vectors are the rows of the matrices. The considered columns within each row will stop before reaching the diagonal.
                        s=scatter(app.CCPlotClDist, ClDist((1+i),(1:i)),CC((1+i),(1:i)),[],"blue","filled");
                        hold(app.CCPlotClDist, 'on');
                        % Add fancy effect to filled scatter plot. All the
                        % scatters which fall near (0,0) will have lower
                        % opacity and vice versa. In this way you might
                        % decide to highlight higly correlated, distant
                        % pairs.
                        % Distance from (0,0).
                        distfromzero = sqrt(ClDist((1+i),(1:i)).^2 + CC((1+i),(1:i)).^2);
                        % Distance from (CC=0).
                        %                         distfromzero = CC((1+i),(1:i)).^2; % With the .^2 you can highlight anticorrelated pairs as well
                        s.AlphaData = distfromzero;
                        s.MarkerFaceAlpha = 'flat';
                    end
                else
                    for i = 1:l
                        % Scatter plot needs to be plotted vector by vector.
                        % Here, vectors are the rows of the matrices. The considered columns within each row will stop before reaching the diagonal.
                        scatter(app.CCPlotClDist, ClDist((1+i),(1:i)),CC((1+i),(1:i)),sz,"blue");
                        hold(app.CCPlotClDist, 'on');
                    end
                end
                
                % Add horizontal line at y = 0.
%                 yline(app.CCPlotClDist,0,':','Color','r','LineWidth',3);
                
                % Create subtitle corresponding to active source data
                % Jan, 30 2021:
                % Title string is now stored inside activeTitle property.
                % Property is automatically refreshed even if you
                % pick plot slots by the new switch handles
                % Subtitle() needs R2020b!
                title(app.CCPlotClDist, app.activeTitle);
                subtitle(app.CCPlotClDist, app.activeSubtitle); % IMPORTANT: subtitle() needs R2020b!
                hold(app.CCPlotClDist, 'off');
            end
            
            % Feb 24, 2021. Fix ax limits
            FixScatterLimits(app);
            
            % Jul 2021. Add area as 3d dimension
            %             if app.SurfaceScatterCB.Value
            %                 figure
            %                 for i = 1:l
            %                     scatter3(ClDist((1+i),(1:i)),CC((1+i),(1:i)),app.AreaSC((1+i),(1:i)),sz,"blue");
            %                     hold on;
            %                     hold(gca,'on')
            %                 end
            %                 xlabel('Pair Distance')
            %                 ylabel('Pair crosscorrelation')
            %                 zlabel('Pair surface')
            %
            %                 figure
            %                 for i = 1:l
            %                     % Scatter plot needs to be plotted vector by vector.
            %                     % Here, vectors are the rows of the matrices. The considered columns within each row will stop before reaching the diagonal.
            %                     scatter(app.AreaSC((1+i),(1:i)),CC((1+i),(1:i)),sz,"blue","filled");
            %                     hold on;
            %                     hold(gca, 'on');
            %                 end
            %                 xlabel('Surface')
            %                 ylabel('Crosscorrelation')
            %             end
            
            app.ComputeClDistBT.BackgroundColor = [0.99,0.92,0.80];
        end

        % Value changed function: ShowColorbarCB
        function ColorbarCallback(app, event)
            % This callback is meant to call the plotMatrixAndColorbar
            % function without overwriting the already-plotted
            % matrices.
            
            if ~isempty(app.ccm.m1)
                matrix = app.ccm.m1;
                ax = app.CCPlot1;
                plotMatrixAndColorbar(app,matrix,ax);
            end
            
            if ~isempty(app.ccm.m2)
                matrix = app.ccm.m2;
                ax = app.CCPlot2;
                plotMatrixAndColorbar(app,matrix,ax);
            end
            
            if ~isempty(app.ccm.m3)
                matrix = app.ccm.m3;
                ax = app.CCPlot3;
                plotMatrixAndColorbar(app,matrix,ax);
            end
            
            if ~isempty(app.CCMatrixPerm)
                matrix = app.CCMatrixPerm;
                ax = app.CCPlotRandomPermutation;
                plotMatrixAndColorbar(app,matrix,ax);
            end
            
            
        end

        % Button pushed function: FindPairBT
        function FindPair(app, event)
            % Jan 19 2021
            % This whole callback is really nasty, but it does its job.
            % All I wanted to do was to find the underlying pair of clusters for
            % each selected point of the CC / Distance scatter plot - simply by clicking on it.
            % Tragically, MATLAB doesn't support easy datatips handles
            % within Appdesigner GUI, so a little fantasy was needed.
            % Basically, in order to let this callback work, you need to click
            % on just one single dot of the scatter plot, until its data tip shows up.
            % After data tip shows up, then you have click on the Find BT to launch the callback.
            
            % The final output indices refer to the clusters in 'No sorting' modality.
            
            % Find any pre-existing data tips
            tth = findall(app.CCPlotClDist,'Type','hggroup');
            % Only 1 data tip is supported
            if size(tth,1) ~= 1
                errordlg(['Please, select one single scatter point in order to' ...
                    ' find its corresponding pair of clusters. Data tip must show up.' ...
                    ' Cluster indices refer to "No sorting" method.'],"Find pair of clusters from scatter dot")
                return
            end
            
            % Get data tip coordinates
            a=tth.Position;
            distance=a(1); % Distance of Data tip
            cc=a(2); % CC value of Data tip
            % Original x y sources of scatter plot
            m=app.par.CC.CCmatrix;
            d=app.par.CC.clDistance;
            
            % Find coordinates of CC value within original crosscorrelation matrix.
            [ccxs, ccys] = find(m==cc);
            
            % It is possible that you will find more than one couple of CC
            % coordinates, in the case two pairs of clusters share the same crosscorrelation.
            % Therefore, consider also the x value, i.e. the distance
            [distancexs, distanceys] = find(d==distance);
            
            % Now, you have to compare the coordinates of found values within
            % the CC matrix and the ClDist matrix. You can use intersect to
            % find the common row and column values of the cluster pair(s)
            [~, ix] = intersect(ccxs,distancexs);
            [~, iy] = intersect(ccys,distanceys);
            % ix and iy are the indices in which intersection was successful.
            % Intersect again the indices in order to be sure to find the right couple
            tmpIndex = intersect(ix,iy);
            % At this point, tmpIndex will have one or two indices. The
            % 'two indices' case occurs whenever you find e.g. pair 7
            % and 63 AND pair 63 and 7. Directionality here is not
            % important, so you just take the first couple
            i = tmpIndex(1);
            str = append("(", num2str(ccxs(i))," - ", num2str(ccys(i)),")");
            % Text output
            app.FindPairLabel.Text = str;
        end

        % Button pushed function: CompareBT
        function CompareRasterPermuted(app, event)
            % Jan 21, 2021
            % Create a raster plot in order to compare original source and
            % permuted traces. For the moment, 'raster plot' is simply an
            % imagesc figure, which is easily readable for our purposes.
            % This feature is useful whenever you investigate DF I
            % or DF U traces, which don't have any filling of the 'zero' points. In that case,
            % the non-zero regions are furtherly binarized, so that you will clearly spot them
            % on the raster plot, and the comparison will be easy.
            % Otherwise, whenever you permute the "all" sources, this raster plot
            % will be simply made by an imagesc function, without binarization.
            %
            % Remember that, as regards the 'No event split' method, whenever you are
            % applying it to the 'all' traces, the boundaries of the future semi-random offsets are inherited
            % by the "non-all" corresponding traces.
            
            if ~isempty(app.rasterPermutedSource) && ~isempty(app.rasterPermutedOutput)
                
                % tiledlayout() function allows to create multiple-figure plots. It requires
                % R2019b or later.
                tiledlayout(2,1,'TileSpacing','Compact');
                
                % Colormap. Flipud makes 1 = black and 0 = white
                cm = flipud(gray);
                
                % Top plot
                ax = nexttile;
                x = app.rasterPermutedSource;
                % Binarize plot if you are looking at DF U/I filtered
                % sources
                if app.rasterIsAll == 0
                    x(x~=0) = 1;
                    imagesc(ax,x);
                    ax.Colormap = cm;
                else  % Otherwise simple imagesc() with parula
                    imagesc(ax,x);
                    ax.Colormap = parula;
                end
                
                % Graphics
                set(ax.Title,'String','Original time series');
                
                xlabel 'Time'
                ylabel 'Cluster'
                set(ax,'TickDir','out')
                box off
                
                % Bottom plot: Permuted stack
                ax2 = nexttile;
                y = app.rasterPermutedOutput;
                % Binarize plot if you are looking at DF U/I filtered
                % sources
                if app.rasterIsAll == 0
                    y(y~=0) = 1;
                    imagesc(ax2,y);
                    ax2.Colormap = cm;
                else % Otherwise simple imagesc() with parula
                    imagesc(ax2,y);
                    ax2.Colormap = parula;
                end
                
                % Graphics
                set(ax2.Title,'String','Permuted time series');
                % If 'No split' method was used, then report it within the title
                if app.lastMatrix == 2
                    set(ax2.Title,'String','FIX Permuted time series');
                end
                
                xlabel 'Time';
                ylabel 'Cluster';
                set(ax2,'TickDir','out')
                box off
            end
        end

        % Value changed function: ShowPermutedCB
        function ShowPermutedInParent(app, event)
            % 26 Jan, 2021
            % Plot permuted trace on Binary Map parent application
            if app.ShowPermutedCB.Value
                if ~isempty(app.rasterPermutedOutput)
                    app.par.ChildPermutedTrack = app.rasterPermutedOutput;
                    app.par.ChildRandomOffsets = app.RandomOffsets;
                    plotTimeSeries(app.par); % Refresh
                end
            else
                % If CB is unchecked, empty Child variables
                app.par.ChildPermutedTrack = [];
                app.par.ChildRandomOffsets = [];
                plotTimeSeries(app.par); % Refresh
            end
        end

        % Button pushed function: GenerateRandomPhasesBT
        function GenerateRandomPhases(app, event)
            app.GenerateRandomPhasesBT.BackgroundColor = [1,0,0];
            drawnow
            
            % Initialise original source series
            % For the moment, the source of the data is determined by the app.TSselection
            % RB handles within the crosscorrelation tab
            switch app.TSselection.SelectedObject.Text
                case 'DF intersection'
                    source = 'deltaFI';
                case 'DF union'
                    source = 'deltaFU';
                case 'DF I all'
                    source = 'deltaFIall';
                case 'DF U all'
                    source = 'deltaFUall';
            end
            % Check if a specific sortig criteria has been selected
            switch app.sortingCriteriaRB.SelectedObject.Text
                case 'No sorting'
                    sourceI = 1:app.par.nClusters;
                case 'Area'
                    sourceI = app.par.CC.sortIarea;
                case 'n Events'
                    sourceI = app.par.CC.sortInEvents;
            end
            % This will be the trace used to compute the crosscorrelation
            % and to generate new random phases
            track = app.par.CCregions.(source)(sourceI(:),:);
            track = track';
            
            % Check -> randPhases_real.m
            % Generate phase-randomized sequences
            nrep = app.TrialsEF.Value; % How many "random experiments"
            % Pre-allocate the matrix that will store
            % the random time series that will be generated. ran is
            % a 3D matrix with dimensions:
            %   1) timepoints
            %   2) channels
            %   3) # of the random experiment (from 1 to nrep)
            app.ran = nan([size(track),nrep]);
            % Generate the phase-randomized series
            for i = 1:nrep
                app.ran(:,:,i) = randPhases_real(track,1);
            end
            app.GenerateRandomPhasesBT.BackgroundColor = [0.85,0.92,0.72];
        end

        % Value changed function: SwitchCC1SW
        function SwitchToMatrix1(app, event)
            % Switch matrix
            app.par.CC.CCmatrix = app.ccm.m1;
            app.par.CC.clDistance = app.ccm.pd1;
            
            % CC sorting. Implemented Jan 2021
            % Clusters might be sorted in terms of the integral of their CC.
            %             app.par.CCregions.intCC = sum (app.par.CC.CCmatrix,2);        % Column vector holding the CC summed on all clusters
            % Get sorting by the whole CC Matrix
            app.par.CCregions.intCC = max(app.par.CC.CCmatrix,[],2); % Find max value of each row
            [~,app.par.CC.sortIintCC] = sort(app.par.CCregions.intCC,'descend'); % Sort here the pairs
            % Initialize arrays
            app.par.CC.NNdistance = zeros(app.par.nClusters,1);
            app.par.CC.NNind = zeros(app.par.nClusters,1);
            % Compute the strongest link for each cluster
            for ClI1=1:app.par.nClusters
                tmp = app.par.CC.CCmatrix(ClI1,:);
                [app.par.CC.NNdistance(ClI1), app.par.CC.NNind(ClI1)] = max(tmp);
            end
            
            % March 15, 2021. Total CC score
            % I wanted to create a 'score' of crosscorrelation based on the
            % sum of the crosscorrelation with all the other clusters. A
            % new column has been added in app.par.CCregions.
            % This information is additional to the one provided by the
            % Nearest Neighbour. I would like to make a sort of heatmap out of
            % it.
            %
            % Sometimes, when using grid, some NaNs are found...
            tmpTotScore = app.par.CC.CCmatrix;
            tmpTotScore(isnan(tmpTotScore)) = 0;
            % Sum columns of each row and assign column vector to cluster table.
            app.par.CCregions.totCC = sum(tmpTotScore,2);
            
            % Last matrix is not permuted
            app.lastMatrix = 0;
            % Get plot title string
            app.activeTitle = get(app.CCPlot1, 'Title').String;
            
            % Refresh handles
            currentSwitch = app.SwitchCC1SW;
            switchMatrix(app,currentSwitch);
        end

        % Value changed function: SwitchCC2SW
        function SwitchToMatrix2(app, event)
            % Switch matrix
            app.par.CC.CCmatrix = app.ccm.m2;
            app.par.CC.clDistance = app.ccm.pd2;
            
            % CC sorting. Implemented Jan 2021
            % Clusters might be sorted in terms of the integral of their CC.
            %             app.par.CCregions.intCC = sum (app.par.CC.CCmatrix,2);        % Column vector holding the CC summed on all clusters
            % Get sorting by the whole CC Matrix
            app.par.CCregions.intCC = max(app.par.CC.CCmatrix,[],2); % Find max value of each row
            [~,app.par.CC.sortIintCC] = sort(app.par.CCregions.intCC,'descend'); % Sort here the pairs
            % Initialize arrays
            app.par.CC.NNdistance = zeros(app.par.nClusters,1);
            app.par.CC.NNind = zeros(app.par.nClusters,1);
            % Compute the strongest link for each cluster
            for ClI1=1:app.par.nClusters
                tmp = app.par.CC.CCmatrix(ClI1,:);
                [app.par.CC.NNdistance(ClI1), app.par.CC.NNind(ClI1)] = max(tmp);
            end
            
            % March 15, 2021. Total CC score
            % I wanted to create a 'score' of crosscorrelation based on the
            % sum of the crosscorrelation with all the other clusters. A
            % new column has been added in app.par.CCregions.
            % This information is additional to the one provided by the
            % Nearest Neighbour. I would like to make a sort of heatmap out of
            % it.
            %
            % Sometimes, when using grid, some NaNs are found...
            tmpTotScore = app.par.CC.CCmatrix;
            tmpTotScore(isnan(tmpTotScore)) = 0;
            % Sum columns of each row and assign column vector to cluster table.
            app.par.CCregions.totCC = sum(tmpTotScore,2);
            
            % Last matrix is not permuted
            app.lastMatrix = 0;
            % Get plot title string
            app.activeTitle = get(app.CCPlot2, 'Title').String;
            
            % Refresh handles
            currentSwitch = app.SwitchCC2SW;
            switchMatrix(app,currentSwitch);
        end

        % Value changed function: SwitchCC3SW
        function SwitchToMatrix3(app, event)
            % Switch matrix
            app.par.CC.CCmatrix = app.ccm.m3;
            app.par.CC.clDistance = app.ccm.pd3;
            
            % CC sorting. Implemented Jan 2021
            % Clusters might be sorted in terms of the integral of their CC.
            %             app.par.CCregions.intCC = sum (app.par.CC.CCmatrix,2);        % Column vector holding the CC summed on all clusters
            % Get sorting by the whole CC Matrix
            app.par.CCregions.intCC = max(app.par.CC.CCmatrix,[],2); % Find max value of each row
            [~,app.par.CC.sortIintCC] = sort(app.par.CCregions.intCC,'descend'); % Sort here the pairs
            % Initialize arrays
            app.par.CC.NNdistance = zeros(app.par.nClusters,1);
            app.par.CC.NNind = zeros(app.par.nClusters,1);
            % Compute the strongest link for each cluster
            for ClI1=1:app.par.nClusters
                tmp = app.par.CC.CCmatrix(ClI1,:);
                [app.par.CC.NNdistance(ClI1), app.par.CC.NNind(ClI1)] = max(tmp);
            end
            
            % March 15, 2021. Total CC score
            % I wanted to create a 'score' of crosscorrelation based on the
            % sum of the crosscorrelation with all the other clusters. A
            % new column has been added in app.par.CCregions.
            % This information is additional to the one provided by the
            % Nearest Neighbour. I would like to make a sort of heatmap out of
            % it.
            %
            % Sometimes, when using grid, some NaNs are found...
            tmpTotScore = app.par.CC.CCmatrix;
            tmpTotScore(isnan(tmpTotScore)) = 0;
            % Sum columns of each row and assign column vector to cluster table.
            app.par.CCregions.totCC = sum(tmpTotScore,2);
            
            % Last matrix is not permuted
            app.lastMatrix = 0;
            % Get plot title string
            app.activeTitle = get(app.CCPlot3, 'Title').String;
            
            % Refresh handles
            currentSwitch = app.SwitchCC3SW;
            switchMatrix(app,currentSwitch);
        end

        % Value changed function: SwitchCCPSW
        function SwitchToMatrixP(app, event)
            % Switch matrix
            app.par.CC.CCmatrix = app.CCMatrixPerm;
            app.par.CC.clDistance = app.ClDistancePerm;
            
            % CC sorting. Implemented Jan 2021
            % Clusters might be sorted in terms of the integral of their CC.
            %             app.par.CCregions.intCC = sum (app.par.CC.CCmatrix,2);        % Column vector holding the CC summed on all clusters
            % Get sorting by the whole CC Matrix
            app.par.CCregions.intCC = max(app.par.CC.CCmatrix,[],2); % Find max value of each row
            [~,app.par.CC.sortIintCC] = sort(app.par.CCregions.intCC,'descend'); % Sort here the pairs
            % Initialize arrays
            app.par.CC.NNdistance = zeros(app.par.nClusters,1);
            app.par.CC.NNind = zeros(app.par.nClusters,1);
            % Compute the strongest link for each cluster
            for ClI1=1:app.par.nClusters
                tmp = app.par.CC.CCmatrix(ClI1,:);
                [app.par.CC.NNdistance(ClI1), app.par.CC.NNind(ClI1)] = max(tmp);
            end
            
            % March 15, 2021. Total CC score
            % I wanted to create a 'score' of crosscorrelation based on the
            % sum of the crosscorrelation with all the other clusters. A
            % new column has been added in app.par.CCregions.
            % This information is additional to the one provided by the
            % Nearest Neighbour. I would like to make a sort of heatmap out of
            % it.
            %
            % Sometimes, when using grid, some NaNs are found...
            tmpTotScore = app.par.CC.CCmatrix;
            tmpTotScore(isnan(tmpTotScore)) = 0;
            % Sum columns of each row and assign column vector to cluster table.
            app.par.CCregions.totCC = sum(tmpTotScore,2);
            
            % Last matrix is permuted
            app.lastMatrix = 1; % Actually it could be also == 2 -> FIX rand. But that's not important
            % Get plot title string
            app.activeTitle = get(app.CCPlotRandomPermutation, 'Title').String;
            % Refresh handles
            currentSwitch = app.SwitchCCPSW;
            switchMatrix(app,currentSwitch);
        end

        % Button pushed function: ComputeSynchronizationBT
        function ComputeSynchronization(app, event)
            % Rocco, Jan 27, 2021
            % The code which follows was originally written by Gabriele Nardi.
            % In the future, I will try to bring some little changes, in order
            % to optimize his calculations for calcium imaging purposes.
            % In this case, we are using as time series the sources which
            % were generated by the 'Clusters' function in the Binary Map
            % application.
            % At the end, with the calculation of the Participation Index,
            % we are basically looking for "clusters of clusters".
            
            app.ComputeSynchronizationBT.BackgroundColor = [1 0 0];
            cla(app.SynCCPlot)
            cla(app.EigenvaluesPlot)
            cla(app.ParticipationIndexPlot)
            drawnow
            
            %% INITIALISE SERIES
            % For the moment, the source of the data is determined by the app.TSselection
            % RB handles within the crosscorrelation tab
            switch app.TSselection.SelectedObject.Text
                case 'DF intersection'
                    source = 'deltaFI';
                case 'DF union'
                    source = 'deltaFU';
                case 'DF I all'
                    source = 'deltaFIall';
                case 'DF U all'
                    source = 'deltaFUall';
            end
            % Check if a specific sortig criteria has been selected
            switch app.sortingCriteriaRB.SelectedObject.Text
                case 'No sorting'
                    sourceI = 1:app.par.nClusters;
                case 'Area'
                    sourceI = app.par.CC.sortIarea;
                case 'n Events'
                    sourceI = app.par.CC.sortInEvents;
            end
            % This will be the trace used to compute the crosscorrelation
            % and to generate new random phases
            track = app.par.CCregions.(source)(sourceI(:),:);
            track = track';
            
            % Here I will add the possibility to calculate the integral of
            % the spectrum...
            %             % Compute just the central part of the spectrum, as it happens within first tab
            %             if app.ComputeIntegralSynCB.Value
            %                 track = track - mean(track);
            %             end
            
            %% 1) FULL CROSSCORRELATION
            % Calculate the correlation matrix. Given the formula of the corrcoef
            % function, it should be the same as "standardizig" the channels as done in
            % Li2007 (subtracting the mean and deviding for the standard deviation).
            r = corrcoef(track); % We used before xtrack()
            % Plot the correlation matrix
            imagesc(app.SynCCPlot,r);
            app.SynCCPlot.DataAspectRatio = [1 1 1];
            app.SynCCPlot.PlotBoxAspectRatio = [1 1 1];
            load ('unionjack.mat', "unionjack");
            colormap(app.SynCCPlot,unionjack);
            colorbar(app.SynCCPlot);
            M = max(abs(r),[],'all');
            % Set symmetric limits for the colormap such that the white color
            % corresponds to the value zero
            app.SynCCPlot.CLim = [-M, M];
            
            %% 2) PHASE-RANDOMIZED SERIES have to be previously processed
            % in GenerateRandomPhases callback.
            % The nrep trials will be stored in app.ran property
            if isempty(app.ran)
                errordlg("Generate random phases first","Dependency error")
                return
            end
            nrep = app.TrialsEF.Value; % How many "random experiments"
            
            %% 3) COMPUTE METRICS
            % Compute the eigenvalues and eigenvectors
            [v,tmp] = eig(r);
            % Egenvectors are the columns of the matrix v
            % Eigenvalues are the diagonal elements of tmp, and we will call them
            % lambda
            lambda = diag(tmp);
            % Inizialize the matrix that will contain the eigenvectors from the
            % randomized traces
            lambda_rand = nan([size(lambda,1),nrep]);
            for i = 1:nrep
                [~,tmp] = eig(corrcoef(squeeze(app.ran(:,:,i))));
                lambda_rand(:,i) = diag(tmp);
            end
            % Calculate the "random eigenvalues" mean and std
            lambda_rand_mean = mean(lambda_rand,2);
            lambda_rand_std = std(lambda_rand,[],2);
            % Calculate the syn_index using as a threshold the mean plus "multipl".
            % They use 3.5 since they have 9 channels. We can consider to increase this
            % number
            % Possible proportion:
            % app.KValueSP.Value = 3.5 * size((track),2) / 16;
            multip = app.KValueSP.Value;
            syn_index = (lambda-lambda_rand_mean)./(size(track,2)-lambda_rand_mean)...
                .* double(lambda>(lambda_rand_mean+multip.*lambda_rand_std));
            app.GlobalSynchronizationIndexEF.Value = max(syn_index,[],'all');
            % Calculate number of clusters (actually, clusters of clusters)
            N_clust= logical(syn_index);
            N_clust= sum (N_clust);
            app.NumberofClustersEF.Value = N_clust;
            
            %% 4) VISUALIZE RESULTS
            % Plot eigenvalues
            plot(app.EigenvaluesPlot,lambda_rand_mean,'o-k') % Mean "random" eigenvalues
            hold (app.EigenvaluesPlot,'on')
            plot(app.EigenvaluesPlot,lambda_rand_mean-multip.*lambda_rand_std,'-k');
            plot(app.EigenvaluesPlot,lambda_rand_mean+multip.*lambda_rand_std,'-k');
            plot(app.EigenvaluesPlot, lambda,'o-r') % Actual eigenvalues
            
            % Plot Participation index data
            app.PI = (syn_index').*v.^2;   % Just the one over threshold have been taken in consideration
            %            plot(app.ParticipationIndexPlot,PI(:,end-2:end))
            % Plot all traces or single trace
            trace = app.TraceSP.Value;
            a = app.PI'; % Need this to plot
            if trace == 0
                plot(app.ParticipationIndexPlot,1:app.par.nClusters,app.PI(:,:))
                hold(app.ParticipationIndexPlot,'off');
            else
                plot(app.ParticipationIndexPlot,1:app.par.nClusters,a(end+1-trace,:))
                hold(app.ParticipationIndexPlot,'off');
            end
            app.ComputeSynchronizationBT.BackgroundColor = [0.96,0.96,0.96];
            
            % Set EF limits
            app.TraceSP.Limits = [0,N_clust];
        end

        % Value changed function: TraceSP
        function PIShowSingleTrace(app, event)
            cla(app.ParticipationIndexPlot);
            trace = app.TraceSP.Value;
            a = app.PI'; % Need this to plot
            if trace == 0
                plot(app.ParticipationIndexPlot,1:app.par.nClusters,app.PI(:,:))
                hold(app.ParticipationIndexPlot,'off');
            else
                plot(app.ParticipationIndexPlot,1:app.par.nClusters,a(end+1-trace,:))
                hold(app.ParticipationIndexPlot,'off');
            end
        end

        % Value changed function: FixScatterAxesCB, FixScatterAxesXMaxEF, 
        % ...and 3 other components
        function FixScatterLimits(app, event)
            % Feb, 24 2021. Regulate axes limits of CC/Distance scatter plot
            value = app.FixScatterAxesCB.Value;
            YMLim = max(abs(app.par.CC.CCmatrix),[],'all');
            if value % Fixed
                app.CCPlotClDist.XLim = [app.FixScatterAxesXMinEF.Value app.FixScatterAxesXMaxEF.Value];
                app.CCPlotClDist.YLim = [app.FixScatterAxesYMinEF.Value app.FixScatterAxesYMaxEF.Value];
            else % Automatic
                app.CCPlotClDist.XLim = [0 max(app.par.CC.clDistance,[],'all')];
                app.CCPlotClDist.YLim = [-YMLim YMLim];
            end
        end

        % Callback function: ButtonGroup, DisplayCCDistributionsCB, 
        % ...and 11 other components
        function ShowCCDistribution(app, event)
            % Feb 26. This callback allows to hide the existing CC matrices
            % and show the correspondent distribution histograms instead.
            % Check out computeDistributions() for further comments.
            % This function is applied to all the existing CC figures.
            
            % Arguments are: app, axes, CC data, title, subtitle, and
            % string for the statistics.
            if ~isempty(app.ccm.m1)
                computeDistributions(app,app.CCPlot1,app.ccm.m1,app.CCPlot1.Title.String, ...
                    app.CCPlot1.Subtitle.String, app.NormalScoreLabel1)
            end
            if ~isempty(app.ccm.m2)
                computeDistributions(app,app.CCPlot2,app.ccm.m2,app.CCPlot2.Title.String, ...
                    app.CCPlot2.Subtitle.String, app.NormalScoreLabel2)
            end
            if ~isempty(app.ccm.m3)
                computeDistributions(app,app.CCPlot3,app.ccm.m3,app.CCPlot3.Title.String, ...
                    app.CCPlot3.Subtitle.String, app.NormalScoreLabel3)
            end
            if ~isempty(app.CCMatrixPerm)
                computeDistributions(app,app.CCPlotRandomPermutation,app.CCMatrixPerm, ...
                    app.CCPlotRandomPermutation.Title.String, app.CCPlotRandomPermutation.Subtitle.String, ...
                    app.NormalScoreLabelP)
            end
            
            % Clear limits of QQ plots
            app.QQPlotLimits = [];
            % Clear limits of Cdf plots
            app.CdfPlotLimits = [];
        end

        % Button pushed function: SaveFiguresBT
        function SaveFigures(app, event)
            % Feb 27, 2021.
            % This callback is needed to export the desired
            % figures.
            % At the basis of this callback there is the use of
            % exportgraphics() function, which is available since R2020a.
            % This function can work on an Appdesigner UIAxes, or,
            % obviously, also on an independent figure. What I'm doing here is
            % using both the approaches: if the user selects just one
            % figure, than simply the correspondent UIAxes will be picked.
            % Otherwise, in the case of multiselection, this callback will
            % create an invisible tiledlayout() figure, which allows to
            % merge all the desired plot within a single picture.
            % Moreover, I'm gonna use this multiplot approach also to
            % export all the information that regard the filtering /
            % binarization parameters which were used in parent apps. In
            % this way (which is customizable) you can easily avoid getting
            % lost into all the filtering settings that might be used...
            %
            
            % Count the number of figures checked. All the related
            % checkboxes have the tag 'exp'. The legend also is seen as a
            % figure, so it will have the tag aswell.
            app.SelectedFigures = findall(app.ClustercrosscorrelationUIFigure,'Tag','exp','Value',1);
            nSelectedFigures = length(app.SelectedFigures);
            % Name of original file. It will be printed within the figure,
            % and, moreover, it will be added to the Save file name of the
            % picture.
            fname = sprintf(app.par.parentApp.fileIn);
            % Remove underscores
            fname = regexprep(fname,'_','-');
            % If no figures were selected, do nothing
            if nSelectedFigures == 0
                return
            end
            
            % Case #1: Single picture export
            
            % If only 1 figure is selected
            if nSelectedFigures == 1
                % Get the right plot
                if strcmp(app.SelectedFigures.Text,'CC Matrix #1') == 1
                    ax=app.CCPlot1;
                elseif strcmp(app.SelectedFigures.Text,'CC Matrix #2') == 1
                    ax=app.CCPlot2;
                elseif strcmp(app.SelectedFigures.Text,'CC Matrix #3') == 1
                    ax=app.CCPlot3;
                elseif strcmp(app.SelectedFigures.Text,'CC Matrix #P') == 1
                    ax=app.CCPlotP;
                elseif strcmp(app.SelectedFigures.Text,'CC / Distance scatter') == 1
                    ax=app.CCPlotClDist;
                elseif strcmp(app.SelectedFigures.Text,'Include parent settings') == 1
                    return % If only legend is selected, then don't even show it
                    % March, 3. Boundaries from Binary Map added
                elseif strcmp(app.SelectedFigures.Text,'Boundaries') == 1
                    % Create axes and plot again boundaries (since their
                    % view might not be selected in parent app)
                    ax = axes;
                    set(gcf,"Position",[441,93,534,576]);
                    % Hide it
                    set(gcf,'Visible','off');
                    ax.PlotBoxAspectRatio = [1 1 1];
                    ax.DataAspectRatio = [1 1 1];
                    imshow(log10(app.par.parentApp.mdImage),[]);
                    hold on
                    nevnts = length(app.par.eventFeatures.xyArea);
                    for i=1:nevnts
                        plot(app.par.eventFeatures.Footprint{i}(:,1),...
                            app.par.eventFeatures.Footprint{i}(:,2));
                    end
                    hold off
                    title('Boundaries');
                    % If export mode is turned one, just show the picture
                    % and stop there
                    if app.ExportModePreviewRB.Value
                        title({fname,'Boundaries'});
                        set(gcf,'Visible','on')
                        set(gcf,"Position",[441,93,534,576]);
                        return
                    end
                end
                
                % Add file name to title of single-picture export
                oldTitle = ax.Title.String;
                singleExpTitle = {fname, ... % Title in 2 lines
                    oldTitle};
                
                % If Save RB is checked, then hide figure and export it
                % directly. Otherwise, tiledlayout will be shown, and
                % user might save it manually...
                if app.ExportModeSaveRB.Value
                    % Set default title dinamically
                    % Remove commas and spaces from original title string
                    tmp = regexprep(ax.Title.String, ',' ,'-');
                    tmp2 = regexprep(tmp, ':' ,'');
                    newStr = regexprep(tmp2, ' ' ,'');
                    tit = append(newStr,'.jpg'); % Default is .jpg
                    % Add string 'dib' to default title if distribution modality is selected.
                    % Not valid for scatter plot.
                    if app.DisplayCCDistributionsCB.Value
                        if strcmp(app.SelectedFigures.Text,'CC / Distance scatter') == 0
                            tit = append('DIB ',tit);
                        end
                    end
                    % Store file name string into a property...
                    app.SingleExpTit = append(fname,'---',tit);
                    app.SingleExpTit = regexprep(app.SingleExpTit,'_',' ');
                    %                     ax.Title.String = app.SingleExpTit;
                    filter = {'*.jpg';'*.png';'*.tif';'*.pdf';'*.eps'};
                    % Change briefly the title of in-app axes to print in
                    % the exported picture also the name of the file
                    ax.Title.String = singleExpTitle;
                    % Avoid some bugs regarding plot box which are due to title change
                    preservePosition = ax.Position;
                    [filename,filepath] = uiputfile(filter,'defname',app.SingleExpTit);
                    % Export graphics
                    if ischar(filename)
                        exportgraphics(ax,[filepath filename]);
                    end
                    % And then, restore old title
                    ax.Title.String = oldTitle;
                    ax.Position = preservePosition;
                    close all % Close invisible figures, e.g. boundaries figure
                else % If 'Preview' RB is chosen... just show output onto an external figure
                    title(ax,singleExpTitle);
                    fig = figure("Position",[321,174,392,306],"Units","normalized");
                    ob = copyobj(ax,fig);
                    ob.PlotBoxAspectRatio = [1 1 1];
                    ob.OuterPosition(1) = 0;
                    ob.OuterPosition(2) = 0;
                end
                % Restore old title
                ax.Title.String = oldTitle;
                % Following istructions are for multi-picture export only, so stop here.
                return
            end
            
            % *
            
            % Case #2: Multi-picture export
            %
            % If selected figures are more than 1, then create a proper
            % tiled layout, with the right amount of columns and rows.
            if nSelectedFigures > 1
                app.TiledExport = figure;
                if nSelectedFigures <= 2
                    t = tiledlayout(1,2,"TileSpacing","compact","Padding","compact");
                    % Set size, important for export resolution
                    app.TiledExport.Position = [182,319,929,400];
                elseif nSelectedFigures > 2 && nSelectedFigures <=4
                    t = tiledlayout(2,2,"TileSpacing","compact","Padding","compact");
                    % Set size, important for export resolution
                    app.TiledExport.Position = [338,-178,821,809];
                elseif nSelectedFigures > 4 && nSelectedFigures <=6
                    t = tiledlayout(3,2,"TileSpacing","compact","Padding","compact");
                    % Set size, important for export resolution
                    app.TiledExport.Position = [338,-178,914,814];
                elseif nSelectedFigures > 6
                    % March 3. Added boundaries. If more than 6 selected,
                    % get error
                    close all;
                    errordlg(['Please, deselect one figure in order to' ...
                        ' generate a proper tiled layout'],"You can select up to 6 figures at once");
                    return
                end
                % Add shared title with the name of the file
                % Get file name from imageGateway
                sharedTitleTmp = sprintf(app.par.parentApp.fileIn);
                sharedTitle = regexprep(sharedTitleTmp, '_' ,'-'); % Fix format bug
                title(t,sharedTitle,'Color','r');
                %                 tmp.VerticalAlignment = "bottom";
                %                 tmp.Position(2) = tmp.Position(2)*0.2; % Fix vertical alignment
                % Now, i want to plot all selected figures within
                % tiledlayout. I'm using the same code for the
                % nSelectedFigures == 1 case; although, in this case I
                % prefer to take advantage of the function called
                % exportMultipleFigures, which is applied to all the
                % instances.
                if ~isempty(findall(app.SelectedFigures,'Text','CC / Distance scatter'))
                    ax=app.CCPlotClDist;
                    % Feb 28, 2021.
                    % This is a particular case, which doesn't use the
                    % common function exportMultipleFigures.
                    %
                    % In the case of the scatter plot, in fact, you can't get
                    % full children data because it was obtained with a for
                    % loop.
                    % So, initially I thought of using copyobj()
                    % function.
                    % Unfortunately, if you try to paste directly the copied object
                    % onto the tiled layout, some problems related to the relative position
                    % within the figure will emerge.
                    %
                    % So, at the end, I decided to put different approaches together: I am
                    % taking a frame of the copied object (which is easier
                    % to manipulate) and then finally I am putting into
                    % tiledlayout.
                    
                    % First, create an invisible figure which will be used to make
                    % a screenshot of its content. I preferred to operate the
                    % screenshot onto a separate figure since in this way
                    % you can adjust some parameters, e.g. position and
                    % size.
                    tmpfig = figure('Visible',"on");
                    % Copyobj() from appdesigner to external figure
                    tmp = copyobj(ax,tmpfig);
                    tmp.PlotBoxAspectRatio = [1 1 1];
                    % Hide textual information
                    tmp.Title.Visible ="off";
                    tmp.Subtitle.Visible ="off";
                    tmpfig.Children.XLabel.String = '';
                    tmpfig.Children.YLabel.String = '';
                    %                     set(gca,'XColor','none')
                    %                     set(gca,'YColor','none')
                    tmp.Units = 'pixels';
                    % Get screenshot within the proper bounding box
                    rect = [-32 -20 288 300];
                    f=getframe(tmp,rect);
                    % Delete invisible figure
                    close(tmpfig)
                    % Then, add screenshot to the tiledlayout. Things such as title
                    % and subtitle are added again manually, to preserve
                    % resolution
                    nexttile
                    imshow(f.cdata)
                    % Re-add manually textual information
                    str = string(append(ax.Title.String,'\n' ... % Subtitle() strangely doesn't work. \n allows to mimic it.
                        ,ax.Subtitle.String));
                    title(sprintf(str));
                    x = xlabel('Distance of cluster pair','VerticalAlignment','top');
                    x.Position(2) = x.Position(2)*0.9; % Fix vertical alignment
                    y = ylabel('Crosscorrelation');
                    y.Position(1) = y.Position(1)*0.3; % Fix horizontal alignment
                end
                % Insertion of #1,2,3,P plots will be made through a
                % specific function, exportMultipleFigures()
                if  ~isempty(findall(app.SelectedFigures,'Text','CC Matrix #1'))
                    ax=app.CCPlot1;
                    climits = [app.CLims1MinSP.Value app.CLims1MaxSP.Value];
                    matrix = app.ccm.m1;
                    exportMultipleFigures(app,ax,climits,matrix)
                end
                if ~isempty(findall(app.SelectedFigures,'Text','CC Matrix #2'))
                    ax=app.CCPlot2;
                    climits = [app.CLims2MinSP.Value app.CLims2MaxSP.Value];
                    matrix = app.ccm.m2;
                    exportMultipleFigures(app,ax,climits,matrix)
                end
                if ~isempty(findall(app.SelectedFigures,'Text','CC Matrix #3'))
                    ax=app.CCPlot3;
                    climits = [app.CLims3MinSP.Value app.CLims3MaxSP.Value];
                    matrix = app.ccm.m3;
                    exportMultipleFigures(app,ax,climits,matrix)
                end
                if ~isempty(findall(app.SelectedFigures,'Text','CC Matrix Permuted'))
                    ax=app.CCPlotRandomPermutation;
                    climits = [app.CLimsPermMinSP.Value app.CLimsPermMaxSP.Value];
                    matrix = app.CCMatrixPerm;
                    exportMultipleFigures(app,ax,climits,matrix)
                end
                % Boundaries from Binary Map parent application
                if ~isempty(findall(app.SelectedFigures,'Text','Boundaries'))
                    nexttile
                    % More or less, same code as above for single picture
                    imshow(log10(app.par.parentApp.mdImage),[]);
                    hold on
                    nevnts = length(app.par.eventFeatures.xyArea);
                    for i=1:nevnts
                        plot(app.par.eventFeatures.Footprint{i}(:,1),...
                            app.par.eventFeatures.Footprint{i}(:,2));
                    end
                    hold off
                    % Set title and proportions
                    title('Boundaries');
                    daspect([1 1 1]);
                    pbaspect([1 1 1]);
                end
                % Include parent information
                if ~isempty(findall(app.SelectedFigures,'Text','Include parent settings'))
                    % Add 'legend' in which all the relevant information
                    % about previous inputs and outputs are showed
                    nexttile
                    % Axes() has some incompatibilities with tiledlayout.
                    % So, create axes via plot()
                    plot(0,0); hold on; plot(12,12);
                    % Hide box
                    set(gca,'XColor','none')
                    set(gca,'YColor','none')
                    set(gca,'Color',[1.00,0.96,0.96])
                    % Add text with settings information
                    text(2,12,'Parent settings and outputs:',"FontWeight","bold","Color","r")
                    text(2,10,'Image Gateway',"FontWeight","bold")
                    %% Imagegateway data:
                    % Luminance mask
                    if app.par.parentApp.thresholdFck.Value
                        tmp = 'mask = yes;';
                        thrMask = append('thr = ', num2str(app.par.parentApp.thrFtxt.Value));
                        mask = append(tmp,' ', thrMask);
                    else
                        mask = 'mask = no;';
                    end
                    text(2,9,mask)
                    % Parameters of min duration and size
                    mintime = num2str(app.par.parentApp.minDurationTxt.Value);
                    minsize = num2str(app.par.parentApp.minSizeTxt.Value);
                    ts = append('min. duration = ',mintime,'; ','min. px = ',minsize,';');
                    text(2,8,ts)
                    % ABnormal mask
                    if app.par.parentApp.inheritABMaskCB.Value || app.par.parentApp.inheritABMaskRawCB.Value
                        if app.par.parentApp.inheritABMaskCB.Value
                            ab = "AB-normal filter = yes (filtered);";
                        end
                        if app.par.parentApp.inheritABMaskRawCB.Value
                            ab = "AB-normal filter = yes (raw);";
                        end
                    else
                        ab = "AB-normal filter = no;";
                    end
                    text(2,7,ab)
                    %% Binary Map data:
                    text(2,6,'Binary Map',"FontWeight","bold")
                    % Connectivity
                    if app.par.px26Bt.Value
                        conn = 'Connectivity = 26;';
                    elseif app.par.px18Bt.Value
                        conn = 'Connectivity = 18;';
                    elseif app.par.px6Bt.Value
                        conn = 'Connectivity = 6;';
                    end
                    text(2,5,conn);
                    % Source filter
                    if app.par.RawBt.Value
                        source = 'Further size filter = no (Raw);';
                    elseif app.par.filteredBt.Value
                        sourceTmp = 'Further size filter = yes; ';
                        thrSizeFilt = num2str(app.par.minptsdb.Value);
                        source = append(sourceTmp,'thr = ',thrSizeFilt,';');
                    end
                    text(2,4,source);
                    % Total (filtered) events
                    if app.par.AlleventsBt.Value
                        events = app.par.nEventsTxt.Text;
                    elseif app.par.FilteredBt.Value
                        events = app.par.nEventsFltTxt.Text;
                    end
                    text(2,3,events,"Color","b");
                    % Clusters
                    text(2,2,app.par.clusterTxt.Text,"Color","b");
                    % Include discarded events
                    if app.par.includeDiscardedEventsCB.Value
                        text(2,1,append('Include discarded events = yes; ','TOT: ', ...
                            num2str(app.par.nClusters)),"Color","b");
                    else
                        text(2,1,'Include discarded events = no',"Color","b");
                    end
                end
                
                % March 31, 2021. Include statistics labels.
                if ~isempty(findall(app.SelectedFigures,'Text','Include t test label'))
                    nexttile
                    % Axes() has some incompatibilities with tiledlayout.
                    % So, create axes via plot()
                    plot(0,0); hold on; plot(12,12);
                    % Hide box
                    set(gca,'XColor','none')
                    set(gca,'YColor','none')
                    set(gca,'Color',[1.00,0.96,0.96])
                    % Add text with settings information
                    text(0.1,12,'Statistics:',"FontWeight","bold","Color","r")
                    % 1
                    text(0.1,10,'Stat. 1:',"FontWeight","bold");
                    stat1 = text(0.1,9,'//');
                    % 2
                    text(0.1,8,'Stat. 2:',"FontWeight","bold");
                    stat2 = text(0.1,7,'//');
                    % 3
                    text(0.1,6,'Stat. 3:',"FontWeight","bold");
                    stat3 = text(0.1,5,'//');
                    % P
                    text(0.1,4,'Stat. Permuted:',"FontWeight","bold");
                    statP = text(0.1,3,'//');
                    % Find active labels and print them
                    if app.NormalScoreLabel1.Visible == 1 && app.SaveFigure1CB.Value
                        delete(stat1);
                        str = string(app.NormalScoreLabel1.Text);
                        text(0.1,9,str)
                    end
                    if app.NormalScoreLabel2.Visible == 1 && app.SaveFigure2CB.Value
                        delete(stat2);
                        str = string(app.NormalScoreLabel2.Text);
                        text(0.1,7,str)
                    end
                    if app.NormalScoreLabel3.Visible == 1 && app.SaveFigure3CB.Value
                        delete(stat3);
                        str = string(app.NormalScoreLabel3.Text);
                        text(0.1,5,str)
                    end
                    if app.NormalScoreLabelP.Visible == 1 && app.SaveFigurePCB.Value
                        delete(statP);
                        str = string(app.NormalScoreLabelP.Text);
                        text(0.1,3,str)
                    end
                    
                end
                
                % If save RB is checked, hide figure. Otherwise, show tiledlayout and
                % let the user make the choices
                if app.ExportModeSaveRB.Value
                    app.TiledExport.Visible = "off";
                    fname = sprintf(app.par.parentApp.fileIn);
                    tit = append(fname,'_','Results.jpg'); % Default is .jpg
                    filter = {'*.jpg';'*.png';'*.tif';'*.pdf';'*.eps'};
                    [filename,filepath] = uiputfile(filter,'defname',tit);
                    % Export graphics
                    if ischar(filename)
                        exportgraphics(app.TiledExport,[filepath filename]);
                    end
                    % Delete figure
                    close(app.TiledExport);
                end
                
            end
        end

        % Value changed function: AutoFixedDibLimitsBT
        function AutoFixedDibLimits(app, event)
            % March 19, 2021. Automatically toggle fixed limits.
            %
            % Take the max absolute value of all existing limits, and then
            % force 'fixed' visualization option for all
            % existing plots around that value. Existing plots will be
            % histograms and/or scatter plot.
            
            if app.AutoFixedDibLimitsBT.Value
                
                % This function is related to the histogram display
                % modality only, so it does not affect quantile-quantile nor cdf
                % visualisation modalities.
                if app.DisplayCCDistributionsCB.Value && ~app.QQorCdfCB.Value
                    
                    % First. find the max limit value among all active plots
                    l = findall(app.ClustercrosscorrelationUIFigure,'Tag','lim'); % 'lim' tag was set
                    allLimits = zeros(1,8);
                    for i= 1:8 % Vectors didn't seem to work...
                        allLimits(i) = l(i).Value;
                    end
                    maxLimit = max(abs(allLimits));
                    
                    % Then, set new limit interval in EFs for the 'fixed'
                    % visualisation.
                    % Histogram (X only)
                    app.DistributionXMinEF.Value = -maxLimit;
                    app.DistributionXMaxEF.Value = maxLimit;
                    % Scatter plot (Y only)
                    app.FixScatterAxesYMinEF.Value = -maxLimit;
                    app.FixScatterAxesYMaxEF.Value = maxLimit;
                    
                    % Finally, force 'fixed' visualisation of limits...
                    % ...for histograms first...
                    set(app.DistributionFixedRB, 'Value', 1);
                    ShowCCDistribution(app); % Callback
                    % ...and then for scatter plot.
                    set(app.FixScatterAxesCB, 'Value', 1) % No callback needed
                    
                end
            end
        end

        % Button pushed function: ExportHistogramsBT
        function ExportHistograms(app, event)
            % March 31. Save histogram matrices as .mat files.
            %
            % A logic property is created, by which the very same callback
            % and function for displaying histograms are exploited.
            % When this property is 1, then the 'save' option runs, and the rest
            % of the following code is not computed.
            app.ExportHistogramLogic = 1;
            
            % Callback that then calls the computeDistributions(app,...)
            % function for each one of the active plots
            ShowCCDistribution(app);
            
            % Importantly, restore the 0 condition for the logic property
            app.ExportHistogramLogic = 0;
            
            app.ExportHistogramsBT.BackgroundColor = [0.3 0.5 0.2];
        end

        % Button pushed function: Import1BT
        function ImportHistogram1(app, event)
            % Launch dedicated function
            importMatrix(app,app.Import1Plot);
            % Store matrix and title from file name
            app.Imported1 = app.ImportedTmp;
            app.ImportedTitle1 = app.ImportedTitleTmp;
        end

        % Button pushed function: Import2BT
        function ImportHistogram2(app, event)
            % Launch dedicated function
            importMatrix(app,app.Import2Plot);
            % Store matrix and title from file name
            app.Imported2 = app.ImportedTmp;
            app.ImportedTitle2 = app.ImportedTitleTmp;
        end

        % Callback function: FixAutoImportedLimitsBT, ImportUseCustomCB
        function FixAutoImportedLimits(app, event)
            % Check both plots have been imported
            if ~isempty(app.Imported1) && ~isempty(app.Imported2)
                
                % Clear axis, replot, and restore title
                cla(app.Import1Plot,'reset');
                cla(app.Import2Plot,'reset');
                histogram(app.Import1Plot, app.Imported1,app.DistributionBinEF.Value,'Normalization','probability')
                histogram(app.Import2Plot, app.Imported2,app.DistributionBinEF.Value,'Normalization','probability')
                title(app.Import1Plot,app.ImportedTitle1);
                title(app.Import2Plot,app.ImportedTitle2);
                
                % Find maximal absolute value
                max1 = max(abs(app.Imported1));
                max2 = max(abs(app.Imported2));
                maxLim = max(abs(max1),abs(max2));
                % Set it as interval limit for both active plots
                app.Import1Plot.XLim = [-maxLim maxLim];
                app.Import2Plot.XLim = [-maxLim maxLim];
                app.Import1Plot.PlotBoxAspectRatio = [1 1 1];
                app.Import2Plot.PlotBoxAspectRatio = [1 1 1];
                
                % Then, make other adjustements using the handles on the
                % first tab.
                if app.ImportUseCustomCB.Value
                    
                    % Bin
                    histogram(app.Import1Plot, app.Imported1,app.DistributionBinEF.Value,'Normalization','probability')
                    histogram(app.Import2Plot, app.Imported2,app.DistributionBinEF.Value,'Normalization','probability')
                    
                    % X and Y limits
                    app.Import1Plot.XLim = [app.DistributionXMinEF.Value,app.DistributionXMaxEF.Value];
                    app.Import1Plot.YLim = [app.DistributionYMinEF.Value,app.DistributionYMaxEF.Value];
                    app.Import2Plot.XLim = [app.DistributionXMinEF.Value,app.DistributionXMaxEF.Value];
                    app.Import2Plot.YLim = [app.DistributionYMinEF.Value,app.DistributionYMaxEF.Value];
                    
                    % Log scale
                    if app.SetLogCCDistributionCB.Value
                        set(app.Import1Plot,'YScale','log');
                        set(app.Import2Plot,'YScale','log');
                    else
                        set(app.Import1Plot,'YScale','linear');
                        set(app.Import2Plot,'YScale','log');
                    end
                    
                    % Vertical line at 0
                    if app.X0LineCB.Value
                        hold(app.Import1Plot, 'on');
                        xline(app.Import1Plot,0,'--r','LineWidth', 2)
                        hold(app.Import1Plot, 'off');
                        
                        hold(app.Import2Plot, 'on');
                        xline(app.Import2Plot,0,'--r','LineWidth', 2)
                        hold(app.Import2Plot, 'off');
                    end
                    
                    % Again, title
                    title(app.Import1Plot,app.ImportedTitle1);
                    title(app.Import2Plot,app.ImportedTitle2);
                    
                end
            end
        end

        % Button pushed function: ImportKSBT
        function ImportComputeKS(app, event)
            % Compute statistical test to assess similarity between the two
            % imported distribution. KS test seems to be the better option.
            if app.ImportedKSNormalizeCB.Value
                a = normalize(app.Imported1);
                b = normalize(app.Imported2);
            else
                a = app.Imported1;
                b = app.Imported2;
            end
            if ~isempty(a) && ~isempty(b)
                
                % First, get general information of the two sets
                nString1=append('n: ', num2str(length(a)),'; ');
                muString1=append("x̄: ", num2str(mean(a),'%0.1f'),'; ');
                mdString1=append('Md: ', num2str(median(a),'%0.1f'),'; ');
                stdString1=append('Std: ', num2str(std(a),'%0.1f'),';');
                
                nString2=append('n: ', num2str(length(b)),'; ');
                muString2=append("x̄: ", num2str(mean(b),'%0.1f'),'; ');
                mdString2=append('Md: ', num2str(median(b),'%0.1f'),'; ');
                stdString2=append('Std: ', num2str(std(b),'%0.1f'),';');
                
                % Then, perform the Kolmogorov-Smirnov test
                [h, p, stat] = kstest2(a,b,'Alpha',app.ImportKSAlphaEF.Value);
                
                % Append as string all the information regarding the results of the
                % test.
                statTmpString = append(...
                    'Abnormal: ',num2str(h),'; ', ...
                    'Stat.: ',num2str(stat, '%0.3f'),'; ');
                % If p-value is less than 0.001, print it
                if p < 0.001
                    statTmpString = append(statTmpString, 'P-Value: <0.001');
                else
                    statTmpString = append(statTmpString, 'P-Value: ',num2str(p,'%0.4f'));
                end
                % KS also displays critical value
                %                 statTmpString = append(statTmpString,'; CV: ', num2str(cv,'%0.3f'));
                
                % Finally, concatenate all the information in a single
                % string.
                statString = {'Histogram #1';append(nString1,muString1,mdString1,stdString1);''; ...
                    'Histogram #2'; append(nString2,muString2,mdString2,stdString2); ...
                    '';'Kolmogorov-Smirnov Test:';statTmpString};
                statString = string(statString);
                app.ImportKSTextArea.Value = statString;


            end
        end

        % Button pushed function: ImportAB1BT
        function ImportAB1(app, event)
            % Launch dedicated function
            importABMatrix(app,app.ImportAB1Plot);
            % Store matrix and title from file name
            app.ImportedAB1 = app.ImportedABTmp;
            app.ImportedABTitle1 = app.ImportedABTitleTmp;
        end

        % Button pushed function: ImportAB2BT
        function ImportAB2(app, event)
            % Launch dedicated function
            importABMatrix(app,app.ImportAB2Plot);
            % Store matrix and title from file name
            app.ImportedAB2 = app.ImportedABTmp;
            app.ImportedABTitle2 = app.ImportedABTitleTmp;
        end

        % Button pushed function: ImportABMergeBT
        function ImportABMerge(app, event)
            % March 31, 2021.
            % Fuse the two AB-normal / DFF maps together, just for a
            % qualitative analysis.
            figure
            C = imfuse(app.ImportedAB1,app.ImportedAB2,'falsecolor','Scaling','joint','ColorChannels',[1 2 0]);
            imshow(C)
        end

        % Button pushed function: ImportABSubtractNormValuesBT
        function ImportABSubtractNormValues(app, event)
            % March 31, 2021.
            % As a second kind of insight, it might be interesting to
            % subtract the two maps element by element.
            % In order to do so, I thought to normalize the range of the values
            % between 0 and 1.
            % In this way, by subtracting B (presumably post TBS) to A (pre TBS),
            % I would like to consider only the <0 points.
            
            a = app.ImportedAB1;
            b = app.ImportedAB2;
            % Normalize [0,1].
            normA = a - min(a(:));
            normA = normA ./ max(normA(:));
            a = normA;
            
            normB = b - min(b(:));
            normB = normB ./ max(normB(:));
            b = normB;
            
            % Subtract. Then, binarize taking into account only <0 points.
            app.NormSubtractedAB = a - b;
            tmp = app.NormSubtractedAB;
            thr = app.ImportABSubtractEF.Value;
            tmp(tmp>=thr) = 0;
            tmp(tmp<thr) = 1;
            app.NormSubtractedBinAB = tmp;
            
            % Show result.
            figure
            tiledlayout(1,2,"TileSpacing","compact")
            
            nexttile
            imagesc(app.NormSubtractedAB)
            title('Subtraction output')
            pbaspect([1 1 1])
            daspect([1 1 1])
            colorbar
            
            nexttile
            imagesc(app.NormSubtractedBinAB)
            title('Binary mask. 1s are <0')
            pbaspect([1 1 1])
            daspect([1 1 1])
            colorbar
        end

        % Button pushed function: ImportABSaveMaskBT
        function ImportABSaveMask(app, event)
            % March 31, 2021.
            % Save computed bin mask. Ideally, we would like to use it for
            % a new analysis in Imagegateway. I'm going to add a button to
            % Imagegateway to apply this computed mask to the classic
            % analysis.
            if ~isempty(app.NormSubtractedBinAB)
                
                fname = 'MERGEBINMASK.mat';
                fld = 'C:\Users\LaBandaPassante\Documents\MATLAB\Rocco\Maps\Masks\';
                expTit = append(fld,fname);
                
                % Function save() automatically replaces files with same
                % name. This is a temporary workaround...
                whileCounter = 0;
                while isfile(expTit) == 1 % While same-named file exists
                    % Remove last part of the string, including the format
                    whileCounter = whileCounter + 1;
                    expTitTmp = strrep(expTit,'.mat','');
                    % Add reiteration number string inside the file title
                    % and restore format. Not nice.
                    expTit = append(expTitTmp,append('-(',num2str(whileCounter),')'),'.mat');
                    
                end
                
                % Save mask
                MergeABMask = app.NormSubtractedBinAB;
                save(expTit, 'MergeABMask');
                
            end
        end

        % Button pushed function: ImportedKSAnalysisScreenshotBT
        function ImportedKSAnalysisScreenshot(app, event)
            % A screenshot of the entire KS Analysis panel is automatically made via
            % the external screencapture.m script - Copyright (c) 2016, Yair Altman
            % It is better to set dynamic titles, since the screencapture()
            % function automatically replaces already existing files.
            str = app.Import1Plot.Title.String; % Just take one of the two imported plots' titles
            str = append('Comparison KS - ',str,'.jpg');
            screencapture(app.ImportedKSAnalysisPanel,str)
        end

        % Button pushed function: DisplaymergeofhighabnormalityButton
        function displayMergeHighAbnormality(app, event)
            a=binarize(app.ImportedAB1,(median(app.ImportedAB1)+2.5*(std(app.ImportedAB1))));
            b=binarize(app.ImportedAB2,(median(app.ImportedAB2)+2.5*(std(app.ImportedAB2))));
            
            figure
            
            C = imfuse(a,b,'falsecolor','Scaling','joint','ColorChannels',[1 2 0]);
            imshow(C)
            %             daspect = [1 1 1];
            %             pbaspect = [1 1 1]
        end

        % Image clicked function: Epoch
        function TimeShiftOverview(app, event)
            app.Epoch.BackgroundColor = [1 1 0];
            set(app.TimeShiftCB,"Value",true);
            
            figure
            title('Time travellers')
            hold on
            for delay=1:5:400
                
                
                set(app.TimeShiftEF,"Value",delay)
                
                plotCC1(app,event) % Callback to the computation of CC matrix
                matrix = app.par.CC.CCmatrix;
                % it
                % is better to not take into account the whole CC matrix,
                % but just the values which are beneath the NaN diagonal.
                % In order to do so, first create a NaN matrix with the same size
                % of the original one. Fill it with the interesting
                % (non-repeating) values; then, delete NaNs and reshape
                % into a vector. This steps are not so necessary, and they will
                % make the process a bit slower, nevertheless it's better to be
                % cautious.
                tmp = nan(app.par.nClusters);
                % Important: from this moment on, we are going
                % to take into account only the points which
                % are beneath the diagonal of a square matrix. So, the
                % bottom side of the matrix. This will allow to not
                % double the number of observation.
                for i = 1:(app.par.nClusters-1)
                    tmp((1+i),(1:i)) = matrix((1+i),(1:i));
                end
                tmp = reshape(tmp,1,[]);
                matrix = tmp(~isnan(tmp));
                matrixValues = sort(matrix);
                
                
                plot(delay,mean(matrixValues,'all'),"*r"); % This is the mean of the entire, asymettric time-shifed matrix. This will change in order to consider just one of the two halves.
                
                
            end
            
            set(app.Epoch, "BackgroundColor" , 'none');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ClustercrosscorrelationUIFigure = uifigure('Visible', 'off');
            app.ClustercrosscorrelationUIFigure.Name = 'Cluster crosscorrelation';
            app.TabGroup = uitabgroup(app.ClustercrosscorrelationUIFigure);
            app.CrosscorrelationTab = uitab(app.TabGroup);
            app.CrosscorrelationTab.Title = 'Crosscorrelation';
            app.TimeSeriesSourcePanel = uipanel(app.CrosscorrelationTab);
            app.TimeSeriesSourcePanel.Title = 'Time series source';
            app.TSselection = uibuttongroup(app.TimeSeriesSourcePanel);
            app.DFintersectionButton = uiradiobutton(app.TSselection);
            app.DFintersectionButton.Enable = 'off';
            app.DFintersectionButton.Visible = 'off';
            app.DFintersectionButton.Text = 'DF intersection';
            app.DFunionButton = uiradiobutton(app.TSselection);
            app.DFunionButton.Text = 'DF union';
            app.DFIallButton = uiradiobutton(app.TSselection);
            app.DFIallButton.Enable = 'off';
            app.DFIallButton.Visible = 'off';
            app.DFIallButton.Text = 'DF I all';
            app.DFUallButton = uiradiobutton(app.TSselection);
            app.DFUallButton.Text = 'DF U all';
            app.DFUallButton.Value = true;
            app.sortingCriteriaRB = uibuttongroup(app.TimeSeriesSourcePanel);
            app.sortingCriteriaRB.Tooltip = {'Criteria for clusetr sorting'};
            app.sortingCriteriaRB.Title = 'Sorting criteria';
            app.NosortingButton = uiradiobutton(app.sortingCriteriaRB);
            app.NosortingButton.Text = 'No sorting';
            app.nEventsButton = uiradiobutton(app.sortingCriteriaRB);
            app.nEventsButton.Text = 'n Events';
            app.AreaButton = uiradiobutton(app.sortingCriteriaRB);
            app.AreaButton.Text = 'Area';
            app.AreaButton.Value = true;
            app.CCcalculationPanel = uipanel(app.CrosscorrelationTab);
            app.CCcalculationPanel.Title = 'CC calculation';
            app.DeltaLagLabel = uilabel(app.CCcalculationPanel);
            app.DeltaLagLabel.Text = 'Delta Lag:';
            app.DeltaLagEF = uieditfield(app.CCcalculationPanel, 'numeric');
            app.DeltaLagEF.Value = 2;
            app.CenterLagLabel = uilabel(app.CCcalculationPanel);
            app.CenterLagLabel.Text = 'Center Lag:';
            app.CenterLagEF = uieditfield(app.CCcalculationPanel, 'numeric');
            app.CenterLagCB = uicheckbox(app.CCcalculationPanel);
            app.CenterLagCB.Text = '';
            app.TimeshiftLabel = uilabel(app.CCcalculationPanel);
            app.TimeshiftLabel.Text = 'Time shift:';
            app.TimeShiftCB = uicheckbox(app.CCcalculationPanel);
            app.TimeShiftCB.Text = '';
            app.RotateTimeShiftCB = uicheckbox(app.CCcalculationPanel);
            app.RotateTimeShiftCB.Text = 'Positive time shift';
            app.RotateTimeShiftCB.Value = true;
            app.ClDistPanel = uipanel(app.CrosscorrelationTab);
            app.CCPlotClDist = uiaxes(app.ClDistPanel);
            title(app.CCPlotClDist, 'Crosscorrelation against clusters distance')
            xlabel(app.CCPlotClDist, 'Cluster distance')
            ylabel(app.CCPlotClDist, 'Crosscorrelation')
            app.ComputeClDistBT = uibutton(app.ClDistPanel, 'push');
            app.ComputeClDistBT.ButtonPushedFcn = createCallbackFcn(app, @ComputeClDist, true);
            app.ComputeClDistBT.Text = 'Compute ClDist / CC scatter plot';
            app.FindPairBT = uibutton(app.ClDistPanel, 'push');
            app.FindPairBT.ButtonPushedFcn = createCallbackFcn(app, @FindPair, true);
            app.FindPairBT.Text = 'Find pair';
            app.FindPairLabel = uilabel(app.ClDistPanel);
            app.FindPairLabel.Text = '';
            app.FixScatterAxesCB = uicheckbox(app.ClDistPanel);
            app.FixScatterAxesCB.ValueChangedFcn = createCallbackFcn(app, @FixScatterLimits, true);
            app.FixScatterAxesCB.Text = 'Fix:   X                             Y';
            app.FixScatterAxesXMaxEF = uieditfield(app.ClDistPanel, 'numeric');
            app.FixScatterAxesXMaxEF.ValueChangedFcn = createCallbackFcn(app, @FixScatterLimits, true);
            app.FixScatterAxesXMaxEF.Value = 130;
            app.FixScatterAxesYMinEF = uieditfield(app.ClDistPanel, 'numeric');
            app.FixScatterAxesYMinEF.ValueDisplayFormat = '%.0f';
            app.FixScatterAxesYMinEF.ValueChangedFcn = createCallbackFcn(app, @FixScatterLimits, true);
            app.FixScatterAxesYMinEF.Value = -500;
            app.FixScatterAxesYMaxEF = uieditfield(app.ClDistPanel, 'numeric');
            app.FixScatterAxesYMaxEF.ValueDisplayFormat = '%.0f';
            app.FixScatterAxesYMaxEF.ValueChangedFcn = createCallbackFcn(app, @FixScatterLimits, true);
            app.FixScatterAxesYMaxEF.Value = 500;
            app.FixScatterAxesXMinEF = uieditfield(app.ClDistPanel, 'numeric');
            app.FixScatterAxesXMinEF.ValueChangedFcn = createCallbackFcn(app, @FixScatterLimits, true);
            app.PermutationPanel = uipanel(app.CrosscorrelationTab);
            app.CCPlotRandomPermutation = uiaxes(app.PermutationPanel);
            title(app.CCPlotRandomPermutation, 'CC Matrix upon random permutation')
            app.RandomPermutationBT = uibutton(app.PermutationPanel, 'push');
            app.RandomPermutationBT.ButtonPushedFcn = createCallbackFcn(app, @RandomPermutation, true);
            app.RandomPermutationBT.Text = 'Random permutation';
            app.CLimsPermMaxSP = uispinner(app.PermutationPanel);
            app.CLimsPermMaxSP.ValueDisplayFormat = '%.0f';
            app.CLimsPermMaxSP.ValueChangedFcn = createCallbackFcn(app, @ApplyCLimsPerm, true);
            app.CLimsPermMaxSP.Tag = 'lim';
            app.CLimsPermMaxSP.Value = 1;
            app.RandPermFixedOffsetsCB = uicheckbox(app.PermutationPanel);
            app.RandPermFixedOffsetsCB.Tooltip = {'Random offsets of the permutation will fall within the ''zero'' region of the trace. Useful for non-''all'' analysis '};
            app.RandPermFixedOffsetsCB.Text = 'Don''t split events';
            app.CLimsPermMinSP = uispinner(app.PermutationPanel);
            app.CLimsPermMinSP.ValueDisplayFormat = '%.0f';
            app.CLimsPermMinSP.ValueChangedFcn = createCallbackFcn(app, @ApplyCLimsPerm, true);
            app.CLimsPermMinSP.Tag = 'lim';
            app.CLimsLabelP = uilabel(app.PermutationPanel);
            app.CLimsLabelP.Text = 'Limits:';
            app.SwitchCCPSW = uiswitch(app.PermutationPanel, 'rocker');
            app.SwitchCCPSW.ValueChangedFcn = createCallbackFcn(app, @SwitchToMatrixP, true);
            app.SwitchCCPSW.Tag = 'SW';
            app.SwitchCCPSW.Enable = 'off';
            app.NormalScoreLabelP = uilabel(app.PermutationPanel);
            app.NormalScoreLabelP.Tag = 'stat';
            app.NormalScoreLabelP.Visible = 'off';
            app.FlipTimeDirectionCB = uicheckbox(app.PermutationPanel);
            app.FlipTimeDirectionCB.Tooltip = {'Flip time direction of randomly chosen traces  (p = 0.5)'};
            app.FlipTimeDirectionCB.Text = 'Flip time direction';
            app.FlipTimeDirectionCB.Value = true;
            app.TrialsLabel_2 = uilabel(app.PermutationPanel);
            app.TrialsLabel_2.Text = 'Trials:';
            app.PermutationTrialsEF = uieditfield(app.PermutationPanel, 'numeric');
            app.PermutationTrialsEF.Value = 1;
            app.CCSlotsPanel = uipanel(app.CrosscorrelationTab);
            app.CCPlot3 = uiaxes(app.CCSlotsPanel);
            title(app.CCPlot3, 'CC Matrix #3')
            app.CCPlot2 = uiaxes(app.CCSlotsPanel);
            title(app.CCPlot2, 'CC Matrix #2')
            app.CCPlot1 = uiaxes(app.CCSlotsPanel);
            title(app.CCPlot1, 'CC Matrix #1')
            app.SwitchCC1SW = uiswitch(app.CCSlotsPanel, 'rocker');
            app.SwitchCC1SW.ValueChangedFcn = createCallbackFcn(app, @SwitchToMatrix1, true);
            app.SwitchCC1SW.Tag = 'SW';
            app.SwitchCC1SW.Enable = 'off';
            app.SwitchCC2SW = uiswitch(app.CCSlotsPanel, 'rocker');
            app.SwitchCC2SW.ValueChangedFcn = createCallbackFcn(app, @SwitchToMatrix2, true);
            app.SwitchCC2SW.Tag = 'SW';
            app.SwitchCC2SW.Enable = 'off';
            app.SwitchCC3SW = uiswitch(app.CCSlotsPanel, 'rocker');
            app.SwitchCC3SW.ValueChangedFcn = createCallbackFcn(app, @SwitchToMatrix3, true);
            app.SwitchCC3SW.Tag = 'SW';
            app.SwitchCC3SW.Enable = 'off';
            app.ComputeCCMatrix1BT = uibutton(app.CCSlotsPanel, 'push');
            app.ComputeCCMatrix1BT.ButtonPushedFcn = createCallbackFcn(app, @plotCC1, true);
            app.ComputeCCMatrix1BT.Text = 'Compute CC Matrix 1';
            app.ComputeCCMatrix2BT = uibutton(app.CCSlotsPanel, 'push');
            app.ComputeCCMatrix2BT.ButtonPushedFcn = createCallbackFcn(app, @plotCC2, true);
            app.ComputeCCMatrix2BT.Text = 'Compute CC Matrix 2';
            app.ComputeCCMatrix3BT = uibutton(app.CCSlotsPanel, 'push');
            app.ComputeCCMatrix3BT.ButtonPushedFcn = createCallbackFcn(app, @plotCC3, true);
            app.ComputeCCMatrix3BT.Text = 'Compute CC Matrix 3';
            app.CLims1MaxSP = uispinner(app.CCSlotsPanel);
            app.CLims1MaxSP.ValueDisplayFormat = '%.0f';
            app.CLims1MaxSP.ValueChangedFcn = createCallbackFcn(app, @ApplyCLims1, true);
            app.CLims1MaxSP.Tag = 'lim';
            app.CLims1MaxSP.Value = 1;
            app.CLims2MinSP = uispinner(app.CCSlotsPanel);
            app.CLims2MinSP.ValueDisplayFormat = '%.0f';
            app.CLims2MinSP.ValueChangedFcn = createCallbackFcn(app, @ApplyCLims2, true);
            app.CLims2MinSP.Tag = 'lim';
            app.CLims2MaxSP = uispinner(app.CCSlotsPanel);
            app.CLims2MaxSP.ValueDisplayFormat = '%.0f';
            app.CLims2MaxSP.ValueChangedFcn = createCallbackFcn(app, @ApplyCLims2, true);
            app.CLims2MaxSP.Tag = 'lim';
            app.CLims2MaxSP.Value = 1;
            app.CLims3MinSP = uispinner(app.CCSlotsPanel);
            app.CLims3MinSP.ValueDisplayFormat = '%.0f';
            app.CLims3MinSP.ValueChangedFcn = createCallbackFcn(app, @ApplyCLims3, true);
            app.CLims3MinSP.Tag = 'lim';
            app.CLims3MaxSP = uispinner(app.CCSlotsPanel);
            app.CLims3MaxSP.ValueDisplayFormat = '%.0f';
            app.CLims3MaxSP.ValueChangedFcn = createCallbackFcn(app, @ApplyCLims3, true);
            app.CLims3MaxSP.Tag = 'lim';
            app.CLims3MaxSP.Value = 1;
            app.CLims1MinSP = uispinner(app.CCSlotsPanel);
            app.CLims1MinSP.ValueDisplayFormat = '%.0f';
            app.CLims1MinSP.ValueChangedFcn = createCallbackFcn(app, @ApplyCLims1, true);
            app.CLims1MinSP.Tag = 'lim';
            app.CLimsLabel1 = uilabel(app.CCSlotsPanel);
            app.CLimsLabel1.Text = 'Limits:';
            app.CLimsLabel2 = uilabel(app.CCSlotsPanel);
            app.CLimsLabel2.Text = 'Limits:';
            app.CLimsLabel3 = uilabel(app.CCSlotsPanel);
            app.CLimsLabel3.Text = 'Limits:';
            app.NormalScoreLabel2 = uilabel(app.CCSlotsPanel);
            app.NormalScoreLabel2.Tag = 'stat';
            app.NormalScoreLabel2.Visible = 'off';
            app.NormalScoreLabel1 = uilabel(app.CCSlotsPanel);
            app.NormalScoreLabel1.Tag = 'stat';
            app.NormalScoreLabel1.Visible = 'off';
            app.NormalScoreLabel3 = uilabel(app.CCSlotsPanel);
            app.NormalScoreLabel3.Tag = 'stat';
            app.NormalScoreLabel3.Visible = 'off';
            app.TabGroup2 = uitabgroup(app.CrosscorrelationTab);
            app.DistributioncomparisonTab = uitab(app.TabGroup2);
            app.DistributioncomparisonTab.Title = 'Distribution comparison';
            app.DisplayCCDistributionsPanel = uipanel(app.DistributioncomparisonTab);
            app.DisplayCCDistributionsPanel.Title = 'Display CC Distributions';
            app.LimitsButtonGroup = uibuttongroup(app.DisplayCCDistributionsPanel);
            app.LimitsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ShowCCDistribution, true);
            app.LimitsButtonGroup.Title = 'Limits';
            app.DistributionAutoRB = uiradiobutton(app.LimitsButtonGroup);
            app.DistributionAutoRB.Text = 'Auto';
            app.DistributionFixedRB = uiradiobutton(app.LimitsButtonGroup);
            app.DistributionFixedRB.Text = 'Fixed';
            app.DistributionFixedRB.Value = true;
            app.XlimitsLabel = uilabel(app.LimitsButtonGroup);
            app.XlimitsLabel.Text = 'X limits:';
            app.DistributionXMinEF = uieditfield(app.LimitsButtonGroup, 'numeric');
            app.DistributionXMinEF.ValueDisplayFormat = '%.0f';
            app.DistributionXMinEF.ValueChangedFcn = createCallbackFcn(app, @ShowCCDistribution, true);
            app.DistributionXMinEF.Value = -10;
            app.DistributionXMaxEF = uieditfield(app.LimitsButtonGroup, 'numeric');
            app.DistributionXMaxEF.ValueDisplayFormat = '%.0f';
            app.DistributionXMaxEF.ValueChangedFcn = createCallbackFcn(app, @ShowCCDistribution, true);
            app.DistributionXMaxEF.Value = 10;
            app.YlimitsLabel = uilabel(app.LimitsButtonGroup);
            app.YlimitsLabel.Text = 'Y limits:';
            app.DistributionYMaxEF = uieditfield(app.LimitsButtonGroup, 'numeric');
            app.DistributionYMaxEF.ValueDisplayFormat = '%.3f';
            app.DistributionYMaxEF.ValueChangedFcn = createCallbackFcn(app, @ShowCCDistribution, true);
            app.DistributionYMaxEF.Value = 1;
            app.SetLogCCDistributionCB = uicheckbox(app.LimitsButtonGroup);
            app.SetLogCCDistributionCB.ValueChangedFcn = createCallbackFcn(app, @ShowCCDistribution, true);
            app.SetLogCCDistributionCB.Text = 'Y log';
            app.SetLogCCDistributionCB.Value = true;
            app.DistributionYMinEF = uieditfield(app.LimitsButtonGroup, 'numeric');
            app.DistributionYMinEF.ValueChangedFcn = createCallbackFcn(app, @ShowCCDistribution, true);
            app.DistributionYMinEF.Value = 0.001;
            app.AutoFixedDibLimitsBT = uibutton(app.LimitsButtonGroup, 'state');
            app.AutoFixedDibLimitsBT.ValueChangedFcn = createCallbackFcn(app, @AutoFixedDibLimits, true);
            app.AutoFixedDibLimitsBT.Tooltip = {'Force fixed limits around max CC found among active plots'};
            app.AutoFixedDibLimitsBT.Text = {'Fix'; 'all'};
            app.DisplayCCDistributionsCB = uicheckbox(app.DisplayCCDistributionsPanel);
            app.DisplayCCDistributionsCB.ValueChangedFcn = createCallbackFcn(app, @ShowCCDistribution, true);
            app.DisplayCCDistributionsCB.Text = 'Display CC Distributions';
            app.DisplayCCDistributionsCB.Value = true;
            app.BinLabel = uilabel(app.DisplayCCDistributionsPanel);
            app.BinLabel.Text = 'Bin';
            app.DistributionBinEF = uieditfield(app.DisplayCCDistributionsPanel, 'numeric');
            app.DistributionBinEF.ValueChangedFcn = createCallbackFcn(app, @ShowCCDistribution, true);
            app.DistributionBinEF.Value = 0.5;
            app.NormalityTestBG = uibuttongroup(app.DisplayCCDistributionsPanel);
            app.NormalityTestBG.SelectionChangedFcn = createCallbackFcn(app, @ShowCCDistribution, true);
            app.KSRB = uiradiobutton(app.NormalityTestBG);
            app.KSRB.Tooltip = {'Kolmogorov-Smirnov test to assess similarity of sample data to permuted data'};
            app.KSRB.Text = 'KS';
            app.TTestRB = uiradiobutton(app.NormalityTestBG);
            app.TTestRB.Tooltip = {'Student''s t-test for normal distribution with mu = 0 and unknown variance'};
            app.TTestRB.Text = 't-test';
            app.TTestRB.Value = true;
            app.NormalityTestCB = uicheckbox(app.NormalityTestBG);
            app.NormalityTestCB.ValueChangedFcn = createCallbackFcn(app, @ShowCCDistribution, true);
            app.NormalityTestCB.Tooltip = {'Show sample parameters and compute statistical test for normal distribution'};
            app.NormalityTestCB.Text = 'Statistics:';
            app.NormalityTestCB.Value = true;
            app.PearsonRB = uiradiobutton(app.NormalityTestBG);
            app.PearsonRB.Tooltip = {'Pearson''s goodnes-of-fit test for general t-Location Scale distribution (skewed-prone normal)'};
            app.PearsonRB.Text = '𝛘2';
            app.ButtonGroup = uibuttongroup(app.DisplayCCDistributionsPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ShowCCDistribution, true);
            app.CdfRB = uiradiobutton(app.ButtonGroup);
            app.CdfRB.Tooltip = {'Cumulative density function'};
            app.CdfRB.Text = 'Cdf';
            app.QQRB = uiradiobutton(app.ButtonGroup);
            app.QQRB.Tooltip = {'Quantile-Quantile plot'};
            app.QQRB.Text = 'QQ';
            app.QQRB.Value = true;
            app.QQorCdfCB = uicheckbox(app.ButtonGroup);
            app.QQorCdfCB.ValueChangedFcn = createCallbackFcn(app, @ShowCCDistribution, true);
            app.QQorCdfCB.Tooltip = {'Show alternative visualisation modalities'};
            app.QQorCdfCB.Text = '';
            app.X0LineCB = uicheckbox(app.DisplayCCDistributionsPanel);
            app.X0LineCB.ValueChangedFcn = createCallbackFcn(app, @ShowCCDistribution, true);
            app.X0LineCB.Text = 'x=0 line';
            app.X0LineCB.Value = true;
            app.GraphicsTab = uitab(app.TabGroup2);
            app.GraphicsTab.Title = 'Graphics';
            app.GraphicSettingsPanel = uipanel(app.GraphicsTab);
            app.GraphicSettingsPanel.Title = 'Graphics and other settings';
            app.RestoreDefaultBT = uibutton(app.GraphicSettingsPanel, 'push');
            app.RestoreDefaultBT.ButtonPushedFcn = createCallbackFcn(app, @RestoreDefault, true);
            app.RestoreDefaultBT.Text = 'Restore default limits';
            app.AutoCLimsBT = uibutton(app.GraphicSettingsPanel, 'push');
            app.AutoCLimsBT.ButtonPushedFcn = createCallbackFcn(app, @UnionjackCLims, true);
            app.AutoCLimsBT.Text = 'Apply unionjack CLims';
            app.ShowColorbarCB = uicheckbox(app.GraphicSettingsPanel);
            app.ShowColorbarCB.ValueChangedFcn = createCallbackFcn(app, @ColorbarCallback, true);
            app.ShowColorbarCB.Text = 'Show colorbars';
            app.ShowColorbarCB.Value = true;
            app.ColormapLabel = uilabel(app.GraphicSettingsPanel);
            app.ColormapLabel.Text = 'Colormap:';
            app.chooseColormapDD = uidropdown(app.GraphicSettingsPanel);
            app.chooseColormapDD.Items = {'hot', 'gray', 'bone', 'copper', 'jet', 'parula', 'unionjack'};
            app.chooseColormapDD.ItemsData = {'1', '2', '3', '4', '5', '6', '7'};
            app.chooseColormapDD.ValueChangedFcn = createCallbackFcn(app, @AppyColormap, true);
            app.chooseColormapDD.Value = '6';
            app.ShowPermutedCB = uicheckbox(app.GraphicSettingsPanel);
            app.ShowPermutedCB.ValueChangedFcn = createCallbackFcn(app, @ShowPermutedInParent, true);
            app.ShowPermutedCB.Text = 'Show permuted traces in parent';
            app.ClDistGraphicCB = uicheckbox(app.GraphicSettingsPanel);
            app.ClDistGraphicCB.Text = 'Highlight distant correlated pairs in scatter plot';
            app.ScatterSizeLabel = uilabel(app.GraphicSettingsPanel);
            app.ScatterSizeLabel.Text = 'Scatter: Size';
            app.ScatterSizeEF = uieditfield(app.GraphicSettingsPanel, 'numeric');
            app.ScatterSizeEF.Value = 24;
            app.CompareBT = uibutton(app.GraphicSettingsPanel, 'push');
            app.CompareBT.ButtonPushedFcn = createCallbackFcn(app, @CompareRasterPermuted, true);
            app.CompareBT.Text = 'Raster';
            app.ButtonGroup_2 = uibuttongroup(app.GraphicSettingsPanel);
            app.SCDistanceRB = uiradiobutton(app.ButtonGroup_2);
            app.SCDistanceRB.Text = 'Distance';
            app.SCDistanceRB.Value = true;
            app.SCAreaRB = uiradiobutton(app.ButtonGroup_2);
            app.SCAreaRB.Text = 'Area';
            app.SCEventsRB = uiradiobutton(app.ButtonGroup_2);
            app.SCEventsRB.Text = 'Events';
            app.ExportTab = uitab(app.TabGroup2);
            app.ExportTab.Title = 'Export';
            app.SaveFiguresBT = uibutton(app.ExportTab, 'push');
            app.SaveFiguresBT.ButtonPushedFcn = createCallbackFcn(app, @SaveFigures, true);
            app.SaveFiguresBT.Text = 'Save figures';
            app.SelectFiguresPanel = uipanel(app.ExportTab);
            app.SelectFiguresPanel.Title = 'Select multiple figures';
            app.SaveFigure1CB = uicheckbox(app.SelectFiguresPanel);
            app.SaveFigure1CB.Tag = 'exp';
            app.SaveFigure1CB.Text = 'CC Matrix #1';
            app.SaveFigure1CB.Value = true;
            app.SaveFigure2CB = uicheckbox(app.SelectFiguresPanel);
            app.SaveFigure2CB.Tag = 'exp';
            app.SaveFigure2CB.Text = 'CC Matrix #2';
            app.SaveFigure3CB = uicheckbox(app.SelectFiguresPanel);
            app.SaveFigure3CB.Tag = 'exp';
            app.SaveFigure3CB.Text = 'CC Matrix #3';
            app.SaveFigurePCB = uicheckbox(app.SelectFiguresPanel);
            app.SaveFigurePCB.Tag = 'exp';
            app.SaveFigurePCB.Text = 'CC Matrix Permuted';
            app.SaveFigureCCDistCB = uicheckbox(app.SelectFiguresPanel);
            app.SaveFigureCCDistCB.Tag = 'exp';
            app.SaveFigureCCDistCB.Text = 'CC / Distance scatter';
            app.SaveFigureCCDistCB.Value = true;
            app.BoundariesCheckBox = uicheckbox(app.SelectFiguresPanel);
            app.BoundariesCheckBox.Tag = 'exp';
            app.BoundariesCheckBox.Tooltip = {'Include boundaries plot from Binary Map app'};
            app.BoundariesCheckBox.Text = 'Boundaries';
            app.BoundariesCheckBox.Value = true;
            app.SaveFiguresLegendCB = uicheckbox(app.ExportTab);
            app.SaveFiguresLegendCB.Tag = 'exp';
            app.SaveFiguresLegendCB.Tooltip = {'Include a legend with all the settings used in parent apps. Also, interesting outputs are shown'};
            app.SaveFiguresLegendCB.Text = 'Include parent settings';
            app.ExportModeButtonGroup = uibuttongroup(app.ExportTab);
            app.ExportModeButtonGroup.Title = 'Export mode';
            app.ExportModePreviewRB = uiradiobutton(app.ExportModeButtonGroup);
            app.ExportModePreviewRB.Text = 'Preview';
            app.ExportModeSaveRB = uiradiobutton(app.ExportModeButtonGroup);
            app.ExportModeSaveRB.Text = 'Save';
            app.ExportModeSaveRB.Value = true;
            app.ExportHistogramsBT = uibutton(app.ExportTab, 'push');
            app.ExportHistogramsBT.ButtonPushedFcn = createCallbackFcn(app, @ExportHistograms, true);
            app.ExportHistogramsBT.Text = 'Export histograms';
            app.SaveStatisticalTestCB = uicheckbox(app.ExportTab);
            app.SaveStatisticalTestCB.Tag = 'exp';
            app.SaveStatisticalTestCB.Tooltip = {'Include a legend with all the settings used in parent apps. Also, interesting outputs are shown'};
            app.SaveStatisticalTestCB.Text = 'Include t test label';
            app.SaveStatisticalTestCB.Value = true;
            app.NormalizeRangeButtonGroup = uibuttongroup(app.CrosscorrelationTab);
            app.NormalizeRangeButtonGroup.Title = 'xcorr() ''scaleopt''';
            app.NormalizeRangeNoneRB = uiradiobutton(app.NormalizeRangeButtonGroup);
            app.NormalizeRangeNoneRB.Text = 'None';
            app.NormalizedRB = uiradiobutton(app.NormalizeRangeButtonGroup);
            app.NormalizedRB.Text = 'Normalized';
            app.NormalizedRB.Value = true;
            app.TimeShiftEF = uispinner(app.CrosscorrelationTab);
            app.TimeShiftEF.Value = 33;
            app.SynchronizationmeasurementbetaTab = uitab(app.TabGroup);
            app.SynchronizationmeasurementbetaTab.Title = 'Synchronization measurement (beta)';
            app.SynCCPlot = uiaxes(app.SynchronizationmeasurementbetaTab);
            title(app.SynCCPlot, 'Crosscorrelation')
            app.EigenvaluesPlot = uiaxes(app.SynchronizationmeasurementbetaTab);
            title(app.EigenvaluesPlot, 'Eigenvalues')
            xlabel(app.EigenvaluesPlot, 'X')
            ylabel(app.EigenvaluesPlot, 'Y')
            app.ParticipationIndexPlot = uiaxes(app.SynchronizationmeasurementbetaTab);
            title(app.ParticipationIndexPlot, 'Participation Index')
            xlabel(app.ParticipationIndexPlot, 'Non-Nan pixel linear index')
            ylabel(app.ParticipationIndexPlot, 'Participation Index')
            app.SynchronizationIndexLabel = uilabel(app.SynchronizationmeasurementbetaTab);
            app.SynchronizationIndexLabel.Text = 'Synchronization Index';
            app.ComputeSynchronizationBT = uibutton(app.SynchronizationmeasurementbetaTab, 'push');
            app.ComputeSynchronizationBT.ButtonPushedFcn = createCallbackFcn(app, @ComputeSynchronization, true);
            app.ComputeSynchronizationBT.Text = 'Compute';
            app.KLabel = uilabel(app.SynchronizationmeasurementbetaTab);
            app.KLabel.Text = 'K:';
            app.KValueSP = uispinner(app.SynchronizationmeasurementbetaTab);
            app.KValueSP.Value = 3.5;
            app.TrialsLabel = uilabel(app.SynchronizationmeasurementbetaTab);
            app.TrialsLabel.Text = 'Trials:';
            app.TrialsEF = uieditfield(app.SynchronizationmeasurementbetaTab, 'numeric');
            app.TrialsEF.Value = 10;
            app.GlobalSynchronizationIndexLabel = uilabel(app.SynchronizationmeasurementbetaTab);
            app.GlobalSynchronizationIndexLabel.Text = 'Global Synchronization Index:';
            app.GlobalSynchronizationIndexEF = uieditfield(app.SynchronizationmeasurementbetaTab, 'numeric');
            app.NumberofclustersofclustersEditFieldLabel = uilabel(app.SynchronizationmeasurementbetaTab);
            app.NumberofclustersofclustersEditFieldLabel.Text = 'Number of clusters (of clusters):';
            app.NumberofClustersEF = uieditfield(app.SynchronizationmeasurementbetaTab, 'numeric');
            app.GenerateRandomPhasesBT = uibutton(app.SynchronizationmeasurementbetaTab, 'push');
            app.GenerateRandomPhasesBT.ButtonPushedFcn = createCallbackFcn(app, @GenerateRandomPhases, true);
            app.GenerateRandomPhasesBT.Text = 'Generate phase-randomized series';
            app.TraceSpinnerLabel = uilabel(app.SynchronizationmeasurementbetaTab);
            app.TraceSpinnerLabel.Text = 'Trace:';
            app.TraceSP = uispinner(app.SynchronizationmeasurementbetaTab);
            app.TraceSP.ValueChangedFcn = createCallbackFcn(app, @PIShowSingleTrace, true);
            app.ImportComparisonTab = uitab(app.TabGroup);
            app.ImportComparisonTab.Title = 'Imported data comparison';
            app.ImportedKSAnalysisPanel = uipanel(app.ImportComparisonTab);
            app.ImportedKSAnalysisPanel.Title = 'Two-sample Kolmogorov-Smirnov test of imported data';
            app.Import2Plot = uiaxes(app.ImportedKSAnalysisPanel);
            title(app.Import2Plot, 'Imported histogram  #2')
            zlabel(app.Import2Plot, 'Z')
            app.Import1Plot = uiaxes(app.ImportedKSAnalysisPanel);
            title(app.Import1Plot, 'Imported histogram #1')
            zlabel(app.Import1Plot, 'Z')
            app.Import1BT = uibutton(app.ImportedKSAnalysisPanel, 'push');
            app.Import1BT.ButtonPushedFcn = createCallbackFcn(app, @ImportHistogram1, true);
            app.Import1BT.Text = 'Import histogram #1';
            app.Import2BT = uibutton(app.ImportedKSAnalysisPanel, 'push');
            app.Import2BT.ButtonPushedFcn = createCallbackFcn(app, @ImportHistogram2, true);
            app.Import2BT.Text = 'Import histogram #2';
            app.FixAutoImportedLimitsBT = uibutton(app.ImportedKSAnalysisPanel, 'push');
            app.FixAutoImportedLimitsBT.ButtonPushedFcn = createCallbackFcn(app, @FixAutoImportedLimits, true);
            app.FixAutoImportedLimitsBT.Text = 'Fix common limits';
            app.KSResultsTextAreaLabel = uilabel(app.ImportedKSAnalysisPanel);
            app.KSResultsTextAreaLabel.Text = 'KS Results:';
            app.ImportKSTextArea = uitextarea(app.ImportedKSAnalysisPanel);
            app.SignificanceLabel = uilabel(app.ImportedKSAnalysisPanel);
            app.SignificanceLabel.Text = 'Significance α:';
            app.ImportKSAlphaEF = uieditfield(app.ImportedKSAnalysisPanel, 'numeric');
            app.ImportKSAlphaEF.Value = 0.05;
            app.ImportUseCustomCB = uicheckbox(app.ImportedKSAnalysisPanel);
            app.ImportUseCustomCB.ValueChangedFcn = createCallbackFcn(app, @FixAutoImportedLimits, true);
            app.ImportUseCustomCB.Text = 'Use custom from first tab';
            app.ImportUseCustomCB.Value = true;
            app.ImportKSBT = uibutton(app.ImportedKSAnalysisPanel, 'push');
            app.ImportKSBT.ButtonPushedFcn = createCallbackFcn(app, @ImportComputeKS, true);
            app.ImportKSBT.Text = 'Kolmogorov-Smirnov test';
            app.ImportedKSAnalysisScreenshotBT = uibutton(app.ImportedKSAnalysisPanel, 'push');
            app.ImportedKSAnalysisScreenshotBT.ButtonPushedFcn = createCallbackFcn(app, @ImportedKSAnalysisScreenshot, true);
            app.ImportedKSAnalysisScreenshotBT.Text = 'Export result';
            app.ImportedKSNormalizeCB = uicheckbox(app.ImportedKSAnalysisPanel);
            app.ImportedKSNormalizeCB.Text = 'Normalize histograms';
            app.ImportABNormalPanel = uipanel(app.ImportComparisonTab);
            app.ImportABNormalPanel.Title = 'AB-Normal / DFF subtraction analysis';
            app.ImportAB2Plot = uiaxes(app.ImportABNormalPanel);
            title(app.ImportAB2Plot, 'Imported  #2')
            zlabel(app.ImportAB2Plot, 'Z')
            app.ImportAB1Plot = uiaxes(app.ImportABNormalPanel);
            title(app.ImportAB1Plot, 'Imported #1')
            zlabel(app.ImportAB1Plot, 'Z')
            app.ImportABMergeAnalysisPanel = uipanel(app.ImportABNormalPanel);
            app.ImportABMergeAnalysisPanel.Title = 'AB-Normal merge analysis';
            app.ImportABMergeBT = uibutton(app.ImportABMergeAnalysisPanel, 'push');
            app.ImportABMergeBT.ButtonPushedFcn = createCallbackFcn(app, @ImportABMerge, true);
            app.ImportABMergeBT.Text = 'Display merge';
            app.ImportABSubtractNormValuesBT = uibutton(app.ImportABMergeAnalysisPanel, 'push');
            app.ImportABSubtractNormValuesBT.ButtonPushedFcn = createCallbackFcn(app, @ImportABSubtractNormValues, true);
            app.ImportABSubtractNormValuesBT.Text = 'Subtract [0 1] normalized values';
            app.BinthrEditFieldLabel = uilabel(app.ImportABMergeAnalysisPanel);
            app.BinthrEditFieldLabel.Text = 'Bin thr.:';
            app.ImportABSubtractEF = uieditfield(app.ImportABMergeAnalysisPanel, 'numeric');
            app.ImportABSubtractEF.Value = -0.2;
            app.ImportABSaveMaskBT = uibutton(app.ImportABMergeAnalysisPanel, 'push');
            app.ImportABSaveMaskBT.ButtonPushedFcn = createCallbackFcn(app, @ImportABSaveMask, true);
            app.ImportABSaveMaskBT.Text = 'Save computed bin mask';
            app.DisplaymergeofhighabnormalityButton = uibutton(app.ImportABMergeAnalysisPanel, 'push');
            app.DisplaymergeofhighabnormalityButton.ButtonPushedFcn = createCallbackFcn(app, @displayMergeHighAbnormality, true);
            app.DisplaymergeofhighabnormalityButton.Text = 'Display merge of high abnormality';
            app.ImportAB1BT = uibutton(app.ImportABNormalPanel, 'push');
            app.ImportAB1BT.ButtonPushedFcn = createCallbackFcn(app, @ImportAB1, true);
            app.ImportAB1BT.Text = 'Import AB-Normal / DFF #1';
            app.ImportAB2BT = uibutton(app.ImportABNormalPanel, 'push');
            app.ImportAB2BT.ButtonPushedFcn = createCallbackFcn(app, @ImportAB2, true);
            app.ImportAB2BT.Text = 'Import AB-Normal / DFF #2';
            app.TimeshiftoverviewTab = uitab(app.TabGroup);
            app.TimeshiftoverviewTab.Title = 'Time-shift overview';
            app.Epoch = uiimage(app.TimeshiftoverviewTab);
            app.Epoch.ImageClickedFcn = createCallbackFcn(app, @TimeShiftOverview, true);
            app.Epoch.ImageSource = 'Epoch_-_Winged.png';
            % Show the figure after all components are created
            app.ClustercrosscorrelationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BinMapCCMatrix(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ClustercrosscorrelationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)BinaryMapCCMatrix_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ClustercrosscorrelationUIFigure)
        end
    end
end