classdef ImageGateway3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        imageGateway                   matlab.ui.Figure
        FurtherlogicalprocessingPanel  matlab.ui.container.Panel
        timeIterationEF                matlab.ui.control.NumericEditField
        timeIterationCB                matlab.ui.control.CheckBox
        dilateBinaryEF                 matlab.ui.control.NumericEditField
        dilateBinaryCB                 matlab.ui.control.CheckBox
        closeBinaryEF                  matlab.ui.control.NumericEditField
        closeBinaryCB                  matlab.ui.control.CheckBox
        StatusPanel                    matlab.ui.container.Panel
        statusNow                      matlab.ui.control.Label
        history                        matlab.ui.control.Label
        NavigateframestacksPanel       matlab.ui.container.Panel
        advanceMultiFrames             matlab.ui.control.Button
        advance1frame                  matlab.ui.control.Button
        back1Frame                     matlab.ui.control.Button
        backMultiFrames                matlab.ui.control.Button
        currentFrTxt                   matlab.ui.control.Label
        selectFrame                    matlab.ui.control.Slider
        TabGroup                       matlab.ui.container.TabGroup
        LoadTab                        matlab.ui.container.Tab
        displayBtnGroup                matlab.ui.container.ButtonGroup
        ImportMergeBinMaskBT           matlab.ui.control.Button
        inheritABMaskEF                matlab.ui.control.NumericEditField
        MinsizeEditFieldLabel          matlab.ui.control.Label
        inheritABMaskRawCB             matlab.ui.control.CheckBox
        inheritABMaskCB                matlab.ui.control.CheckBox
        BMapp_pb                       matlab.ui.control.Button
        LFPcorrelate                   matlab.ui.control.CheckBox
        createAVIbtn                   matlab.ui.control.Button
        selectInputCh                  matlab.ui.container.ButtonGroup
        Ch1Ch2RB                       matlab.ui.control.RadioButton
        ch2RB                          matlab.ui.control.RadioButton
        ch1RB                          matlab.ui.control.RadioButton
        uipanel2                       matlab.ui.container.ButtonGroup
        aviRB_2                        matlab.ui.control.RadioButton
        TIFFmultiImageRB               matlab.ui.control.RadioButton
        aviRB                          matlab.ui.control.RadioButton
        prairieRB                      matlab.ui.control.RadioButton
        uipanel1                       matlab.ui.container.Panel
        toNaNcb                        matlab.ui.control.CheckBox
        Setto0Button                   matlab.ui.control.Button
        offsetCh2EF                    matlab.ui.control.NumericEditField
        Offsetchannel2Label            matlab.ui.control.Label
        openBTN                        matlab.ui.control.Button
        SamplingsEditField             matlab.ui.control.NumericEditField
        SamplingsEditFieldLabel        matlab.ui.control.Label
        stackSizeTxt                   matlab.ui.control.Label
        offsetCh1EF                    matlab.ui.control.NumericEditField
        Offsetchannel1Label            matlab.ui.control.Label
        resetProcessing                matlab.ui.control.Button
        timeAndSpaceTxt                matlab.ui.control.Label
        moreInfoTxt                    matlab.ui.control.Label
        PreprocessingTab               matlab.ui.container.Tab
        SavePreprocessedImageStackPanel  matlab.ui.container.Panel
        savePreprocessedData           matlab.ui.control.Button
        ClearedgesofimagestackPanel    matlab.ui.container.Panel
        clearTop                       matlab.ui.control.NumericEditField
        TopborttomleftrightpxLabel     matlab.ui.control.Label
        clearRight                     matlab.ui.control.NumericEditField
        clearLeft                      matlab.ui.control.NumericEditField
        clearBottom                    matlab.ui.control.NumericEditField
        clearEdgesBt                   matlab.ui.control.Button
        uipanel5_2                     matlab.ui.container.Panel
        Ch2Label_2                     matlab.ui.control.Label
        KalmanStrCh2                   matlab.ui.control.NumericEditField
        KalmanChSelection              matlab.ui.container.ButtonGroup
        KalmanBoth                     matlab.ui.control.RadioButton
        KalmanCh2                      matlab.ui.control.RadioButton
        KalmanCh1                      matlab.ui.control.RadioButton
        KalmanStrCh1                   matlab.ui.control.NumericEditField
        FilterstrengthCh1Label         matlab.ui.control.Label
        KalmanFlt                      matlab.ui.control.CheckBox
        computeKalman                  matlab.ui.control.Button
        StackCalibrationPanel          matlab.ui.container.Panel
        calibrateStackBT               matlab.ui.control.Button
        FlatCB                         matlab.ui.control.CheckBox
        darkSubtractionCB              matlab.ui.control.CheckBox
        LoadDarkButton                 matlab.ui.control.Button
        LoadFlatFieldButton            matlab.ui.control.Button
        StackregistrationPanel         matlab.ui.container.Panel
        registrationMode               matlab.ui.container.ButtonGroup
        dftregistrationRB              matlab.ui.control.RadioButton
        imregisterRB                   matlab.ui.control.RadioButton
        SpLPfilterCk_3                 matlab.ui.control.CheckBox
        LPregEF                        matlab.ui.control.NumericEditField
        FiltersizepxEditFieldLabel     matlab.ui.control.Label
        LPregCB                        matlab.ui.control.CheckBox
        regOnSquareCB                  matlab.ui.control.CheckBox
        registerStackBT_2              matlab.ui.control.Button
        registerStackBT                matlab.ui.control.Button
        SpLPfilterCk_2                 matlab.ui.control.CheckBox
        selectRegistrationSource       matlab.ui.container.ButtonGroup
        referenceBothRB                matlab.ui.control.RadioButton
        referenceCh2RB                 matlab.ui.control.RadioButton
        referenceCh1RB                 matlab.ui.control.RadioButton
        panelLowPassFilter             matlab.ui.container.Panel
        Ch2Label                       matlab.ui.control.Label
        kernelCh1EF_2                  matlab.ui.control.NumericEditField
        ButtonGroup                    matlab.ui.container.ButtonGroup
        iFilterBothRB                  matlab.ui.control.RadioButton
        iFilterCh2RB                   matlab.ui.control.RadioButton
        iFilterCh1RB                   matlab.ui.control.RadioButton
        kernelCh1EF                    matlab.ui.control.NumericEditField
        KernelPxCh1Label               matlab.ui.control.Label
        SpLPfilterCk                   matlab.ui.control.CheckBox
        filterBtn                      matlab.ui.control.Button
        MapsTab                        matlab.ui.container.Tab
        ABnormalMaps                   matlab.ui.container.ButtonGroup
        exportABNormalBT               matlab.ui.control.Button
        AND_OR_DD_2                    matlab.ui.control.DropDown
        LogicLabel                     matlab.ui.control.Label
        allABsMaskBT                   matlab.ui.control.Button
        GenerateABnormalmaskLabel      matlab.ui.control.Label
        ABThrStdEF                     matlab.ui.control.NumericEditField
        ABThrStdCB                     matlab.ui.control.CheckBox
        getAutoThresholdBT             matlab.ui.control.Button
        getABHistoBT                   matlab.ui.control.Button
        normalAreasThr2EF              matlab.ui.control.NumericEditField
        Thr2EditFieldLabel             matlab.ui.control.Label
        normalAreasThr1EF              matlab.ui.control.NumericEditField
        Thr1EditFieldLabel             matlab.ui.control.Label
        ABmaskCB                       matlab.ui.control.Button
        AND_OR_DD                      matlab.ui.control.DropDown
        ABMaskDisplayChoice2DD         matlab.ui.control.DropDown
        ABMaskDisplayChoice1DD         matlab.ui.control.DropDown
        BetweenLabel                   matlab.ui.control.Label
        CDFbinNumberEF                 matlab.ui.control.NumericEditField
        CDFbindefaultLabel             matlab.ui.control.Label
        computeGaussMap                matlab.ui.control.Button
        SEbinsEditField                matlab.ui.control.NumericEditField
        SEbinsEditFieldLabel           matlab.ui.control.Label
        KSnTxt                         matlab.ui.control.NumericEditField
        ABsourceDF                     matlab.ui.control.RadioButton
        ABsourceF                      matlab.ui.control.RadioButton
        text27                         matlab.ui.control.Label
        statsAndBinarization           matlab.ui.container.Panel
        includeNegDF                   matlab.ui.control.CheckBox
        mskFstats                      matlab.ui.control.Label
        computeRGBtimeMap              matlab.ui.control.Button
        RGBmapRight                    matlab.ui.control.NumericEditField
        RGBmapLeft                     matlab.ui.control.NumericEditField
        RGBintervalsEditFieldLabel     matlab.ui.control.Label
        minSizeTxt                     matlab.ui.control.NumericEditField
        minDurationTxt                 matlab.ui.control.NumericEditField
        thrStdTxt                      matlab.ui.control.NumericEditField
        thrFtxt                        matlab.ui.control.NumericEditField
        maxDistTxt                     matlab.ui.control.NumericEditField
        minDistTxt                     matlab.ui.control.NumericEditField
        minFdistTxt                    matlab.ui.control.NumericEditField
        binDistTxt                     matlab.ui.control.NumericEditField
        distFbin                       matlab.ui.control.NumericEditField
        maxFdistTxt                    matlab.ui.control.NumericEditField
        text26                         matlab.ui.control.Label
        thresholdFck                   matlab.ui.control.CheckBox
        statsDF                        matlab.ui.control.Label
        computeDFstats                 matlab.ui.control.Button
        computeFstats                  matlab.ui.control.Button
        FstatsTxt                      matlab.ui.control.Label
        globalStatsCk                  matlab.ui.control.CheckBox
        text14                         matlab.ui.control.Label
        text13                         matlab.ui.control.Label
        text12                         matlab.ui.control.Label
        computeBinaryStack             matlab.ui.control.Button
        statsMskDF                     matlab.ui.control.Label
        uipanel9                       matlab.ui.container.Panel
        computeAll                     matlab.ui.control.Button
        uipanel10                      matlab.ui.container.ButtonGroup
        thPercEF                       matlab.ui.control.NumericEditField
        PercentileButton               matlab.ui.control.RadioButton
        MeanButton                     matlab.ui.control.RadioButton
        adaptiveDfCk                   matlab.ui.control.RadioButton
        simpleDfcK                     matlab.ui.control.RadioButton
        computeDf                      matlab.ui.control.Button
        uipanel8                       matlab.ui.container.ButtonGroup
        binMethodDD                    matlab.ui.control.DropDown
        binTxt                         matlab.ui.control.NumericEditField
        computeBinning                 matlab.ui.control.Button
        binRB                          matlab.ui.control.RadioButton
        noBinRB                        matlab.ui.control.RadioButton
        ROIsTab                        matlab.ui.container.Tab
        CROIEditorPaduaPanel           matlab.ui.container.Panel
        ImportBoundariesBT             matlab.ui.control.Button
        ExportBoundariesBT             matlab.ui.control.Button
        DrawBoundariesBT               matlab.ui.control.Button
        ROIcontrolButtonGroup          matlab.ui.container.ButtonGroup
        showROIsCB                     matlab.ui.control.CheckBox
        defRoiRadius                   matlab.ui.control.NumericEditField
        SizeofdefaultROIpxLabel        matlab.ui.control.Label
        CircleBt                       matlab.ui.control.RadioButton
        SquareBt                       matlab.ui.control.RadioButton
        NoROIsBt                       matlab.ui.control.RadioButton
        DisplayTab                     matlab.ui.container.Tab
        uipanel14                      matlab.ui.container.Panel
        restoreDefaultSettingsBT       matlab.ui.control.Button
        timeBarGraphicsCB              matlab.ui.control.CheckBox
        chooseColormapDD               matlab.ui.control.DropDown
        ColormapLabel                  matlab.ui.control.Label
        applyThrF                      matlab.ui.control.CheckBox
        thrF                           matlab.ui.control.NumericEditField
        ABmaxb                         matlab.ui.control.NumericEditField
        ABminb                         matlab.ui.control.NumericEditField
        ABmax                          matlab.ui.control.NumericEditField
        ABmaxLabel                     matlab.ui.control.Label
        ABmin                          matlab.ui.control.NumericEditField
        ABminLabel                     matlab.ui.control.Label
        FmaxTxt                        matlab.ui.control.NumericEditField
        FmaxLabel                      matlab.ui.control.Label
        FminTxt                        matlab.ui.control.NumericEditField
        FminLabel                      matlab.ui.control.Label
        DFmaxTxt                       matlab.ui.control.NumericEditField
        DFFmaxLabel                    matlab.ui.control.Label
        DFminTxt                       matlab.ui.control.NumericEditField
        DFFminEditFieldLabel           matlab.ui.control.Label
        RightWindowPanel               matlab.ui.container.Panel
        animateBinBG                   matlab.ui.container.ButtonGroup
        applyMaskAnimateCB             matlab.ui.control.CheckBox
        animateBinBT                   matlab.ui.control.Button
        animateBinDFFStackRB           matlab.ui.control.RadioButton
        animateBinFluoStackRB          matlab.ui.control.RadioButton
        animateBinCurrentOutRB         matlab.ui.control.RadioButton
        uibuttongroup7                 matlab.ui.container.ButtonGroup
        mergeColorsCB                  matlab.ui.control.CheckBox
        filteredBinRB                  matlab.ui.control.RadioButton
        binaryRawRB                    matlab.ui.control.RadioButton
        binaryImageSource              matlab.ui.container.ButtonGroup
        SERBb                          matlab.ui.control.RadioButton
        RGBcoding                      matlab.ui.control.RadioButton
        areaRBb                        matlab.ui.control.RadioButton
        KSRBb                          matlab.ui.control.RadioButton
        KSstdRBb                       matlab.ui.control.RadioButton
        areaStdRBb                     matlab.ui.control.RadioButton
        FbinRB                         matlab.ui.control.RadioButton
        binMaxRB                       matlab.ui.control.RadioButton
        LeftWindowPanel                matlab.ui.container.Panel
        animateStackBG                 matlab.ui.container.ButtonGroup
        animateStackABMaskCB           matlab.ui.control.CheckBox
        animateStackBT                 matlab.ui.control.Button
        animateStackDFFRB              matlab.ui.control.RadioButton
        animateStackFluoRB             matlab.ui.control.RadioButton
        applyABmaskCB                  matlab.ui.control.CheckBox
        maskOn                         matlab.ui.control.CheckBox
        IWautoBrCB                     matlab.ui.control.CheckBox
        displayROIs                    matlab.ui.control.CheckBox
        IWsource                       matlab.ui.container.ButtonGroup
        medianFluoRB_2                 matlab.ui.control.RadioButton
        IWdFRB                         matlab.ui.control.RadioButton
        IWdFmaxRB                      matlab.ui.control.RadioButton
        IWmedianFluoRB                 matlab.ui.control.RadioButton
        SERB                           matlab.ui.control.RadioButton
        KSstdRB                        matlab.ui.control.RadioButton
        areaRB                         matlab.ui.control.RadioButton
        areaStdRB                      matlab.ui.control.RadioButton
        KSRB                           matlab.ui.control.RadioButton
        IWfluoRB                       matlab.ui.control.RadioButton
        IWmeanFluoRB                   matlab.ui.control.RadioButton
        IWmaxFluoRB                    matlab.ui.control.RadioButton
        IWselectCh                     matlab.ui.container.ButtonGroup
        IWfluoCh1Ch2RB                 matlab.ui.control.RadioButton
        IWfluoCh2RB                    matlab.ui.control.RadioButton
        IWfluoCh1RB                    matlab.ui.control.RadioButton
        uipanel23                      matlab.ui.container.Panel
        text17                         matlab.ui.control.Label
        timeBarUIAxes_Bin              matlab.ui.control.UIAxes
        binaryExplore                  matlab.ui.control.UIAxes
        uipanel21                      matlab.ui.container.Panel
        uibuttongroupDistribution      matlab.ui.container.ButtonGroup
        maskedDFck                     matlab.ui.control.RadioButton
        maskedBinnedFck                matlab.ui.control.RadioButton
        distributionLogCk              matlab.ui.control.CheckBox
        dFck                           matlab.ui.control.RadioButton
        binnedFck                      matlab.ui.control.RadioButton
        distributionW                  matlab.ui.control.UIAxes
        uipanel11                      matlab.ui.container.Panel
        traceToClipboard               matlab.ui.control.Button
        currentFrameTxt                matlab.ui.control.Label
        LogPlotCB                      matlab.ui.control.CheckBox
        uipanel13                      matlab.ui.container.ButtonGroup
        binaryRB                       matlab.ui.control.RadioButton
        plotDeltaFcK                   matlab.ui.control.RadioButton
        plotBinnedFcK                  matlab.ui.control.RadioButton
        plotFcK                        matlab.ui.control.RadioButton
        timeExplore                    matlab.ui.control.UIAxes
        uipanel12                      matlab.ui.container.Panel
        pointerInfoTxt                 matlab.ui.control.Label
        animateFCK                     matlab.ui.control.CheckBox
        timeBarUIAxes_Disp             matlab.ui.control.UIAxes
        imageExplore                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Property % Description
        
        
        LWindow
        dirIn           % data directory
        
        dataOut         % This structure holds all processed stacks and outputs produced by Image Gateway
        % Field description
        % .frames               Equal to the metadata.frames field
        % .xdim
        % .ydim
        % .meanSP               mean sampling period
        % timeI
        % workImages (y,x,channel,frame)    this file carries over the comp pipeline including reg, filtering and binning
        % regImages
        % binnedImages
        % deltaF image stack
        % meanF             mean fluorescence
        % medianF           median fluorescence
        % maxF              maximum projection
        
        dist            % This structure contains all the distributions computed by Image Gateway
        %         medianF         stats of the entire sample (fluorescence)
        %         stdF
        %         mskMedianF      stats of the masked sample
        %         mskStdF
        
        %         medianDF        stats (DF/F)
        %         stdDF
        %         mskMedianDF        stats (DF/F)
        %         mskStdDF
        
        %         Fleft           % Limits of the fluo distribution
        %         Fright
        %         Fnel            % Histogram fluorescence
        %         Fcent
        %         maxF            % Max fluo projection
        %         mskFnel         masked Histogram fluorescence
        %         mskFcent        masked
        %         DFleft
        %         DFright
        %         DFnel           % Histogram DF/F
        %         DFcent
        %         minDF           % Min of all   DF values
        %         maxDF           % Max
        %         mskDFcent       masked DF histogram
        %         mskMinDF        Min of masked DF values
        %         mskMaxDF        Max of masked DF values
        
        mdImage         % Mean fluo projection
        maxProjDF       % Max DF projection
        medianDF        % Median DF projection
        
        % ABnormal maps
        ABmapLimits     % struct holding the display limits of all the ABnormal maps
        ABmapLimB
        ABmap
        CDFmap
        mnF
        mxF
        
        % Imaging data and associated properties
        rawImages       % Buffer holding the original set of images being loaded
        %        binnedImages    % Image stack after binning
        deltaF          % DF/F image stack
        imagesBuffer    % stack buffer used occasionally for temporary storage
        binMask         % binary mask
        mask            % non-binned mask for visualisation
        Fmask           % binned mask for visualisation
        temp            % temporary buffer
        dataPath        % path holding the loaded data
        fileIn          % image stack fie name
        
        metadata        % Structure containing metadata descriptors of the raw images
        % Fileds of the metadata structure
        % .frames    number of frames
        % .xdim      image width
        % .ydim      image height
        % .tpoints
        % .meanSP    mean sampling period (sec)
        
        timeI           % time axis of the imaging file computed from the average sampling period
        processCh       % channel to be processed. Reserved for future extensions
        currentFrame    % frame being displayied at this time
        tifInfo
        % ROI and data analysis
        ROI             % structure containing all that is necessary
        % ROI.handle      hande to the poligonal reppresentation of the ROI
        % ROI.label
        % ROI.center      [x,y]
        % ROI.xpoly       array holding the polygon vertex coordinates
        % ROI.ypoly
        % ROI.mask        binary mask (same size of the binary image array
        % ROI.binF        time course of the binned fluorescence
        % ROI.deltaF      time course of the binned DF/F
        % ROI.binary      time course of the binarized data
        % ROI.binaryF     time course of the filtered binarized data
        % ROI.visibility  visibility of ROI and trace
        % ROI.group       this ROI belongs to?
        % ROI.color
        
        nROIs           % number of defined ROIs
        currentROI      %
        clrLUT          % array containing the RGB triplets for a 8 value set of colours obtained
        % from the jet colormap
        ROIedit         % handle of the ROI editor app.
        
        % Status flags
        FmaskFlag       % boolean flag: apply fluo mask?
        deltaFflag
        statsFlag
        binaryFlag
        FstatsFlag
        DFstatsFlag
        mskFstatsFlag
        mskDFstatsFlag
        
        % more
        fileTime        % String containing the time of the day when the data file was created
        binF            % binning factor
        WIrows          % Rows and Columns of the workImage bstack
        WIcolumns
        wy
        wx
        currentX        % coordinates returned by cliking on an image window
        currentY
        
        % Binarization
        globalMd
        meanMainMode
        stdMainMode
        numMainMode
        numTotal
        nstd
        minLen
        minSize
        binaryThrImages
        erodedBinaryImages
        binaryProjection
        binRGB
        meanRGB
        binaryRawSum
        binarySum
        thrIs
        
        % handles to graphic objects
        mdImageW        % handle to the dsplayied median image
        Fmovie          % handle to animation window (fluo window)
        Bmovie          % handle to animation window (binary window)
        Xcorr           % handle to the load EPhysiol gui
        BinaryApp       % handle to the binary map application app  %Ema 8/07/19
        ROIApp          % handle to the ROI module %Ema 28/07/19
        
        % RG, Jan 2021
        %
        % ABnormality masks:
        ABmask
        totABMask
        erodedBackup % Needed to save filtered binarized data without mask
        erodedBackup2 % Same with raw binarized data
        %
        % 'Animate' buttons:
        colouredBinStack
        errordlgAlreadyShowed %errordlg: show it just once
        errordlgAlreadyShowedStack %errordlg: show it just once
        %
        % Colormaps
        CMap
        %
        % RG, April, 2021.
        %
        % ROIs are needed for Padua format.
        % This will be restricted to those analyses.
        CROIEditorMask
    end
    
    methods (Access = public)
        
        
        %*************DISPLAY BINARY IMAGE******************
        function displayBinaryImage(app)
            if app.binaryFlag
                if app.RGBcoding.Value
                    % display the time-RGB encoded projection
                    % use the ABmapLimB data to normalize the RGB image
                    %
                    % This means that you need to compute ABnormal maps
                    % first


                    app.ABmapLimB.RGBcodin = [ 0.001 0.03];


                    tmp = (app.meanRGB-app.ABmapLimB.RGBcodin(1));
                    tmp = tmp/(app.ABmapLimB.RGBcodin(2)-app.ABmapLimB.RGBcodin(1));
                    
                    
                    imagesc(app.binaryExplore,tmp);
                    set(app.imageExplore,'Colormap',jet); % Better keep hot colormap for RGB... This will change
                    set(app.imageExplore,'XTick',[]);
                    set(app.imageExplore,'YTick',[]);
                    set(app.imageExplore,'XLim',[0 size(tmp,1)]);
                    set(app.imageExplore,'YLim',[0 size(tmp,2)]);
                    set(app.binaryExplore,'Clim',app.ABmapLimB.RGBcodin)
                    
                    axis(app.binaryExplore,[0 app.WIrows 0 app.WIcolumns])
                end
                % First compute the aspect ratio of the axes
                set(app.binaryExplore,'XTick',[]);
                set(app.binaryExplore,'YTick',[]);
                set(app.binaryExplore,'XLim',[0 size(app.binaryThrImages,1)]);
                set(app.binaryExplore,'YLim',[0 size(app.binaryThrImages,2)]);
                asp = app.binaryExplore.Position(3)/app.binaryExplore.Position(4);
                if get(app.FbinRB,'Value')
                    % plot the current frame
                    if app.binaryRawRB.Value
                        % plot raw binary frame
                        %         app.binImage = imagesc(tmp,'ButtonDownFcn',@mdImageClick);
                        %         set(app.imageExplore,'Clim',Flim);  % impose the new axes limits
                        frame = squeeze(app.binaryThrImages(:,:,1,app.currentFrame));
                        [row, column] = find(frame);
                        scatter(app.binaryExplore,column,row,app.binF,'s','filled');
                    else
                        frame = squeeze(app.erodedBinaryImages(:,:,1,app.currentFrame));
                        [row, column] = find(frame);
                        scatter(app.binaryExplore,column,row,app.binF,'s','filled')
                    end
                    axis(app.binaryExplore,[0 asp*app.WIcolumns 0 app.WIrows])
                    set(app.binaryExplore,'Ydir','reverse')
                    
                end
                % Maximum Projection
                if app.binMaxRB.Value
                    imagesc(app.binaryExplore,app.binaryProjection)
                    set(app.binaryExplore,'Colormap',app.CMap); % Better keep hot colormap for Maximum Prj
                    set(app.binaryExplore,'XTick',[]);
                    set(app.binaryExplore,'YTick',[]);
                    set(app.binaryExplore,'XLim',[0 size(app.binaryProjection,1)]);
                    set(app.binaryExplore,'YLim',[0 size(app.binaryProjection,2)]);
                    axis(app.binaryExplore,[0 asp*app.WIcolumns 0 app.WIrows])
                    % Dec 2021, needed for display merge function
                    app.binaryExplore.BackgroundColor = [0.8700 0.9200 0.9800];
                    app.binaryExplore.CLim = [0 26];
                    app.binaryExplore.ZColor = [0.1500 0.1500 0.1500];
                end
                
            end
            
            % Display the ABmaps
            wndSel = app.binaryImageSource.SelectedObject.Tag;
            if ~contains('FbinRBbinMaxRB',wndSel)&& ~app.RGBcoding.Value % ABnormals are picked by esclusion
                % AB-map selected
                imagesc(app.binaryExplore,app.ABmap.(wndSel))
                set(app.binaryExplore,'Clim',app.ABmapLimB.(wndSel))
                
                set(app.binaryExplore,'XTick',[]);
                set(app.binaryExplore,'YTick',[]);
                set(app.binaryExplore,'XLim',[0 size(mean(app.dataOut.workImages,4),1)]);
                set(app.binaryExplore,'YLim',[0 size(mean(app.dataOut.workImages,4),2)]);
                %axis(app.binaryExplore,[0 app.WIrows 0 app.WIcolumns])
                %                         colormap(app.binaryExplore,'parula') %RG
                colormap(app.binaryExplore,app.CMap)
            end
            set(app.binaryExplore,'fontsize',8,'BackgroundColor',[0.87,0.92,0.98])
            
            
            %***********RG 2020 Merge colors****************
            if app.mergeColorsCB.Value
                mergeColors(app);
            end
            
            %***********RG 2020 Time bar graphics ****************
            timeBar(app);
            
            % **********RG 2020 These modifications are needed to solve some incompatibilities
            % with Windows OS. *************
            % Refresh correctly Colormap also for right window
            if ~app.RGBcoding.Value % Except for RGB
                set(app.binaryExplore,'colormap',app.CMap);
                colormap(app.binaryExplore,app.CMap);
                % Even for Max Prj
            end
            
            % Make sure X and Y labels are removed in right plot axes
            set(app.binaryExplore,'xticklabel',[]);
            set(app.binaryExplore,'yticklabel',[]);
            
            % Also, make sure that mergeColors graphics match exactly left
            % window outpt
            if app.mergeColorsCB.Value
                %graphic adjustements
                app.binaryExplore.BackgroundColor = app.imageExplore.BackgroundColor;
                app.binaryExplore.CLim = app.imageExplore.CLim;
                app.binaryExplore.ZColor = app.imageExplore.ZColor;
            end
        end
        
        function mdImageClick(app, src, event)
            % May 2, 2019. GMR
            % This callback has been redesigned after the migration to App Designer
            % Apparently, 'app' and 'src' both points to the calling app. Only God knows why is that.
            % The routine has been modify to allow the creation and management of ROIs
            
            % This function responds to the click event on the image displayed on the
            % imageExplorer axes.
            
            switch event.EventName
                case 'Hit'
                    % the field IntersectionPoint contains the coordinates of the clicked point.
                    posData = event.IntersectionPoint; % array [x y z];
                    parentImage = event.Source;  % This object contains the image with all its properties
                    x = posData(1,1);
                    y = posData(1,2);
                case 'MouseData'
                    % Properties: Src/EvntName
            end
            
            % next: identify the size of the source image for quality control of the
            % selected point
            
            app.currentX = int16(x);  % let the app know what we do here
            app.currentY = int16(y);
            fieldX = get(parentImage,'xData');          % coordinates of the src image
            fieldY = get(parentImage,'yData');
            
            % quality control
            if inpolygon(x,y,fieldX,fieldY)
                strOut = ['x: ' num2str(app.currentX) ' y: ' num2str(app.currentY)];
                mf = parentImage.CData(app.currentY,app.currentX);   % the field 'CData' contains the image
                strOut = [strOut ' F: ' num2str(mf, '%6.3g')];
                app.pointerInfoTxt.Text = strOut;
                % Are we in ROI create mode?
                if app.SquareBt.Value || app.CircleBt.Value
                    % Crete a new ROI. May 2019. GMR. ROIs are created as squares of fixed dimension
                    % Create a rectangular ROI of the defualt diameter
                    app.nROIs = app.nROIs + 1;
                    
                    if app.SquareBt.Value      % create a square ROI
                        app.ROI(app.nROIs).xpoly(1) = x - app.defRoiRadius.Value;
                        app.ROI(app.nROIs).xpoly(2) = x + app.defRoiRadius.Value;
                        app.ROI(app.nROIs).xpoly(3) = x + app.defRoiRadius.Value;
                        app.ROI(app.nROIs).xpoly(4) = x - app.defRoiRadius.Value;
                        app.ROI(app.nROIs).ypoly(1) = y - app.defRoiRadius.Value;
                        app.ROI(app.nROIs).ypoly(2) = y - app.defRoiRadius.Value;
                        app.ROI(app.nROIs).ypoly(3) = y + app.defRoiRadius.Value;
                        app.ROI(app.nROIs).ypoly(4) = y + app.defRoiRadius.Value;
                    end
                    if app.CircleBt.Value      % create a square ROI
                        nsides = int16(3 * app.defRoiRadius.Value);
                        sideL = (1.5 * 3.1428 * app.defRoiRadius.Value) / nsides;
                        pg = nsidedpoly(nsides,'Center',[x y],'SideLength',3);
                        app.ROI(app.nROIs).xpoly = pg.Vertices (:,1);
                        app.ROI(app.nROIs).ypoly = pg.Vertices (:,2);
                    end
                    app.ROI(app.nROIs).center(1) = x;
                    app.ROI(app.nROIs).center(2) = y;
                    app.ROI(app.nROIs).visibility = 1;
                    app.ROI(app.nROIs).color = mod(app.nROIs,8);
                    app.ROI(app.nROIs).label = 'none';
                    app.ROI(app.nROIs).group = 'none';
                    % now update the ROI editor
                    if app.nROIs == 1
                        top = 1.01;      % this is needed because of the spinner control behaviour
                    else
                        top = app.nROIs;
                    end
                    app.ROIedit.ROIspinner.Limits =  [1 top];
                    app.ROIedit.ROIspinner.Value =  app.nROIs;        % The ROI editor points to the newest ROI
                    app.ROIedit.ColorDropDown.Value = num2str(app.ROI(app.nROIs).color);
                    % create the polyshape object
                    app.ROI(app.nROIs).handle = polyshape(app.ROI(app.nROIs).xpoly, app.ROI(app.nROIs).ypoly);
                    
                    app.ROI(app.nROIs).mask = poly2mask(app.ROI(app.nROIs).xpoly,app.ROI(app.nROIs).ypoly,app.wx,app.wy);
                    
                    hold (app.imageExplore,'on');
                    plot(app.imageExplore, app.ROI(app.nROIs).handle,'FaceAlpha',0);
                    hold (app.imageExplore,'off');
                    computeROItimeCourse (app)
                end
                % Are we in ROI edit mode?
                
                % Now compute and plot the proper data in the time explore window
                plotTimeCourse(app)
            end
        end
        
        function offABnormalMap(app)
            app.KSstdRB.Visible = 'off';
            app.KSRB.Visible = 'off';
            app.KSstdRBb.Visible = 'off';
            app.KSRBb.Visible = 'off';
            app.areaRB.Visible = 'off';
            app.areaRBb.Visible = 'off';
            app.areaStdRB.Visible = 'off';
            app.areaStdRBb.Visible = 'off';
            app.SERB.Visible = 'off';
            app.SERBb.Visible = 'off';
        end
        
        function onABnormalMap(app)
            app.KSstdRB.Visible = 'on';
            app.KSstdRB.Tag = 'KSstdRB';
            app.KSRB.Visible = 'on';
            app.KSRB.Tag = 'KSRB';
            app.KSstdRBb.Visible = 'on';
            app.KSstdRBb.Tag = 'KSstdRB';
            app.KSRBb.Visible = 'on';
            app.KSRBb.Tag = 'KSRB';
            app.areaRB.Visible = 'on';
            app.areaRB.Tag = 'areaRB';
            app.areaRBb.Visible = 'on';
            app.areaRBb.Tag = 'areaRB';
            app.areaStdRB.Visible = 'on';
            app.areaStdRB.Tag = 'areaStdRB';
            app.areaStdRBb.Visible = 'on';
            app.areaStdRBb.Tag = 'areaStdRB';
            app.SERB.Visible = 'on';
            app.SERBb.Visible = 'on';
            app.SERB.Tag = 'SE';
            app.SERBb.Tag = 'SE';
        end
        
        function openFile(app, selection, path_in)
            % Open a image file: first clean the info field
            app.stackSizeTxt.Text = '';
            app.timeAndSpaceTxt.Text = '';
            app.moreInfoTxt.Text = '';
            if app.prairieRB.Value, fmtSt = '*.tif';
                pathAndFileIn = [path_in selection];
                % open a Prairie data directory
                strOut = [];
                [app.rawImages,app.metadata] = import_PrairieTimeSequence (pathAndFileIn);
                strOut = ['Size: ' num2str(app.metadata.xdim) 'x' num2str(app.metadata.ydim)];
                strOut = [strOut 'x' num2str(app.metadata.frames)];
                app.stackSizeTxt.Text = strOut;
                app.stackSizeTxt.Visible = 'on';
                strOut = [];
                strOut = ['Mean SP (s): ' num2str(app.metadata.meanSP, '%1.4g')];
                strOut = [strOut '    Pixel size (um): ' num2str(app.metadata.dx_um, '%2.4g')];
                app.timeAndSpaceTxt.Text = strOut;
                app.timeAndSpaceTxt.Visible = 'on';
                strOut = [];
                strOut = ['Objective: ' strjoin(app.metadata.objective) '  NA: ' num2str(app.metadata.objNA, '%1.3g')];
                strOut = [strOut '  Zoom factor: ' num2str(app.metadata.zoom, '%2.3g')];
                app.moreInfoTxt.Text = strOut;
                app.moreInfoTxt.Visible = 'on';
            end
            
            % Open TIF multiimage
            if get(app.TIFFmultiImageRB,'Value'), fmtSt = '*.tif';
                pathAndFileIn = [path_in selection];
                app.tifInfo = imfinfo(pathAndFileIn);
                nframe = length(app.tifInfo);
                app.metadata.frames = nframe;
                app.dataOut.frames = nframe;
                app.metadata.xdim = app.tifInfo(1).Width;
                app.metadata.ydim = app.tifInfo(1).Height;
                app.dataOut.xdim = app.metadata.xdim;
                app.dataOut.ydim = app.metadata.ydim;
                app.metadata.tpoints = app.tifInfo(1).StripOffsets;
                app.rawImages=zeros(app.metadata.xdim,app.metadata.ydim,1,nframe);
                tmp=zeros(app.metadata.xdim,app.metadata.ydim,1);
                %                 app.LWindow=zeros(app.metadata.xdim,app.metadata.ydim,1,nframe)
                app.metadata.meanSP = app.SamplingsEditField.Value;  %str2double(app.SPTIFF.String);
                app.dataOut.meanSP = app.metadata.meanSP;
                for i=1:nframe
                    %offsettif=handles.tifInfo(i).Offset;
                    tmp = imread(pathAndFileIn,'Index',i);
                    %tmp=tmp-offsettif;
                    app.rawImages(:,:,:,i)=tmp;
                end
            end
            
            setAllInitialValues (app);
            app.statusNow.Text = 'Imaging data loaded';
        end
        
        function plotDistribution(app)
            % Ricompute and plot the distribution of the selected entity
            
            % Sept 2020. This code will be modified to allow the
            % recomputation of the bin size on the fly
            
            cla(app.distributionW);
            if (app.binnedFck.Value && app.FstatsFlag)
                bar(app.distributionW,app.dist.Fcent,app.dist.Fnel);
                axis(app.distributionW,[app.dist.Fleft app.dist.Fright -inf inf])
            end
            if (app.dFck.Value && app.DFstatsFlag)
                bar(app.distributionW,app.dist.DFcent,app.dist.DFnel);
                axis(app.distributionW,[app.dist.DFleft app.dist.DFright -inf inf])
            end
            if (app.maskedBinnedFck.Value && app.mskFstatsFlag)
                bar(app.distributionW,app.dist.Fcent,app.dist.mskFnel);
                axis(app.distributionW,[app.dist.Fleft app.dist.Fright -inf inf])
            end
            if (app.maskedDFck.Value && app.mskDFstatsFlag)
                bar(app.distributionW,app.dist.DFcent,app.dist.mskDFnel);
                axis(app.distributionW,[app.dist.DFleft app.dist.DFright -inf inf])
            end
            
            % finally check whether we should apply a log scale
            if app.distributionLogCk.Value, set(app.distributionW, 'YScale', 'log')
            else
                set(app.distributionW, 'YScale', 'lin')
            end
            set(app.distributionW,'fontsize',8,'color',[0.89 0.94 0.90],'BackgroundColor',[0.89 0.94 0.90])
        end
        
        function setAllInitialValues(app)
            % September 2020
            % Reset the interface as a new data set has been loaded or it
            % has been restored to the initial raw values
            
            % Set the empty data point to NaN if desired
            % This happens during spiral scan: the points outside of the spiral circle
            % are set to zero, but they should not contribute to any of the statistics.
            
            app.dataOut = [];
            
            if app.ch1RB.Value, app.dataOut.workImages = app.rawImages(:,:,1,:);
                app.metadata.ch = 1;
                app.IWfluoCh2RB.Visible = false;
                app.IWfluoCh1Ch2RB.Visible = false;
            end
            if app.ch2RB.Value, app.dataOut.workImages = app.rawImages(:,:,2,:);
                app.metadata.ch = 1;
                app.IWfluoCh2RB.Visible = false;
                app.IWfluoCh1Ch2RB.Visible = false;
            end
            if app.Ch1Ch2RB.Value, app.dataOut.workImages = app.rawImages;
                app.metadata.ch = 2;
                app.IWfluoCh2RB.Visible = true;
                app.IWfluoCh1Ch2RB.Visible = true;
            end
            
            app.dataOut.workImages = cast(app.dataOut.workImages,'double');     % type recast necessary since the
            if app.toNaNcb.Value
                app.dataOut.workImages(app.dataOut.workImages==0) = NaN;        % original class of the data is uint16 that does not support NaN
            end
            
            % subtract offsets
            switch app.selectInputCh.SelectedObject.Text
                case 'Channel 1'
                    app.dataOut.workImages(:,:,1,:) = app.dataOut.workImages - app.offsetCh1EF.Value;
                case 'Channel 2'
                    app.dataOut.workImages(:,:,1,:) = app.dataOut.workImages - app.offsetCh2EF.Value;
                case 'Load both channels'
                    app.dataOut.workImages(:,:,1:2,:) = app.dataOut.workImages - [app.offsetCh1EF.Value app.offsetCh2EF.Value];
            end
            
            % create the x axis
            app.timeI = (0:app.metadata.meanSP:app.metadata.meanSP*(app.metadata.frames-1));
            app.dataOut.timeI = app.timeI;
            
            app.FstatsTxt.Visible = 0;
            app.mskFstats.Visible = 0;
            app.statsDF.Visible = 0;
            app.statsMskDF.Visible = 0;
            
            app.FstatsFlag = 0;
            app.mskFstatsFlag = 0;
            app.mskDFstatsFlag = 0;
            app.DFstatsFlag = 0;
            
            % Each processor will be specifical targetted to one or both channels.
            % app.processCh = 1;
            % set all initial values
            
            % compute the maxProjection and the mean of the fluorescence
            for j=1:app.metadata.ch
                app.dataOut.maxF (:,:,j) = max(app.dataOut.workImages(:,:,j,:),[],4);
                app.dataOut.medianF (:,:,j) = median(app.dataOut.workImages(:,:,j,:),4);
                app.dataOut.meanF (:,:,j) = mean(app.dataOut.workImages(:,:,j,:),4);
            end
            
            app.currentFrame = 1;
            app.selectFrame.Limits = [1 app.metadata.frames];
            app.dataOut.deltaFflag = 0;
            app.statsFlag = 0;
            app.binaryFlag = 0;
            app.FmaskFlag = 0;
            app.FstatsFlag = 0;
            app.DFstatsFlag = 0;
        end
        
    end
    
    methods (Access = public)
        
        %*************DISPLAY IMAGE EXPLORER******************
        
        function displayImageExplore(app)
            % February 27, 2018. Modified for alpha masking.
            % April 14, 2018. Modified to display the AB normal maps
            % May 2019. Modified at the time of the App-lification. GMR
            % May 2019, it handles the plot of active ROIs
            % July 2019, modified to allow the plotting of the entropy map.
            
            % May 2020. Image Gateway 2.0 The code has been completely
            % rearranged to comply with the new structure of the GUI and with the
            % handling of both channels. I also modified the handling of the alpha mask
            
            % The Clim on the images are imposed at the end of the drawing routines.
            Dflim = [app.DFminTxt.Value app.DFmaxTxt.Value];
            Flim = [app.FminTxt.Value app.FmaxTxt.Value];
            
            cla(app.imageExplore);
            %app.mdImageW = imagesc(md,'ButtonDownFcn',@mdImageClick);
            
            switch app.IWsource.SelectedObject.Text
                case 'Fluo frames'      % Display the current frame
                    xlim = [0 size(app.dataOut.workImages,1)];
                    ylim = [0 size(app.dataOut.workImages,2)];
                    switch app.IWselectCh.SelectedObject.Text
                        case 'Show Ch1'
                            tmp = squeeze(app.dataOut.workImages(:,:,1,app.currentFrame));
                            % plot fluorescence
                            app.mdImageW = imagesc(app.imageExplore,tmp);
                            %                        app.mdImageW.ButtonDownFcn = createCallbackFcn(app, @app.mdImageClick, true);
                            set(app.imageExplore,'Clim',Flim);  % impose the new axes limits
                        case 'Show Ch2'
                            tmp = squeeze(app.dataOut.workImages(:,:,2,app.currentFrame));
                            % plot fluorescence
                            app.mdImageW = imagesc(app.imageExplore,tmp);
                            %                            app.mdImageW.ButtonDownFcn = createCallbackFcn(app, @app.mdImageClick, true);
                        case 'Show Ch1 and Ch2'
                            % Visualize the two channels as RGB data
                    end
                    if app.IWautoBrCB.Value
                        clim(1) = min(min(tmp));
                        clim(2) = max(max(tmp));
                    else
                        clim = Flim;
                    end
                case 'Max projection'
                    xlim = [0 size(app.dataOut.maxF,1)];
                    ylim = [0 size(app.dataOut.maxF,2)];
                    switch app.IWselectCh.SelectedObject.Text
                        case 'Show Ch1'
                            tmp = app.dataOut.maxF(:,:,1);
                            app.mdImageW = imagesc(app.imageExplore,tmp);
                            app.mdImageW.ButtonDownFcn = createCallbackFcn(app, @app.mdImageClick, true);
                        case 'Show Ch2'
                            tmp = app.dataOut.maxF(:,:,2);
                            app.mdImageW = imagesc(app.imageExplore,tmp);
                            app.mdImageW.ButtonDownFcn = createCallbackFcn(app, @app.mdImageClick, true);
                    end
                    if app.IWautoBrCB.Value
                        clim(1) = min(min(tmp));
                        clim(2) = max(max(tmp));
                    else
                        clim = Flim;
                    end
                    
                case 'Mean'
                    xlim = [0 size(app.dataOut.meanF,1)];
                    ylim = [0 size(app.dataOut.meanF,2)];
                    switch app.IWselectCh.SelectedObject.Text
                        case 'Show Ch1'
                            tmp = app.dataOut.meanF(:,:,1);
                            app.mdImageW = imagesc(app.imageExplore,tmp);
                            app.mdImageW.ButtonDownFcn = createCallbackFcn(app, @app.mdImageClick, true);
                        case 'Show Ch2'
                            tmp = app.dataOut.meanF(:,:,2);
                            app.mdImageW = imagesc(app.imageExplore,tmp);
                            app.mdImageW.ButtonDownFcn = createCallbackFcn(app, @app.mdImageClick, true);
                    end
                    if app.IWautoBrCB.Value
                        clim(1) = min(min(tmp));
                        clim(2) = max(max(tmp));
                    else
                        clim = Flim;
                    end
                case 'Median'
                    xlim = [0 size(app.dataOut.medianF,1)];
                    ylim = [0 size(app.dataOut.medianF,2)];
                    switch app.IWselectCh.SelectedObject.Text
                        case 'Show Ch1'
                            tmp = app.dataOut.medianF(:,:,1);
                            app.mdImageW = imagesc(app.imageExplore,tmp);
                            app.mdImageW.ButtonDownFcn = createCallbackFcn(app, @app.mdImageClick, true);
                        case 'Show Ch2'
                            tmp = app.dataOut.medianF(:,:,2);
                            app.mdImageW = imagesc(app.imageExplore,tmp);
                            app.mdImageW.ButtonDownFcn = createCallbackFcn(app, @app.mdImageClick, true);
                    end
                    if app.IWautoBrCB.Value
                        % Set the Clim automatically based on the computed min and
                        % max of the plotted image
                        clim(1) = min(min(tmp));
                        clim(2) = max(max(tmp));
                    else
                        clim = Flim;
                    end
                    
                case 'Delta F/F frames'      % Display delta F/F of the current frame
                    switch app.IWselectCh.SelectedObject.Text
                        case 'Show Ch1'
                            % plot deltaF/F
                            tmp = app.dataOut.deltaF(:,:,1,app.currentFrame);
                        case 'Show Ch2'
                            % plot deltaF/F
                            tmp = app.dataOut.deltaF(:,:,2,app.currentFrame);
                    end
                    xlim = [0 size(tmp,1)];
                    ylim = [0 size(tmp,2)];
                    app.mdImageW = imagesc(app.imageExplore,tmp);
                    app.mdImageW.ButtonDownFcn = createCallbackFcn(app, @app.mdImageClick, true); %???
                    if app.IWautoBrCB.Value
                        % Set the Clim automatically based on the computed min and
                        % max of the plotted image
                        clim(1) = min(min(tmp));
                        clim(2) = max(max(tmp));
                    else
                        clim = Dflim;
                    end
                case 'Delta F/F Max'
                    if app.dataOut.deltaFflag      % check if aready computed
                        % plot deltaF/F
                        tmp = app.maxProjDF;
                        app.mdImageW = imagesc(app.imageExplore,tmp);
                        set(app.imageExplore,'Colormap',app.CMap);
                        xlim = [0 size(tmp,1)];
                        ylim = [0 size(tmp,2)];
                        if app.IWautoBrCB.Value
                            % Set the Clim automatically based on the computed min and
                            % max of the plotted image
                            clim(1) = min(min(tmp));
                            clim(2) = max(max(tmp));
                        else
                            clim = Dflim;
                        end
                        app.mdImageW.ButtonDownFcn = createCallbackFcn(app, @app.mdImageClick, true);
                    end
            end
            
            % Display the ABmaps
            wndSel = app.IWsource.SelectedObject.Tag;
            if ~contains('framesRBmaxRBaverageRB',wndSel)
                % AB-map selected
                tmp = app.ABmap.(wndSel);
                xlim = [0 size(tmp,1)];
                ylim = [0 size(tmp,2)];
                app.mdImageW = imagesc(app.imageExplore,tmp);
                set(app.imageExplore,'Colormap',app.CMap);
                set(app.imageExplore,'XLim',xlim);
                set(app.imageExplore,'YLim',ylim);
                app.mdImageW.ButtonDownFcn = createCallbackFcn(app, @app.mdImageClick, true);
                %                set(app.imageExplore,'Clim',app.ABmapLimits.(wndSel));
                if app.IWautoBrCB.Value
                    % Set the Clim automatically based on the computed min and
                    % max of the plotted image
                    clim(1) = min(min(tmp));
                    clim(2) = max(max(tmp));
                else
                    clim = app.ABmapLimits.(wndSel);
                end
            end
            
            % check if the mask preview flag is on
            if app.maskOn.Value
                hold (app.imageExplore,'on')
                % Prepare the black overlay that is masked out later. We do
                % this in order to show the masked areas as black
                black = zeros(xlim(2),ylim(2));
                h = imshow(black,'Parent',app.imageExplore); % Save the handle; we'll need it later
                
                hold (app.imageExplore,'off')
                % Select the mask depending on the size of the plotted image
                d = xlim(2) - size(app.Fmask,2);
                if d == 0
                    set(h, 'AlphaData', app.Fmask);     % binned mask
                else
                    set(h, 'AlphaData', app.mask);      % non binned mask
                end
            end
            
            set(app.imageExplore,'fontsize',8,'BackgroundColor',[1.00 0.95 0.87],'Color',[1.00 0.95 0.87])
            %colormap(app.imageExplore,'parula');
            %axis(app.imageExplore,[0 app.WIrows 0 app.WIcolumns])
            
            if app.displayROIs.Value
                % display ROIs if present
                if app.nROIs > 0
                    hold (app.imageExplore,'on')
                    for i=1:app.nROIs
                        if app.ROI(i).visibility
                            % plot the ROI
                            clr = app.clrLUT(app.ROI(i).color,:);
                            plot (app.imageExplore,app.ROI(i).handle,'FaceAlpha',0,...
                                'EdgeColor',clr)
                        end
                    end
                    hold (app.imageExplore,'off')
                end
            end
            % Set axis parameters
            set(app.imageExplore,'Colormap',app.CMap);
            set(app.imageExplore,'XLim',xlim);
            set(app.imageExplore,'YLim',ylim);
            set(app.imageExplore,'XTick',[]);
            set(app.imageExplore,'YTick',[]);
            % LUT and plot limits must be set at the end of drawing operations
            set(app.imageExplore,'Clim',clim)
            
            
            % RG Dec 2020. Implementing AB-normal mask
            if ~app.maskOn.Value % This is to avoid errors with the first mask. This will change.
                
                if app.applyABmaskCB.Value && ~isempty(app.ABmask)
                    
                    % Fix size incompatibilities using repelem().
                    if size(app.imageExplore.Children.CData) == size(app.ABmask)
                        ABmaskAdj = app.ABmask;
                        
                    else % This else is needed when e.g. the user goes from the MaxPrj
                        % Display to the frame display.
                        bin = app.binTxt.Value;
                        ABmaskAdj = repelem(app.ABmask,bin,bin);
                    end
                    
                    maskedCData = app.imageExplore.Children.CData .* ABmaskAdj;
                    
                    % Repeat graphic adjustments. Maybe due to the fact that
                    % maskedCData is a local variable. Although, it works.
                    imagesc(app.imageExplore,maskedCData)
                    app.imageExplore.XLim = [0 length(ABmaskAdj)];
                    app.imageExplore.YLim = [0 length(ABmaskAdj)];
                    set(app.imageExplore, 'colormap',app.CMap);
                    set(app.imageExplore,'XTick',[]);
                    set(app.imageExplore,'YTick',[]);
                    set(app.imageExplore,'XLim',xlim);
                    set(app.imageExplore,'YLim',ylim);
                    set(app.imageExplore,'Clim',clim)
                    
                    
                end
                
            end
            
            %refresh timebar
            timeBar(app);
        end


        function plotTimeCourse(app)
            % May, 8, 2019
            % This function plots the time series depending on the work modality.
            app.temp = [];
            if isempty(app.currentX)
                app.currentX = 1;
                app.currentY = 1;
            end
            
            % select the data to be plotted according to the radio button selection in
            % the timeExplore axes and to the active channel.
            % May 2019, modified to display the ROIs time course
            if app.showROIsCB.Value
                cla(app.timeExplore)
                hold(app.timeExplore,'on')
                for i=1:app.nROIs
                    if app.ROI(i).visibility
                        if app.plotBinnedFcK.Value
                            app.temp = app.ROI(i).binF;
                        end
                        if app.plotDeltaFcK.Value
                            app.temp = app.ROI(i).deltaF;
                        end
                        if app.binaryRB.Value
                        end
                        clr(:) = app.clrLUT(app.ROI(i).color,:);
                        plot (app.timeExplore,app.temp,'Color',clr);
                    end
                end
                hold(app.timeExplore,'off')
            else
                % plot the current point
                if app.plotFcK.Value
                    % This plot the raw fluorescence (not corrected for offset)
                    % correct coordinates for the bin factor
                    bf = app.binTxt.Value;
                    xx = bf * app.currentX;
                    yy = bf * app.currentY;
                    app.temp(1:app.metadata.frames) = app.rawImages(yy...
                        ,xx,1,1:app.metadata.frames);
                end
                if app.plotBinnedFcK.Value
                    app.temp(1:app.metadata.frames) = app.dataOut.binnedImages(app.currentY...
                        ,app.currentX,1,1:app.metadata.frames);
                end
                if app.plotDeltaFcK.Value
                    app.temp(1:app.metadata.frames) = app.dataOut.deltaF(app.currentY...
                        ,app.currentX,1,1:app.metadata.frames);
                end
                if app.binaryRB.Value && app.binaryFlag
                    if app.binaryRawRB.Value
                        app.temp(1:app.metadata.frames) = app.binaryRawSum(1:app.metadata.frames);
                    else
                        app.temp(1:app.metadata.frames) = app.binarySum(1:app.metadata.frames);
                    end
                end
                plot (app.timeExplore,app.temp);
            end
            if app.LogPlotCB.Value, set(app.timeExplore, 'YScale', 'log')
            else
                set(app.timeExplore, 'YScale', 'lin')
            end
        end
        
        function arraystring = num2clip(app,array)
            %NUM2CLIP copies a numerical-array to the clipboard
            %
            %   ARRAYSTRING = NUM2CLIP(ARRAY)
            %
            %   Copies the numerical array ARRAY to the clipboard as a tab-separated
            %   string.  This format is suitable for direct pasting to Excel and other
            %   programs.
            %
            %   The tab-separated result is returned as ARRAYSTRING.  This
            %   functionality has been included for completeness.
            %
            %Author: Grigor Browning
            %Last update: 02-Sept-2005
            
            %convert the numerical array to a string array
            %note that num2str pads the output array with space characters to account
            %for differing numbers of digits in each index entry
            arraystring = num2str(array);
            arraystring(:,end+1) = newline; %add a carrige return to the end of each row
            %reshape the array to a single line
            %note that the reshape function reshape is column based so to reshape by
            %rows one must use the inverse of the matrix
            arraystring = reshape(arraystring',1,prod(size(arraystring))); %reshape the array to a single line
            
            arraystringshift = [' ',arraystring]; %create a copy of arraystring shifted right by one space character
            arraystring = [arraystring,' ']; %add a space to the end of arraystring to make it the same length as arraystringshift
            
            %now remove the additional space charaters - keeping a single space
            %charater after each 'numerical' entry
            arraystring = arraystring((double(arraystring)~=32 | double(arraystringshift)~=32) & ~(double(arraystringshift==10) & double(arraystring)==32) );
            
            arraystring(double(arraystring)==32) = char(9); %convert the space characters to tab characters
            
            clipboard('copy',arraystring); %copy the result to the clipboard ready for pasting
        end
        
        function computeROItimeCourse(app)
            % this function computes the time course of binned F and DF/F in the specified ROI
            for i=1:app.metadata.frames
                tmp(:,:) = app.dataOut.binnedImages(:,:,1,i);
                tmp(isnan(tmp)) = 0;
                tmp = tmp  .* app.ROI(app.nROIs).mask;
                app.ROI(app.nROIs).binF(i) = sum(sum(tmp,2),1);
                tmp(:,:) = app.dataOut.deltaF(:,:,1,i);
                tmp(isnan(tmp)) = 0;
                tmp = tmp  .* app.ROI(app.nROIs).mask;
                tmp(isnan(tmp))=0;              % no idea why I need to do it twice...
                app.ROI(app.nROIs).deltaF(i) = sum(sum(tmp,2),1);
            end
            plotTimeCourse(app)
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function imageGateway_OpeningFcn(app)
            
            % Add here all the initialization you need to run at program launch
            app.dirIn = [pwd '\'];
            
        
            %% CodeOcean allocation of helper files
            pathTmp=pwd;
            pathHelpers=fullfile(pathTmp,'Helpers');
            addpath(pathHelpers);


            % initialize structure for AB-map limits
            app.ABmapLimits = struct('KSRB',[0 1],'KSstdRB',[0 1],'areaRB',[0 1],'areaStdRB',[0 1],'SE',[0 4]);
            app.ABmapLimB = struct('KSRB',[0 1],'KSstdRB',[0 1],'areaRB',[0 1],'areaStdRB',[0 1],'RGBcodin',[0 0.2]);
            app.ABmap = struct('KSRB',[],'KSstdRB',[],'areaRB',[],'areaStdRB',[]);
            % inactivate controls that are illegal at opening
            offABnormalMap(app);
            app.ROIedit = [];
            app.FmaskFlag = 0;
            app.nROIs = 0;
            
            % Set some controls as invisible
            app.IWfluoCh2RB.Visible = false;
            app.IWfluoCh1Ch2RB.Visible = false;
            app.statusNow.Visible = false;
            app.history.Visible = false;
            
            % compute the clrLUT array
            cmap = jet;
            
            app.clrLUT(1:8,:) = cmap (1:8:64,:);
            app.stackSizeTxt.Visible = 0;
            app.timeAndSpaceTxt.Visible = 0;
            app.moreInfoTxt.Visible = 0;
            
            app.FstatsTxt.Visible = 0;
            app.mskFstats.Visible = 0;
            app.statsDF.Visible = 0;
            app.statsMskDF.Visible = 0;
            
        end

        % Value changed function: ABmax
        function ABmax_Callback(app, event)
            % make sure that an AB-map is selected for display in the imageExplore
            % window
            mx = app.ABmax.Value;
            wndSel = app.IWsource.SelectedObject.Tag;
            if ~contains('framesRBmaxRBaverageRB', wndSel)
                % AB-map selected
                app.ABmapLimits.(wndSel)(2) = mx;
            end
            
            displayImageExplore (app)
        end

        % Value changed function: ABmaxb
        function ABmaxb_Callback(app, event)
            % make sure that an AB-map is selected for display in the binaryExplore window
            mx = app.ABmaxb.Value;
            wndSel = app.binaryImageSource.SelectedObject.Tag;
            if ~contains('FbinRBbinMaxRB',wndSel)
                % AB-map selected
                app.ABmapLimB.(wndSel)(2) = mx;
            end
            
            displayBinaryImage (app)
        end

        % Value changed function: ABmin
        function ABmin_Callback(app, event)
            % make sure that an AB-map is selected for display in the imageExplore window
            mn = app.ABmin.Value;
            wndSel = app.IWsource.SelectedObject.Tag;
            if ~contains('framesRBmaxRBaverageRB',wndSel)
                % AB-map selected
                app.ABmapLimits.(wndSel)(1) = mn;
            end
            
            displayImageExplore (app)
        end

        % Value changed function: ABminb
        function ABminb_Callback(app, event)
            % make sure that an AB-map is selected for display in the binaryExplore window
            mn = app.ABminb.Value;
            wndSel = app.binaryImageSource.SelectedObject.Tag;
            if ~contains('FbinRBbinMaxRB',wndSel)
                % AB-map selected
                app.ABmapLimB.(wndSel)(1) = mn;
            end
            
            displayBinaryImage (app)
        end

        % Button pushed function: BMapp_pb
        function BMapp_pb_Callback(app, event)
            % hObject    handle to BMapp_pb (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.BinaryApp=applicationBinaryMap_App(app);
            
            
            app.BinaryApp.extractDomains.Name = ...
                append('Binary Map - Cluster finder: ', sprintf(app.fileIn));
        end

        % Callback function
        function DFmaxTxt_Callback(app, event)
            displayImageExplore (app)
        end

        % Callback function
        function DFminTxt_Callback(app, event)
            displayImageExplore (app)
        end

        % Callback function
        function FmaxTxt_Callback(app, event)
            displayImageExplore (app)
        end

        % Callback function
        function FminTxt_Callback(app, event)
            displayImageExplore (app)
        end

        % Value changed function: LFPcorrelate
        function LFPcorrelate_Callback(app, event)
            % This function has been redisegned at the time of the APPification of gateway.
            % GMR May 1, 2019
            
            if isempty(app.metadata), errNow = errordlg('Load an imaging data first','Monkey Error');
            else
                if isempty(app.Xcorr) % check if the child process already exists by checking the existance of the field.
                    % if it app.Xcorr is empty this is the first time we call the Xcorr process
                    % Open the child process
                    app.Xcorr = loadEPhys_App(app);      % handle to the child figure
                    % arguments: parent app
                    app.LFPcorrelate.Value = 1;          % make sure that the checkbox correctly reflects the child status
                else
                    % two possibilites: it exist and then it must be closed. or it
                    % exists but it has been deleted. In that case it must be restarted somehow
                    if ~isvalid(app.Xcorr)        % now check whether app.Xcorr is a valid handle.
                        app.Xcorr = loadEPhys_App(app);      % handle to the child app
                        app.LFPcorrelate.Value = 1;          % make sure that the checkbox correctly reflects the child status
                    else
                        % close and delete the child process
                        %close(app.Xcorr);
                        delete(app.Xcorr);      % delete the handle to the child process
                        app.LFPcorrelate.Value = 0;
                    end
                end
            end
        end

        % Button pushed function: advance1frame
        function advance1frame_Callback(app, event)
            if app.currentFrame == app.metadata.frames
                app.currentFrame = 1;
            else
                app.currentFrame = app.currentFrame + 1;
            end
            app.currentFrTxt.Text = num2str(app.currentFrame);
            app.selectFrame.Value = double(app.currentFrame);
            displayImageExplore (app);
            displayBinaryImage (app);
            app.currentFrameTxt.Text = ['Current frame: ' num2str(app.currentFrame)];
            
            
        end

        % Button pushed function: advanceMultiFrames
        function advanceMultiFrames_Callback(app, event)
            if app.currentFrame >= (app.metadata.frames-9)
                app.currentFrame = 1;
            else
                app.currentFrame = app.currentFrame + 10;
            end
            app.currentFrTxt.Text = num2str(app.currentFrame);
            app.selectFrame.Value = double(app.currentFrame);
            displayImageExplore (app);
            displayBinaryImage (app);
            
        end

        % Value changed function: animateFCK
        function animateFCK_Callback(app, event)
            switch app.IWselectCh.SelectedObject.Text
                case 'Show Ch1'
                    ch = 1;
                case 'Show Ch2'
                    ch = 2;
            end
            if app.animateFCK.Value       % Activate time lapse viewer
                if app.IWfluoRB.Value
                    % play the binned frames
                    app.Fmovie = implay(app.dataOut.workImages(:,:,ch,:));
                    app.Fmovie.Visual.setPropertyValue('UseDataRange',true);
                    app.Fmovie.Visual.setPropertyValue('DataRangeMin',-Inf);
                    app.Fmovie.Visual.setPropertyValue('DataRangeMax',Inf);
                else
                    app.Fmovie = implay(app.dataOut.deltaF(:,:,ch,:));
                end
            else
                % Close already open animation window
                if ishandle(app.Fmovie)
                    close(app.Fmovie);
                end
            end
        end

        % Button pushed function: back1Frame
        function back1Frame_Callback(app, event)
            if app.currentFrame == 1
                app.currentFrame = app.metadata.frames;
            else
                app.currentFrame = app.currentFrame - 1;
            end
            app.currentFrTxt.Text = num2str(app.currentFrame);
            app.selectFrame.Value = double(app.currentFrame);
            displayImageExplore (app);
            displayBinaryImage (app);
            
        end

        % Button pushed function: backMultiFrames
        function backMultiFrames_Callback(app, event)
            if app.currentFrame <= 10
                app.currentFrame = app.metadata.frames;
            else
                app.currentFrame = app.currentFrame - 10;
            end
            app.currentFrTxt.Text = num2str(app.currentFrame);
            app.selectFrame.Value = double(app.currentFrame);
            displayImageExplore (app);
            displayBinaryImage (app);
            
        end

        % Selection changed function: binaryImageSource
        function binaryImageSource_SelectionChangedFcn(app, event)
            wndSel = app.binaryImageSource.SelectedObject.Tag;
            if ~contains('FbinRBbinMaxRB',wndSel) %questo if riguarda il display refresh delle mappe abnormal nella right window
                % AB-map selected
                mn = app.ABmapLimB.(wndSel)(1);
                mx = app.ABmapLimB.(wndSel)(2);
                app.ABminb.Value = mn;
                app.ABmaxb.Value = mx;
            end
            
            displayBinaryImage (app)
        end

        % Button pushed function: computeBinaryStack
        function computeBinaryStack_Callback(app, event)
            % September 2020, Binarization proceeds only on Ch 1.
            % To be generalized
            
            tic
            
            %create waitbar and disable commands
            %waitb = waitbar(0.11,'Binarization maps are processing. Please wait.', 'WindowStyle',"modal", 'WindowState',"normal");
            app.computeBinaryStack.BackgroundColor = [1,0,0];
            app.statusNow.Text = 'Begin compute binary stack';
            drawnow
            
            if app.globalStatsCk.Value
                
            else
                
            end
            
            if app.thresholdFck.Value
                % stats of the masked datat set
                app.thrIs = app.dist.mskMedianDF + app.dist.mskStdDF * app.thrStdTxt.Value;
            else
                app.thrIs = app.dist.medianDF + app.dist.stdDF * app.thrStdTxt.Value;
            end
            % the image stack binaryThrImage contains a binary representation of the DF stack with 1 in the
            % pixels where DF/F is larger than the app.nstd*std limit
            app.binaryThrImages = app.dataOut.deltaF>app.thrIs;
            % now it is time to remove pixels that are filtered away because of low S/N ratio
            if app.thresholdFck.Value
                for i=1:app.metadata.frames
                    tmp = app.binaryThrImages (:,:,1,i);
                    tmp = tmp .* app.binMask;
                    app.binaryThrImages (:,:,1,i) = tmp(:,:);
                end
            end
            e = toc;
            app.statusNow.Text = sprintf('Done: %0.1f Filter now', num2str(e));
            %            waitbar(0.33, waitb,'Binarization maps are processing. Please wait.', 'WindowStyle',"modal", 'WindowState',"normal");
            
            % Score each pixel according to the DF metrics
            % pixels are scored with the following metrics:
            % 1) number of frames falling outside of the normality threshold
            % 2) total power of the frames outside of the normality threshold
            % 3) entropy of the sequence
            % 4) number of events lasting longer than n frames
            
            % app.nstd = app.thrStdTxt.Value;
            app.minLen = app.minDurationTxt.Value;
            %             structEl = 1:app.minLen+2;
            %             structEl = structEl ./ structEl;
            %             % now zero the edges of the structuring element
            %             structEl(1) = 0;
            %             structEl(app.minLen+2) = 0;
            structEl = strel('line',app.minLen,0);  % 1 row several columns. Erosion performed on columns only,
            
            app.minSize = app.minSizeTxt.Value;
            structElxy = ones(app.minSize);
            
            [r, c,~, f] = size(app.dataOut.deltaF);     % The tilde takes care of the unused channel number
            
            
            % now remove all events shorter than the minimal duration
            if app.minLen > 1
                for sheetN=1:r
                    % extract one sheet for the morphological erosion
                    tmp = app.binaryThrImages(sheetN,:,1,:);
                    sheet = squeeze(tmp);
                    erodedSheet = imerode(sheet,structEl);
                    % and now apply the complementary dilation
                    erodedSheet = imdilate(erodedSheet,structEl);
                    app.erodedBinaryImages(sheetN,1:c,1,1:f) = erodedSheet(1:c,1:f);
                end
            else
                app.erodedBinaryImages = app.binaryThrImages;   % no filtering for minimal length
            end
            app.statusNow.Text = 'Temporal filtering completed';
            
            %            waitbar(0.66, waitb,'Binarization maps are processing. Please wait.', 'WindowStyle',"modal", 'WindowState',"normal");
            % now remove events smaller than a specified size
            if app.minSize > 1
                for i=1:app.metadata.frames
                    tmp = app.erodedBinaryImages (:,:,1,i);
                    filteredFrame = imerode(tmp,structElxy);
                    filteredFrame = imdilate(filteredFrame,structElxy);
                    app.erodedBinaryImages (:,:,1,i) = filteredFrame;
                end
            end
            %            waitbar(0.99, waitb,'Binarization maps are processing. Please wait.', 'WindowStyle',"modal", 'WindowState',"normal");
            
            %*
            % March 24, 2021. Rocco
            %
            % I'm trying to add some additional features to the binarisation filtering,
            % wishing to facilite cluster extraction.
            %
            % Filter of pixel time iteration. Check how many
            % times each pixel is 1 along the time dimension.
            if app.timeIterationCB.Value
                thr = app.timeIterationEF.Value;
                % Number of elements of each row / column
                l = numel(app.erodedBinaryImages(:,1,1,1));
                % Process element by elements using rows and columns. This
                % is slow, maybe it will change.
                for r = 1:l
                    for c = 1:l
                        [scores,~] = find(app.erodedBinaryImages(r,c,1,:) == 1);
                        % If number of found 1s along the time dimension
                        % surpasses the threshold, than keep that pixel information.
                        % Otherwise, silence it at all times.
                        if length(scores) <= thr
                            app.erodedBinaryImages(r,c,1,:) = 0;
                        end
                    end
                end
                
            end
            % Increase size of logical domains.This might be helpful to
            % close separated regions and generate larger clusters.
            if app.dilateBinaryCB.Value
                se = strel('disk',app.dilateBinaryEF.Value);
                for i=1:app.metadata.frames
                    tmp = app.erodedBinaryImages (:,:,1,i);
                    filteredFrame = imdilate(tmp,se);
                    app.erodedBinaryImages (:,:,1,i) = filteredFrame;
                end
            end
            % #2 Or, additionally, imclose() function is specific to join
            % near regions.
            if app.closeBinaryCB.Value
                se = strel('disk',app.closeBinaryEF.Value);
                for i=1:app.metadata.frames
                    tmp = app.erodedBinaryImages (:,:,1,i);
                    %filteredFrame = imclose(tmp,se);
                                        filteredFrame = imerode(tmp,se); % 260723

                    app.erodedBinaryImages (:,:,1,i) = filteredFrame;
                end
            end
            %*
            
            % compute the projection of all images
            app.binaryProjection = sum(app.erodedBinaryImages,4);
            
            % compute the projection x, y of each frame
            % these vectors contain the time course of the number of px over threshold
            app.binarySum = squeeze(sum(sum(app.erodedBinaryImages,2),1));
            app.binaryRawSum = squeeze(sum(sum(app.binaryThrImages,2),1));
            % plot the temporal profile of the number of 'meaningful' pixels
            plot(app.timeExplore,app.binarySum);
            set(app.timeExplore,'fontsize',8)
            
            app.binaryFlag = 1;
            displayBinaryImage (app)
            
            %restore commands
            app.computeBinaryStack.BackgroundColor = [0.80,0.88,0.97];
            %            close(waitb)
            e = toc;
            app.statusNow.Text = sprintf('Binary stack completed %0.1f', num2str(e));
            drawnow
        end

        % Button pushed function: computeBinning
        function computeBinning_Callback(app, event)
            % This function compute the binned down image stack
            %
            % This function reduces the resolution of the image by the given factor by
            % creating larger pixels. The image size must be evenly disible by the bin size
            
            tic
            %disp ('Compute binning');
            
            %pop up waitbar while disabling other commands
            app.computeBinning.BackgroundColor = [1,0,0];
            %            waitb = waitbar(0.1,'Image processing. Please wait.', 'WindowStyle',"modal", 'WindowState',"normal");
            app.statusNow.Text = 'Bin images';
            drawnow
            
            app.dataOut.binnedImages = [];
            if get(app.binRB,'Value')
                app.binF = app.binTxt.Value;
                %app.binF = uint8(app.binTxt.Value);
                % compute dimension of the binned images
                
                % how large is one image?
                % reserve space for the binned array
                % RG Dec 2020
                % Note: binning is only possible when bins perfectly match
                % the size of the image. Maybe this should be changed.
                app.dataOut.binnedImages = ones(app.metadata.xdim/app.binF, app.metadata.ydim/app.binF,...
                    app.metadata.ch, app.metadata.frames, 'double');
                % Operate on one frame at the time
                % It processes both channels if they are both active, otherwise process
                % only the singleton channel of the workImages array
                for chJ = 1:app.metadata.ch
                    for i=1:app.metadata.frames
                        % extract the current frame
                        cF = app.dataOut.workImages(:,:,chJ,i);
                        
                        % RG Dec 2020
                        %
                        % Two new methods to compute binning, Max and Min, are added.
                        % The new function is called binImageMethod(), and is
                        % basically pretty similar to the original binImage() function,
                        % which btw I kept untouched in the original binImage.m file in ImageGateway's folder.
                        % So, the only difference is that I added one "if" statement,
                        % which depends on the Value of the DD and
                        % allows to choose the type of transformation.
                        % Both these new methods exploit faithfully the reshape() criterion
                        % as well. More info are present in the comments of binImageMethod.m
                        % file.
                        
                        tmp = binImageMethod(cF,app.binF,app.binMethodDD.Value);
                        app.dataOut.binnedImages(:,:,chJ,i) = tmp(:,:);
                    end
                end
                app.dataOut.workImages = app.dataOut.binnedImages;
            else
                % do nothing... pretty sure this will change.
            end
            
            %median of channel 1
            app.mdImage = median(app.dataOut.workImages (:,:,1,:),4);
            [app.WIrows, app.WIcolumns, ~] = size(app.dataOut.workImages);
            cla(app.imageExplore);
            
            imagesc(app.imageExplore,app.mdImage,'ButtonDownFcn',@mdImageClick);
            set(app.imageExplore,'Colormap',app.CMap);
            set(app.imageExplore,'XTick',[]);
            set(app.imageExplore,'YTick',[]);
            set(app.imageExplore,'XLim',[0 size(mean(app.dataOut.workImages,4),1)]);
            set(app.imageExplore,'YLim',[0 size(mean(app.dataOut.workImages,4),2)]);
            set(app.imageExplore,'fontsize',8)
            
            [app.wy, app.wx, ~] = size(app.dataOut.workImages);
            app.computeBinning.BackgroundColor = [0.99,0.92,0.80];
            app.statusNow.Text = 'Bin stack: done';
            app.history.Text = [app.history.Text ' Bin'];
            %rehabilitate commands
            %             waitbar(0.99, waitb, 'Finishing...', 'WindowStyle',"modal", 'WindowState',"normal");
            %             close(waitb);
            drawnow
            e = toc;
            disp (['End of binning: ' num2str(e) ' sec']);
        end

        % Button pushed function: computeDFstats
        function computeDFstats_Callback(app, event)
            % Compute the statistics and distribution of the DF/F signal
            % September 2020. This code processes Ch 1 only. To be generalized in the future.
            
            % The statistics of the non masked data have been already computed at the end of the DF computation
            % However we need to recompute the histogram in case the limits or bin number have been modified
            
            app.computeDFstats.BackgroundColor = [1,0,0];
            app.statusNow.Text = 'Compute stats DF/F';
            drawnow
            processCh1 = 1; % This variable name changed. Dec 2020
            allDF = app.dataOut.deltaF(:,:,processCh1,:);
            allDF = squeeze(allDF);
            %allDF = allDF(:);
            
            % compute the histogram of the entire data set
            bins = app.binDistTxt.Value;
            app.dist.DFleft = app.minDistTxt.Value;
            app.dist.DFright = app.maxDistTxt.Value;
            xValues = app.dist.DFleft:(app.dist.DFright-app.dist.DFleft)/bins:app.dist.DFright;
            [app.dist.DFnel,tempBins] = histcounts(allDF(:),xValues,"Normalization","probability");
            
            % Compute the statistics of the masked data set
            % The masked data are set to NaN so they do not influence the statistics and the binarization.
            % First, extract the indexes of the non masked data
            
            if ~isempty(app.Fmask)
                binmask = app.Fmask + 1;    % One could also use app.binMask...
                binmask(binmask==2) = NaN;
                for i=1:app.metadata.frames
                    allDF(:,:,i) = allDF(:,:,i) .* binmask;
                end
            end
            allDF = allDF(isfinite(allDF));
            app.dist.mskMedianDF = median(allDF);       % stats of the entire sample
            app.dist.mskStdDF = std(allDF);
            app.dist.mskMinDF = min(allDF);
            app.dist.mskMaxDF = max(allDF);
            
            strOut = ['Masked MD: ' num2str(app.dist.mskMedianDF,'%2.4g')];
            strOut = [strOut '   StD: ' num2str(app.dist.mskStdDF,'%2.4g')];
            strOut = [strOut '   Min: ' num2str(app.dist.mskMinDF,'%2.4g')];
            strOut = [strOut '   Max: ' num2str(app.dist.mskMaxDF,'%2.4g')];
            app.statsMskDF.Text = strOut;
            
            app.dist.mskDFnel = histcounts(allDF,xValues,"Normalization","probability");
            
            app.mskDFstatsFlag = 1;
            app.DFstatsFlag = 1;
            app.statsDF.Visible = 1;
            app.statsMskDF.Visible = 1;
            
            app.dist.DFcent(1:bins) = tempBins(1:bins) + (tempBins(2) - tempBins(1))/2;
            app.computeDFstats.BackgroundColor = [0.89,0.94,0.90];
            app.statusNow.Text = 'Stats DF/F: done';
            drawnow
            plotDistribution(app)
        end

        % Button pushed function: computeDf
        function computeDf_Callback(app, event)
            % Compute the DF/F matrix for the source data
            % This can be computed according to three algorithms:
            % Simplified: DF/F = (F(t)-mF)/mF  where mF indicates the median of F
            % Adaptive: computed according to konnerth algorithm
            % Bleach correction: an exponential decline is fitted to the a model of the
            % data baseline and it is used to correct F before of computation of DF/F
            
            % First: preallocate memory space. Keep in mind that the size of the stack
            % changes according to the operation of the upstream pipeline.
            % DF/F ic computed on 1 channel only
            
            %pop up waitbar while disabling other commands
            %            waitb = waitbar(0.1,'DF/F computation is processing. Please wait.', 'WindowStyle',"modal", 'WindowState',"normal");
            app.computeDf.BackgroundColor = [1,0,0];
            app.statusNow.Text = 'Compute DF/F';
            drawnow;
            app.processCh = 1;      % Obviously this has to be revised as follows: I need a control
            % in the GUI that defines on what ch compute the DF/F. it could be 1,2 or
            % both (in presence of both a R and G.
            
            [xw, yw , ~, f] = size(app.dataOut.workImages);
            tempOut = zeros(xw,yw,f,'double');
            temp = app.dataOut.workImages(:,:,app.processCh,:);
            temp = squeeze(temp);
            % As of Sept 2020 DF is only computed on a single channel. This
            % will  be generalized
            
            % Compute and display statistics of Fluorescence and offset
            minF = min(temp,[],'all');
            maxF = max(temp,[],'all');
            
            % Compute DF/F according to the selected algorithm
            if app.simpleDfcK.Value
                md = app.mdImage;    % Median on frames
                app.mdImage = md;
                for i=1:f
                    tempOut(:,:,i) = (temp(:,:,i)-md(:,:))./md(:,:);      % compute element by element division
                end
            end
            
            if get(app.MeanButton,'Value')
                md = mean (temp,3);
                for i=1:f
                    tempOut(:,:,i) = (temp(:,:,i)-md(:,:))./md(:,:);      % compute element by element division
                end
            end
            
            if app.PercentileButton.Value
                md = prctile(temp,app.thPercEF.Value,3);
                
                % Rocco, 17 Jan 2021
                % I added this thing here in on
                % cluster time series are generated, and this is due to the fact
                % that DF/F stack contains NaNs and infs.
                % Sometimes medianDF is NaN and this generate a lot of
                % errors in the binary map app.
                % In particular
                mdzeros = find(md==0);
                if ~isempty(mdzeros)
                    %                     md=md+0.00001;
                    md(mdzeros) = 0.00001;
                end
                
                for i=1:f
                    tempOut(:,:,i) = (temp(:,:,i)-md(:,:))./md(:,:);      % compute element by element division
                end
            end
            
            if get(app.adaptiveDfCk,'Value')
                app.dataOut.deltaF=adaptiveDF4(app.dataOut.workImages,app.metadata.meanSP);
            end
            app.dataOut.deltaF(:,:,app.processCh,:) = tempOut(:,:,:);
            
            % compute the max projection and the average projection
            app.maxProjDF(:,:,app.processCh) = max(app.dataOut.deltaF(:,:,app.processCh,:),[],4);
            app.medianDF(:,:,app.processCh) = median(app.dataOut.deltaF(:,:,app.processCh,:),4);
            
            app.dist.minDF = min(app.dataOut.deltaF(isfinite(app.dataOut.deltaF)));
            app.dist.maxDF = max(app.dataOut.deltaF(isfinite(app.dataOut.deltaF)));
            app.dist.stdDF = std(app.dataOut.deltaF(isfinite(app.dataOut.deltaF)));
            app.dist.medianDF = median(app.medianDF,'all');
            
            strOut = [ 'Median DF/F: ' num2str(app.dist.medianDF,'%3.4g')];
            strOut = [strOut '  StD: ' num2str(app.dist.stdDF,'%3.4g')];
            strOut = [strOut '  Min: ' num2str(app.dist.minDF,'%3.4g')];
            strOut = [strOut '  Max: ' num2str(app.dist.maxDF,'%3.4g')];
            app.statsDF.Text = strOut;
            
            app.dataOut.deltaFflag = 1;
            
            %restore commands and close waitbar
            
            app.computeDf.BackgroundColor = [0.99,0.92,0.80];
            app.statusNow.Text = 'Compute DF/F: done';
            app.history.Text = [app.history.Text ' DF/F'];
            drawnow;
            %            disp (['End of DF/F computation: ' num2str(e) ' sec']);
        end

        % Button pushed function: computeFstats
        function computeFstats_Callback(app, event)
            % Compute statistics and distribution of binned fluorescence
            % September 2020. Separate the distributions of the 2 channels
            % to be generalized
            app.computeFstats.BackgroundColor = [1,0,0];
            app.statusNow.Text = 'Compute stats F';
            drawnow
            if app.ch1RB.Value
                % If this is selected there is only one channel on the input data.
                allF = app.dataOut.workImages(:,:,1,:);
                tmp = allF(~isnan(allF));
                app.dist.medianF = median(tmp);       % stats of the entire sample
                app.dist.stdF = std(tmp);
                app.dist.mnF = min(tmp);
                app.dist.mxF = max(tmp);
                bins = app.distFbin.Value;
                app.dist.Fleft = app.minFdistTxt.Value;
                app.dist.Fright = app.maxFdistTxt.Value;
                xValues = app.dist.Fleft:(app.dist.Fright-app.dist.Fleft)/bins:app.dist.Fright;
                
                [app.dist.Fnel,tempBins] = histcounts(tmp,xValues,"Normalization","probability");
                strOut = ['Fluorescence MD:' num2str(app.dist.medianF,'%1.4g')];
                strOut = [strOut '  StD:' num2str(app.dist.stdF,'%1.4g')];
                strOut = [strOut '  Min:' num2str(app.dist.mnF,'%1.4g')];
                strOut = [strOut '  Max:' num2str(app.dist.mxF,'%1.4g')];
                app.FstatsTxt.Text = strOut;
                
                % Now compute the stats of the masked data
                tmp = tmp(tmp>app.thrFtxt.Value);        % Extract px over the threshold
                app.dist.mskMedianF = median(tmp);       % stats of the entire sample
                app.dist.mskStdF = std(tmp);
                app.dist.mskMinF = min(tmp);
                app.dist.mskMaxF = max(tmp);
                
                app.dist.mskFnel = histcounts(tmp,xValues,"Normalization","probability");
                strOut = ['Masked Fluo MD:' num2str(app.dist.mskMedianF,'%2.4g')];
                strOut = [strOut '  StD:' num2str(app.dist.mskStdF,'%2.4g')];
                strOut = [strOut '  Min:' num2str(app.dist.mskMinF,'%2.4g')];
                strOut = [strOut '  Max:' num2str(app.dist.mskMaxF,'%2.4g')];
                app.mskFstats.Text = strOut;
                app.dist.Fcent(1:bins) = tempBins(1:bins) + (tempBins(2) - tempBins(1))/2;
                
                app.FstatsFlag = 1;
                app.mskFstatsFlag = 1;
                app.FstatsTxt.Visible = 1;
                app.mskFstats.Visible = 1;
                
            end
            app.statusNow.Text = 'Stats F: done';
            app.computeFstats.BackgroundColor = [0.89,0.94,0.90];
            drawnow
            plotDistribution(app)
        end

        % Button pushed function: computeGaussMap
        function computeGaussMap_Callback(app, event)
            % This function computes the AB-normal maps of the imaging stack. The map is
            % obtained by computing a 'distance' between the unitary gaussian distribution and
            % the cdf of each pixel
            % The distance is computed using two different metrics:
            % mode = 1, 2 the probability p that each pixel process belongs to
            % a normal distribution. The larger is p the darker is the map.
            % mode = 3, 4 the area between the pixel cdf and the unitary gaussian
            % distribution
            
            % Computationally speaking the longest task is the computation of the stats
            % of each process Once that is done, the computation of the metrics is
            % straighforward. Therefore, I compute the four metrics at once.
            
            % GMR April, 2018.
            
            % July 2019. A fifth map is added by computing the Shannon entropy of each pixel
            % dec 2020, some implementations by RG
            
            
            %first, disable temporarily all the underlying controls. they
            %will be reactivated at the end of the computation (drawnow
            %needed).
            app.computeGaussMap.BackgroundColor = [1 0 0];
            
            %this function creates the waitbar. first number indicates the
            % percentage of animation of the waitbar, and starts with 0.
            f = waitbar(0,'AB-normal maps are processing. Please wait.', 'WindowStyle',"modal", 'WindowState',"normal");


            tic
            % Preallocation of the output maps. The CDF matrix makes use of the a priori
            % understanding that the bin number is 1401. To be fixed if we extend the
            % range. The present range is -6 +8 standard deviation units that, quite
            % frankly, should be more than enough.
            
            app.ABmap.KSRB = zeros(app.WIrows, app.WIcolumns);
            app.ABmap.KSstdRB = zeros(app.WIrows, app.WIcolumns);
            app.ABmap.areaRB = zeros(app.WIrows, app.WIcolumns);
            app.ABmap.areaStdRB = zeros(app.WIrows, app.WIcolumns);
            app.ABmap.SE = zeros(app.WIrows, app.WIcolumns);
            app.CDFmap =  zeros(app.WIrows, app.WIcolumns, app.CDFbinNumberEF.Value);
            % first, read the degree of freedom to be used for the KS test (mode = 1 or 2)
            nFreedom = app.KSnTxt.Value;
            % second, initialize the ktestGauss function. This is performed by calling
            % the function with the first field different from 0.
            [ks, ksstd, area, areastd, cdf] = kstestGauss(1, [],-6,8,nFreedom);
            source = app.ABsourceF.Value;   % this defines if the map is computed from F or DF/F
            % prepare the histo edges for the entropy computation
            SEbins = app.SEbinsEditField.Value;
            % compute the edges array used for the distribution computation
            if source
                %                 edges = linspace(app.mnF,app.mxF,SEbins);
                edges = linspace(app.dist.mnF,app.dist.mxF,SEbins); %RG 2020
            else
                % we create 1 super bin that contains all negative fluctuations
                edges(2:SEbins) = linspace(0,app.dist.maxDF,SEbins-1);    % remember: the median of DF is 0
                edges(1) = app.dist.minDF;
                %edges = linspace(app.dist.minDF,app.dist.maxDF,SEbins);
            end
            %waitbar can be animated with values included in [0,1]. you can
            %easily set a proportion with the number of columns of the ab
            %maps to get the % unit. the number of columns of course will
            %change depending on the binning.
            waitbarunit = ((100 / app.WIcolumns) *0.01);
            
            for column = 1:app.WIcolumns
                %instead show the counting process on the waitbar.
                waitbar((waitbarunit*column),f,['Process column ' num2str(column)]); %la waitbar non deve superare l'1
                for row = 1:app.WIrows
                    % extract the process to analyze
                    if source
                        process = app.dataOut.workImages(row,column,:);
                    else
                        process = app.dataOut.deltaF(row,column,:);
                    end
                    
                    process = permute(process,[3 2 1]);     % transform process in a row vector
                    [ks, ksstd, area, areastd, cdf] = kstestGauss(0, process,-6,8,nFreedom);
                    app.ABmap.KSRB (row,column) = ks;
                    app.ABmap.KSstdRB (row,column) = ksstd;
                    app.ABmap.areaRB (row,column) = area;
                    app.ABmap.areaStdRB (row,column) = areastd;
                    app.CDFmap(row,column,:) = cdf(:); % cdf = 1401 x 1
                    
                    % now compute the Shannon entropy of the process
                    % First, compute the probability of each value from the CDF
                    p = histcounts(process, edges,'normalization','probability');
                    % Second, extract only the non zero elements
                    p = p(p>0);
                    % Third, compute the entropy
                    app.ABmap.SE (row,column) = - sum(p .* log2(p));
                end
            end
            waitbar(.95,f,'Normalising the maps');


            % Normalisation of the maps (except ks) between 0 and 1
            app.ABmap.KSstdRB = app.ABmap.KSstdRB/max(max(app.ABmap.KSstdRB));
            app.ABmap.areaRB = app.ABmap.areaRB/max(max(app.ABmap.areaRB));
            app.ABmap.areaStdRB = app.ABmap.areaStdRB/max(max(app.ABmap.areaStdRB));
            % now set the default values for the display limits
            app.ABmapLimits.KSRB = [0 1];
            app.ABmapLimB.KSRB = [0 1];
            app.ABmapLimits.KSstdRB = [0 1];
            app.ABmapLimB.KSstdRB = [0 1];
            app.ABmapLimits.areaRB = [0 1];
            app.ABmapLimB.areaRB = [0 1];
            app.ABmapLimits.areaStdRB = [0 1];
            app.ABmapLimB.areaStdRB = [0 1];
            app.ABmapLimits.SE = [0 1];       % Shannon Entropy
            
            onABnormalMap(app);
            e = toc;
            disp(['End of AB-map computation: ' num2str(e) ' sec']);
            waitbar(0.99,f,'Finishing');
            
            close(f);
            
            %rehabilitates controls
            app.computeGaussMap.BackgroundColor = [0.99,0.92,0.80];
            drawnow;
            
        end

        % Button pushed function: createAVIbtn
        function createAVIbtn_Callback(app, event)
            % Feb. 18th, 2018.
            % This function create a non compressed AVI movie of the binarized data
            
            % create the output object
            AVIout = VideoWriter ('ROIdemo.avi','Grayscale AVI');
            open(AVIout);
            
            % prepare the figure for the video out.
            % remember to set the dimension according with the image stack size.
            hFig = figure('units','pixels','position',[200 200 256 256]);
            hMovie = axes('units','pixels','position',[0 0 256 256],'Visible','off');
            axis ([0 app.WIrows 0 app.WIcolumns]);
            set(hMovie, 'XTick', [], 'YTick', []);
            
            axes(hMovie);
            for i=1:app.metadata.frames
                frame = squeeze(app.erodedBinaryImages(:,:,1,i));
                [row, column] = find(frame);
                scatter(hMovie,column,row,1);
                axis ([0 app.WIrows 0 app.WIcolumns]);
                %set(hMovie, 'XTick', [], 'YTick', []);
                refresh;
                %    myavi = addframe(myavi, hFig);
                im = frame2im(getframe(hFig));
                im = rgb2gray(im);
                writeVideo(AVIout,im);
            end
            close(AVIout);
        end

        % Value changed function: distributionLogCk
        function distributionLogCk_Callback(app, event)
            plotDistribution(app);
        end

        % Button pushed function: filterBtn
        function LPfilterStack(app, event)
            % compute the low pass filtered image set
            % Still to be modified for 2 channel operation Sept 2020
            
            app.filterBtn.BackgroundColor = [1,0,0];
            app.statusNow.Text = 'Compute LP filter';
            drawnow
            processCh = 1;
            fltSize = app.kernelCh1EF.Value;
            fltMatrix = [];
            fltMatrix = ones(fltSize,fltSize) / fltSize^2;    % normalization
            
            if app.SpLPfilterCk.Value
                % the filter is included in the pipeline analysis
                for frame=1:app.metadata.frames
                    tmp = app.dataOut.workImages (:,:,processCh,frame);
                    tmp = imfilter(tmp,fltMatrix);
                    app.dataOut.workImages (:,:,processCh,frame) = tmp;
                end
            else
                % do nothing... pretty sure this will change.
            end
            app.history.Text = [app.history.Text ' LP'];
            app.statusNow.Text = 'Compute LP filter: done';
            app.filterBtn.BackgroundColor = [0.99,0.92,0.80];
            drawnow
        end

        % Selection changed function: IWsource
        function IWsource_SelectionChangedFcn(app, event)
            % set the scale limits in the 'Display Control' panel if a AB-map is being displayed
            wndSel = app.IWsource.SelectedObject.Tag;
            if ~contains('framesRBmaxRBaverageRB', wndSel)
                % AB-map selected
                mn = app.ABmapLimits.(wndSel)(1);
                mx = app.ABmapLimits.(wndSel)(2);
                app.ABmin.Value = mn;
                app.ABmax.Value = mx;
            end
            displayImageExplore (app)
            
            % Also, refresh right window (useful to update the merge w/
            % colors). But only if CB is checked: in this way no useful
            % time is spent when there's no need for it.
            if app.mergeColorsCB.Value
                displayBinaryImage(app);
            end
        end

        % Selection changed function: IWselectCh
        function IWselectCh_SelectionChangedFcn(app, event)
            displayImageExplore (app)
        end

        % Button pushed function: openBTN
        function openBTN_Callback(app, event)
            
            % Select the proper mode opening code
            app.openBTN.BackgroundColor = [1,0,0];
            drawnow
            offABnormalMap(app);
            
            if get(app.prairieRB,'Value'), fmtSt = '*.tif';
            end
            if get(app.TIFFmultiImageRB,'Value'), fmtSt = '*.tif';
            end
            if get(app.aviRB,'Value'), fmtSt = '*.avi';
            end
            
            [selection,path_in,~] = uigetfile ([fmtSt], "Select imaging data");
            
            % update the current data directory
            app.dirIn = path_in;
            fileInfo = {'name' 'date' 'bytes' 'isdir' 'datenum'};    % structure for storage of file info
            if selection ~= 0
                app.statusNow.Visible = true;
                app.statusNow.Text = 'Open imaging data file';
                drawnow
                % set to invisible all illegal controls
                fileInfo = dir([path_in selection]);
                timeNum = datestr(fileInfo.datenum);
                app.fileTime = timeNum (13:20);
                % Time of the day (hr:mn:ss) when the file has been generated
                openFile(app,selection,path_in)
                app.dataPath = path_in;
                app.fileIn = selection;
                app.imageGateway.Name = ['Image Gateway ' selection];
                app.history.Visible = true;
                app.history.Text = 'Data In';
            end
            app.statusNow.Text = 'Open imaging data file: done';
            app.openBTN.BackgroundColor = [0.96,0.96,0.96];
            drawnow
            
            % RG Dec 2020
            % I decided to create a DD that changes the colormap of
            % ImageExplore. Hence, the original 'hot' colormap is set here
            % as the starter parameter, and CMap property is filled. I added this
            % statement down here because "load" callback is meant to be called as the very
            % first thing and, likely, will just run once.
            if isempty(app.CMap) % Just to be sure...
                app.CMap = hot;
            end
        end

        % Callback function
        function previewFthr_Callback(app, event)
            % Here we compute an alpha channel by comparing the median fluorescence with the threshold
            
            if app.previewFthr.Value
                thr = app.thrFtxt.Value;
                app.Fmask = app.mdImage;
                app.Fmask(app.Fmask<=thr) = 0;
                app.Fmask(app.Fmask>0) = 1;
                app.Fmask = -(app.Fmask-1);
                % the mask holds 1 where the fdata is invalid and he mask is opaque and 0
                % were the data is valid and the mask is transparent
                app.FmaskFlag = 1;
                
                app.black = app.mdImage;
                app.black = app.black * 0;
            else
                app.FmaskFlag = 0;
            end
            displayImageExplore (app);
        end

        % Button pushed function: resetProcessing
        function resetProcessing_Callback(app, event)
            app.resetProcessing.BackgroundColor = [1,0,0];
            app.statusNow.Text = 'Resetting input data';
            drawnow
            setAllInitialValues(app)
            app.history.Visible = true;
            app.history.Text = 'Data';
            app.statusNow.Text = 'Reset input data: done';
            app.resetProcessing.BackgroundColor = [0.96,0.96,0.96];
            drawnow
        end

        % Value changed function: selectFrame
        function selectFrame_Callback(app, event)
            app.currentFrame = int16(app.selectFrame.Value);
            app.currentFrTxt.Text = num2str(app.currentFrame);
            % display the frame pointed by app.currentFrame
            displayImageExplore (app);
            displayBinaryImage (app);
            
        end

        % Value changed function: thresholdFck
        function thresholdFck_Callback(app, event)
            % September 2020
            % When this option is selected, all following computations (ie binarization, events extraction etc) are
            % performed on the masked data only. This is accomplished by creating a binary mask to exclude all bad points
            % from the following computations
            
            % Furthermore, we also compute the alpha channel for the data visualization
            % The mask is computed on the dataOut.workImage stack and it is
            % resized in order to create a mask also for the non-binned data
            
            % The visualisation of the mask as an alpha channel is controlled by a checkbox on the dispay tab.
            
            if app.thresholdFck.Value
                % Compute the thresholded image stack
                % Compute the mask
                thr = app.thrFtxt.Value;
                app.binMask = app.mdImage;      % This is the median projection of the BINNED stack
                app.binMask(app.binMask <= thr) = 0;
                % all masked elements NOT = 0 are made equal to 1
                app.binMask(app.binMask>0) = 1;
                % This mask is multiplied with the binarized stack BEFORE spatial and
                % temporal filtering.
                app.binMask(isnan(app.binMask)) = 0;
                
                % Now compute the alpha channel for data visualization
                app.Fmask = -(app.binMask-1);
                % the mask holds 1 where the fdata is invalid and he mask is opaque and 0
                % were the data is valid and the mask is transparent
                % compute non binned mask
                app.mask = imresize(app.Fmask,app.binTxt.Value,'nearest');
                app.FmaskFlag = 1;
            end
        end

        % Selection changed function: uibuttongroup7
        function uibuttongroup7_SelectionChangedFcn(app, event)
            displayBinaryImage (app)
        end

        % Selection changed function: uibuttongroupDistribution
        function uibuttongroupDistribution_SelectionChangedFcn(app, event)
            plotDistribution(app);
        end

        % Value changed function: DFminTxt
        function changeDFmin(app, event)
            displayImageExplore (app);
        end

        % Value changed function: DFmaxTxt
        function changeDFmax(app, event)
            displayImageExplore (app);
        end

        % Value changed function: FminTxt
        function changeFmin(app, event)
            displayImageExplore (app);
        end

        % Value changed function: FmaxTxt
        function changeFmax(app, event)
            displayImageExplore (app);
        end

        % Button pushed function: computeRGBtimeMap
        function computeRGBmap(app, event)
            % Compute the RGB coded binary time series. A standard color map has only
            % 64 RGB entries. We will expand the LUT later on. The jet colormap plot
            % the rainbow starting from blue to green, yellow and red. Time axis starts
            % at the blue end of the spectra.
            %clMap = colormap('jet');        % clMap contains the RGB triplets
            
            clMap = colormap('jet');        % clMap contains the RGB triplets
            
            
            % for j=1:63
            %     newj = 1 + 10 * (j-1)
            %     newCmap (newj:newj+10,1) = linspace(clmap(j,1),clmap(j+1,1),10);         % R component
            %     newCmap (newj:newj+10,2) = linspace(clmap(j,2),clmap(j+1,2),10);         % G component
            %     newCmap (newj:newj+10,3) = linspace(clmap(j,3),clmap(j+1,3),10);         % B component
            % end
            colN = length(clMap);   % dimension of the colormap


            app.metadata.meanSP = app.SamplingsEditField.Value; %2021
            
            
            % compute the indexes of the interval
            
            indexLeft = 1 + int16(app.RGBmapLeft.Value/app.metadata.meanSP);
            indexRight = 1 + int16(app.RGBmapRight.Value/app.metadata.meanSP);
            
            nFrames = indexRight - indexLeft + 1;
            
            % RG 2021. In order to match the correct colours, the length of
            % the colormap should be decreased. Empirically, for 460 frames
            % (300s) tim arrives up to 74
             aa=jet(double(74));
             clMap = colormap(aa);
             colN = length(clMap);
            
            % initialize RGB stack
            app.binRGB = zeros(app.wy,app.wx,3,nFrames);
            for i=1:nFrames
                % compute the RGB code belonging to the time slice
                tim = int16(1+(colN-1)*i/nFrames); % correggere
                frame = indexLeft + i - 1;
                app.binRGB (:,:,1,i) = clMap(tim,1) * app.erodedBinaryImages (:,:,1,frame);
                app.binRGB (:,:,2,i) = clMap(tim,2) * app.erodedBinaryImages (:,:,1,frame);
                app.binRGB (:,:,3,i) = clMap(tim,3) * app.erodedBinaryImages (:,:,1,frame);
                % for proper display of RGB images, the RGB chanels must be the third
                % dimension of the array
            end
            % project the RGB stack
            app.meanRGB = mean(app.binRGB,4);


        end

        % Value changed function: LogPlotCB
        function logPlot(app, event)
            plotTimeCourse(app)
        end

        % Button pushed function: traceToClipboard
        function traceToClip(app, event)
            % June 2019
            % Data from the time course axes are saved on the clipboard
            if app.showROIsCB.Value
                outTrace = 0;
                for i=1:app.nROIs
                    if app.ROI(i).visibility
                        outTrace = outTrace + 1;
                        if app.plotBinnedFcK.Value
                            app.temp(outTrace,:) = app.ROI(i).binF(:);
                        end
                        if app.plotDeltaFcK.Value
                            app.temp(outTrace,:) = app.ROI(i).deltaF(:);
                        end
                        if app.binaryRB.Value
                        end
                    end
                end
                tmp = app.temp';
            else
                tmp = app.temp';
            end
            arrayout = num2clip(app,tmp);
        end

        % Selection changed function: uipanel13
        function changeData(app, event)
            plotTimeCourse(app)
        end

        % Value changed function: displayROIs
        function refreshROIs(app, event)
            displayImageExplore (app)
        end

        % Selection changed function: ROIcontrolButtonGroup
        function ROImodeOnOff(app, event)
            % Activate the ROI mode and open the ROI dialog box
            
            selectedButton = app.ROIcontrolButtonGroup.SelectedObject;
            if isempty(app.metadata), errNow = errordlg('Load an imaging data first','Monkey Error');
                app.NoROIsBt.Value = 1;
            else
                % first: check if the selected option is no ROI. This option would close the ROI editor
                if app.NoROIsBt.Value
                    % close the ROI editor.
                    if ~isempty(app.ROIedit)        % now check whether app.ROIeditor is a valid handle.
                        % close and delete the ROI editor
                        delete(app.ROIedit);      % delete the handle to the child process
                    end
                else
                    if isempty(app.ROIedit) % check if the child process already exists by checking the existance of the field.
                        % Two possibilites: 1) it does not exist and then it must be opened.
                        % 2) it exists but it has been deleted. In that case it must be restarted somehow
                        % if it app.ROIeditor is empty this is the first time we call it
                        % Open the editor
                        app.ROIedit= ROIeditor(app);      % handle to the child figure
                    else
                        if ~isvalid(app.ROIedit)        % now check whether app.ROIeditor is a valid handle.
                            app.ROIedit = ROIeditor(app);      % handle to the child app
                        end
                    end
                end
            end
        end

        % Value changed function: showROIsCB
        function showROItrace(app, event)
            value = app.showROIsCB.Value;
            plotTimeCourse (app)
        end

        % Value changed function: SamplingsEditField
        function SP(app, event)
            value = app.SamplingsEditField.Value;
            
        end

        % Callback function
        function ROIapplication(app, event)
            app.ROIApp=applicationROIeditor(app);
        end

        % Button pushed function: registerStackBT
        function registerStack(app, event)
            % Registration of the app.dataOut.workImages array
            % October 2020, February 2021. GMR
            
            app.statusNow.Text = 'Register stack';
            drawnow
            % First prepare the stack for the registration run depending on the chosen options
            if app.regOnSquareCB.Value
                % Find the smallest dimension and the radius of the inscribed circle
                minS = min([app.metadata.xdim app.metadata.ydim]);
                insideSq = floor(minS/(2*sqrt(2)))-2;
                % Find the center
                cnt = [floor(app.metadata.xdim/2) floor(app.metadata.ydim/2)];
                xind = [cnt(1)-insideSq cnt(1)+insideSq];
                yind = [cnt(2)-insideSq cnt(2)+insideSq];
            else
                xind = [1 app.metadata.xdim];
                yind = [1 app.metadata.xdim];
            end
            switch app.selectRegistrationSource.SelectedObject.Text
                case 'Align on Ch1'
                    tmpReg = squeeze(app.dataOut.workImages(xind(1):xind(2),yind(1):yind(2),1,:));
                case 'Align on Ch2'
                    tmpReg = squeeze(app.dataOut.workImages(xind(1):xind(2),yind(1):yind(2),2,:));
                case 'Align on both'
                    tmpReg = squeeze(app.dataOut.workImages(xind(1):xind(2),yind(1):yind(2),1,:) ...
                        +app.dataOut.workImages(xind(1):xind(2),yind(1):yind(2),2,:));
            end
            tic
            if app.LPregCB.Value      % Low pass filter on the reference stack
                app.statusNow.Text = 'Register stack: LP filter';
                drawnow
                fltSize = app.LPregEF.Value;
                fltMatrix = [];
                fltMatrix = ones(fltSize,fltSize) / fltSize^2;    % normalization
                tmpReg = imfilter(tmpReg,fltMatrix);
                app.statusNow.Text = 'Register stack: LP filter done';
                drawnow
            end
            
            % tmpReg will be used to compute the transformation
            toc
            % Configure the alignment tool. This does not perform the alignment which occurs in the following loop.
            [optimizer,metric] = imregconfig('monomodal');
            % 'monomodal' (similar brightness and contrast) or 'multimodal' (different brightness or contrast).
            % Properties of the optimizer and metric objects can be tuned
            tic
            outReg = tmpReg;
            for i=2:app.metadata.frames
                fixed = tmpReg(:,:,i-1);
                moving = tmpReg(:,:,i);
                % First, compute the trasform between the two adjacent frames of the stack.
                transform = imregtform(moving, fixed, 'translation', optimizer, metric,...
                    'DisplayOptimization',false);
                % Apply the computed transforms to the data set used for registration
                tmpReg(:,:,i) = imwarp(moving,transform,'OutputView',imref2d(size(fixed)));
                % If the registration is performed on the low passed images stack the actual transformation
                % must be applied on the original data.
                % The proper low pass filter or predictive Kalman filter must be applied on the unfiltered registered stack.
                rawMoving = app.dataOut.workImages(:,:,1,i);
                app.dataOut.workImages(:,:,1,i) = imwarp(rawMoving,transform,'OutputView',imref2d(size(fixed)));
                app.statusNow.Text = ['Register stack: frame ' num2str(i)];
                drawnow
            end
            app.history.Text = [app.history.Text ' Reg'];
            app.statusNow.Text = 'Register stack: done';
            drawnow
            toc
        end

        % Value changed function: IWautoBrCB, maskOn
        function redoDisplay(app, event)
            displayImageExplore (app)
        end

        % Callback function
        function mergeColors(app, event)
            %RG dec 2020
            
            %this cb is a graphic tool to show the merge between the
            %binarized regions and what is seen on the left window. the nice
            %thing of it is that it takes directly the output from the left
            %window, without caring about the source.
            
            %if bin frames rb is selected
            if app.FbinRB.Value
                
                %.Children.CData is a great way to extrapolate data from one UIAxes. it's useful because
                %in this way you can get directly the output from the
                %display window, without interference of any sort with all the underlying input controls.
                ieOutput = app.imageExplore.Children.CData;
                
                %conversely, bin window data is taken from its source, ie
                %the property, depending on the rb raw/filtered.
                switch app.uibuttongroup7.SelectedObject.Text
                    case 'Raw'
                        binOutput = app.binaryThrImages(:,:,1,app.currentFrame);
                    case 'Filtered'
                        binOutput = app.erodedBinaryImages(:,:,1,app.currentFrame);
                end
                %squeeze removes some bugs
                binOutput = squeeze(binOutput);
                
                %my desire is to multiplicate element by element these two
                %matrices. the problem is that they do not have always the
                %same size due to the binning of the display matrix.
                
                %if there's no difference in size, e.g. with no binning,
                %then the solution simple.
                
                if length(ieOutput) == length(binOutput)
                    %by the way, this condition also applies to the very
                    %first interaction by the user. i have no idea why it
                    %happens, nevertheless it seems to work fine.
                    
                    merge = ieOutput .* binOutput;
                    
                    %graphic adjustements
                    app.binaryExplore.XLim = [0 length(binOutput)];
                    app.binaryExplore.YLim = [0 length(binOutput)];
                    set(app.binaryExplore,'Colormap',app.CMap);
                    app.binaryExplore.BackgroundColor = app.imageExplore.BackgroundColor;
                    app.binaryExplore.CLim = app.imageExplore.CLim;
                    app.binaryExplore.ZColor = app.imageExplore.ZColor;
                    %axis(app.binaryExplore,[0 asp*app.WIcolumns 0 app.WIrows])
                    imagesc(app.binaryExplore, merge);
                    
                    %then, if the display and the bin matrices are not of the
                    %same size, some adjustment is needed. one way to do it is
                    %by working on the binned matrix, which is of course quite
                    %easy to reshape. repelem() function is used to achieve
                    %this.
                else
                    
                    %this is how repelem() function works:
                    % A = [1 2; 3 4]
                    % A = 2×2
                    %
                    %      1     2
                    %      3     4
                    %
                    % B = repelem(A,3,2)
                    % B = 6×4
                    %
                    %      1     1     2     2
                    %      1     1     2     2
                    %      1     1     2     2
                    %      3     3     4     4
                    %      3     3     4     4
                    %      3     3     4     4
                    
                    %take the binning value which was used to compute the
                    %left map
                    bin = app.binTxt.Value;
                    %and apply repelem on the binned mask. notice that you
                    %want to substitute single elements with a square
                    %matrix with side = bin.
                    binOutputInt = repelem(binOutput,bin,bin);
                    
                    %now multiply
                    merge = ieOutput .* binOutputInt;
                    
                    %graphic adjustements. one important part of this block
                    %is regarding the limits of the axes, because you have
                    %to adapt them to fit the rigth window size and make it
                    %comparable with the left window.
                    %i could use this code here:
                    %                   app.binaryExplore.XLim = [0 128*bin];
                    %                   app.binaryExplore.YLim = [0 128*bin];
                    %but this other one is more trustable:
                    app.binaryExplore.XLim = [0 length(binOutputInt)];
                    app.binaryExplore.YLim = [0 length(binOutputInt)];
                    %axis(app.binaryExplore,[0 asp*app.WIcolumns 0 app.WIrows])
                    set(app.binaryExplore,'Colormap',app.CMap);
                    app.binaryExplore.BackgroundColor = app.imageExplore.BackgroundColor;
                    app.binaryExplore.CLim = app.imageExplore.CLim;
                    app.binaryExplore.ZColor = app.imageExplore.ZColor;
                    
                    
                    imagesc(app.binaryExplore, merge);
                end
            end
            
            %             if app.binMaxRB.Value
            %                 displayBinaryImage(app)
            %             end
        end

        % Value changed function: mergeColorsCB
        function refreshGUI(app, event)
            displayBinaryImage(app);
        end

        % Button pushed function: ABmaskCB
        function computeABmask(app, event)
            %the rationale of the dropdown menus is that you might want to
            %combine in several ways the information carried by different
            %metrics of abnormal maps.
            %this could be accomplished by applying a AND or OR logic
            %between the two masks that we obtained once we set the
            %threhsold.
            %of course, if one is intersted in just applying a mask derived
            %by a single map, dropdown menus should point to the same map.
            
            %another aspect, is that the AND and OR logic refer to the 1s
            %of the masks, and not to the 0s. this could seem a trivial
            %statement, but to me it was quite tricky because initially i
            %was more interested in the zeros than the ones. apart from
            %this, however, the meaning should be clear.
            
            %get maps from the dropdown menus
            ABChoice1 = app.ABMaskDisplayChoice1DD.Value;
            ABChoice2 = app.ABMaskDisplayChoice2DD.Value;
            threshold1 = app.normalAreasThr1EF.Value;
            threshold2 = app.normalAreasThr2EF.Value;
            ABn1 = app.ABmap.(ABChoice1);
            ABn2 = app.ABmap.(ABChoice2);
            
            %transform the selected ab-normal maps in 0/1 binarized maps.
            %each map has its threshold.
            filtABn1 =  (ABn1 >= threshold1);
            filtABn2 =  (ABn2 >= threshold2);
            
            %create the AND mask. the AND mask will comprehend only the 1s
            %that are in common between the two ab-normal masks which were
            %generated above
            ANDones = filtABn1 .* filtABn2;
            
            %create the OR mask. OR mask will sum the interesting region
            %taken from the two masks. this map will appear to be less
            %"sparsed" than the AND one. obviously this is because you take
            %for totally granted all the information from both maps.
            %sum of the masks will generate a matrix constituted of 0s,1s
            %and 2s. 2s will then be transformed to 1s.
            ORones = filtABn1 + filtABn1;
            ORones = (ORones >= 1);
            
            %select desired map and show it to the user
            if app.AND_OR_DD.Value == "AND"
                app.ABmask = ANDones;
            else
                app.ABmask = ORones;
            end
            figure
            imagesc(app.ABmask)
            
            %app.ABmask now is saved and could be used in the display
            %tab.
            
        end

        % Value changed function: applyABmaskCB
        function applyABMask(app, event)
            displayImageExplore(app)
            displayBinaryImage(app) % Added
        end

        % Button pushed function: getABHistoBT
        function getABHisto(app, event)
            
            
            %this button creates a combined plot with the two selected maps
            %and their distributions.
            %the original idea was to utilize
            %these histograms to assess "by eye" which could have been a
            %possible threshold to binarize the ab maps and create a mask from them.
            %for this reason, i also kept a disp() function which shows on
            %the command window the bin edges of the resulting histograms.
            %of course this is not particulary useful, so it can be
            %removed.
            
            
            ABChoice1 = app.ABMaskDisplayChoice1DD.Value;
            ABChoice2 = app.ABMaskDisplayChoice2DD.Value;
            ABn1 = app.ABmap.(ABChoice1);
            ABn2 = app.ABmap.(ABChoice2);
            
            %tiledlayout allow to create multiple-figure plots. it requires
            %R2019b or later.
            tiledlayout(2,2); % Requires R2019b or later
            
            % Top-left plot
            nexttile
            imagesc(ABn1);
            title(['Map 1 | ' ABChoice1])
            
            % Bottom plot
            nexttile
            imagesc(ABn2);
            title(['Map 2 | ' ABChoice2])
            
            % Bottom-left plot
            nexttile
            h1=histogram(ABn1);
            title(['Distribution 1 | ' ABChoice1])
            
            % Bottom-right plot
            nexttile
            h2=histogram(ABn2);
            title(['Distribution 2 | ' ABChoice2])
            
            %output of bin edges on the command window. this part of the
            %code is not necessary.
            disp('Bin Edges of Histogram 1: ');
            disp(h1.BinEdges);
            disp('Bin Edges of Histogram 2: ');
            disp(h2.BinEdges);
        end

        % Button pushed function: getAutoThresholdBT
        function getAutoThreshold(app, event)
            %of course you might not desire to assess the threshold for the
            %ab-normal mask simply "by eye".
            %one possible way to assess a first threshold, is by looking at
            %the median() value of the distribution. you'll get a quite
            %reliable threshold, which very probably will not exclude
            %important points.
            %a second way to assess a higher threshold, is by moving from
            %the median towards the "interesting" side of the distribution
            %by a certain number of standard deviations.
            %i have no idea which could be the most precise way to assess the
            %number of standard deviations away from the median. i tried to use std * 2.5 to be quite sure to not
            %consider useless parts.
            
            %this code takes the type of abnormal metrics by the selected
            %value in dropdown menus.
            ABChoice1 = app.ABMaskDisplayChoice1DD.Value;
            ABChoice2 = app.ABMaskDisplayChoice2DD.Value;
            ABn1 = app.ABmap.(ABChoice1);
            ABn2 = app.ABmap.(ABChoice2);
            thstd = app.ABThrStdEF.Value;
            
            %this is if you decide to move away from the median of x stds
            if app.ABThrStdCB.Value
                %get std value of map 1
                stdABn1 = std(ABn1(isfinite(ABn1)));
                %compute median of map 1
                medianABn1 = median(ABn1,'all');
                %set threshold thstd standard deviations away from the
                %median
                autoThresholdABn1 = medianABn1 + stdABn1 * thstd;
                %refresh editfield with the threshold 1
                app.normalAreasThr1EF.Value = autoThresholdABn1;
                
                %now repeat the same thing for map 2
                stdABn2 = std(ABn2(isfinite(ABn2)));
                medianABn2 = median(ABn2,'all');
                autoThresholdABn2 = medianABn2 + stdABn2 * thstd;
                %%refresh editfield with the threshold 2
                app.normalAreasThr2EF.Value = autoThresholdABn2;
                
                %otherwise, you might want to just consider the median as a reliable threshold.
            else
                %if you make a comparison with the histogram() if the
                %distribution, the median will fall on the right edge of the
                %bin which is most represented.
                app.normalAreasThr1EF.Value = median(ABn1,'all');
                app.normalAreasThr2EF.Value = median(ABn2,'all');
            end
            
        end

        % Button pushed function: allABsMaskBT
        function allABsMask(app, event)
            %this button creates a mask using the information coming from all
            %the 5 metrics that are currently exploited on image gateway, and not with just 2 of them.
            %my initial desire was to make the most stringent map possibile,
            %for example by using all the 5 metrics and multiplying them with an AND
            %logic. by the way, i also kept the possibility to apply the OR logic.
            %moreover, for the sake of this purpose, i also decided to implement
            %automatically a median + 2.5 * standard deviation threhsold for all
            %the single metrics.
            
            %computation of the total AND mask
            if app.AND_OR_DD_2.Value == "AND"
                %string is needed to get the texts from the 5 values of dropdown
                %menus in "map" tab
                maps = string(app.ABMaskDisplayChoice1DD.Items);
                %is use a for loop to process all maps. maybe one day it
                %could be made with vectors, although the loop is quite
                %fast even in this way.
                
                %preallocation
                app.totABMask=ones(app.WIcolumns);
                for i = 1:1:5
                    x = maps(i);
                    ABn = app.ABmap.(x);
                    %get value of standard deviation of the distribution of
                    %the current ab-normal metric
                    stdABn = std(ABn(isfinite(ABn)));
                    %get the median of the current distribution
                    medianABn = median(ABn,'all');
                    %compute a strict threshold
                    autoThresholdABn = medianABn + stdABn * 2.50;
                    %binarize
                    filtABn =  (ABn >= autoThresholdABn);
                    %multiply the current mask with the total mask
                    app.totABMask = app.totABMask .* filtABn;
                end
                
                imagesc(app.totABMask);
                app.ABmask = app.totABMask;
            else
                
                %computation of the total OR matrix
                maps = string(app.ABMaskDisplayChoice1DD.Items);
                app.totABMask=zeros(app.WIcolumns);
                for i = 1:1:5
                    x = maps(i);
                    
                    ABn = app.ABmap.(x);
                    %get value of standard deviation of the distribution of
                    %the current ab-normal metric
                    stdABn = std(ABn(isfinite(ABn)));
                    %get the median of the current distribution
                    medianABn = median(ABn,'all');
                    %compute a strict threshold
                    autoThresholdABn = medianABn + stdABn * 2.50;
                    %binarize
                    filtABn =  (ABn >= autoThresholdABn);
                    %now i sum the current mask to the total mask. notice
                    %that with this method of course i am going to keep the
                    %pure zeros. the other values will be integer
                    %numbers included between [1,5].
                    app.totABMask = app.totABMask + filtABn;
                end
                %set to 1 all values which are 1,2,3,4 or 5. this could
                %have be done also with:              app.totABMask = (app.totABMask ~= 0);
                app.totABMask = (app.totABMask >= 1);
                
                imagesc(app.totABMask);
                app.ABmask = app.totABMask;
            end
        end

        % Value changed function: timeBarGraphicsCB
        function timeBar(app, event)
            
            %create a graphic effect to show a time frame bar on the right of the plot.
            %there are several ways to do it. i chose to display a
            %non-interactive, 2px thin UIAxes, which shows a red bar moving
            %towards the top of the graph as time passes by.
            tb = app.timeBarUIAxes_Disp;
            tb_bin = app.timeBarUIAxes_Bin;
            %hide plot axes
            tb.Visible = 'off';
            tb_bin.Visible = 'off';
            %preclude unwanted interactions between the plot and the
            %cursor. this command removes all data within the axes,
            %which are useless since we just want to design a qualitative graphic
            %effect.
            %moreover, axes' data is useless whenever you navigate near the
            %extremities of the time series. the timebar is not a point,
            %but has been represented as a line with its intrinsic height.
            %so there's no precise relationship with the frames.
            %hides data tips
            disableDefaultInteractivity(tb);
            disableDefaultInteractivity(tb_bin);
            %hides zoom and toolbars
            tb.Toolbar.Visible = 'off';
            tb_bin.Toolbar.Visible = 'off';
            
            
            %xlim is for the thickness of the bar. actually useless since
            %the bar is displayed correctly anyway.
            tb.XLim = [0 5];
            tb_bin.XLim = [0 5];
            %ylim is for the overall length of the graph. notice that i
            %underlined here a +10. this is because the bar is not a point,
            %but has an intrisic length, as arbitrary it is. so it's like
            %the first dot of the bar corresponds the actual time frame, while the
            %rest shifts towards bottom to generate the graphic effect of a line. by
            %the way, perhaps this is useless thoughtfulness.
            
            %take the number of total frames of the imaging data and set
            %ylim.
            totFrames = app.metadata.frames;
            %barlength is nice when its ~1cm. we can express it as a fraction of the
            %total number of frames. in this way the length is constant
            %with respect to UIaxes parameters even with different frame stacks.
            barLength = totFrames*0.15;
            tb.YLim = [0 (totFrames+barLength)];
            tb_bin.YLim = [0 (totFrames+barLength)];
            %also, i find prettier if the bar moves towards bottom as time
            %passes by, so i revert y axes direction (playing with the gui,
            %i didn't want to multiply by -1).
            tb.YDir = 'reverse';
            tb_bin.YDir = 'reverse';
            
            
            %TIME BAR 1) Disp
            %if the graphics cb is checked, and frame or fluoframes is selected in disp tab, show the time bar
            if app.timeBarGraphicsCB.Value && (app.IWfluoRB.Value || app.IWdFRB.Value)
                
                %take the current frame and use its value to plot a
                %numberframe+10px line on the y axes. x axes is not important.
                frame = app.currentFrame;
                plot(tb, [0; 0], [frame; frame+barLength], '-k', 'LineWidth', 1, 'Color',[1 0 0] );
                %text of the number of the frame on the right of the bar, in centered position with respect to its length. double() is
                %needed to avoid some errors with the slider.
                text(tb, 1,double(frame + barLength/2), num2str(frame), "FontSize",9,"FontWeight","bold");
                
                %also, add reference text about the outermost values of the frame stack
                f_first = text(tb, 1,1 + barLength/2, '1', "FontSize",9); %reference of first frame
                f_last = text(tb, 1.5,totFrames + barLength/2, num2str(totFrames), "FontSize",9,"HorizontalAlignment","center"); %reference of the last frame
                
                %if you are not showing frames, or if graphics cb is not
                %checked, hide/delete all this useless stuff
            else
                plot(tb, [0; 0], [0; 0], '-k', 'LineWidth', 3, 'Color',[0 0 0]);
            end
            
            %TIME BAR 2) Bin
            %if graphics cb is checked and frames rb is selected in bin
            %window, then show the bin time bar. also, check if binary data
            %has already been computed.
            if  app.timeBarGraphicsCB.Value && app.FbinRB.Value && ~isempty(app.binaryProjection)
                
                %take the current frame and use its value to plot a
                %numberframe+10px line on the y axes. x axes is not important.
                frame = app.currentFrame;
                plot(tb_bin, [0; 0], [frame; frame+barLength], '-k', 'LineWidth', 1, 'Color','blue' );
                %text of the number of the frame on the right of the bar, in centered position with respect to its length. double() is
                %needed to avoid some errors with the slider.
                text(tb_bin, 1,double(frame + barLength/2), num2str(frame), "FontSize",9,"FontWeight","bold");
                
                %also, add reference text about the outermost values of the frame stack
                f_bin_first = text(tb_bin, 1,1 + barLength/2, '1', "FontSize",9); %reference of first frame
                f_bin_last = text(tb_bin, 1.5,totFrames + barLength/2, num2str(totFrames), "FontSize",9,"HorizontalAlignment","center"); %reference of the last frame
                
                %otherwise hide/delete it
            else
                plot(tb_bin, [0; 0], [0; 0], '-k', 'LineWidth', 3, 'Color',[0 0 0]);
            end
            
            
            %when the current frame is in the vicinity of the extremities
            %of the time series, hide the reference text to avoid an
            %overlap. i assume that the overlap occurs when the current
            %frame is below 10% or above 90% of the total number frames. this thing
            %makes this graphic effect responsive with respect to different numbers of
            %total frames.
            frame = app.currentFrame;
            if frame > totFrames * 0.1 && frame < totFrames * 0.90
                f_first.Visible = 'on';
                f_last.Visible = 'on';
                f_bin_first.Visible = 'on';
                f_bin_last.Visible = 'on';
            elseif frame < totFrames * 0.1
                f_first.Visible = 'off';
                f_bin_first.Visible = 'off';
            elseif frame > totFrames * 0.90
                f_last.Visible = 'off';
                f_bin_last.Visible = 'off';
            end
        end

        % Value changed function: binTxt
        function binWarning(app, event)
            % Binning methods seem to be dependent on the perfect match
            % between bins and original size. Remainder should be 0.
            %             r = rem( a , b ) returns the remainder after division of a by b , where a is the dividend and b is
            % the divisor. This function is often called the remainder operation,
            % which can be expressed as r = a - b. *fix(a./b) .
            % The rem function follows the convention that rem(a,0) is NaN .
            
            % First I want to take the size of the data
            a = squeeze(app.dataOut.workImages); %remove channel dimension
            [w, h, ~] = size(a);
            
            % THen, I take the value of the EF
            v = app.binTxt.Value;
            
            % And check if remainder is 0 for either width and length
            if rem (w,v) ~= 0 || rem (w,h) ~= 0
                helpdlg({'Bin size does not seem to be compatible with the size of the image. ',...
                    'Size of image is: ' num2str(w) 'x' num2str(h),...
                    'Please choose another value such that no remainder is left.'});
                app.binTxt.Value = 1;
            end
        end

        % Button pushed function: animateBinBT
        function animateBin(app, event)
            % RG 20 Dec 2020
            %
            %
            %
            % Added new functions for the computation of the .avi files. Basically
            % there are now three options: 1) merge bin data with the current left
            % window output (therefore with the possibility to show the colors of a
            % single frame or the colors of one particular mask; 2) merge bin data with
            % the stack of fluorescence frames; and 3) merge bin data with the stack of
            % DF/F frames.
            % Notice that, in order to show colors, the 1st option asks if the MergeColors
            % CB is checked. If not, no colors will be shown.
            %
            %
            % Also, it seems that it is not possible to change automatically the colormap properties
            % of the implay() output. Therefore, some hints are showed.
            
            %************** Option #1: left window current output is chosen
            if app.animateBinCurrentOutRB.Value
                
                % First, check if colors should be computed
                if app.mergeColorsCB.Value
                    
                    switch app.uibuttongroup7.SelectedObject.Text
                        case 'Raw'
                            binSource = app.binaryThrImages(:,:,1,:);
                        case 'Filtered'
                            binSource = app.erodedBinaryImages(:,:,1,:);
                    end
                    binSource = squeeze(binSource);
                    [wb,hb,~] = size(binSource);
                    % Then, get disp window output in that moment. This could
                    % be the max prj, as well as a single frame or an abnormal
                    % map. Every combination is possible, since this is just
                    % the output. It will be multiplied with the bin frames in
                    % a similar manner as it happens for the mergeColors
                    % callback.
                    ieOutput = app.imageExplore.Children.CData;
                    [wd,hd] = size(ieOutput); %obv, since it's just one picture
                    %output, no frame data is present -- nor needed.
                    
                    % I want to merge the output of display window with these
                    % binarized areas. Therefore, I first check if the two
                    % matrices have equal size.
                    if wb == wd && hb == hd
                        
                        app.colouredBinStack = binSource .* ieOutput;
                        
                    else
                        
                        %this is how repelem() function works:
                        % A = [1 2; 3 4]
                        % A = 2×2
                        %
                        %      1     2
                        %      3     4
                        %
                        % B = repelem(A,3,2)
                        % B = 6×4
                        %
                        %      1     1     2     2
                        %      1     1     2     2
                        %      1     1     2     2
                        %      3     3     4     4
                        %      3     3     4     4
                        %      3     3     4     4
                        
                        %take the binning value which was used to compute the
                        %left map
                        bin = app.binTxt.Value;
                        %and apply repelem on the binned mask. notice that you
                        %want to substitute single elements with a square
                        %matrix with side = bin.
                        binSource = repelem(binSource,bin,bin);
                        
                        %now multiply
                        app.colouredBinStack = binSource .* ieOutput;
                        
                    end
                    
                    % Still option 1 -- if currentframeCB is checked, but mergecolorsCB is not checked,
                    % then compute simple 0/1 animation of Bin stack. This
                    % implies that no further implay() call is needed: so I'm
                    % gonna adding an exception for the very exact situation at
                    % the very end of the function. See later
                else % Implay() is directly launched
                    if app.binaryRawRB.Value
                        % play the raw (no binary flters) binned frames
                        app.Bmovie = implay(app.binaryThrImages(:,:,1,:));
                        app.Bmovie.Visual.setPropertyValue('UseDataRange',true);
                        app.Bmovie.Visual.setPropertyValue('DataRangeMin',-Inf);
                        app.Bmovie.Visual.setPropertyValue('DataRangeMax',Inf);
                    else
                        app.Bmovie = implay(app.erodedBinaryImages(:,:,1,:));
                        app.Bmovie.Visual.setPropertyValue('UseDataRange',true);
                        app.Bmovie.Visual.setPropertyValue('DataRangeMin',-Inf);
                        app.Bmovie.Visual.setPropertyValue('DataRangeMax',Inf);
                    end
                    
                    % Now, the user choosed currentFrame + no
                    % mergecolors, and implay() function has been
                    % launched with the correct parameters (no colormap and data
                    % range: - inf , inf), so there's no need for further
                    % instructions.
                    return
                    
                end
                
                %*************Option #2: animate binarized data with fluo stack
            elseif app.animateBinFluoStackRB.Value
                
                % Again, check which bin Source to get
                switch app.uibuttongroup7.SelectedObject.Text
                    case 'Raw'
                        binSource = app.binaryThrImages(:,:,1,:);
                    case 'Filtered'
                        binSource = app.erodedBinaryImages(:,:,1,:);
                end
                % Get width and height of bin source
                binSource = squeeze(binSource);
                [wb,hb,~] = size(binSource);
                % Get frame stack source and its width and height as
                % well.
                % This time, the ieOutput is taken from the
                % fluorescence frame stack. I decided to squeeze both
                % bin and disp stacks since there's only one channel.
                % The tilde is for time, but I already know that it's
                % the same in the two stacks.
                ieOutput = squeeze(app.dataOut.workImages(:,:,1,:));
                [wd,hd, ~] = size(ieOutput);
                
                % I want to merge the fluo frame stack with the
                % binarized areas. Therefore, I first check if the two
                % matrices have equal size.
                if wb == wd && hb == hd
                    
                    % this multiplies bin and stack frame by frame
                    app.colouredBinStack = binSource .* ieOutput;
                    
                else %if there's some difference in size due to binning
                    
                    % take the binning value which was used to compute the
                    % left map
                    bin = app.binTxt.Value;
                    % and apply repelem on the binned mask. You
                    % want to substitute single elements with a square
                    % matrix with side = bin.
                    % this is how repelem() function works:
                    % A = [1 2; 3 4]
                    % A = 2×2
                    %
                    %      1     2
                    %      3     4
                    %
                    % B = repelem(A,3,2)
                    % B = 6×4
                    %
                    %      1     1     2     2
                    %      1     1     2     2
                    %      1     1     2     2
                    %      3     3     4     4
                    %      3     3     4     4
                    %      3     3     4     4
                    
                    binSource = repelem(binSource,bin,bin);
                    
                    %now multiply
                    app.colouredBinStack = binSource .* ieOutput;
                end
                
                %************Option #3: animate binarized data with DF/F stack
            elseif app.animateBinDFFStackRB.Value
                
                % Again, check which bin Source to get
                switch app.uibuttongroup7.SelectedObject.Text
                    case 'Raw'
                        binSource = app.binaryThrImages(:,:,1,:);
                    case 'Filtered'
                        binSource = app.erodedBinaryImages(:,:,1,:);
                end
                % Get width and height of bin source
                binSource = squeeze(binSource);
                [wb,hb,~] = size(binSource);
                % Get frame stack source and its width and height as
                % well.
                % This time, the ieOutput is taken from the
                % DF/F frame stack. I decided to squeeze both
                % bin and disp stacks since there's only one channel.
                % The tilde is for time, but I already know that it's
                % the same in the two stacks.
                ieOutput = squeeze(app.dataOut.deltaF(:,:,1,:)); %this is the only line which changes with respect to option #2
                [wd,hd, ~] = size(ieOutput);
                
                % I want to merge the DF/F stack with the
                % binarized areas. Therefore, I first check if the two
                % matrices have equal size.
                if wb == wd && hb == hd
                    
                    % this multiplies bin and stack frame by frame
                    app.colouredBinStack = binSource .* ieOutput;
                    
                else %if there's some difference in size due to binning
                    
                    % take the binning value which was used to compute the
                    % left map
                    bin = app.binTxt.Value;
                    % and apply repelem on the binned mask. You
                    % want to substitute single elements with a square
                    % matrix with side = bin.
                    % this is how repelem() function works:
                    % A = [1 2; 3 4]
                    % A = 2×2
                    %
                    %      1     2
                    %      3     4
                    %
                    % B = repelem(A,3,2)
                    % B = 6×4
                    %
                    %      1     1     2     2
                    %      1     1     2     2
                    %      1     1     2     2
                    %      3     3     4     4
                    %      3     3     4     4
                    %      3     3     4     4
                    
                    binSource = repelem(binSource,bin,bin);
                    
                    %now multiply
                    app.colouredBinStack = binSource .* ieOutput;
                end
                
            end
            
            % Next step is to launch the implay() function to play the
            % video of the animation. But, before of that, I decided to add the
            % opportunity of adding the AB-normal mask to both frame
            % stacks. This option makes no sense for the first case,
            % current frame, since in that case you can just apply the mask
            % on left window.
            if app.applyMaskAnimateCB.Value && ~isempty(app.ABmask)
                app.colouredBinStack = app.colouredBinStack .* app.ABmask;
            end
            
            % ***************Play the video
            % After you obtained app.colouredBinstack, finally, play the
            % video.
            app.Bmovie = implay(app.colouredBinStack);
            
            % These following lines try in different ways to set the  hot colormap of the movie, but they
            % strangely
            % don't apply it automatically...
            %             app.Bmovie.Visual.setPropertyValue('ColorMap.MapExpression','hot(256)');
            %             app.Bmovie.Visual.ColorMap.MapExpression = 'hot(256)';
            app.Bmovie.Visual.ColorMap.Map = app.CMap;
            
            app.Bmovie.Visual.setPropertyValue('UseDataRange',true);
            
            % Data range is important in order to use in the video the
            % same colormap scale that the user is actually using in the GUI. It varies
            % depending on the type of source. You can easily extrapolate
            % data scale
            % from CLim property. This solution adapts to any
            % combination -- even with Abnormal maps.
            app.Bmovie.Visual.setPropertyValue('DataRangeMin',app.imageExplore.CLim(1));
            app.Bmovie.Visual.setPropertyValue('DataRangeMax',app.imageExplore.CLim(2));
            % Unless you have selected Fluo or DF/F Source. In that case,
            % use the EFs values
            if app.animateBinFluoStackRB.Value
                app.Bmovie.Visual.setPropertyValue('DataRangeMin',app.FminTxt.Value);
                app.Bmovie.Visual.setPropertyValue('DataRangeMax',app.FmaxTxt.Value);
            end
            if app.animateBinDFFStackRB.Value
                app.Bmovie.Visual.setPropertyValue('DataRangeMin',app.DFminTxt.Value);
                app.Bmovie.Visual.setPropertyValue('DataRangeMax',app.DFmaxTxt.Value);
            end
            % Finally, in order to show colors in the video window, you
            % need to go to "Tool" -> "Colormap" and then just click "OK"
            % to the already-set values. I don't know why this interaction
            % is needed; one possible explanation is that it depends on the data type.
            % For instance, for AB-normal maps this interaction by the user
            % is not needed, and the video is automatically shown with the right
            % settings.
            
            % So, I want to tell the user (just once) what he/she is
            % supposed to do to show colours. So I create an errordlg and
            % the property errordlgAlreadyShowed which pops up right after
            % the first time one color-needing option is selected!
            if app.mergeColorsCB.Value || app.animateBinFluoStackRB.Value || app.animateBinDFFStackRB.Value
                
                % Shows a hint to the user
                if isempty(app.errordlgAlreadyShowed)
                    opts = struct('WindowStyle','modal',...
                        'Interpreter','tex');
                    errordlg({'Go to:',...
                        '\it "Tools" \rightarrow "Colormap" \rightarrow "OK"',...
                        '\rm to show merged colors'},'Hint to display colors', opts) ;
                    app.errordlgAlreadyShowed = 1;
                end
            end
            
            % Close already open animation window
            if ishandle(app.Bmovie)
                close(app.Bmovie);
            end


        end

        % Button pushed function: animateStackBT
        function animateStack(app, event)
            % RG Dec 2020
            % Animate fluo frame stack or DFF frame stack, and eventually
            % apply a mask.
            % To animate binarized data, check animateBin callback.
            
            if app.animateStackFluoRB.Value
                % Get fluo frames stack and remove channel dimension
                framestack = squeeze(app.dataOut.workImages(:,:,1,:));
                % Also, take colormap scale from the related EFs
                cmapscale = [app.FminTxt.Value , app.FmaxTxt.Value];
                % If RB is checked and AB-Mask exist, apply it to stack
                if app.animateStackABMaskCB.Value && ~isempty(app.ABmask)
                    framestack = framestack .* app.ABmask;
                end
            else %app.animateStackDFFRB.Value
                % Get DFF frames stack and remove channel dimension
                framestack = squeeze(app.dataOut.deltaF(:,:,1,:));
                % Also, take colormap scale from the related EFs
                cmapscale = [app.DFminTxt.Value , app.DFmaxTxt.Value];
                % If RB is checked and AB-Mask exist, apply it to stack
                if app.animateStackABMaskCB.Value && ~isempty(app.ABmask)
                    framestack = framestack .* app.ABmask;
                end
            end
            
            %Run Implay()
            app.Fmovie = implay(framestack);
            % Keep GUI ColorMap scale in the output Video
            %             app.Fmovie.Visual.setPropertyValue('ColorMap.MapExpression','hot(256)');
            %             app.Fmovie.Visual.ColorMap.MapExpression = 'hot(256)';
            app.Fmovie.Visual.ColorMap.Map = app.CMap;
            app.Fmovie.Visual.setPropertyValue('UseDataRange',true);
            % Since we are considering source stacks, colormap scale is not
            % directly related to the GUI experience. Although, I decided
            % to keep it the same by getting the values from the relative EFs.
            % Of course it will need a final "ok" by the user, and changes
            % could be done.
            app.Fmovie.Visual.setPropertyValue('DataRangeMin',cmapscale(1));
            app.Fmovie.Visual.setPropertyValue('DataRangeMax',cmapscale(2));
            
            % Shows once a hint to the user
            if isempty(app.errordlgAlreadyShowedStack)
                opts = struct('WindowStyle','modal',...
                    'Interpreter','tex');
                errordlg({'Go to:',...
                    '\it "Tools" \rightarrow "Colormap" \rightarrow "OK"',...
                    '\rm to show merged colors'},'Hint to display colors', opts) ;
                app.errordlgAlreadyShowedStack = 1;
            end
            
            % Close already open animation window
            if ishandle(app.Fmovie)
                close(app.Fmovie);
            end
        end

        % Value changed function: chooseColormapDD
        function chooseColormap(app, event)
            % Take the value from DD. I decided to not use directly string
            % because that gave problems with creating variable names. This
            % solution is longer but cleaner.
            % Notice also that I decided to let the user pick only between
            % those colormaps which are supported by the (outated) implay()
            % Video Editor GUI. In this way, I can export the preferred colormap
            % onto the Animate handles as well.
            
            % Pick the numeric value of DD. Each one is associated to a
            % different array of Matlab colormaps.
            v = str2double(app.chooseColormapDD.Value);
            % CMap property has already been defined as equal to hot. See openBTN_Callback
            if    v == 2
                app.CMap = gray;
            elseif   v == 3
                app.CMap = bone;
            elseif   v == 4
                app.CMap = copper;
            elseif   v == 5
                app.CMap = jet;
            elseif   v == 6
                app.CMap = parula;
            else % I prefer to keep default map as 'else', just in case of errors within the previous statements
                app.CMap = hot;
            end
            
            %Apply
            set(app.imageExplore,'Colormap',app.CMap);
            
            %         % No refresh is needed for left window
            %         displayImageExplore(app);
            
            %Refresh right window if mergeColors is active
            displayBinaryImage(app);
            
        end

        % Button pushed function: restoreDefaultSettingsBT
        function restoreDefaultSettings(app, event)
            % Restore default graphic settings
            
            app.DFminTxt.Value = 0;
            app.DFmaxTxt.Value = 5;
            app.FminTxt.Value = 0;
            app.FmaxTxt.Value = 1000;
            app.thrF.Value = 0;
            app.ABmin.Value= 0;
            app.ABmax.Value= 1;
            app.ABminb.Value= 0;
            app.ABmaxb.Value= 1;
            app.CMap = hot;
            
            % Refresh output
            displayImageExplore(app);
            displayBinaryImage(app);
        end

        % Value changed function: inheritABMaskCB
        function inheritABMask(app, event)
            % With this callback you can apply the mask obtained by
            % abnormal maps to the 'filtered' stack, thus inheriting this masked
            % data on BinaryApp. Once the
            % multiplication is done, the original filtered data is not
            % lost, because it is saved as app.erodedBackup property. In this
            % way, you can switch between the two modes as many times you want,
            % within the same session.
            % BinaryApp uses  app.erodedBinaryImages(:,:,1,:) property, so
            % I will change that.
            
            % First, make sure mask exists
            if ~isempty(app.ABmask)
                
                % Then, apply mask or not
                if isempty(app.erodedBackup)
                    % Save backup of original filtered data the first time this
                    % callback is used
                    app.erodedBackup = app.erodedBinaryImages(:,:,1,:);
                end
                
                if app.inheritABMaskCB.Value
                    app.erodedBinaryImages(:,:,1,:) = app.erodedBinaryImages(:,:,1,:) .* double(app.ABmask);
                    % March, 2021. Remove cropped 1 px wide leftovers
                    app.erodedBinaryImages(:,:,1,:) = bwareaopen(app.erodedBinaryImages(:,:,1,:), app.inheritABMaskEF.Value);
                else
                    if ~isempty(app.erodedBackup) % Just to make sure, actually useless.
                        app.erodedBinaryImages(:,:,1,:) = app.erodedBackup;
                    end
                end
                
            end
            
            % If the CB is checked, of course, binarized data visualisation
            % on the Imagegateway GUI will also be affected. But it can be
            % easily restored by unchecking the button.
            %             displayImageExplore(app); % Just to make sure, actually useless.
            displayBinaryImage(app);
        end

        % Value changed function: inheritABMaskRawCB
        function inheritABMaskRaw(app, event)
            % Feb 2021. This CB allows to apply the Ab normal mask to the
            % 'raw' binarized data. This callback has been added just in case
            % some problem occurs with the luminance mask and, more
            % generally, with the 'filtered' binarization
            % First, make sure mask exists
            if ~isempty(app.ABmask)
                
                % Then, apply mask or not
                if isempty(app.erodedBackup2)
                    % Save backup of original filtered data the first time this
                    % callback is used
                    app.erodedBackup2 = app.binaryThrImages(:,:,1,:);
                end
                
                if app.inheritABMaskRawCB.Value
                    app.binaryThrImages(:,:,1,:) = app.binaryThrImages(:,:,1,:) .* double(app.ABmask);
                    % March, 2021. Remove cropped 1 px wide leftovers
                    app.binaryThrImages(:,:,1,:) = bwareaopen(app.binaryThrImages(:,:,1,:), app.inheritABMaskEF.Value);
                else
                    if ~isempty(app.erodedBackup2) % Just to make sure, actually useless.
                        app.binaryThrImages(:,:,1,:) = app.erodedBackup2;
                    end
                end
                
            end
            
            % If the CB is checked, of course, binarized data visualisation
            % on the Imagegateway GUI will also be affected. But it can be
            % easily restored by unchecking the button.
            %             displayImageExplore(app); % Just to make sure, actually useless.
            displayBinaryImage(app);
        end

        % Button pushed function: computeKalman
        function KalmanFilter(app, event)
            % January 2021. Compute the Kalman filter of the image sequence
            % Still to be modified for 2 channel operation
            
            app.computeKalman.BackgroundColor = [1,0,0];
            app.statusNow.Text = 'Compute Kalman filter';
            drawnow
            processCh = 1;
            fltStr = app.KalmanCh1.Value;
            
            if app.KalmanFlt.Value
                % the filter is included in the pipeline analysis
                tmp = squeeze(app.dataOut.workImages (:,:,processCh,:));
                tmp = Kalman_Stack_Filter(tmp,fltStr);
                app.dataOut.workImages (:,:,processCh,:) = tmp;
            else
                % do nothing... pretty sure this will change.
            end
            app.history.Text = [app.history.Text ' Kalman'];
            app.statusNow.Text = 'Compute Kalman filter: done';
            
            app.computeKalman.BackgroundColor = [0.99,0.92,0.80];
            drawnow
        end

        % Button pushed function: clearEdgesBt
        function clearEdges(app, event)
            % February 2021
            % After alignment the edges are often charactierized by strong
            % F fluctuations due to the fact that pixels on the edges might
            % have not been imaged in the entire sequence because of
            % movement artefact. Therefore, the edges are cleared by
            % substituting all values with ones.
            
            app.statusNow.Text = 'Clearing stack edges';
            drawnow
            % To be generalized for 2 channel processing
            processCh = 1;
            % Clear top
            app.dataOut.workImages (1:app.clearTop.Value,1:app.metadata.xdim,processCh,1:app.metadata.frames) = 1;
            % Clear bottom
            app.dataOut.workImages (app.metadata.ydim-app.clearBottom.Value+1:app.metadata.ydim,...
                1:app.metadata.xdim,processCh,1:app.metadata.frames) = 1;
            % Clear Left
            app.dataOut.workImages (1:app.metadata.ydim,1:app.clearLeft.Value,processCh,1:app.metadata.frames) = 1;
            % Clear right
            app.dataOut.workImages (1:app.metadata.ydim,...
                app.metadata.xdim-app.clearRight.Value+1:app.metadata.xdim,processCh,1:app.metadata.frames) = 1;
            app.statusNow.Text = 'Clearing stack edges: done';
            drawnow
            
        end

        % Button pushed function: savePreprocessedData
        function savePreprocessedIS(app, event)
            % February 2021. Save the preprocessed image stack as separate
            % 16 bit TIF files. The files can be composed to a single
            % multi-image tif file with imageJ
            fileOut = [app.dirIn 'export\'];
            
            for i=1:app.metadata.frames
                f = [fileOut num2str(i) '.tif'];
                tempI = squeeze(app.dataOut.workImages(:,:,1,i));
                imwrite2tif(tempI,[],f,'uint16')
            end
            
        end

        % Button pushed function: exportABNormalBT
        function exportABNormal(app, event)
            % March 31, 2020.
            % Just added a handle to export the desired AB-Normal map. In
            % order to make things faster, this button extracts the map
            % corresponding to the first dropdown menu (the left one),
            % which is in the same panel.
            
            % First, get the matrix
            ABChoice1 = app.ABMaskDisplayChoice1DD.Value;
            ab = app.ABmap.(ABChoice1);
            
            % Then, save it automatically inside a default folder...
            
            % Get filename
            fname = sprintf(app.fileIn);
            fname = strrep(fname,'.','-'); % Remove '.'
            fname = strrep(fname,' ','-'); % Remove spaces ' '
            fname = strrep(fname,'_','-'); % Remove '_'
            % Create string of AB-Normal metric
            ABChoiceString = string(ABChoice1);
            
            % Note: folder name of course is temporary and shall be
            % changed for future use!
            fld = 'C:\Users\LaBandaPassante\Documents\MATLAB\Rocco\Maps\';
            expTit = append(fld,fname,ABChoiceString,'-MAP.mat');
            
            % Function save() automatically replaces files with same
            % name. This is a temporary workaround
            whileCounter = 0;
            while isfile(expTit) == 1 % While same-named file exists
                % Remove last part of the string, including the format
                whileCounter = whileCounter + 1;
                expTitTmp = strrep(expTit,'-MAP.mat','');
                % Add reiteration number string inside the file title
                % and restore format. Not nice.
                expTit = append(expTitTmp,append('-(',num2str(whileCounter),')'),'-MAP.mat');
            end
            
            % Save matrix
            save(expTit, 'ab');
        end

        % Button pushed function: ImportMergeBinMaskBT
        function ImportMergeBinMask(app, event)
            % March 31, 2021.
            % In BinMapCCMatrix child application there is now the possibility of
            % creating a 'merge' binary mask, made by the subtraction of presumably
            % pre-TBS abnormality mask and the post-TBS one.
            % This mask highlights only the domains which are (more) active after the
            % theta burst.
            % With this button, you can simply import an already created merge mask; it
            % will be set as an AB-normal mask, and will fill the classic app.ABmask
            % property.
            % After that, you can take advantage of all the other handles which are present on the
            % parent and child apps.
            [file,path] = uigetfile;
            filepath = append(path,file);
            load(filepath,'MergeABMask');
            
            % Set MergeABMask as classic AB-Normality property
            app.ABmask = MergeABMask;
        end

        % Button pushed function: DrawBoundariesBT
        function CROIEditorDraw(app, event)
            % RG April 2021. Padua.
            % Padua data requires to analyze specific ROIs
            % along the stack. So, I'm adding this quick, external GUI
            % called CROIEditor, which was found online. Basically, it is a
            % classical ROI editor, but allows to isolate more than 1
            % region.
            % Ideally, this should be launched right after the binning computation
            % and before the DFF and statistics computation and subsequent binarization.
            % In fact, the mask is applied to the binned F stack,
            % with the idea of computing the DF/F after the masking.
            % You could also perform this crop before the binning; but, in
            % my opinion, this option could not be the best one, because of the NaN
            % borders.
            % F max projection is used as a guideline background, which
            % also sets the dimensions of the mask.
            bin = app.binTxt.Value;
            a=normalize(app.dataOut.maxF);
            if ~isempty(app.dataOut.binnedImages) % Check if binning was computed
                backgroundFPrj = imresize(uint8(a),1/bin);
            else
                backgroundFPrj = uint8(a);
            end
            tmpDraw = CROIEditor(backgroundFPrj); % Launch CROIEditor.m with F MaxPrj background
            % Adjust external GUI contrast
            d = gcf;
            d.Children(11).CLimMode = 'auto';
            d.Children(12).CLimMode = 'auto';
            
            % Wait for ROI to be assigned...
            waitfor(tmpDraw,'roi');
            if ~isvalid(tmpDraw)
                return
            end
            % Get logical mask of drawn polygons
            sz = size(app.dataOut.workImages);
            app.CROIEditorMask = zeros(sz(1:2));
            [app.CROIEditorMask, ~, ~] = tmpDraw.getROIData;
            delete(tmpDraw);
            % Change the 0s in NaN
            app.CROIEditorMask = double(app.CROIEditorMask);
            app.CROIEditorMask(app.CROIEditorMask == 0) = NaN;
            % Apply the created mask to the binned fluorescence stack. Make sure to
            % multiply by the actual mask, which was put in the first frame.
            app.dataOut.workImages = app.dataOut.workImages .* app.CROIEditorMask;
            % Transform NaNs in 1s. This is needed because many NaNs would not
            % allow the computation of the clusters' time series. This is the
            % same approach that is used to crop the borders in the
            % 'Preprocessing' tab.
            app.dataOut.workImages(isnan(app.dataOut.workImages)) = 1;
            % After this, refresh the display handle with the binned
            % fluorescence of the drawn ROIs, as it happens in the "binning" function.
            app.mdImage = median(app.dataOut.workImages(:,:,1,:),4);
            cla(app.imageExplore);
            imagesc(app.imageExplore,app.mdImage,'ButtonDownFcn',@mdImageClick);
            set(app.imageExplore,'Colormap',app.CMap);
            set(app.imageExplore,'XTick',[]);
            set(app.imageExplore,'YTick',[]);
            set(app.imageExplore,'XLim',[0 size(mean(app.dataOut.workImages,4),1)]);
            set(app.imageExplore,'YLim',[0 size(mean(app.dataOut.workImages,4),2)]);
            set(app.imageExplore,'fontsize',8)
            % Refresh other F data...
            % Compute the maxProjection and the mean of the fluorescence
            if ~isempty(app.dataOut.binnedImages)
                app.dataOut.maxF (:,:,1) = imresize(max(app.dataOut.workImages(:,:,1,:),[],4),bin);
                app.dataOut.medianF (:,:,1) = imresize(median(app.dataOut.workImages(:,:,1,:),4),bin);
                app.dataOut.meanF (:,:,1) = imresize(mean(app.dataOut.workImages(:,:,1,:),4),bin);
            else
                app.dataOut.maxF (:,:,1) = max(app.dataOut.workImages(:,:,1,:),[],4);
                app.dataOut.medianF (:,:,1) = median(app.dataOut.workImages(:,:,1,:),4);
                app.dataOut.meanF (:,:,1) = mean(app.dataOut.workImages(:,:,1,:),4);
            end
            % At this point, binned fluorescence has been correctly
            % replaced with its masked counterpart. The next step should be
            % to recalculate again the DF/F, and then compute the
            % statistics and binarize.
        end

        % Button pushed function: ExportBoundariesBT
        function CROIEditorExport(app, event)
            % RG April 2021. Padua.
            % Export the CROIEditor mask. Useful for comparison pre and
            % post TBS in Padua format.
            if ~isempty(app.CROIEditorMask)
                % File name
                fname = sprintf(app.fileIn);
                fname = strrep(fname,'.',''); % Remove '.'
                fname = strrep(fname,' ',''); % Remove spaces ' '
                % Folder identity
                % Note: folder name of course is temporary and shall be
                % changed for future use!
                fld = 'AstroBoundaries\';
                expTit = append(fld,fname,'-ASTRO.mat');
                % Function save() automatically replaces files with same
                % name. This is a temporary workaround, and it is very nasty because
                % doesn't cancel previus numbers. E.g., if you have three copies,
                % instead of being named (1)...(2)...(3), they will be named (1)...(1)(2)...(1)(2)(3).
                % Not that important.
                whileCounter = 0;
                while isfile(expTit) == 1 % While same-named file exists
                    % Remove last part of the string, including the format
                    whileCounter = whileCounter + 1;
                    expTitTmp = strrep(expTit,'-ASTRO.mat','');
                    % Add reiteration number string inside the file title
                    % and restore format. Not nice.
                    expTit = append(expTitTmp,append('-(',num2str(whileCounter),')'),'-ASTRO.mat');
                end
                % Save matrix
                AstroROI = app.CROIEditorMask;
                save(expTit, 'AstroROI');
            end
        end

        % Button pushed function: ImportBoundariesBT
        function CROIEditorImportAndApply(app, event)
            % RG April 2021. Padua.
            % Import and apply an already created CROIEditor ROI. Again,
            % this is just needed for the analysis of Padua format.
            % Get .mat file and load it.
            [file,path] = uigetfile({'*.mat'},...
               'Select ROI mask','C:\Users\LaBandaPassante\Documents\MATLAB\Rocco\Image Gateway 3 -Oct 2021\Image Gateway 3\AstroBoundaries\');
            filepath = append(path,file);
            load(filepath,'AstroROI');
            app.CROIEditorMask = AstroROI;
            % Apply the created mask to the binned fluorescence stack.
            app.dataOut.workImages = app.dataOut.workImages .* app.CROIEditorMask;
            % Transform NaNs in 1s. This is needed because many NaNs would not
            % allow the computation of the clusters' time series. This is the
            % same approach that is used to crop the borders in the
            % 'Preprocessing' tab.
            app.dataOut.workImages(isnan(app.dataOut.workImages)) = 1;
            % After this, refresh the display handle with the binned
            % fluorescence of the drawn ROIs, as it happens in the "binning" function.
            app.mdImage = median(app.dataOut.workImages (:,:,1,:),4);
            cla(app.imageExplore);
            imagesc(app.imageExplore,app.mdImage,'ButtonDownFcn',@mdImageClick);
            set(app.imageExplore,'Colormap',app.CMap);
            set(app.imageExplore,'XTick',[]);
            set(app.imageExplore,'YTick',[]);
            set(app.imageExplore,'XLim',[0 size(mean(app.dataOut.workImages,4),1)]);
            set(app.imageExplore,'YLim',[0 size(mean(app.dataOut.workImages,4),2)]);
            set(app.imageExplore,'fontsize',8)
            % Refresh other F data...
            % Compute the maxProjection and the mean of the fluorescence
            bin = app.binTxt.Value;
            if ~isempty(app.dataOut.binnedImages)
                app.dataOut.maxF (:,:,1) = imresize(max(app.dataOut.workImages(:,:,1,:),[],4),bin);
                app.dataOut.medianF (:,:,1) = imresize(median(app.dataOut.workImages(:,:,1,:),4),bin);
                app.dataOut.meanF (:,:,1) = imresize(mean(app.dataOut.workImages(:,:,1,:),4),bin);
            else
                app.dataOut.maxF (:,:,1) = max(app.dataOut.workImages(:,:,1,:),[],4);
                app.dataOut.medianF (:,:,1) = median(app.dataOut.workImages(:,:,1,:),4);
                app.dataOut.meanF (:,:,1) = mean(app.dataOut.workImages(:,:,1,:),4);
            end
            % At this point, binned fluorescence has been correctly
            % replaced with its masked counterpart. The next step should be
            % to recalculate again the DF/F, and then compute the
            % statistics and binarize.
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.imageGateway = uifigure('Visible', 'off');
            colormap(app.imageGateway, 'parula');
            app.imageGateway.Name = 'imageGateway';
            app.uipanel12 = uipanel(app.imageGateway);
            app.imageExplore = uiaxes(app.uipanel12);
            app.imageExplore.XTick = [];
            app.imageExplore.YTick = [];
            app.timeBarUIAxes_Disp = uiaxes(app.uipanel12);
            app.timeBarUIAxes_Disp.XTick = [];
            app.timeBarUIAxes_Disp.XTickLabel = '';
            app.timeBarUIAxes_Disp.YTick = [];
            app.timeBarUIAxes_Disp.Visible = 'off';
            app.animateFCK = uicheckbox(app.uipanel12);
            app.animateFCK.ValueChangedFcn = createCallbackFcn(app, @animateFCK_Callback, true);
            app.animateFCK.Visible = 'off';
            app.animateFCK.Tooltip = 'Toggle animation of the F or DF/F stack';
            app.animateFCK.Text = 'Animate';
            app.pointerInfoTxt = uilabel(app.uipanel12);
            app.pointerInfoTxt.Text = '';
            app.uipanel11 = uipanel(app.imageGateway);
            app.timeExplore = uiaxes(app.uipanel11);
            app.uipanel13 = uibuttongroup(app.uipanel11);
            app.uipanel13.SelectionChangedFcn = createCallbackFcn(app, @changeData, true);
            app.plotFcK = uiradiobutton(app.uipanel13);
            app.plotFcK.Tooltip = 'Display plot of fluorescence';
            app.plotFcK.Text = 'F';
            app.plotBinnedFcK = uiradiobutton(app.uipanel13);
            app.plotBinnedFcK.Tooltip = 'Display plot of binned fluorescence';
            app.plotBinnedFcK.Text = 'Binned F';
            app.plotDeltaFcK = uiradiobutton(app.uipanel13);
            app.plotDeltaFcK.Tooltip = 'Display plot of (delta F)/F';
            app.plotDeltaFcK.Text = 'DF';
            app.binaryRB = uiradiobutton(app.uipanel13);
            app.binaryRB.Tooltip = 'Display plot of (delta F)/F';
            app.binaryRB.Text = 'Binary';
            app.binaryRB.Value = true;
            app.LogPlotCB = uicheckbox(app.uipanel11);
            app.LogPlotCB.ValueChangedFcn = createCallbackFcn(app, @logPlot, true);
            app.LogPlotCB.Text = 'Log';
            app.currentFrameTxt = uilabel(app.uipanel11);
            app.currentFrameTxt.Text = 'Current frame:';
            app.traceToClipboard = uibutton(app.uipanel11, 'push');
            app.traceToClipboard.ButtonPushedFcn = createCallbackFcn(app, @traceToClip, true);
            app.traceToClipboard.Tooltip = {'Send current trace to the clipboard'};
            app.traceToClipboard.Text = 'Clipboard';
            app.uipanel21 = uipanel(app.imageGateway);
            app.distributionW = uiaxes(app.uipanel21);
            app.distributionW.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.uibuttongroupDistribution = uibuttongroup(app.uipanel21);
            app.uibuttongroupDistribution.SelectionChangedFcn = createCallbackFcn(app, @uibuttongroupDistribution_SelectionChangedFcn, true);
            app.binnedFck = uiradiobutton(app.uibuttongroupDistribution);
            app.binnedFck.Tooltip = 'Plot distribution of binned fluorescence';
            app.binnedFck.Text = 'Binned F';
            app.dFck = uiradiobutton(app.uibuttongroupDistribution);
            app.dFck.Tooltip = 'Plot distribution of binned fluorescence';
            app.dFck.Text = 'DF/F';
            app.dFck.Value = true;
            app.distributionLogCk = uicheckbox(app.uibuttongroupDistribution);
            app.distributionLogCk.ValueChangedFcn = createCallbackFcn(app, @distributionLogCk_Callback, true);
            app.distributionLogCk.Tooltip = 'Set the vertical axis to logarithmic scale';
            app.distributionLogCk.Text = 'Log';
            app.maskedBinnedFck = uiradiobutton(app.uibuttongroupDistribution);
            app.maskedBinnedFck.Tooltip = 'Plot distribution of binned fluorescence';
            app.maskedBinnedFck.Text = 'Masked Binned F';
            app.maskedDFck = uiradiobutton(app.uibuttongroupDistribution);
            app.maskedDFck.Tooltip = 'Plot distribution of binned fluorescence';
            app.maskedDFck.Text = 'Masked DF/F';
            app.uipanel23 = uipanel(app.imageGateway);
            app.binaryExplore = uiaxes(app.uipanel23);
            app.binaryExplore.XTick = [];
            app.binaryExplore.YTick = [];
            app.timeBarUIAxes_Bin = uiaxes(app.uipanel23);
            app.timeBarUIAxes_Bin.XTick = [];
            app.timeBarUIAxes_Bin.XTickLabel = '';
            app.timeBarUIAxes_Bin.YTick = [];
            app.timeBarUIAxes_Bin.Visible = 'off';
            app.text17 = uilabel(app.uipanel23);
            app.text17.Text = '';
            app.TabGroup = uitabgroup(app.imageGateway);
            app.LoadTab = uitab(app.TabGroup);
            app.LoadTab.Title = 'Load';
            app.uipanel1 = uipanel(app.LoadTab);
            app.uipanel1.Title = 'Input Imaging Data';
            app.moreInfoTxt = uilabel(app.uipanel1);
            app.moreInfoTxt.Text = 'moreInfo';
            app.timeAndSpaceTxt = uilabel(app.uipanel1);
            app.timeAndSpaceTxt.Text = 'timeAndSpaceTxt';
            app.resetProcessing = uibutton(app.uipanel1, 'push');
            app.resetProcessing.ButtonPushedFcn = createCallbackFcn(app, @resetProcessing_Callback, true);
            app.resetProcessing.Tooltip = 'Reset processing pipeline to the original raw data';
            app.resetProcessing.Text = 'Reset';
            app.Offsetchannel1Label = uilabel(app.uipanel1);
            app.Offsetchannel1Label.Tooltip = {'Offset of input channels. When loading data files from  a microscope equipped with high sensitivity sensor, check that the minimum is not zero since it leads to an erroneous computation of DF/F. Add 1 by using an offset of -1.'};
            app.Offsetchannel1Label.Text = 'Offset channel 1:';
            app.offsetCh1EF = uieditfield(app.uipanel1, 'numeric');
            app.offsetCh1EF.ValueDisplayFormat = '%.1f';
            app.offsetCh1EF.Tooltip = {'Offset of input channels. When loading data files from  a microscope equipped with high sensitivity sensor, check that the minimum is not zero since it leads to an erroneous computation of DF/F. Add 1 by using an offset of -1.'};
            app.offsetCh1EF.Value = -1;
            app.stackSizeTxt = uilabel(app.uipanel1);
            app.stackSizeTxt.Text = 'stackSizeTxt';
            app.SamplingsEditFieldLabel = uilabel(app.uipanel1);
            app.SamplingsEditFieldLabel.Text = 'Sampling (s):';
            app.SamplingsEditField = uieditfield(app.uipanel1, 'numeric');
            app.SamplingsEditField.Limits = [0 Inf];
            app.SamplingsEditField.ValueChangedFcn = createCallbackFcn(app, @SP, true);
            app.SamplingsEditField.Value = 0.67;
            app.openBTN = uibutton(app.uipanel1, 'push');
            app.openBTN.ButtonPushedFcn = createCallbackFcn(app, @openBTN_Callback, true);
            app.openBTN.Tooltip = 'Select and open a video sequence';
            app.openBTN.Text = 'Open';
            app.Offsetchannel2Label = uilabel(app.uipanel1);
            app.Offsetchannel2Label.Tooltip = {'Offset of input channels. When loading data files from  a microscope equipped with high sensitivity sensor, check that the minimum is not zero since it leads to an erroneous computation of DF/F. Add 1 by using an offset of -1.'};
            app.Offsetchannel2Label.Text = 'Offset channel 2:';
            app.offsetCh2EF = uieditfield(app.uipanel1, 'numeric');
            app.offsetCh2EF.ValueDisplayFormat = '%.1f';
            app.offsetCh2EF.Tooltip = {'Offset of input channels. When loading data files from  a microscope equipped with high sensitivity sensor, check that the minimum is not zero since it leads to an erroneous computation of DF/F. Add 1 by using an offset of -1.'};
            app.Setto0Button = uibutton(app.uipanel1, 'push');
            app.Setto0Button.Text = 'Set to 0';
            app.toNaNcb = uicheckbox(app.uipanel1);
            app.toNaNcb.Tooltip = {'This option force all pixels = 0 to NaN. This is used to remove the edges of the spiral scan from the stat computations. Set to false for all data types other than spiral scan.'};
            app.toNaNcb.Text = '0 to NaN';
            app.uipanel2 = uibuttongroup(app.LoadTab);
            app.uipanel2.Title = 'Input Mode';
            app.prairieRB = uiradiobutton(app.uipanel2);
            app.prairieRB.Tooltip = 'Open a Prairie multichannels directory';
            app.prairieRB.Text = 'Prairie (auto detect Padua and Pisa data format)';
            app.aviRB = uiradiobutton(app.uipanel2);
            app.aviRB.Tooltip = 'Open a video AVI file';
            app.aviRB.Text = 'AVI';
            app.TIFFmultiImageRB = uiradiobutton(app.uipanel2);
            app.TIFFmultiImageRB.Tooltip = 'Open a TIFF multi image file';
            app.TIFFmultiImageRB.Text = 'TIFF multi image';
            app.TIFFmultiImageRB.Value = true;
            app.aviRB_2 = uiradiobutton(app.uipanel2);
            app.aviRB_2.Tooltip = 'Open a video AVI file';
            app.aviRB_2.Text = 'Matlab format (wide field imaging)';
            app.selectInputCh = uibuttongroup(app.LoadTab);
            app.selectInputCh.Tooltip = {'Select the channels to load from the input data'};
            app.selectInputCh.Title = 'Select input channels';
            app.ch1RB = uiradiobutton(app.selectInputCh);
            app.ch1RB.Text = 'Channel 1';
            app.ch1RB.Value = true;
            app.ch2RB = uiradiobutton(app.selectInputCh);
            app.ch2RB.Text = 'Channel 2';
            app.Ch1Ch2RB = uiradiobutton(app.selectInputCh);
            app.Ch1Ch2RB.Tooltip = 'Process both channels';
            app.Ch1Ch2RB.Text = 'Load both channels';
            app.displayBtnGroup = uibuttongroup(app.LoadTab);
            app.displayBtnGroup.Title = 'External Process';
            app.createAVIbtn = uibutton(app.displayBtnGroup, 'push');
            app.createAVIbtn.ButtonPushedFcn = createCallbackFcn(app, @createAVIbtn_Callback, true);
            app.createAVIbtn.Tooltip = 'Create non-compressed AVI file with binarized data';
            app.createAVIbtn.Text = 'Bin>AVI';
            app.LFPcorrelate = uicheckbox(app.displayBtnGroup);
            app.LFPcorrelate.ValueChangedFcn = createCallbackFcn(app, @LFPcorrelate_Callback, true);
            app.LFPcorrelate.Tooltip = 'Load electrophysiology data and compute correlation';
            app.LFPcorrelate.Text = 'Load Electrophysiology';
            app.BMapp_pb = uibutton(app.displayBtnGroup, 'push');
            app.BMapp_pb.ButtonPushedFcn = createCallbackFcn(app, @BMapp_pb_Callback, true);
            app.BMapp_pb.Text = 'Clusters';
            app.inheritABMaskCB = uicheckbox(app.displayBtnGroup);
            app.inheritABMaskCB.ValueChangedFcn = createCallbackFcn(app, @inheritABMask, true);
            app.inheritABMaskCB.Text = 'Apply AB-mask to filtered';
            app.inheritABMaskRawCB = uicheckbox(app.displayBtnGroup);
            app.inheritABMaskRawCB.ValueChangedFcn = createCallbackFcn(app, @inheritABMaskRaw, true);
            app.inheritABMaskRawCB.Text = 'Apply AB-mask to raw';
            app.MinsizeEditFieldLabel = uilabel(app.displayBtnGroup);
            app.MinsizeEditFieldLabel.Text = 'Min. size';
            app.inheritABMaskEF = uieditfield(app.displayBtnGroup, 'numeric');
            app.inheritABMaskEF.Value = 2;
            app.ImportMergeBinMaskBT = uibutton(app.displayBtnGroup, 'push');
            app.ImportMergeBinMaskBT.ButtonPushedFcn = createCallbackFcn(app, @ImportMergeBinMask, true);
            app.ImportMergeBinMaskBT.Text = 'Import binary mask (from cluster crosscorrelation)';
            app.PreprocessingTab = uitab(app.TabGroup);
            app.PreprocessingTab.Title = 'Preprocessing';
            app.panelLowPassFilter = uipanel(app.PreprocessingTab);
            app.panelLowPassFilter.Title = 'Stack low pass filter';
            app.filterBtn = uibutton(app.panelLowPassFilter, 'push');
            app.filterBtn.ButtonPushedFcn = createCallbackFcn(app, @LPfilterStack, true);
            app.filterBtn.Tooltip = {'Substitute the working stack with the low pass filtered stack'};
            app.filterBtn.Text = 'Compute';
            app.SpLPfilterCk = uicheckbox(app.panelLowPassFilter);
            app.SpLPfilterCk.Tooltip = 'Select wether apply low pass spatial filter on the computation pipeline';
            app.SpLPfilterCk.Text = 'LP Filter';
            app.KernelPxCh1Label = uilabel(app.panelLowPassFilter);
            app.KernelPxCh1Label.Text = 'Kernel (Px) Ch1';
            app.kernelCh1EF = uieditfield(app.panelLowPassFilter, 'numeric');
            app.kernelCh1EF.Limits = [0 Inf];
            app.kernelCh1EF.ValueDisplayFormat = '%.0f';
            app.kernelCh1EF.Value = 2;
            app.ButtonGroup = uibuttongroup(app.panelLowPassFilter);
            app.iFilterCh1RB = uiradiobutton(app.ButtonGroup);
            app.iFilterCh1RB.Text = 'Apply to Ch1';
            app.iFilterCh1RB.Value = true;
            app.iFilterCh2RB = uiradiobutton(app.ButtonGroup);
            app.iFilterCh2RB.Text = 'Apply to Ch2';
            app.iFilterBothRB = uiradiobutton(app.ButtonGroup);
            app.iFilterBothRB.Text = 'Apply to both';
            app.kernelCh1EF_2 = uieditfield(app.panelLowPassFilter, 'numeric');
            app.kernelCh1EF_2.Limits = [0 Inf];
            app.kernelCh1EF_2.ValueDisplayFormat = '%.0f';
            app.kernelCh1EF_2.Value = 2;
            app.Ch2Label = uilabel(app.panelLowPassFilter);
            app.Ch2Label.Text = 'Ch2';
            app.StackregistrationPanel = uipanel(app.PreprocessingTab);
            app.StackregistrationPanel.Title = 'Stack registration';
            app.selectRegistrationSource = uibuttongroup(app.StackregistrationPanel);
            app.referenceCh1RB = uiradiobutton(app.selectRegistrationSource);
            app.referenceCh1RB.Text = 'Align on Ch1';
            app.referenceCh1RB.Value = true;
            app.referenceCh2RB = uiradiobutton(app.selectRegistrationSource);
            app.referenceCh2RB.Text = 'Align on Ch2';
            app.referenceBothRB = uiradiobutton(app.selectRegistrationSource);
            app.referenceBothRB.Text = 'Align on both';
            app.SpLPfilterCk_2 = uicheckbox(app.StackregistrationPanel);
            app.SpLPfilterCk_2.Tooltip = 'Select wether apply low pass spatial filter on the computation pipeline';
            app.SpLPfilterCk_2.Text = 'Perform stack registration';
            app.registerStackBT = uibutton(app.StackregistrationPanel, 'push');
            app.registerStackBT.ButtonPushedFcn = createCallbackFcn(app, @registerStack, true);
            app.registerStackBT.Text = 'Register';
            app.registerStackBT_2 = uibutton(app.StackregistrationPanel, 'push');
            app.registerStackBT_2.Text = 'Reset';
            app.regOnSquareCB = uicheckbox(app.StackregistrationPanel);
            app.regOnSquareCB.Tooltip = {'Use the square included in the inscribed circle (to be used with spiral scan)'};
            app.regOnSquareCB.Text = 'Register on central square (select when using spiral scan)';
            app.LPregCB = uicheckbox(app.StackregistrationPanel);
            app.LPregCB.Tooltip = {'Use the square included in the inscribed circle (to be used with spiral scan)'};
            app.LPregCB.Text = 'Low pass filter of the registration stack';
            app.FiltersizepxEditFieldLabel = uilabel(app.StackregistrationPanel);
            app.FiltersizepxEditFieldLabel.Text = 'Filter size(px)';
            app.LPregEF = uieditfield(app.StackregistrationPanel, 'numeric');
            app.LPregEF.Limits = [1 Inf];
            app.LPregEF.ValueDisplayFormat = '%.0f';
            app.LPregEF.Value = 2;
            app.SpLPfilterCk_3 = uicheckbox(app.StackregistrationPanel);
            app.SpLPfilterCk_3.Tooltip = {'Set to zero the border of the image stack on the basis of the registration shifts.'};
            app.SpLPfilterCk_3.Text = 'Auto Crop the output stack';
            app.registrationMode = uibuttongroup(app.StackregistrationPanel);
            app.registrationMode.Tooltip = {'Select mode of registration'};
            app.imregisterRB = uiradiobutton(app.registrationMode);
            app.imregisterRB.Text = 'Use imregister';
            app.imregisterRB.Value = true;
            app.dftregistrationRB = uiradiobutton(app.registrationMode);
            app.dftregistrationRB.Text = 'Use dft registration';
            app.StackCalibrationPanel = uipanel(app.PreprocessingTab);
            app.StackCalibrationPanel.Title = 'Stack Calibration';
            app.LoadFlatFieldButton = uibutton(app.StackCalibrationPanel, 'push');
            app.LoadFlatFieldButton.Text = 'Load Flat Field';
            app.LoadDarkButton = uibutton(app.StackCalibrationPanel, 'push');
            app.LoadDarkButton.Text = 'Load Dark';
            app.darkSubtractionCB = uicheckbox(app.StackCalibrationPanel);
            app.darkSubtractionCB.Text = 'Perform dark subtraction';
            app.FlatCB = uicheckbox(app.StackCalibrationPanel);
            app.FlatCB.Text = 'Perform flat correction';
            app.calibrateStackBT = uibutton(app.StackCalibrationPanel, 'push');
            app.calibrateStackBT.Text = 'Calibrate';
            app.uipanel5_2 = uipanel(app.PreprocessingTab);
            app.uipanel5_2.Title = 'Stack Kalman filter';
            app.computeKalman = uibutton(app.uipanel5_2, 'push');
            app.computeKalman.ButtonPushedFcn = createCallbackFcn(app, @KalmanFilter, true);
            app.computeKalman.Tooltip = {'Substitute the working stack with the Kalman filtered stack'};
            app.computeKalman.Text = 'Compute';
            app.KalmanFlt = uicheckbox(app.uipanel5_2);
            app.KalmanFlt.Tooltip = {'Select wether apply Kalman filter on the computation pipeline'};
            app.KalmanFlt.Text = 'Kalman';
            app.FilterstrengthCh1Label = uilabel(app.uipanel5_2);
            app.FilterstrengthCh1Label.Tooltip = {'Strength of the filter. When larger than 0.5 the predicted values have a larger weight than the observed values (range 0-1)'};
            app.FilterstrengthCh1Label.Text = 'Filter strength Ch1';
            app.KalmanStrCh1 = uieditfield(app.uipanel5_2, 'numeric');
            app.KalmanStrCh1.Limits = [0 1];
            app.KalmanStrCh1.ValueDisplayFormat = '%.2f';
            app.KalmanStrCh1.Tooltip = {'Strength of the filter. When larger than 0.5 the predicted values have a larger weight than the observed values (range 0-1)'};
            app.KalmanStrCh1.Value = 0.5;
            app.KalmanChSelection = uibuttongroup(app.uipanel5_2);
            app.KalmanCh1 = uiradiobutton(app.KalmanChSelection);
            app.KalmanCh1.Text = 'Apply to Ch1';
            app.KalmanCh1.Value = true;
            app.KalmanCh2 = uiradiobutton(app.KalmanChSelection);
            app.KalmanCh2.Text = 'Apply to Ch2';
            app.KalmanBoth = uiradiobutton(app.KalmanChSelection);
            app.KalmanBoth.Text = 'Apply to both';
            app.KalmanStrCh2 = uieditfield(app.uipanel5_2, 'numeric');
            app.KalmanStrCh2.Limits = [0 1];
            app.KalmanStrCh2.ValueDisplayFormat = '%.2f';
            app.KalmanStrCh2.Tooltip = {'Strength of the filter. When larger than 0.5 the predicted values have a larger weight than the observed values (range 0-1)'};
            app.KalmanStrCh2.Value = 0.5;
            app.Ch2Label_2 = uilabel(app.uipanel5_2);
            app.Ch2Label_2.Tooltip = {'Strength of the filter. When larger than 0.5 the predicted values have a larger weight than the observed values (range 0-1)'};
            app.Ch2Label_2.Text = 'Ch2';
            app.ClearedgesofimagestackPanel = uipanel(app.PreprocessingTab);
            app.ClearedgesofimagestackPanel.Title = 'Clear edges of image stack';
            app.clearEdgesBt = uibutton(app.ClearedgesofimagestackPanel, 'push');
            app.clearEdgesBt.ButtonPushedFcn = createCallbackFcn(app, @clearEdges, true);
            app.clearEdgesBt.Text = 'Clear';
            app.clearBottom = uieditfield(app.ClearedgesofimagestackPanel, 'numeric');
            app.clearBottom.Limits = [0 Inf];
            app.clearBottom.ValueDisplayFormat = '%.0f';
            app.clearBottom.Value = 25;
            app.clearLeft = uieditfield(app.ClearedgesofimagestackPanel, 'numeric');
            app.clearLeft.Limits = [0 Inf];
            app.clearLeft.ValueDisplayFormat = '%.0f';
            app.clearLeft.Value = 25;
            app.clearRight = uieditfield(app.ClearedgesofimagestackPanel, 'numeric');
            app.clearRight.Limits = [0 Inf];
            app.clearRight.ValueDisplayFormat = '%.0f';
            app.clearRight.Value = 25;
            app.TopborttomleftrightpxLabel = uilabel(app.ClearedgesofimagestackPanel);
            app.TopborttomleftrightpxLabel.Text = 'Top, borttom, left, right (px):';
            app.clearTop = uieditfield(app.ClearedgesofimagestackPanel, 'numeric');
            app.clearTop.Limits = [0 Inf];
            app.clearTop.ValueDisplayFormat = '%.0f';
            app.clearTop.Value = 25;
            app.SavePreprocessedImageStackPanel = uipanel(app.PreprocessingTab);
            app.SavePreprocessedImageStackPanel.Title = 'Save Preprocessed Image Stack';
            app.savePreprocessedData = uibutton(app.SavePreprocessedImageStackPanel, 'push');
            app.savePreprocessedData.ButtonPushedFcn = createCallbackFcn(app, @savePreprocessedIS, true);
            app.savePreprocessedData.Text = 'Save (TIF)';
            app.MapsTab = uitab(app.TabGroup);
            app.MapsTab.Title = 'Maps';
            app.uipanel8 = uibuttongroup(app.MapsTab);
            app.uipanel8.Title = 'Image Binning';
            app.noBinRB = uiradiobutton(app.uipanel8);
            app.noBinRB.Tooltip = 'Select wether apply binning on the computation pipeline';
            app.noBinRB.Text = 'No binning';
            app.binRB = uiradiobutton(app.uipanel8);
            app.binRB.Tooltip = 'Select wether apply binning on the computation pipeline';
            app.binRB.Text = 'Binning';
            app.binRB.Value = true;
            app.computeBinning = uibutton(app.uipanel8, 'push');
            app.computeBinning.ButtonPushedFcn = createCallbackFcn(app, @computeBinning_Callback, true);
            app.computeBinning.Text = 'Compute';
            app.binTxt = uieditfield(app.uipanel8, 'numeric');
            app.binTxt.Limits = [1 8];
            app.binTxt.ValueDisplayFormat = '%.0f';
            app.binTxt.ValueChangedFcn = createCallbackFcn(app, @binWarning, true);
            app.binTxt.Value = 2;
            app.binMethodDD = uidropdown(app.uipanel8);
            app.binMethodDD.Items = {'Sum', 'Max', 'Min', 'Median'};
            app.binMethodDD.Value = 'Sum';
            app.uipanel9 = uipanel(app.MapsTab);
            app.uipanel9.Title = 'Computation of DeltaF/F';
            app.computeDf = uibutton(app.uipanel9, 'push');
            app.computeDf.ButtonPushedFcn = createCallbackFcn(app, @computeDf_Callback, true);
            app.computeDf.Text = 'Compute';
            app.uipanel10 = uibuttongroup(app.uipanel9);
            app.simpleDfcK = uiradiobutton(app.uipanel10);
            app.simpleDfcK.Tooltip = 'Baseline computed as the median of F. No correction for bleach';
            app.simpleDfcK.Text = 'Simplified (Median)';
            app.adaptiveDfCk = uiradiobutton(app.uipanel10);
            app.adaptiveDfCk.Tooltip = 'Adaptive computation of the baseline according to Konnerth et al.';
            app.adaptiveDfCk.Text = 'Adaptive';
            app.MeanButton = uiradiobutton(app.uipanel10);
            app.MeanButton.Text = 'Mean';
            app.MeanButton.Value = true;
            app.PercentileButton = uiradiobutton(app.uipanel10);
            app.PercentileButton.Text = 'Percentile';
            app.thPercEF = uieditfield(app.uipanel10, 'numeric');
            app.thPercEF.Value = 15;
            app.computeAll = uibutton(app.uipanel9, 'push');
            app.computeAll.Tooltip = 'Compute filter, bin and DeltaF/F ';
            app.computeAll.Text = 'Compute All';
            app.statsAndBinarization = uipanel(app.MapsTab);
            app.statsAndBinarization.Title = 'Compute Process Statistics and Binarization';
            app.statsMskDF = uilabel(app.statsAndBinarization);
            app.statsMskDF.Tooltip = 'Minimum and maxim values of deltaF/F';
            app.statsMskDF.Text = 'Msk DF/F';
            app.computeBinaryStack = uibutton(app.statsAndBinarization, 'push');
            app.computeBinaryStack.ButtonPushedFcn = createCallbackFcn(app, @computeBinaryStack_Callback, true);
            app.computeBinaryStack.Tooltip = {'Binarize the stack using the statistics of the DeltaF/F processes'};
            app.computeBinaryStack.Text = 'Binarize';
            app.text12 = uilabel(app.statsAndBinarization);
            app.text12.Text = 'Min       Max      Bins';
            app.text13 = uilabel(app.statsAndBinarization);
            app.text13.Tooltip = 'Minimal distance of events from the median';
            app.text13.Text = 'DF/F normality limit (in std units):';
            app.text14 = uilabel(app.statsAndBinarization);
            app.text14.Tooltip = 'Minimum duration of each event (frames):';
            app.text14.Text = 'Min event duration (f):';
            app.globalStatsCk = uicheckbox(app.statsAndBinarization);
            app.globalStatsCk.Tooltip = {'Use the same threshold for all pixels of the image field. Otherwise, a different threshold is computed for each pixel.'};
            app.globalStatsCk.Text = 'Global threshold';
            app.FstatsTxt = uilabel(app.statsAndBinarization);
            app.FstatsTxt.Tooltip = 'Statistics of binned fluorescence';
            app.FstatsTxt.Text = 'F';
            app.computeFstats = uibutton(app.statsAndBinarization, 'push');
            app.computeFstats.ButtonPushedFcn = createCallbackFcn(app, @computeFstats_Callback, true);
            app.computeFstats.Tooltip = 'Compute F statistics and distribution';
            app.computeFstats.Text = 'Compute';
            app.computeDFstats = uibutton(app.statsAndBinarization, 'push');
            app.computeDFstats.ButtonPushedFcn = createCallbackFcn(app, @computeDFstats_Callback, true);
            app.computeDFstats.Tooltip = 'Compute F statistics and distribution';
            app.computeDFstats.Text = 'Compute';
            app.statsDF = uilabel(app.statsAndBinarization);
            app.statsDF.Tooltip = 'Descriptors of  deltaF/F statistics';
            app.statsDF.Text = 'Stats DF/F';
            app.thresholdFck = uicheckbox(app.statsAndBinarization);
            app.thresholdFck.ValueChangedFcn = createCallbackFcn(app, @thresholdFck_Callback, true);
            app.thresholdFck.Tooltip = {'The mask is computed on the binned median projection and excludes all pixels <= the threshold from following computation. Distributions are computed both on masked and non masked data. When checked: 1) computes the mask. 2) Compute the statistics on F and DF/F. 3) The binarizazion is performed on the masked data only.'};
            app.thresholdFck.Text = 'Compute and apply threshold mask on binned F:';
            app.text26 = uilabel(app.statsAndBinarization);
            app.text26.Tooltip = 'Minimum duration of each event (frames):';
            app.text26.Text = 'Min size (px):';
            app.maxFdistTxt = uieditfield(app.statsAndBinarization, 'numeric');
            app.maxFdistTxt.Limits = [0 Inf];
            app.maxFdistTxt.Value = 400;
            app.distFbin = uieditfield(app.statsAndBinarization, 'numeric');
            app.distFbin.Limits = [10 Inf];
            app.distFbin.ValueDisplayFormat = '%.0f';
            app.distFbin.Value = 400;
            app.binDistTxt = uieditfield(app.statsAndBinarization, 'numeric');
            app.binDistTxt.Limits = [10 Inf];
            app.binDistTxt.ValueDisplayFormat = '%.0f';
            app.binDistTxt.Value = 400;
            app.minFdistTxt = uieditfield(app.statsAndBinarization, 'numeric');
            app.minFdistTxt.Limits = [0 Inf];
            app.minDistTxt = uieditfield(app.statsAndBinarization, 'numeric');
            app.minDistTxt.Value = -1;
            app.maxDistTxt = uieditfield(app.statsAndBinarization, 'numeric');
            app.maxDistTxt.Value = 25;
            app.thrFtxt = uieditfield(app.statsAndBinarization, 'numeric');
            app.thrFtxt.Value = 28;
            app.thrStdTxt = uieditfield(app.statsAndBinarization, 'numeric');
            app.thrStdTxt.Limits = [0 Inf];
            app.thrStdTxt.Value = 2.5;
            app.minDurationTxt = uieditfield(app.statsAndBinarization, 'numeric');
            app.minDurationTxt.Limits = [0 Inf];
            app.minDurationTxt.ValueDisplayFormat = '%.0f';
            app.minDurationTxt.Value = 2;
            app.minSizeTxt = uieditfield(app.statsAndBinarization, 'numeric');
            app.minSizeTxt.Limits = [0 Inf];
            app.minSizeTxt.ValueDisplayFormat = '%.0f';
            app.minSizeTxt.Value = 2;
            app.RGBintervalsEditFieldLabel = uilabel(app.statsAndBinarization);
            app.RGBintervalsEditFieldLabel.Text = 'RGB interval (s):';
            app.RGBmapLeft = uieditfield(app.statsAndBinarization, 'numeric');
            app.RGBmapLeft.Limits = [0 Inf];
            app.RGBmapLeft.Tooltip = {'Color coded tempolar map computed in the specified interval (time flows from blue to red)'};
            app.RGBmapLeft.Value = 1;
            app.RGBmapRight = uieditfield(app.statsAndBinarization, 'numeric');
            app.RGBmapRight.Limits = [0 Inf];
            app.RGBmapRight.Tooltip = {'Color coded tempolar map computed in the specified interval (time flows from blue to red)'};
            app.RGBmapRight.Value = 300;
            app.computeRGBtimeMap = uibutton(app.statsAndBinarization, 'push');
            app.computeRGBtimeMap.ButtonPushedFcn = createCallbackFcn(app, @computeRGBmap, true);
            app.computeRGBtimeMap.Tooltip = {'Color coded tempolar map computed in the specified interval (time flows from blue to red)'};
            app.computeRGBtimeMap.Text = 'Time RGB';
            app.mskFstats = uilabel(app.statsAndBinarization);
            app.mskFstats.Tooltip = 'Statistics of binned fluorescence';
            app.mskFstats.Text = 'F';
            app.includeNegDF = uicheckbox(app.statsAndBinarization);
            app.includeNegDF.Tooltip = {'Use the same threshold for all pixels of the image field. Otherwise, a different threshold is computed for each pixel.'};
            app.includeNegDF.Text = 'DF/F < 1';
            app.ABnormalMaps = uibuttongroup(app.MapsTab);
            app.ABnormalMaps.Title = 'AB-Normal Maps';
            app.text27 = uilabel(app.ABnormalMaps);
            app.text27.Tooltip = 'Degree of freedom of the Kolmogorov Smirnof test';
            app.text27.Text = 'KS freedom deg:';
            app.ABsourceF = uiradiobutton(app.ABnormalMaps);
            app.ABsourceF.Tooltip = 'Select the data source: fluorescence';
            app.ABsourceF.Text = 'Source: F(t)';
            app.ABsourceDF = uiradiobutton(app.ABnormalMaps);
            app.ABsourceDF.Tooltip = 'Select the data source: deltaF/F';
            app.ABsourceDF.Text = 'DF(t)/F';
            app.ABsourceDF.Value = true;
            app.KSnTxt = uieditfield(app.ABnormalMaps, 'numeric');
            app.KSnTxt.Limits = [10 Inf];
            app.KSnTxt.ValueDisplayFormat = '%.0f';
            app.KSnTxt.Value = 40;
            app.SEbinsEditFieldLabel = uilabel(app.ABnormalMaps);
            app.SEbinsEditFieldLabel.Text = 'SE bins:';
            app.SEbinsEditField = uieditfield(app.ABnormalMaps, 'numeric');
            app.SEbinsEditField.Limits = [2 Inf];
            app.SEbinsEditField.ValueDisplayFormat = '%.0f';
            app.SEbinsEditField.Tooltip = {'Number of bins for the computation of Shannon Entropy'};
            app.SEbinsEditField.Value = 100;
            app.computeGaussMap = uibutton(app.ABnormalMaps, 'push');
            app.computeGaussMap.ButtonPushedFcn = createCallbackFcn(app, @computeGaussMap_Callback, true);
            app.computeGaussMap.Tooltip = 'Compute the gauss map fo the stack';
            app.computeGaussMap.Text = 'AB-Map';
            app.CDFbindefaultLabel = uilabel(app.ABnormalMaps);
            app.CDFbindefaultLabel.Text = 'CDF bin  (default)';
            app.CDFbinNumberEF = uieditfield(app.ABnormalMaps, 'numeric');
            app.CDFbinNumberEF.Value = 1401;
            app.BetweenLabel = uilabel(app.ABnormalMaps);
            app.BetweenLabel.Text = 'Between:';
            app.ABMaskDisplayChoice1DD = uidropdown(app.ABnormalMaps);
            app.ABMaskDisplayChoice1DD.Items = {'KSRB', 'KSstdRB', 'areaRB', 'areaStdRB', 'SE'};
            app.ABMaskDisplayChoice1DD.Value = 'KSRB';
            app.ABMaskDisplayChoice2DD = uidropdown(app.ABnormalMaps);
            app.ABMaskDisplayChoice2DD.Items = {'KSRB', 'KSstdRB', 'areaRB', 'areaStdRB', 'SE'};
            app.ABMaskDisplayChoice2DD.Value = 'KSRB';
            app.AND_OR_DD = uidropdown(app.ABnormalMaps);
            app.AND_OR_DD.Items = {'AND', 'OR'};
            app.AND_OR_DD.Value = 'AND';
            app.ABmaskCB = uibutton(app.ABnormalMaps, 'push');
            app.ABmaskCB.ButtonPushedFcn = createCallbackFcn(app, @computeABmask, true);
            app.ABmaskCB.Text = 'Compute AB-Mask';
            app.Thr1EditFieldLabel = uilabel(app.ABnormalMaps);
            app.Thr1EditFieldLabel.Text = 'Thr 1:';
            app.normalAreasThr1EF = uieditfield(app.ABnormalMaps, 'numeric');
            app.normalAreasThr1EF.Value = 0.0001;
            app.Thr2EditFieldLabel = uilabel(app.ABnormalMaps);
            app.Thr2EditFieldLabel.Text = 'Thr 2:';
            app.normalAreasThr2EF = uieditfield(app.ABnormalMaps, 'numeric');
            app.normalAreasThr2EF.Value = 0.0001;
            app.getABHistoBT = uibutton(app.ABnormalMaps, 'push');
            app.getABHistoBT.ButtonPushedFcn = createCallbackFcn(app, @getABHisto, true);
            app.getABHistoBT.Text = 'Get histograms';
            app.getAutoThresholdBT = uibutton(app.ABnormalMaps, 'push');
            app.getAutoThresholdBT.ButtonPushedFcn = createCallbackFcn(app, @getAutoThreshold, true);
            app.getAutoThresholdBT.Text = 'Get Auto Threshold';
            app.ABThrStdCB = uicheckbox(app.ABnormalMaps);
            app.ABThrStdCB.Text = 'w/ Std';
            app.ABThrStdEF = uieditfield(app.ABnormalMaps, 'numeric');
            app.ABThrStdEF.Value = 2.5;
            app.GenerateABnormalmaskLabel = uilabel(app.ABnormalMaps);
            app.GenerateABnormalmaskLabel.Text = 'Generate AB-normal mask:';
            app.allABsMaskBT = uibutton(app.ABnormalMaps, 'push');
            app.allABsMaskBT.ButtonPushedFcn = createCallbackFcn(app, @allABsMask, true);
            app.allABsMaskBT.Text = 'Compute mask w/ all AB-Maps';
            app.LogicLabel = uilabel(app.ABnormalMaps);
            app.LogicLabel.Text = 'Logic:';
            app.AND_OR_DD_2 = uidropdown(app.ABnormalMaps);
            app.AND_OR_DD_2.Items = {'AND', 'OR'};
            app.AND_OR_DD_2.Value = 'OR';
            app.exportABNormalBT = uibutton(app.ABnormalMaps, 'push');
            app.exportABNormalBT.ButtonPushedFcn = createCallbackFcn(app, @exportABNormal, true);
            app.exportABNormalBT.Tooltip = {'Once maps are computed, use this to export the matrix of the first selected map (left dropdown).'};
            app.exportABNormalBT.Text = 'Export #1';
            app.ROIsTab = uitab(app.TabGroup);
            app.ROIsTab.Title = 'ROIs';
            app.ROIcontrolButtonGroup = uibuttongroup(app.ROIsTab);
            app.ROIcontrolButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ROImodeOnOff, true);
            app.ROIcontrolButtonGroup.Title = 'ROI control';
            app.NoROIsBt = uiradiobutton(app.ROIcontrolButtonGroup);
            app.NoROIsBt.Text = 'No ROIs';
            app.NoROIsBt.Value = true;
            app.SquareBt = uiradiobutton(app.ROIcontrolButtonGroup);
            app.SquareBt.Tooltip = {'Draw square of the default side'};
            app.SquareBt.Text = 'Square';
            app.CircleBt = uiradiobutton(app.ROIcontrolButtonGroup);
            app.CircleBt.Tooltip = {'Draw circular ROIS of the default radius'};
            app.CircleBt.Text = 'Circle';
            app.SizeofdefaultROIpxLabel = uilabel(app.ROIcontrolButtonGroup);
            app.SizeofdefaultROIpxLabel.Text = 'Size of default ROI (px):';
            app.defRoiRadius = uieditfield(app.ROIcontrolButtonGroup, 'numeric');
            app.defRoiRadius.Limits = [1 64];
            app.defRoiRadius.ValueDisplayFormat = '%.0f';
            app.defRoiRadius.Value = 5;
            app.showROIsCB = uicheckbox(app.ROIcontrolButtonGroup);
            app.showROIsCB.ValueChangedFcn = createCallbackFcn(app, @showROItrace, true);
            app.showROIsCB.Tooltip = {'Select to show the ROIs overlaied on the image window and the traces in the time explore window'};
            app.showROIsCB.Text = 'Show ROIs traces';
            app.CROIEditorPaduaPanel = uipanel(app.ROIsTab);
            app.CROIEditorPaduaPanel.Title = 'CROI Editor (Padua)';
            app.DrawBoundariesBT = uibutton(app.CROIEditorPaduaPanel, 'push');
            app.DrawBoundariesBT.ButtonPushedFcn = createCallbackFcn(app, @CROIEditorDraw, true);
            app.DrawBoundariesBT.Text = 'Draw boundaries';
            app.ExportBoundariesBT = uibutton(app.CROIEditorPaduaPanel, 'push');
            app.ExportBoundariesBT.ButtonPushedFcn = createCallbackFcn(app, @CROIEditorExport, true);
            app.ExportBoundariesBT.Text = 'Export';
            app.ImportBoundariesBT = uibutton(app.CROIEditorPaduaPanel, 'push');
            app.ImportBoundariesBT.ButtonPushedFcn = createCallbackFcn(app, @CROIEditorImportAndApply, true);
            app.ImportBoundariesBT.Text = 'Import and apply';
            app.DisplayTab = uitab(app.TabGroup);
            app.DisplayTab.Title = 'Display';
            app.LeftWindowPanel = uipanel(app.DisplayTab);
            app.LeftWindowPanel.Title = 'Left Window';
            app.IWselectCh = uibuttongroup(app.LeftWindowPanel);
            app.IWselectCh.SelectionChangedFcn = createCallbackFcn(app, @IWselectCh_SelectionChangedFcn, true);
            app.IWfluoCh1RB = uiradiobutton(app.IWselectCh);
            app.IWfluoCh1RB.Text = 'Show Ch1';
            app.IWfluoCh1RB.Value = true;
            app.IWfluoCh2RB = uiradiobutton(app.IWselectCh);
            app.IWfluoCh2RB.Text = 'Show Ch2';
            app.IWfluoCh1Ch2RB = uiradiobutton(app.IWselectCh);
            app.IWfluoCh1Ch2RB.Text = 'Show Ch1 and Ch2';
            app.IWsource = uibuttongroup(app.LeftWindowPanel);
            app.IWsource.SelectionChangedFcn = createCallbackFcn(app, @IWsource_SelectionChangedFcn, true);
            app.IWsource.Title = 'Images and maps';
            app.IWmaxFluoRB = uiradiobutton(app.IWsource);
            app.IWmaxFluoRB.Tooltip = {'Maximum projection of the entire raw data (no preprocessing or binning)'};
            app.IWmaxFluoRB.Text = 'Max projection';
            app.IWmaxFluoRB.Value = true;
            app.IWmeanFluoRB = uiradiobutton(app.IWsource);
            app.IWmeanFluoRB.Tooltip = {'Mean projection of the entire raw data (no preprocessing or binning)'};
            app.IWmeanFluoRB.Text = 'Mean';
            app.IWfluoRB = uiradiobutton(app.IWsource);
            app.IWfluoRB.Tooltip = {'Visualize a single preprocessed and binned frame'};
            app.IWfluoRB.Text = 'Fluo frames';
            app.KSRB = uiradiobutton(app.IWsource);
            app.KSRB.Tooltip = 'Visualize AB-Normal map: KS metrics';
            app.KSRB.Text = 'KS';
            app.areaStdRB = uiradiobutton(app.IWsource);
            app.areaStdRB.Tooltip = 'Visualize AB-Normal map: Area*std metrics';
            app.areaStdRB.Text = 'Area-std';
            app.areaRB = uiradiobutton(app.IWsource);
            app.areaRB.Tooltip = 'Visualize AB-Normal map: area metrics';
            app.areaRB.Text = 'Area';
            app.KSstdRB = uiradiobutton(app.IWsource);
            app.KSstdRB.Tooltip = 'Visualize AB-Normal map: KS*std metrics';
            app.KSstdRB.Text = 'KS-std';
            app.SERB = uiradiobutton(app.IWsource);
            app.SERB.Tooltip = {'Visualize AB-Normal map: Shannon Entropy'};
            app.SERB.Text = 'Entropy';
            app.IWmedianFluoRB = uiradiobutton(app.IWsource);
            app.IWmedianFluoRB.Tooltip = {'Mean projection of the entire raw data (no preprocessing or binning)'};
            app.IWmedianFluoRB.Text = 'Median';
            app.IWdFmaxRB = uiradiobutton(app.IWsource);
            app.IWdFmaxRB.Tooltip = 'Maximum projection of the entire stack';
            app.IWdFmaxRB.Text = 'Delta F/F Max';
            app.IWdFRB = uiradiobutton(app.IWsource);
            app.IWdFRB.Tooltip = 'Visualize a single frame';
            app.IWdFRB.Text = 'Delta F/F frames';
            app.medianFluoRB_2 = uiradiobutton(app.IWsource);
            app.medianFluoRB_2.Tooltip = {'Mean projection of the entire stack'};
            app.medianFluoRB_2.Text = 'Median';
            app.displayROIs = uicheckbox(app.LeftWindowPanel);
            app.displayROIs.ValueChangedFcn = createCallbackFcn(app, @refreshROIs, true);
            app.displayROIs.Tooltip = {'Display ROI overlay'};
            app.displayROIs.Text = 'ROI';
            app.IWautoBrCB = uicheckbox(app.LeftWindowPanel);
            app.IWautoBrCB.ValueChangedFcn = createCallbackFcn(app, @redoDisplay, true);
            app.IWautoBrCB.Tooltip = 'Toggle animation of the F or DF/F stack';
            app.IWautoBrCB.Text = 'Auto brightness';
            app.maskOn = uicheckbox(app.LeftWindowPanel);
            app.maskOn.ValueChangedFcn = createCallbackFcn(app, @redoDisplay, true);
            app.maskOn.Tooltip = 'Toggle animation of the F or DF/F stack';
            app.maskOn.Text = 'Mask On';
            app.applyABmaskCB = uicheckbox(app.LeftWindowPanel);
            app.applyABmaskCB.ValueChangedFcn = createCallbackFcn(app, @applyABMask, true);
            app.applyABmaskCB.Text = 'Apply AB-Mask';
            app.animateStackBG = uibuttongroup(app.LeftWindowPanel);
            app.animateStackBG.Title = 'Animate stack';
            app.animateStackFluoRB = uiradiobutton(app.animateStackBG);
            app.animateStackFluoRB.Text = 'Fluo';
            app.animateStackFluoRB.Value = true;
            app.animateStackDFFRB = uiradiobutton(app.animateStackBG);
            app.animateStackDFFRB.Text = 'DF/F';
            app.animateStackBT = uibutton(app.animateStackBG, 'push');
            app.animateStackBT.ButtonPushedFcn = createCallbackFcn(app, @animateStack, true);
            app.animateStackBT.Text = 'Animate stack';
            app.animateStackABMaskCB = uicheckbox(app.animateStackBG);
            app.animateStackABMaskCB.Text = 'w/ AB-Mask';
            app.RightWindowPanel = uipanel(app.DisplayTab);
            app.RightWindowPanel.Title = 'Right Window';
            app.binaryImageSource = uibuttongroup(app.RightWindowPanel);
            app.binaryImageSource.SelectionChangedFcn = createCallbackFcn(app, @binaryImageSource_SelectionChangedFcn, true);
            app.binaryImageSource.Title = 'Image and Maps';
            app.binMaxRB = uiradiobutton(app.binaryImageSource);
            app.binMaxRB.Tooltip = 'Maximum projection of the entire stack';
            app.binMaxRB.Text = 'Max Prj';
            app.binMaxRB.Value = true;
            app.FbinRB = uiradiobutton(app.binaryImageSource);
            app.FbinRB.Tooltip = 'Maximum projection of the entire stack';
            app.FbinRB.Text = 'Frames';
            app.areaStdRBb = uiradiobutton(app.binaryImageSource);
            app.areaStdRBb.Tooltip = 'Visualize AB-Normal map: Area*std metrics';
            app.areaStdRBb.Text = 'Area-std';
            app.KSstdRBb = uiradiobutton(app.binaryImageSource);
            app.KSstdRBb.Tooltip = 'Visualize AB-Normal map: KS*std metrics';
            app.KSstdRBb.Text = 'KS-std';
            app.KSRBb = uiradiobutton(app.binaryImageSource);
            app.KSRBb.Tooltip = 'Visualize AB-Normal map: KS metrics';
            app.KSRBb.Text = 'KS';
            app.areaRBb = uiradiobutton(app.binaryImageSource);
            app.areaRBb.Tooltip = 'Visualize AB-Normal map: area metrics';
            app.areaRBb.Text = 'Area';
            app.RGBcoding = uiradiobutton(app.binaryImageSource);
            app.RGBcoding.Tooltip = 'Code timing of pixel activation with RGB coding';
            app.RGBcoding.Text = 'RGB';
            app.SERBb = uiradiobutton(app.binaryImageSource);
            app.SERBb.Enable = 'off';
            app.SERBb.Visible = 'off';
            app.SERBb.Tooltip = 'Visualize AB-Normal map: KS*std metrics';
            app.SERBb.Text = 'Entropy';
            app.uibuttongroup7 = uibuttongroup(app.RightWindowPanel);
            app.uibuttongroup7.SelectionChangedFcn = createCallbackFcn(app, @uibuttongroup7_SelectionChangedFcn, true);
            app.uibuttongroup7.Title = 'Binarized Data';
            app.binaryRawRB = uiradiobutton(app.uibuttongroup7);
            app.binaryRawRB.Tooltip = 'Display the raw binary data';
            app.binaryRawRB.Text = 'Raw';
            app.filteredBinRB = uiradiobutton(app.uibuttongroup7);
            app.filteredBinRB.Tooltip = 'Display the filtered binary data';
            app.filteredBinRB.Text = 'Filtered';
            app.filteredBinRB.Value = true;
            app.mergeColorsCB = uicheckbox(app.uibuttongroup7);
            app.mergeColorsCB.ValueChangedFcn = createCallbackFcn(app, @refreshGUI, true);
            app.mergeColorsCB.Text = 'Display original colours on image and video';
            app.animateBinBG = uibuttongroup(app.RightWindowPanel);
            app.animateBinBG.Title = 'Animate binarized data. With:';
            app.animateBinCurrentOutRB = uiradiobutton(app.animateBinBG);
            app.animateBinCurrentOutRB.Text = 'Current left window output';
            app.animateBinCurrentOutRB.Value = true;
            app.animateBinFluoStackRB = uiradiobutton(app.animateBinBG);
            app.animateBinFluoStackRB.Text = 'Fluo frames stack';
            app.animateBinDFFStackRB = uiradiobutton(app.animateBinBG);
            app.animateBinDFFStackRB.Text = 'Delta F/F frames stack';
            app.animateBinBT = uibutton(app.animateBinBG, 'push');
            app.animateBinBT.ButtonPushedFcn = createCallbackFcn(app, @animateBin, true);
            app.animateBinBT.Text = 'Animate binarized data';
            app.applyMaskAnimateCB = uicheckbox(app.animateBinBG);
            app.applyMaskAnimateCB.Text = 'w/ AB-mask (only for stacks)';
            app.uipanel14 = uipanel(app.DisplayTab);
            app.uipanel14.Title = 'Display Control';
            app.DFFminEditFieldLabel = uilabel(app.uipanel14);
            app.DFFminEditFieldLabel.Text = 'DF/F min:';
            app.DFminTxt = uieditfield(app.uipanel14, 'numeric');
            app.DFminTxt.ValueChangedFcn = createCallbackFcn(app, @changeDFmin, true);
            app.DFFmaxLabel = uilabel(app.uipanel14);
            app.DFFmaxLabel.Text = 'DF/F max:';
            app.DFmaxTxt = uieditfield(app.uipanel14, 'numeric');
            app.DFmaxTxt.Limits = [0 100];
            app.DFmaxTxt.ValueChangedFcn = createCallbackFcn(app, @changeDFmax, true);
            app.DFmaxTxt.Value = 5;
            app.FminLabel = uilabel(app.uipanel14);
            app.FminLabel.Text = 'F min:';
            app.FminTxt = uieditfield(app.uipanel14, 'numeric');
            app.FminTxt.ValueChangedFcn = createCallbackFcn(app, @changeFmin, true);
            app.FmaxLabel = uilabel(app.uipanel14);
            app.FmaxLabel.Text = 'F max:';
            app.FmaxTxt = uieditfield(app.uipanel14, 'numeric');
            app.FmaxTxt.Limits = [0 Inf];
            app.FmaxTxt.ValueChangedFcn = createCallbackFcn(app, @changeFmax, true);
            app.FmaxTxt.Value = 1000;
            app.ABminLabel = uilabel(app.uipanel14);
            app.ABminLabel.Text = 'AB min:';
            app.ABmin = uieditfield(app.uipanel14, 'numeric');
            app.ABmin.Limits = [0 Inf];
            app.ABmin.ValueChangedFcn = createCallbackFcn(app, @ABmin_Callback, true);
            app.ABmaxLabel = uilabel(app.uipanel14);
            app.ABmaxLabel.Text = 'AB max:';
            app.ABmax = uieditfield(app.uipanel14, 'numeric');
            app.ABmax.Limits = [0 Inf];
            app.ABmax.ValueChangedFcn = createCallbackFcn(app, @ABmax_Callback, true);
            app.ABmax.Value = 1;
            app.ABminb = uieditfield(app.uipanel14, 'numeric');
            app.ABminb.Limits = [0 Inf];
            app.ABminb.ValueChangedFcn = createCallbackFcn(app, @ABminb_Callback, true);
            app.ABmaxb = uieditfield(app.uipanel14, 'numeric');
            app.ABmaxb.Limits = [0 Inf];
            app.ABmaxb.ValueChangedFcn = createCallbackFcn(app, @ABmaxb_Callback, true);
            app.ABmaxb.Value = 1;
            app.thrF = uieditfield(app.uipanel14, 'numeric');
            app.thrF.Limits = [0 Inf];
            app.applyThrF = uicheckbox(app.uipanel14);
            app.applyThrF.Text = 'Threshold on F:';
            app.ColormapLabel = uilabel(app.uipanel14);
            app.ColormapLabel.Text = 'Colormap:';
            app.chooseColormapDD = uidropdown(app.uipanel14);
            app.chooseColormapDD.Items = {'hot', 'gray', 'bone', 'copper', 'jet', 'parula'};
            app.chooseColormapDD.ItemsData = {'1', '2', '3', '4', '5', '6'};
            app.chooseColormapDD.ValueChangedFcn = createCallbackFcn(app, @chooseColormap, true);
            app.chooseColormapDD.Value = '1';
            app.timeBarGraphicsCB = uicheckbox(app.uipanel14);
            app.timeBarGraphicsCB.ValueChangedFcn = createCallbackFcn(app, @timeBar, true);
            app.timeBarGraphicsCB.Text = 'Show time bar graphics';
            app.timeBarGraphicsCB.Value = true;
            app.restoreDefaultSettingsBT = uibutton(app.uipanel14, 'push');
            app.restoreDefaultSettingsBT.ButtonPushedFcn = createCallbackFcn(app, @restoreDefaultSettings, true);
            app.restoreDefaultSettingsBT.Text = 'Restore default settings';
            app.NavigateframestacksPanel = uipanel(app.imageGateway);
            app.NavigateframestacksPanel.Title = 'Navigate frame stacks';
            app.selectFrame = uislider(app.NavigateframestacksPanel);
            app.selectFrame.Limits = [1 2];
            app.selectFrame.MajorTicks = [];
            app.selectFrame.ValueChangedFcn = createCallbackFcn(app, @selectFrame_Callback, true);
            app.selectFrame.MinorTicks = [];
            app.selectFrame.Tooltip = 'Select frame';
            app.selectFrame.Value = 1;
            app.currentFrTxt = uilabel(app.NavigateframestacksPanel);
            app.currentFrTxt.Tooltip = 'Current frame';
            app.currentFrTxt.Text = '1';
            app.backMultiFrames = uibutton(app.NavigateframestacksPanel, 'push');
            app.backMultiFrames.ButtonPushedFcn = createCallbackFcn(app, @backMultiFrames_Callback, true);
            app.backMultiFrames.Tooltip = 'Move the current frame';
            app.backMultiFrames.Text = '<<';
            app.back1Frame = uibutton(app.NavigateframestacksPanel, 'push');
            app.back1Frame.ButtonPushedFcn = createCallbackFcn(app, @back1Frame_Callback, true);
            app.back1Frame.Tooltip = 'Move the current frame';
            app.back1Frame.Text = '<';
            app.advance1frame = uibutton(app.NavigateframestacksPanel, 'push');
            app.advance1frame.ButtonPushedFcn = createCallbackFcn(app, @advance1frame_Callback, true);
            app.advance1frame.Tooltip = 'Move the current frame';
            app.advance1frame.Text = '>';
            app.advanceMultiFrames = uibutton(app.NavigateframestacksPanel, 'push');
            app.advanceMultiFrames.ButtonPushedFcn = createCallbackFcn(app, @advanceMultiFrames_Callback, true);
            app.advanceMultiFrames.Tooltip = 'Move the current frame';
            app.advanceMultiFrames.Text = '>>';
            app.StatusPanel = uipanel(app.imageGateway);
            app.StatusPanel.Title = 'Status';
            app.history = uilabel(app.StatusPanel);
            app.history.Text = 'Data  Flat  Reg  Kalman  Bin  DF/F Bin';
            app.statusNow = uilabel(app.StatusPanel);
            app.FurtherlogicalprocessingPanel = uipanel(app.imageGateway);
            app.FurtherlogicalprocessingPanel.Title = 'Further logical processing';
            app.closeBinaryCB = uicheckbox(app.FurtherlogicalprocessingPanel);
            app.closeBinaryCB.Text = 'Close binary regions';
            app.closeBinaryEF = uieditfield(app.FurtherlogicalprocessingPanel, 'numeric');
            app.closeBinaryEF.Value = 1;
            app.dilateBinaryCB = uicheckbox(app.FurtherlogicalprocessingPanel);
            app.dilateBinaryCB.Text = 'Dilate binary regions';
            app.dilateBinaryEF = uieditfield(app.FurtherlogicalprocessingPanel, 'numeric');
            app.dilateBinaryEF.Value = 1;
            app.timeIterationCB = uicheckbox(app.FurtherlogicalprocessingPanel);
            app.timeIterationCB.Text = 'Time iteration threshold';
            app.timeIterationEF = uieditfield(app.FurtherlogicalprocessingPanel, 'numeric');
            % Show the figure after all components are created
            app.imageGateway.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageGateway3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.imageGateway)

            % Execute the startup function
            runStartupFcn(app, @imageGateway_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.imageGateway)
        end
    end
end