classdef applicationBinaryMap_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        extractDomains               matlab.ui.Figure
        uipanel3                     matlab.ui.container.Panel
        ClusterIdentificationPanel   matlab.ui.container.Panel
        MergeClustersCB              matlab.ui.control.CheckBox
        RhoMultiplierEF              matlab.ui.control.NumericEditField
        RhoEditFieldLabel            matlab.ui.control.Label
        includeDiscardedEventsCB     matlab.ui.control.CheckBox
        minDF                        matlab.ui.control.NumericEditField
        maxDF                        matlab.ui.control.NumericEditField
        sortingCriteriaRB            matlab.ui.container.ButtonGroup
        CCButton                     matlab.ui.control.RadioButton
        AreaButton                   matlab.ui.control.RadioButton
        nEventsButton                matlab.ui.control.RadioButton
        NosortingButton              matlab.ui.control.RadioButton
        CCmatrixBt                   matlab.ui.control.Button
        TSselection                  matlab.ui.container.ButtonGroup
        DFUallButton                 matlab.ui.control.RadioButton
        DFIallButton                 matlab.ui.control.RadioButton
        DFunionButton                matlab.ui.control.RadioButton
        DFintersectionButton         matlab.ui.control.RadioButton
        clusterTxt                   matlab.ui.control.Label
        SIthr                        matlab.ui.control.NumericEditField
        TolleranceSILabel            matlab.ui.control.Label
        SpCorrelationBt              matlab.ui.control.Button
        timeSeries                   matlab.ui.control.UIAxes
        uipanel13                    matlab.ui.container.Panel
        uibuttongroup4               matlab.ui.container.ButtonGroup
        nameLabel                    matlab.ui.control.Label
        umpxEF                       matlab.ui.control.NumericEditField
        mpxLabel                     matlab.ui.control.Label
        FilenameEF                   matlab.ui.control.EditField
        defineSomaBT                 matlab.ui.control.Button
        AVRatiosBT                   matlab.ui.control.Button
        computeAndSaveDistributions  matlab.ui.control.Button
        binDistr                     matlab.ui.control.NumericEditField
        BinsLabel                    matlab.ui.control.Label
        minMaxDeltaF                 matlab.ui.control.Label
        toDistr                      matlab.ui.control.NumericEditField
        ToLabel                      matlab.ui.control.Label
        fromDistr                    matlab.ui.control.NumericEditField
        FromEditFieldLabel           matlab.ui.control.Label
        yLogScale                    matlab.ui.control.CheckBox
        selectDistr                  matlab.ui.container.ButtonGroup
        DistanceBt                   matlab.ui.control.RadioButton
        deltaFBt_4                   matlab.ui.control.RadioButton
        deltaFBt_3                   matlab.ui.control.RadioButton
        deltaFBt_2                   matlab.ui.control.RadioButton
        deltaFBt                     matlab.ui.control.RadioButton
        durationBt                   matlab.ui.control.RadioButton
        surfaceBt                    matlab.ui.control.RadioButton
        volumeBt                     matlab.ui.control.RadioButton
        minMaxDurationTxt            matlab.ui.control.Label
        minMaxVolumeTxt              matlab.ui.control.Label
        minMaxSurfaceTxt             matlab.ui.control.Label
        plotdistr                    matlab.ui.control.Button
        xLogScale                    matlab.ui.control.CheckBox
        distributions                matlab.ui.control.UIAxes
        uipanel2                     matlab.ui.container.Panel
        multiPlotMode                matlab.ui.container.ButtonGroup
        NNlinkCB                     matlab.ui.control.CheckBox
        framesCB                     matlab.ui.control.CheckBox
        ClustersButton               matlab.ui.control.RadioButton
        BoundariesButton             matlab.ui.control.RadioButton
        multiDisplay                 matlab.ui.control.UIAxes
        clusterPanel                 matlab.ui.container.Panel
        debugABNormalCB              matlab.ui.control.CheckBox
        debugDrawCB                  matlab.ui.control.CheckBox
        debugGridCB                  matlab.ui.control.CheckBox
        displaySelect                matlab.ui.container.ButtonGroup
        displayFlt                   matlab.ui.control.RadioButton
        dislayRaw                    matlab.ui.control.RadioButton
        connectivityBtGroup          matlab.ui.container.ButtonGroup
        px26Bt                       matlab.ui.control.RadioButton
        px18Bt                       matlab.ui.control.RadioButton
        px6Bt                        matlab.ui.control.RadioButton
        SourceBtGroup                matlab.ui.container.ButtonGroup
        filteredBt                   matlab.ui.control.RadioButton
        RawBt                        matlab.ui.control.RadioButton
        maxptsdb                     matlab.ui.control.EditField
        minptsdb                     matlab.ui.control.EditField
        text6                        matlab.ui.control.Label
        applyclust                   matlab.ui.control.Button
        ExportButton                 matlab.ui.control.Button
        clusterAxis                  matlab.ui.control.UIAxes
        displayPanel                 matlab.ui.container.Panel
        FillCB                       matlab.ui.control.CheckBox
        gCCNormalizeCB               matlab.ui.control.CheckBox
        gCCExcludeEF                 matlab.ui.control.NumericEditField
        gCCExcludeCB                 matlab.ui.control.CheckBox
        ClusterCCScoreBT             matlab.ui.control.Button
        logMax                       matlab.ui.control.NumericEditField
        logMin                       matlab.ui.control.NumericEditField
        LogminmaxEditFieldLabel      matlab.ui.control.Label
        InvertframesCheckBox         matlab.ui.control.CheckBox
        boundaryLineW                matlab.ui.control.NumericEditField
        BoundarywidthEditFieldLabel  matlab.ui.control.Label
        showClusterSpinner           matlab.ui.control.Spinner
        ShowclusterSpinnerLabel      matlab.ui.control.Label
        advace20frames               matlab.ui.control.Button
        back20frames                 matlab.ui.control.Button
        untilTheEnd                  matlab.ui.control.Button
        backToTheBeginning           matlab.ui.control.Button
        advace1frameBM               matlab.ui.control.Button
        back1frameBM                 matlab.ui.control.Button
        currentFrameTxt              matlab.ui.control.Label
        uibuttongroup2               matlab.ui.container.ButtonGroup
        displaythreed                matlab.ui.control.Button
        clustflag                    matlab.ui.control.RadioButton
        BMdFflag                     matlab.ui.control.RadioButton
        uipanel5                     matlab.ui.container.Panel
        ScramblecontrolPanel         matlab.ui.container.Panel
        ScrambleEventsRotateSP       matlab.ui.control.Spinner
        ScrambleEventsRotateCB       matlab.ui.control.CheckBox
        nEventsTxt                   matlab.ui.control.Label
        nEventsFltTxt                matlab.ui.control.Label
        selectSource4FeaturesBT      matlab.ui.container.ButtonGroup
        FilteredBt                   matlab.ui.control.RadioButton
        AlleventsBt                  matlab.ui.control.RadioButton
        ExportFeaturesButton         matlab.ui.control.Button
        extractFeatures              matlab.ui.control.Button
        uipanel7                     matlab.ui.container.Panel
        Xlog10                       matlab.ui.control.CheckBox
        Ylog10                       matlab.ui.control.CheckBox
        YsurfaceCB                   matlab.ui.control.CheckBox
        YdurationCB                  matlab.ui.control.CheckBox
        YdeltaFCB                    matlab.ui.control.CheckBox
        YvolumeCB                    matlab.ui.control.CheckBox
        XYLabel                      matlab.ui.control.Label
        XsurfaceCB                   matlab.ui.control.CheckBox
        XdurationCB                  matlab.ui.control.CheckBox
        XdeltaFCB                    matlab.ui.control.CheckBox
        XvolumeCB                    matlab.ui.control.CheckBox
        featureViewer                matlab.ui.control.UIAxes
    end

    properties (Access = public)
        parentApp            % acces to the parent app, Imagegateway
        
        % variables for the Bin*DF application
        BinRoiDF
        currentFrame
        maxproj
        BinRoiMin
        BinRoiMax
        meanBinRoi
        
        % Cluster segmentation
        CCevents                % connected regions object
        CCeventsFlt             % Connected regions object filtered
        
        eventFeatures           % table of features of all events
        % eventFeatures is a table that contains all stats about all the
        % CCevents (connected domains) that have been identified. Each connected
        % domain form a event. Time series are reconstructed by joining
        % events that share common characteristics.
        
        % 'Volume'          volume of the event (x,y,t)
        % 'Centroid'        centroid of the event
        % "BoundingBox"     defined as origin corner (x,y,t) and length along the three axes
        % 'VoxelList'       indexes of the voxels belonging to the event
        % 'SurfaceArea'     area, in pixels, of the 3D surface of the event
        % 'VoxelValues'     cell array containing the coordinates of all voxels belonging to the event
        % 'MeanIntensity'   meand DF/F of the entire event
        % 'Footprint'       Cell array containing the array of points that describe
        %                   the boundary of the max projection of the event
        % 'xyArea           surface delimited by the footprint. This
        %                   corresponds to the max projection of the event
        % 'Duration'        duration of the events in frames
        % 'ClusterId'       is a pointer to the table CCregion
        
        CCregions               % collated regions
        % CCregions is a table that contains variables describing all the
        % clustered events. One row for each cluster of events
        % The variables of CCregions are:
        % 'clusterId'     Array of the indexes of the events belonging to the cluster as described
        %       in eventFeatures table.
        % 'clusterSI'     Similarity index of all events
        % 'clusterPolyI'   polyshape of the intersection of all the events
        % 'clusterPolyU'   polyshape of the union of all the events
        % 'deltaFI'        deltaF/F computed on the intersection polyshape
        % 'deltaFU'        deltaF/F computed on the union polyshape
        % 'deltaFIall'     deltaF/F computed on the intersection polyshape including the frames in between the events
        % 'deltaFIall'     deltaF/F computed on the union polyshape including the inbetween frames
        % 'timeLine'      time series that contain the index of all events
        %       joned in the cluster. =0 outside of the events
        
        CC                  % Structure holding the cross correlation matrices and associated things
        % Field description
        % 'lag
        
        eventStats              % structure holding the global stats of all events
        connectivity            % degree of connectivity
        minpts
        maxpts
        
        Xscan                   % Binary stack with labelled clusters (all of them!)
        XscanFlt                % Binary stack with labelled clusters (filtered events only)
        nEventsFlt              % n of total events
        nEvents                 % n of events before volume filtering
        nclust
        mymap                   % LUT for events
        
        nClusters               % n of event clusters
        nEvInClusters           % n of events included in clusters
        
        plotsOut                % Structure holding several different output arrays and parameters
        Xscanson
        actualframe
        
        
        stats
        
        %properties of the clusters
        propertytab
        additionalpropertytab
        
        %flags
        bmdfflag
        permissionflag
        clusterflag
        
        % Jan 2021
        % Handle to CC matrix app
        BinMapCCMatrix
        % Inherit permuted track from chid
        ChildPermutedTrack
        ChildRandomOffsets
        
        NotExcluded % Backup of cluster table before deleting clusters by their global CC score
        
        % Cellular bodies coordinates
        xSoma
        ySoma
    end


    methods (Access = private)
        function grid_CreateFcn(app, hObject, handles)
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
                
            end
            app.clusterAxis.Toolbar.Visible = 'off';
            app.clusterAxis.Toolbar.Visible = 'on';
        end
        
        function axesexplorer(app)
            if app.clusterflag
                switch app.displaySelect.SelectedObject.Text
                    case 'Raw'
                        tmp = app.Xscan(:,:,app.currentFrame);
                        imagesc(app.clusterAxis,tmp,[0 app.nEvents+1]);
                    case 'Filtered'
                        tmp = app.XscanFlt(:,:,app.currentFrame);
                        imagesc(app.clusterAxis,tmp,[0 app.nEventsFlt+1]);
                end
                imagesc(app.clusterAxis,tmp,[0 app.nEventsFlt+1]);
                set(app.clusterAxis,'Colormap',app.mymap);
                set(app.clusterAxis,'XTick',[]);
                set(app.clusterAxis,'YTick',[]);
                
                set(app.clusterAxis,'XLim',[0 size(tmp,1)]);
                set(app.clusterAxis,'YLim',[0 size(tmp,2)]);
            end
            if app.bmdfflag
                imagesc(app.multiDisplay,app.BinRoiDF(:,:,:,app.currentFrame),[0 app.BinRoiMax]);
                set(app.multiDisplay,'Colormap',hot);
                set(app.multiDisplay,'XTick',[]);
                set(app.multiDisplay,'YTick',[]);
                set(app.multiDisplay,'XLim',[0 size(app.BinRoiDF,1)]);
                set(app.multiDisplay,'YLim',[0 size(app.BinRoiDF,2)]);
            end
        end
    end
    
    methods (Access = public)
        
        function plotTimeSeries(app)
            % December 28, 2020
            %
            % This function plot the selected series. If the N(earest)
            % N(eighbour) check box is on, the function plots two traces:
            % the trace selected in the spinner (in red) and the trace
            % relative to the cluster with the maximum cross correlation (green).
            
            cla(app.timeSeries)
            switch app.TSselection.SelectedObject.Text
                case 'DF intersection'
                    source = 'deltaFI';
                case 'DF union'
                    source = 'deltaFU';
                case 'DF I all'
                    source = 'deltaFIall';
                case 'DF U all'
                    source = 'deltaFUall';
            end
            trN = app.showClusterSpinner.Value;
            % Check the sorting criteria
            if trN>0
                switch app.sortingCriteriaRB.SelectedObject.Text
                    case 'No sorting'
                        % Do nothing
                    case 'Area'
                        trN = app.CC.sortIarea(trN);
                    case 'n Events'
                        trN = app.CC.sortInEvents(trN);
                        % Jan 18, 2021
                    case 'CC'
                        trN = app.CC.sortIintCC(trN);
                        
                end
            end
            switch trN
                case 0
                    % plot all time series
                    plot(app.timeSeries,app.CCregions.(source)')
                otherwise
                    % plot a single trace
                    plot(app.timeSeries,app.CCregions.(source)(trN,:)-mean(app.CCregions.(source)(trN,:))','r','LineWidth',2)
                    if app.NNlinkCB.Value
                        hold(app.timeSeries,'on')
                        NNi = app.CC.NNind(trN);
                        d = app.CC.clDistance(trN,NNi);
                        if isempty(app.ChildPermutedTrack)
                            plot(app.timeSeries,-app.CCregions.(source)(NNi,:)','g','LineWidth',1)
                        else % I added this in order to show the crosscorrelation of permuted track.
                            % Of course, this happens only when you select
                            % this visualisation option within the
                            % BinMapCCMatrix child app
                            plot(app.timeSeries,-app.ChildPermutedTrack(NNi,:)','g','LineWidth',1)
                        end
                        hold(app.timeSeries,'off')
                        tx = sprintf('NN cluster: %g  Distance: %3.0f  CC %.1f', NNi, d, app.CC.NNdistance(trN));
                        text(app.timeSeries,app.parentApp.metadata.frames/4,app.maxDF.Value-2,...
                            tx,"FontSize",10);
                    end
                    % 26 Jan, 2021
                    % Plot permuted trace from BinMapCCMatrix. The status
                    % of ChildPermutedTrack property (empty or not) depends on the
                    % status of a CB handle which is posed onto the
                    % BinMapCCMatrix child app. Whenever the CB is unchecked, this property is
                    % emptied
                    if ~isempty(app.ChildPermutedTrack)
                        hold(app.timeSeries,'on')
                        plot(app.timeSeries,app.ChildPermutedTrack(trN,:)+2,'b','LineWidth',2)
                        % Show vertical line with offset
                        xline(app.timeSeries,app.ChildRandomOffsets(trN),'--r',{num2str(app.ChildRandomOffsets(trN))},'LineWidth',1);
                        hold(app.timeSeries,'off')
                    end
            end
            % Set the plot limits
            axis(app.timeSeries,[0 app.parentApp.metadata.frames app.minDF.Value app.maxDF.Value])
            yticks(app.timeSeries,'auto')
        end
        
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function applicationBinaryMap_OpeningFcn(app, parentApp)
            % Initialisation and link with Image Gateway
            app.parentApp = parentApp;
            app.currentFrame = 1;
            
            app.clusterAxis.Toolbar.Visible = 'off';
            app.clusterAxis.Toolbar.Visible = 'on';
        end

        % Button pushed function: advace1frameBM
        function advance1frameBM_Callback(app, event)
            if app.currentFrame == app.parentApp.metadata.frames
                app.currentFrame = 1;
            else
                app.currentFrame = app.currentFrame + 1;
            end
            
            app.parentApp.currentFrameTxt.Text = ['Current frame: ' num2str(app.currentFrame)];
            app.currentFrameTxt.Text = ['Current frame: ' num2str(app.currentFrame)];
            axesexplorer(app);
            multiPlot(app,event)
        end

        % Callback function
        function applyBinMask_pb_Callback(app, event)
            % October 2020
            % Code revision. GMR.
            % This function multiplies the binary data with the DF/F
            % process. In this way the F fluctuation is exctracted only in
            % the selected voxels
            
            % well, no idea what this code does. Not very interesting.
            
            app.BinRoiDF(:,:,1,:) = app.parentApp.erodedBinaryImages(:,:,1,:) .* app.parentApp.dataOut.deltaF(:,:,1,:);
            
            deltaF0 = squeeze(app.parentApp.deltaF);
            for col = 1 : size(app.parentApp.deltaF,2)
                for row = 1 : size(app.parentApp.deltaF,1)
                    thisZVector = deltaF0(row, col, :);
                    maxValue = max(thisZVector);
                    app.maxproj(row, col) = maxValue;
                end
            end
            
            % compute the limits of the image range
            app.BinRoiMin = 0;
            app.BinRoiMax = max(max(max(squeeze(app.BinRoiDF(:,:,1,:)))));
            app.meanBinRoi = (app.BinRoiMin + app.BinRoiMax)/2;
            
            
            app.bmdfflag=1;
            axesexplorer(app);
            
        end

        % Button pushed function: applyclust
        function applyClust_Callback(app, event)
            % This function prepares all the parameters necessary for the
            % data segmentation. Nothing amazing to be seen here
            
            app.applyclust.BackgroundColor = [1, 0, 0];
            drawnow
            switch app.connectivityBtGroup.SelectedObject.Text
                case '6 degree'
                    app.connectivity=6;
                case '18 degree'
                    app.connectivity=18;
                case '26 degree'
                    app.connectivity=26;
            end
            
            app.minpts = str2double(app.minptsdb.Value);
            app.maxpts = str2double(app.maxptsdb.Value);
            
            % Select the data source
            switch app.SourceBtGroup.SelectedObject.Text
                case 'Raw'
                    binSource = app.parentApp.binaryThrImages(:,:,1,:);
                case 'Filtered'
                    binSource = app.parentApp.erodedBinaryImages(:,:,1,:);
            end
            binSource = squeeze(binSource);
            sz = size(binSource);
            
            % March 14. Rocco. Debug Grid
            %
            % This button was added just for curiosity, and its output is
            % NOT physiologically relevant (noise). Although, the results might be
            % interesting for debug purposes.
            %
            % The idea was to substitute the original 3d binary data with a
            % grid of same-sized square domains that cover indiscriminately
            % the entire stack. In other words, I wanted to take into account
            % the quasi-whole image ('quasi' because of the 1 px padding between
            % the domains), bin by bin.
            % Will highly crosscorrelated bins show up, despite the lack of any previous
            % filtering? This analysis also can be applied to raw microscopy data
            % that has not been preprocessed with ImageJ (3DMean etc.).
            %
            % Of course, to perform the subsequent analysis, you will need to
            % select the 'discarded events' option
            % before of the computation of the clusters.
            %
            % The quickier way to do this is by creating a ones() 3d matrix
            % and then adding the zeros at the proper rows and columns.
            if app.debugGridCB.Value
                
                sz = size(binSource);
                
                % User choice
                prompt = {'Enter bin size:','Enter padding:'};
                dlgtitle = 'Grid';
                dims = [1 35];
                definput = {'20','0'};
                answer = inputdlg(prompt,dlgtitle,dims,definput);
                binSide = str2double(answer(1)); % Size of square bin edge
                padding = str2double(answer(2)); % Distance between square bins
                
                if padding == 0
                    
                    % If no padding is chosen (best option for small bin size), first create
                    % a binary checkerboard with
                    % given square size, and then crop it to adapt it to the x/y length.
                    % Assign it to the first frame.
                    % Then, create a second checkerboard, complementary to the first one.
                    % Assign it to a non-contiguous frame, e.g. frame 3,
                    % in order to not merge the domains.
                    %
                    % Create checkerboard made of (200*binSide)^2 square bins with edge
                    % equal to binSide.
                    tmpCb = checkerboard(binSide, 200,200);
                    tmpCb( tmpCb > 0.5) = 1; % Some are 0.7
                    % Crop checkerboard to adapt to x y dimension
                    l = sz(1);
                    croppedCb = zeros(l);
                    croppedCb(1:l,1:l) = tmpCb(1:l,1:l);
                    
                    % Assign cropped checkerboard to the first frame of
                    % gridSquareDomains...
                    gridSquareDomains = zeros(sz);
                    gridSquareDomains(:,:,1) = croppedCb;
                    % ...Assign complementary checkerboard to a non-contiguous
                    % frame, e.g. frame 3.
                    gridSquareDomains(:,:,3) = 1 - croppedCb;
                    % In this way, all the xy plane is filled by square
                    % domains (rectangular near the borders).
                    % Force connectivity to 6 to keep all domains
                    % topologically independent.
                    app.connectivity=6;
                    
                else % If padding > 0
                    % Different method: no checkerboard.
                    totSide = binSide + padding;
                    
                    gridSquareDomains = ones(sz);
                    % The square domains are retained constantly all along the
                    % time dimension. Although, this thing is
                    % not actually necessary, since we are interested in the not
                    % filtered, 'all' source.
                    
                    % Shape squares of ones by crossing horizontal and
                    % vertical lines of zeros.
                    for i = totSide:totSide:sz
                        gridSquareDomains(((i-padding):i),:,:) = 0;
                        gridSquareDomains(:,((i-padding):i),:) = 0;
                    end
                    
                end
                
                % Finally, replace original binSource with the new binary grid.
                binSource = gridSquareDomains;
                % April, 2021. A new 'draw' function has been added to
                % Imagegateway in order to adapt to Padua analyses. If the
                % input by ImageGateway is a ROI, then multiply the Grid by
                % the mask.
                if ~isempty(app.parentApp.CROIEditorMask)
                    tmp = app.parentApp.CROIEditorMask;
                    tmp(isnan(app.parentApp.CROIEditorMask)) = 0;
                    binSource = binSource .* tmp;
                end
                
            end
            
            % 20 March, Draw function! Create binary mask from drawn ROIs.
            % CROIEditor.m was a quick option which I found online.
            % April 2021. This function has been added also to
            % Imagegateway, just for Padua analyses.
            if app.debugDrawCB.Value
                sz = size(binSource);
                backgroundMedian = uint8(app.parentApp.maxProjDF.^2);
                tmpDraw = CROIEditor(backgroundMedian); % Launch CROIEditor.m
                % Wait for ROI to be assigned...
                waitfor(tmpDraw,'roi');
                if ~isvalid(tmpDraw)
                    return
                end
                % Get logical mask of drawn polygons
                drawMask = zeros(sz);
                [drawMask(:,:,1), ~, ~] = tmpDraw.getROIData;
                delete(tmpDraw);
                
                % Assign drawn objects with two different ways. If no grid
                % was selected, then,
                % simply, use the ROIs...
                % ...otherwise, multiply the drawn ROIs by the grid domains!
                if ~app.debugGridCB.Value
                    binSource = drawMask; % Replace original binSource
                else
                    binSource = binSource .* drawMask(:,:,1); % Make sure to multiply by the actual mask,
                    % which was put at the first frame.
                end
            end
            
            % 21 March, AB-Normal mask function!
            % Use it alongside grid option to generate quasi-quantized
            % highly crosscorrelated domains
            if app.debugABNormalCB.Value && app.debugGridCB.Value && ~ app.debugDrawCB.Value
                if ~isempty(app.parentApp.ABmask)
                    ABtmp = zeros(sz);
                    ABtmp(:,:,1) = app.parentApp.ABmask;
                    binSource = binSource .* ABtmp(:,:,1);
                end
            end
            
            % app.Xscan contains the labelled 3D domains, nclust will be the
            % cluster number.
            
            app.CCevents = bwconncomp(binSource,app.connectivity);
            'Computed connected domains'
            app.CCeventsFlt = app.CCevents;              % This object will contain only the filtered events
            % Xscan will contain all the identified clusters with their own label
            % XscanFlt will contain all the filtered clusters with their own label
            app.Xscan = zeros(sz,'uint16');
            app.XscanFlt = zeros(sz,'uint16');
            
            app.nEventsFlt = 0;          % This will be the number of events filtered by volume
            
            app.nEvents = size(app.CCevents.PixelIdxList,2);   % Number of identified events before filtering out cluster outside
            % of the selected limits
            labelFlt = uint16(0);                              % Running counter on the selected clusters
            excludedCnt = 0;
            excludedI = zeros(app.nEvents,1);             % This array contains the indexes of the events that does
            % pass the size filter
            'Begin Volume filter'
            for i=1:app.nEvents                           % loop on all clusters
                tmp = app.CCevents.PixelIdxList{1,i};     % Linear indexes of the cluster voxels
                ntmp = size(tmp,1);                       % Number of voxels in the cluster
                app.Xscan(tmp) = i;                       % All clusters, independently on size
                
                % Rejects all clusters smaller or larger than the given size limits (see app GUI)
                if ntmp > app.minpts && ntmp < app.maxpts
                    % the cluster is ok
                    labelFlt = labelFlt+1;      % The cluster counter is used as an identifying label
                    app.XscanFlt(tmp) = labelFlt;
                else
                    % the cluster is rejected and it will be removed from
                    % the cluster table
                    excludedCnt = excludedCnt + 1;
                    excludedI(excludedCnt) = i;
                end
            end
            % Now clean up the filtered events from app.CCeventsFlt
            app.nEventsFlt = labelFlt;
            excludedI(excludedI==0) = [];       % Clean empty values
            app.CCeventsFlt.NumObjects = app.nEventsFlt;
            for rw=excludedCnt:-1:1
                % Remove all excluded cell arrays
                app.CCeventsFlt.PixelIdxList(excludedI(rw)) = [];
                % Please notice that: a) the argument within round parentheses in a cell array
                % refers to the cell itself and not the data within
                % b) cells are removed from the bottom in order to preserve the pointers in excludedI
            end
            'End of volume filter'
            app.Xscanson = app.XscanFlt > 0;       % logical array of the voxels that passed the cluster size test
            app.nEventsTxt.Text = sprintf('Total events: %d', app.nEvents);
            app.nEventsFltTxt.Text = sprintf('Filtered events: %d', app.nEventsFlt);
            % Generate the colour map
            tmpMap = hsv(double(app.nEvents));
            tmpMap = tmpMap(randperm(size(tmpMap, 1)), :);
            black = [0 0 0];
            k = 0;
            app.mymap = [tmpMap(1:k,:); black; tmpMap(k+1:end,:)];
            app.actualframe = app.currentFrame;
            app.clusterflag = 1;        % Events have been identified
            
            axesexplorer(app);
            app.applyclust.BackgroundColor = [0.94,0.94,0.94];
            drawnow
        end

        % Button pushed function: back1frameBM
        function back1frameBM_Callback(app, event)
            if app.currentFrame>1
                app.currentFrame = app.currentFrame - 1;
            end
            
            app.parentApp.currentFrameTxt.Text = ['Current frame: ' num2str(app.currentFrame)];
            app.currentFrameTxt.Text = ['Current frame: ' num2str(app.currentFrame)];
            axesexplorer(app)
            multiPlot(app,event)
        end

        % Callback function
        function backwardframe_Callback(app, event)
            % hObject    handle to backwardframe (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            back1frameBM_Callback(app, event);
        end

        % Button pushed function: displaythreed
        function displaythreed_Callback(app, event)
            % hObject    handle to displaythreed (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if get(app.BMdFflag,'Value')
                workingvolume=app.BinRoiDF(:,:,1,:);  % app.parentHandles.erodedBinaryImages
                workingvolume=squeeze(workingvolume);
                mymapvol=(hot(256));
            end
            if get(app.clustflag,'Value')
                workingvolume=app.Xscan;
                mymapvol=hsv(256);
                mymapvol = mymapvol(randperm(size(mymapvol, 1)),:);
            end
            figure;
            volshow(workingvolume,'Colormap',mymapvol,'BackgroundColor',[0 0 0]);
        end

        % Callback function
        function exportvideo_Callback(app, event)
            % Save the clustered binary array
            imName = [app.parentApp.dirIn 'binClusters.tif'];
            imwrite(app.Xscan,colorMap,imName)
        end

        % Callback function
        function forwardframe_Callback(app, event)
            % hObject    handle to forwardframe (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            advance1frameBM_Callback(app,event);
        end

        % Button pushed function: extractFeatures
        function extractFeatures_Callback(app, event)
            % October 2020
            % This code extracts all geometrical features of the events
            app.extractFeatures.BackgroundColor =[1,0,0];
            drawnow
            DF = squeeze(app.parentApp.dataOut.deltaF);           % deltaF / F
            switch app.selectSource4FeaturesBT.SelectedObject.Text
                case 'All events'
                    % Extract features of all events ignoring volume limits
                    tmpTable = regionprops3(app.CCevents, DF,'Volume','Centroid',"BoundingBox",...
                        'VoxelList','SurfaceArea',"VoxelValues","MeanIntensity");
                    % The previous line extracts the features from ALL events, regardless of their size.
                    % Properties are in the order that appears on the statement list
                    nevnts = app.nEvents;
                case 'Filtered'
                    % Extract only the events within the size limits
                    tmpTable = regionprops3(app.CCeventsFlt, DF,'Volume','Centroid',"BoundingBox",...
                        'VoxelList','SurfaceArea',"VoxelValues","MeanIntensity");
                    nevnts = app.nEventsFlt;
            end
            
            % create additional cell arrays for additional infos
            footprint = cell(nevnts,1);         % Projected boundary of each event
            app.eventFeatures = addvars(tmpTable,footprint,'NewVariableNames','Footprint');
            %            maxSurface = cell(nevnts,1);         % Projected boundary of each event
            maxSurface = zeros(nevnts,1);         % Projected boundary of each event
            app.eventFeatures = addvars(app.eventFeatures,maxSurface,'NewVariableNames','xyArea');
            durationF = zeros(nevnts,1);         % Duration of each event
            app.eventFeatures = addvars(app.eventFeatures,durationF,'NewVariableNames','Duration');
            clusterId = zeros(nevnts,1);         % Cluster  id (to be defined during the analysis of the event clusters)
            app.eventFeatures = addvars(app.eventFeatures,clusterId,'NewVariableNames','ClusterId');
            
            % 'footprint' contains the array describing the bidimensional boundary of the event.
            % 'maxSurface' surface delimited by the footprint. This
            %       corresponds to the max projection of the event
            % 'durationF' duration of the events in frames
            % 'clusterId' is a pointer to the table CCregion that indicates
            %   the cluster to which belongs the event. It is set to 0 if the
            %   event does not belong to any cluster
            
            hold(app.multiDisplay, 'on' )
            imagesc(app.multiDisplay,app.parentApp.maxProjDF);
            set(app.multiDisplay,'Colormap',gray);
            set(app.multiDisplay,'XTick',[]);
            set(app.multiDisplay,'YTick',[]);
            set(app.multiDisplay,'XLim',[0 size(DF,1)]);
            set(app.multiDisplay,'YLim',[0 size(DF,2)]);
            %set(app.axes13, 'YDir', 'reverse');
            
            boundaryFixCounter = 0; % Made for debug reasons. It will be displayed
            
            for i=1:nevnts
                % The next line computes the footprint of the event. The footprint
                % is the projection of the entire event xyt stack on the xy plane
                
                uniquexypos = unique(app.eventFeatures.VoxelList{i}(:,1:2),'rows');
                
                % March 11, 2021. RG
                % Trying to add a 'scramble' option that rotates the
                % absolute position of the boundaries -- keeping intact the
                % relative distance and their shape. For the moment, try to
                % rotate them of -90°.
                % If scrambled clusters are not going to give rise to any
                % significant positive CC, this means that the filtering
                % system works...
                for j= 1:app.ScrambleEventsRotateSP.Value % 1 = -90°; 2 = -180°; 3 = -270°
                    if app.ScrambleEventsRotateCB.Value
                        tmpY = uniquexypos(:,2);
                        l = length(app.parentApp.erodedBinaryImages(:,:,1,1));
                        uniquexypos(:,2) = l - uniquexypos(:,1); % Define rotated Y data
                        uniquexypos(:,1) = tmpY; % Define rotated X data
                    end
                end
                % Results:
                % Unfortunately, positive crosscorrelation
                % asymmetry is retained (and, strangely, even enhanced!)
                % among these randomic regions. No matter the degree of the rotation.
                % But! What is strange is that in the CC / Distance scatter
                % plot you loose the left vertical tail which indicates
                % high CC among short-distanced events. This is very
                % interesting!
                % Another difference is that the mean amplitude of the
                % temporal series is strongly decreased after scrambling.
                
                % Compute relative boundaries. Used later as row indices
                bndArray = boundary(uniquexypos);   % These are the indexes of the elements of uniquexpos
                
                % *****
                % IMPORTANT FIX - March 3, 2021. RG
                % An important bug was found here, which is
                % related to the behaviour of linear footprints.
                % In fact, both boundary() and polygon(), independenlty, are
                % known to return null values whether you give them straight lines.
                % By the way, in this specific case, we know that we
                % have to act on this boundary() step, because it is
                % upstream to the generation of the polygon.
                %
                % This fix is important because if you don't represent these footprints,
                % cluster extraction will give rise to a number of empty clusters,
                % depending on the settings.
                %
                % I decided to fix this problem by building up manually the bounding
                % box around the linear footprint. To make an example,
                % this means that whether uniquexypos is e.g. a
                % straight horizontal line with y = 10 everywhere, I will create a
                % rectangle the ordinate of which is included between y = 9 and y = 11.
                % In a second moment, polyshape will return a linear-shape which has an
                % height of 1.
                % As regards x, I will just take into account the important
                % information, which of course are the minimum and maximum
                % values, i.e. the extremites of the line of this horizontal lines.
                % I just want to make a rectangle, so only 4 vertices shall
                % be needed.
                if isempty(bndArray) && length(uniquexypos(:,1)) ~= 1 % Exclude single pixels
                    %% #1 Find voxel values information
                    myline = uniquexypos; % Tmp
                    repColInd = 0; % Preallocate index of repetition column
                    for colInd = 1:2 % X:Y columns
                        % Of course, straight lines have one coordinate
                        % vector that is made up only of the same repeated
                        % value.
                        % If column is made of repetitions of same element...
                        if length(unique(myline(:,colInd))) == 1
                            repEl = unique(myline(:,colInd)); % ...Get repetead element
                            repColInd = colInd; % ...Get if it's X (1) or Y (2)
                        else
                            otherCol = myline(:,colInd); % Get other coordinate
                            otherColInd = colInd; % Get if other coordinate is X or Y
                        end
                    end
                    
                    % Temporary: diagonal square bug... occurs when
                    % binarization is not space-filtered. Gonna fix it.
                    if repColInd == 0 || otherColInd == 0
                        disp('prob')
                        break
                    end
                    
                    %% #2 Generate manually new bounding box which enwraps the straight line
                    % Find min and max of vector which is not repeated, i.e. the extremes of the straight line
                    m = min(otherCol); M = max(otherCol);
                    % Create the bounding box manually
                    % Length of the line
                    a = [m, m, M, M];
                    b = [repEl-1, repEl+1 ,repEl-1 ,repEl+1]; % Height of the final footprint will be 1...
                    % Assign correctly X and Y
                    straightLineBB = zeros(4,2); % Preallocate new bounding box...
                    straightLineBB(:,repColInd) = b;
                    straightLineBB(:,otherColInd) = a;
                    %% # 3 Refresh output
                    uniquexypos = straightLineBB;
                    bndArray = boundary(uniquexypos);
                    % Counter
                    boundaryFixCounter = boundaryFixCounter + 1;
                end
                % / IMPORTANT FIX - March 3, 2021. RG
                % *****
                
                % forming the border of the event projection
                app.eventFeatures.Footprint{i}(:,:) = uniquexypos(bndArray,:);
                app.eventFeatures.xyArea(i) = length(uniquexypos);       % Surface area of the event
                app.eventFeatures.Duration(i) = app.eventFeatures.BoundingBox(i,6);      % duration of the event
                plot(app.multiDisplay,app.eventFeatures.Footprint{i}(:,1),app.eventFeatures.Footprint{i}(:,2));
            end
            
            % Display counter of fixed boundaries
            disp('Domains the boundaries of which were fixed: ')
            disp(boundaryFixCounter)
            
            % flip the Y axes
            set(app.multiDisplay,'Ydir','reverse');
            hold(app.multiDisplay,'off');
            
            % Compute feature statistics
            app.eventStats.minVolume = min(app.eventFeatures.Volume);
            app.eventStats.maxVolume = max(app.eventFeatures.Volume);
            % Initialize parameters for computation of distributions
            app.plotsOut.volume.fromDistr = app.eventStats.minVolume;
            app.plotsOut.volume.toDistr = app.eventStats.maxVolume;
            app.plotsOut.volume.bins = 40;
            app.fromDistr.Value = app.plotsOut.volume.fromDistr;
            app.toDistr.Value = app.plotsOut.volume.toDistr;
            app.binDistr.Value = app.plotsOut.volume.bins;
            
            app.eventStats.minSurface = min(app.eventFeatures.xyArea);
            app.eventStats.maxSurface = max(app.eventFeatures.xyArea);
            app.plotsOut.surface.fromDistr = app.eventStats.minSurface;
            app.plotsOut.surface.toDistr = app.eventStats.maxSurface;
            app.plotsOut.surface.bins = 40;
            
            app.eventStats.minDurationF = min(app.eventFeatures.Duration);
            app.eventStats.maxDurationF = max(app.eventFeatures.Duration);
            app.plotsOut.duration.fromDistr = app.eventStats.minDurationF;
            app.plotsOut.duration.toDistr = app.eventStats.maxDurationF;
            app.plotsOut.duration.bins = 40;
            
            app.eventStats.minDeltaF = min(app.eventFeatures.MeanIntensity);
            app.eventStats.maxDeltaF = max(app.eventFeatures.MeanIntensity);
            app.plotsOut.deltaF.fromDistr = app.eventStats.minDeltaF;
            app.plotsOut.deltaF.toDistr = app.eventStats.maxDeltaF;
            app.plotsOut.deltaF.bins = 40;
            
            app.minMaxVolumeTxt.Text = sprintf('Min Value: %.0f  Max: %1.0f', ...
                app.eventStats.minVolume,app.eventStats.maxVolume);
            app.minMaxSurfaceTxt.Text = sprintf('Min Value: %.0f  Max: %1.0f', ...
                app.eventStats.minSurface,app.eventStats.maxSurface);
            app.minMaxDurationTxt.Text = sprintf('Min Value: %.0f  Max: %1.0f', ...
                app.eventStats.minDurationF,app.eventStats.maxDurationF);
            app.minMaxDeltaF.Text = sprintf('Min Value: %.2f  Max: %1.2f', ...
                app.eventStats.minDeltaF,app.eventStats.maxDeltaF);
            app.extractFeatures.BackgroundColor =[0.94,0.94,0.94];
            drawnow
            
            app.clusterAxis.Toolbar.Visible = 'off';
            app.clusterAxis.Toolbar.Visible = 'on';
            
        end

        % Callback function: plotdistr, xLogScale
        function computeDistributions(app, event)
            % November 11, 2020. GMR
            % Computation of distributions of some event features
            
            switch app.selectDistr.SelectedObject.Text
                case 'Volume'
                    tmp = app.eventFeatures.Volume;
                    left = app.fromDistr.Value;
                    right = app.toDistr.Value;
                    bins = app.binDistr.Value;
                case 'Surface'
                    tmp = app.eventFeatures.xyArea;
                    left = app.fromDistr.Value;
                    right = app.toDistr.Value;
                    bins = app.binDistr.Value;
                case 'Duration'
                    tmp = app.eventFeatures.Duration;
                    left = app.fromDistr.Value;
                    right = app.toDistr.Value;
                    bins = app.binDistr.Value;
                case 'DeltaF/F'
                    tmp = app.eventFeatures.MeanIntensity;
                    left = app.fromDistr.Value;
                    right = app.toDistr.Value;
                    bins = app.binDistr.Value;
                case 'N Events'
                    tmp = app.CCregions.eventN;
                    % Now add all single events
                    tmp1 = ones(app.nEvents-app.nEvInClusters,1);
                    tmp = [tmp' tmp1'];
                    left = 1;
                    right = max(tmp);
                    bins = right;
                case 'Area '
                    tmp = app.CCregions.areaU;
                    left = 1;
                    right = max(tmp);
                    bins = right;
                case 'CC value'
                    tmp = abs(reshape(app.CC.CCmatrix,[],1));
                    tmp = tmp(tmp>0);
                    left = 1;
                    right = max(tmp);
                    bins = 20;
                case 'Distance'
                    % Distance added June 27, 2021! RG
                    % Soma coordinates have been picked in defineSoma
                    if ~isempty(app.xSoma)
                        tmp = app.CCregions.somaDistance;
                        left = 1;
                        right = max(tmp);
                        bins = right;
                    end
                    
            end
            
            if app.xLogScale.Value
                % compute the distribution of the Log10 of the feature
                tmp = log10(tmp);
                left = log10(left);
                right = log10(right);
                if left<=0
                    left = 0.01;
                end
            end
            
            % Generate the bin limits
            edges = linspace (left,right,bins+1);
            centers = (edges(2:bins+1)+edges(1:bins))/2;
            tmpCounts = histcounts(tmp,edges,'Normalization','probability');
            bar(app.distributions,centers,tmpCounts);
            axis(app.distributions,[left right -inf inf])
            
            % finally check whether we should apply a log scale
            if app.yLogScale.Value, set(app.distributions, 'YScale', 'log')
            else
                set(app.distributions, 'YScale', 'lin')
            end
        end

        % Value changed function: XdeltaFCB, XdurationCB, Xlog10, 
        % ...and 7 other components
        function plotScatter(app, event)
            % Plot scatter plots. No check is performed on unicity of selections
            % This means that you can select 2 different choices for a
            % plotted variable and only one will be taken into account
            
            % determine the feature to be plotted on the X axis
            if app.XvolumeCB.Value
                Xtmp = app.eventFeatures.Volume;
                Xlbl = 'Volume';
            end
            if app.XsurfaceCB.Value
                Xtmp = app.eventFeatures.xyArea;
                Xlbl = 'Surface';
            end
            if app.XdurationCB.Value
                Xtmp = app.eventFeatures.Duration;
                Xlbl = 'Duration';
            end
            if app.XdeltaFCB.Value
                Xtmp = app.eventFeatures.MeanIntensity;
                Xlbl = 'DeltaF/F';
            end
            % determine the feature to be plotted on the Y axis
            if app.YvolumeCB.Value
                Ytmp = app.eventFeatures.Volume;
                Ylbl = 'Volume';
            end
            if app.YsurfaceCB.Value
                Ytmp = app.eventFeatures.xyArea;
                Ylbl = 'Surface';
            end
            if app.YdurationCB.Value
                Ytmp = app.eventFeatures.Duration;
                Ylbl = 'Duration';
            end
            if app.YdeltaFCB.Value
                Ytmp = app.eventFeatures.MeanIntensity;
                Ylbl = 'DeltaF/F';
            end
            if app.Xlog10.Value
                Xtmp = log10(Xtmp);
            end
            if app.Ylog10.Value
                Ytmp = log10(Ytmp);
            end
            if exist('Xtmp',"var") && exist('Ytmp',"var")
                scatter(app.featureViewer,Xtmp,Ytmp)
            end
        end

        % Callback function
        function carmeanvalue(app, event)
            value = app.XdeltaFCB.Value;
            
        end

        % Callback function
        function carduration(app, event)
            value = app.XdurationCB.Value;
            
        end

        % Callback function
        function carsurface(app, event)
            value = app.XsurfaceCB.Value;
            
        end

        % Button pushed function: ExportFeaturesButton
        function exportfeaturetab(app, event)
            % December 2012, GMR.
            % Save features and distributions
            %             fileOut = [app.parentApp.dataPath 'eventFeatures.mat'];
            %             tmp = app.eventFeatures;            % That is the way it is. Cryptic note, Dec 2020.
            %             save(fileOut,'tmp');
            
            % June 2021, RG
            % Export event features with filename
            EF = app.eventFeatures;
            str = string(app.parentApp.fileIn);
            str = strrep(str,'.tif','');
            str = append('EventFeatures_',str,'.mat');
            str = append('EventsAndClusters\',str); % Save .txt in dedicated folder
            save(str,'EF');
            app.ExportFeaturesButton.BackgroundColor = [0.99,0.92,0.80];
        end

        % Button pushed function: ExportButton
        function exportcluster2(app, event)
            AVI3dclust = VideoWriter ('3dclust.avi','Uncompressed AVI');
            open(AVI3dclust);
            %             hFig = figure('units','pixels','position',[200 200 size(app.parentApp.dataOut.workImages,1)...
            %                 size(app.parentApp.dataOut.workImages,2) ]); %size(app.Xscan,1) size(app.Xscan,2)
            hFig = figure('units','pixels','position',[200 200 512 512]);
            hMovie = axes('units','pixels','position',[0 0 512 512],'Visible','off'); %size(app.Xscan,1) size(app.Xscan,2)
            set(hMovie, 'XTick', [], 'YTick', []);
            axes(hMovie);
            %             mymap = hsv(app.nEventsFlt);
            %             mymap = mymap(randperm(size(mymap, 1)), :);
            black=[0 0 0];
            k = 0;
            %            mymap = [mymap(1:k,:); black; mymap(k+1:end,:)];
            for  cf=1:size(app.Xscan,3)
                tmp=app.Xscan(:,:,cf);
                imagesc(tmp,[0 app.nEventsFlt+1]);
                colormap(app.mymap);
                set(gca, 'Visible','off');
                frame =(app.Xscan(:,:,cf));
                [row, column] = find(frame);
                refresh;
                im = frame2im(getframe(hFig));
                writeVideo(AVI3dclust,im);
            end
            display('end of video export');
            close(AVI3dclust);
        end

        % Button pushed function: backToTheBeginning
        function backToStart(app, event)
            app.currentFrame = 1;
            app.parentApp.currentFrameTxt.Text = ['Current frame: ' num2str(app.currentFrame)];
            app.currentFrameTxt.Text = ['Current frame: ' num2str(app.currentFrame)];
            axesexplorer(app)
            multiPlot(app,event)
        end

        % Button pushed function: untilTheEnd
        function advanceToTheEnd(app, event)
            app.currentFrame = app.parentApp.metadata.frames;
            app.parentApp.currentFrameTxt.Text = ['Current frame: ' num2str(app.currentFrame)];
            app.currentFrameTxt.Text = ['Current frame: ' num2str(app.currentFrame)];
            axesexplorer(app)
            multiPlot(app,event)
        end

        % Selection changed function: displaySelect
        function redoDisplay(app, event)
            axesexplorer(app)
            multiPlot(app,event)
        end

        % Selection changed function: selectDistr
        function selectFeatureDistribution(app, event)
            selectedButton = app.selectDistr.SelectedObject;
            switch selectedButton.Text
                case 'Volume'
                    app.fromDistr.Value = app.plotsOut.volume.fromDistr;
                    app.toDistr.Value = app.plotsOut.volume.toDistr;
                    app.binDistr.Value = app.plotsOut.volume.bins;
                    
                case 'Surface'
                    app.fromDistr.Value = app.plotsOut.surface.fromDistr;
                    app.toDistr.Value = app.plotsOut.surface.toDistr;
                    app.binDistr.Value = app.plotsOut.surface.bins;
                    
                case 'Duration'
                    app.fromDistr.Value = app.plotsOut.duration.fromDistr;
                    app.toDistr.Value = app.plotsOut.duration.toDistr;
                    app.binDistr.Value = app.plotsOut.duration.bins;
                    
                case 'DeltaF/F'
                    app.fromDistr.Value = app.plotsOut.deltaF.fromDistr;
                    app.toDistr.Value = app.plotsOut.deltaF.toDistr;
                    app.binDistr.Value = app.plotsOut.deltaF.bins;
                    
            end
        end

        % Value changed function: fromDistr
        function changeFrom(app, event)
            value = app.fromDistr.Value;
            switch app.selectDistr.SelectedObject.Text
                case 'Volume'
                    app.plotsOut.volume.fromDistr = value;
                case 'Surface'
                    app.plotsOut.surface.fromDistr = value;
                case 'Duration'
                    app.plotsOut.duration.fromDistr = value;
                case 'DeltaF/F'
                    app.plotsOut.deltaF.fromDistr = value;
            end
            
        end

        % Value changed function: toDistr
        function changeTo(app, event)
            value = app.toDistr.Value;
            switch app.selectDistr.SelectedObject.Text
                case 'Volume'
                    app.plotsOut.volume.toDistr = value;
                case 'Surface'
                    app.plotsOut.surface.toDistr = value;
                case 'Duration'
                    app.plotsOut.duration.toDistr = value;
                case 'DeltaF/F'
                    app.plotsOut.deltaF.toDistr = value;
            end
            
        end

        % Value changed function: binDistr
        function changeBin(app, event)
            value = app.binDistr.Value;
            switch app.selectDistr.SelectedObject.Text
                case 'Volume'
                    app.plotsOut.volume.bins = value;
                case 'Surface'
                    app.plotsOut.surface.bins = value;
                case 'Duration'
                    app.plotsOut.duration.bins = value;
                case 'DeltaF/F'
                    app.plotsOut.deltaF.bins = value;
            end
            
        end

        % Value changed function: yLogScale
        function distYaxesLog(app, event)
            % Check whether we should apply a log scale
            if app.yLogScale.Value, set(app.distributions, 'YScale', 'log')
            else
                set(app.distributions, 'YScale', 'lin')
            end
        end

        % Button pushed function: back20frames
        function back20F(app, event)
            if app.currentFrame > 20
                app.currentFrame = app.currentFrame - 20;
            end
            
            app.currentFrameTxt.Text = ['Current frame: ' num2str(app.currentFrame)];
            axesexplorer(app)
            multiPlot(app,event)
        end

        % Button pushed function: advace20frames
        function advance20F(app, event)
            if app.currentFrame > app.parentApp.metadata.frames-20
                app.currentFrame = 1;
            else
                app.currentFrame = app.currentFrame + 20;
            end
            app.currentFrameTxt.Text = ['Current frame: ' num2str(app.currentFrame)];
            axesexplorer(app);
            multiPlot(app,event)
        end

        % Button pushed function: SpCorrelationBt
        function computeSpatialCorrelation(app, event)
            % November 2020 GMR
            % Create clusters of events that share common spatial
            % caracteristics
            
            app.SpCorrelationBt.BackgroundColor = [1, 0, 0];
            drawnow
            
            % * Jun 27, 2021! RG
            % Added this line here to sort the events by descending area.
            % This should help in finding better clusters.
            % In fact, by taking into account the larger events first, you
            % (i) make things faster; (ii) avoid splitting clusters
            % whenever using small SI threshold
            app.eventFeatures = sortrows(app.eventFeatures,9,'ascend');
            % *
            
            % First, decide the data source: filtered or the entire space
            % of events
            % create temporary arrays of the required data
            tmpRadiusSq = (app.eventFeatures.xyArea*app.RhoMultiplierEF.Value)/pi;
            nev = length(tmpRadiusSq);
            tmpCentroid = app.eventFeatures.Centroid;       % tmpCentroid is an array
            tmpXY = tmpCentroid(:,1:2);                     % extract the X,Y centroid of each event
            tmpFootprint = app.eventFeatures.Footprint;
            tmpSurface = app.eventFeatures.xyArea;
            
            % Initialize the ClusterId array
            app.eventFeatures.ClusterId = zeros(nev,1);
            
            % Description of the app.CCregion table
            
            % app.CCregions is a table that contains variables describing all the
            % clustered events. One row for each cluster of events
            % The variables of CCregions are:
            % 'eventList'     Array of the indexes of the events belonging to the cluster as described
            %       in eventFeatures table.
            % 'eventN'        Number of events in the cluster
            % 'eventSI'     Similarity index of all events
            % 'clusterPolyU'  polyshape of the union of all the events
            % 'clusterPolyI'  polyshape of the intersection of all the events
            % 'centroidU'     centroids of union and intersection of each clusters
            % 'centroidI'
            % 'deltaFI'       deltaF/F computed on the intersections of all event boundaries
            % 'deltaFU'       deltaF/F computed on the union of all event boundaries
            %       Here are included only the DeltaF data of the events in the cluster
            % 'deltaFIall'    deltaF/F computed on the entire series on the boundary intersections
            % 'deltaFUall'    deltaF/F computed on on the entire series on the boundary unions
            %
            % 'timeLine'      time series that contain the index of all events
            %       joned in the cluster. =0 outside of the events
            
            % Initialize the cell arrays
            % I will store all clusters info in cell arrays and the table
            % will be generated at the end from the individual cell arrays.
            eventList = {};         % Pointers to events (cell array)
            eventN = zeros(1,1);
            eventSI = {};
            
            % Now we have to create temporal series by clustering together
            % individual events of geometrical overlap.
            % The criteria is specified as follows: two events belong to
            % the same cluster if the intersection of their footprints is
            % better than a certain fraction of their union.
            
            % Very large events will invariably NOT belong to any cluster
            % since their ration between intersection and union will be
            % invariably small given their large footprint.
            % Simmetrically small isolated events that occurs very sparsely will
            % not belong to any cluster or will belong to scarcely populated
            % clusters.
            
            % The time series of each cluster is defined by joining
            % together the time series of each event with zeros to complete
            % the missing points. Alternatively we could take the fuzzy
            % intersection of all events forming the cluster... hmmm I like
            % this better.
            
            % Events are identified starting from the temporal beginning of
            % the stack. That means that, as the exploration of the stack
            % continues, it is more and more likely that events are already
            % assigned to a cluster. This should hopefully prevent that the
            % computation progresses as n^2 where n is the number of
            % events.
            
            runningClId = 0;
            imageArea = app.parentApp.WIcolumns * app.parentApp.WIrows;    % Needed for Normalization purpose
            
            for i=nev:-1:1
                % Check if the event already belongs to a cluster. Proceed
                % with starting a new cluster only if the event is not assigned.
                if app.eventFeatures.ClusterId(i)==0
                    % The event has not been assigned yet therefore it could
                    % generate a new event cluster, provided it finds companions.
                    
                    % Get the centroid of the generating event, and compute the size
                    % of the search of the similar events
                    cord = tmpXY(i,:);
                    rhoSq = tmpRadiusSq(i);
                    % Search for all events with a centroid that falls within a
                    % circle centred on cord and of radius rho
                    deltaCord = abs(cord-tmpXY);
                    distSq = deltaCord(:,1).^2+deltaCord(:,2).^2;
                    % Extract the indexes of all events closer than rho. The
                    % computation is done on the distance square because it is faster
                    iEvents = find(distSq<=rhoSq); %CCregions
                    
                    
                    % Now parse the the list to compute the similarity parameter for each event
                    nCl = length(iEvents);
                    motherFootprint = polyshape(cell2mat(tmpFootprint(i)),'Simplify',false);    % footprints are cell arraysmotherFootprint = polyshape(cell2mat(tmpFootprint(i)));    % footprints are cell arrays
                    %                     motherFootprint = polyshape(cell2mat(tmpFootprint(i)),'Simplify',false);
                    % keep in mind that nCl must be at least 1 since each
                    % event will generate a cluster containing itself.
                    if nCl > 1      % The cluster is not a singleton. Keep processing
                        nChildren = 0;
                        for j=1:nCl
                            iEvent = iEvents(j);
                            if iEvent~=i     % Do not process the mother
                                childFootprint = polyshape(cell2mat(tmpFootprint(iEvent)),'Simplify',false);
                                %                                 childFootprint = polyshape(cell2mat(tmpFootprint(iEvent)),'Simplify',false);
                                % The similarity index is computed as the ratio
                                % between the intersection of the two footprints
                                % and their union. This parameter is =1 when the
                                % two footprints are identical and it would be 0 if
                                % they are disjoint (which it cannot be true!).
                                polyInt = intersect(motherFootprint, childFootprint,'KeepCollinearPoints',true);
                                polyUn = union(motherFootprint, childFootprint,'KeepCollinearPoints',true);
                                % compute the similarity index
                                SI = area(polyInt)/area(polyUn);
                                % RG, July 2021.
                                % Sometimes SI is not sufficient. e.g.,
                                % when union is very big and child is too
                                % small. So, add to the computation two
                                % additional similarity indices, which take
                                % into account the area of mother only and
                                % child only at the denominator
                                SIm = area(polyInt)/area(motherFootprint);
                                SIc = area(polyInt)/area(childFootprint);
                                %  if SI>=app.SIthr.Value
                                if SI >= app.SIthr.Value || SIm >= app.SIthr.Value || SIc >= app.SIthr.Value % Same thing as SI seen before.
                                    % Eureka, we have a new child in the family
                                    nChildren = nChildren + 1;
                                    eventList{runningClId+1,1}(nChildren+1) = iEvent;
                                    eventSI{runningClId+1,1}(nChildren+1) = SI;
                                    eventN(runningClId+1,1) = nChildren+1;
                                    app.eventFeatures.ClusterId(iEvent) = runningClId+1;
                                end
                            end
                        end
                        if nChildren>0      % We have children in the cluster. Update the cluster counter
                            runningClId = runningClId + 1; % ;
                            % Add the mother event on top of the CCregions table
                            app.eventFeatures.ClusterId(i) = runningClId;
                            eventList{runningClId,1}(1) = i;
                            eventSI{runningClId,1}(1) = 1;
                        end
                    end
                end
            end
            app.nClusters = runningClId;
            
            % March, 3. This counter doesn't seem to match with the total
            % number of events...
            %             app.nEvInClusters = sum(eventN);
            % Edit it. Use find() to count in-cluster events.
            isClTmp = app.eventFeatures.ClusterId;
            app.nEvInClusters = length(find(isClTmp ~= 0));
            
            % March, 13. With some particular settings, no clusters are
            % found, and only isolated events are available. I would like
            % to investigate these particular cases, so I'm adding this
            % 'if'
            % statement, by which the clusters' table is not created -- it
            % will be directly replaced by the discarded events Table.
            if app.nClusters ~= 0 % If clusters are found
                app.clusterTxt.Text = sprintf('%g events in %g clusters',app.nEvInClusters,app.nClusters);
                %             app.showClusterSpinner.Limits = [0, app.nClusters];
                % Now we know how many rows to assign to the table app.CCregions
                % Process the clustered events by populating the app.CCregion table
                clusterPolyU = cell(app.nClusters,1);
                clusterPolyI = cell(app.nClusters,1);
                centroidU = zeros(app.nClusters,2);
                centroidI = zeros(app.nClusters,2);
                indPolyU = cell(app.nClusters,1);             % Linear indices of the points included in the polygon
                indPolyI = cell(app.nClusters,1);
                areaU = zeros(app.nClusters,1);
                areaI = zeros(app.nClusters,1);
                deltaFI = zeros(app.nClusters,app.parentApp.metadata.frames);
                deltaFU = deltaFI;
                deltaFIall = deltaFI;
                deltaFUall = deltaFI;
                % Jan 2021. This new column is used to sort cluster
                % visualisation
                % from the highest NN value to the lowest NN value.
                intCC = zeros(app.nClusters,1);
                % March 2021. This column stores the sum of all the
                % cross-correlations (integral) values associated to each
                % cluster.
                totCC = zeros(app.nClusters,1);
                
                timeLine = zeros(app.nClusters,app.parentApp.metadata.frames);
                app.CCregions = table(eventList,eventSI,eventN,clusterPolyU,clusterPolyI,centroidU,centroidI,...
                    indPolyU,indPolyI,areaU,areaI,deltaFI,deltaFU,deltaFIall,deltaFUall,timeLine,intCC,totCC,...
                    'VariableNames',{'eventList','eventSI','eventN','clusterPolyU','clusterPolyI',... % March 1. 'clustePolyU'->'clusterPolyU'
                    'centroidU','centroidI','indexesU','indexesI','areaU','areaI','deltaFI','deltaFU',...
                    'deltaFIall','deltaFUall','timeLine','intCC','totCC'});
                im = app.parentApp.dataOut.deltaF(:,:,1,1);     % Grab the first image of the stack for the mask creation
                
                for ClI = 1:app.nClusters
                    % Create the Df/F series by joining the mean Df/F of all events and
                    % padding to zero the intermediate, missing time points
                    % Compute the boundaries (polyshape objects) given by the union and
                    % intersections of the boundaries of all events composing the cluster.
                    motherEv = app.CCregions.eventList{ClI}(1);
                    %                 footp = polyshape(cell2mat(tmpFootprint(motherEv)));    % footprints are cell arrays            first
                    footp = polyshape(cell2mat(tmpFootprint(motherEv)),'Simplify',false);    % footprints are cell arrays            first
                    polyInt = footp;
                    polyUn = footp;
                    % Two passages are required. First: compute intersection
                    % and union of each event belonging to the cluster
                    for EvI = 1:app.CCregions.eventN(ClI)
                        % Loop on all events in the list
                        eventI = app.CCregions.eventList{ClI}(EvI);
                        %                     footp =  polyshape(cell2mat(tmpFootprint(eventI)));
                        footp =  polyshape(cell2mat(tmpFootprint(eventI)),'Simplify',false);
                        polyInt = intersect(polyInt, footp);    % Build up the boundaries of the events as the
                        polyUn = union(polyUn, footp, "KeepCollinearPoints",true);         % union and the intersection of all events
                    end
                    app.CCregions.clusterPolyI{ClI} = polyInt;
                    app.CCregions.clusterPolyU{ClI} = polyUn;
                    
                    % Rocco, Jan 16 2021
                    % 1.) Sometimes intersection is empty. I don't know why, but
                    % that's a very rare thing. One dirty
                    % solution is to make it equal to the union.
                    if isempty(polyInt.Vertices) % Sometimes intersection gives null. In this case, make equal to Un.
                        polyInt = polyUn;
                    end
                    
                    
                    % Rocco, Jan 17 2021
                    % Important bug is fixed here.
                    % Under some rare conditions, poly vertices end to be NaN.
                    if ~isempty(find(isnan(polyInt.Vertices), 1))
                        A = polyInt.Vertices;
                        %  a) You could delete the NaN coordinates in this way:
                        %                 A(~any(~isnan(A), 2),:)=[];
                        % But it's better to interpolate them via smooth(x,2)
                        % function. This function automatically removes the NaN and
                        % replace it with the interpolated value. It also works whenever the Nan
                        % is first or last element of the array.
                        %                     round(smooth(A(:,1),2)); % X coordinates % Round() is particularly necessary for 2xRegion polygons
                        %                     round(smooth(A(:,2),2)); % Y coordinates
                        A(~any(~isnan(A), 2),:)=[];
                        % Generate new polyshape which DOES NOT SIMPLIFY these new vertices
                        polyInt = polyshape(A,'Simplify',false);
                    end
                    % Do the same for Un
                    if ~isempty(find(isnan(polyUn.Vertices), 1))
                        A = polyUn.Vertices;
                        round(smooth(A(:,1),2)); % X coordinates
                        round(smooth(A(:,2),2)); % Y coordinates
                        A(~any(~isnan(A), 2),:)=[];
                        %Generate new polyshape which DOES NOT SIMPLIFY new vertices
                        polyUn = polyshape(A,'Simplify',false);
                    end
                    
                    app.CCregions.centroidU(ClI,1:2) = mean(polyUn.Vertices);   % I tried function centroid but it did only provide
                    app.CCregions.centroidI(ClI,1:2) = mean(polyInt.Vertices);  % the X coordinate!?!?
                    app.CCregions.areaU(ClI) = area(polyUn);
                    app.CCregions.areaI(ClI) = area(polyInt);
                    
                    binMskU = roipoly(im,polyUn.Vertices(:,1),polyUn.Vertices(:,2));
                    
                    binMskI = roipoly(im,polyInt.Vertices(:,1),polyInt.Vertices(:,2));
                    
                    % Rocco, Jan 15 2021
                    % One bug has been found here, in the roipoly() functions.
                    % Basically, this bug depends on the fact that roipoly()
                    % gives rise to an empty mask whenever the footprints are
                    % too small to be assigned to a pixel. As I can see via the
                    % data I got, it seems that this bug originates for wide
                    % triangular shapes.
                    % Of course, whenever these cases occur, you have an empty
                    % mask and therefore an empty temporal series.
                    % One possible solution might be to assign, in these very
                    % particular cases, one single 1 point in the zeros, mask
                    % which has the same coordinates of the centroid.
                    if isempty(find(binMskI==1, 1))
                        vx = round(mean(polyInt.Vertices(:,1)));
                        vy = round(mean(polyInt.Vertices(:,2)));
                        binMskI(vx,vy) = 1;
                    end
                    if isempty(find(binMskU==1, 1))
                        vx = round(mean(polyUn.Vertices(:,1)));
                        vy = round(mean(polyUn.Vertices(:,2)));
                        binMskU(vx,vy) = 1;
                    end
                    
                    
                    normI = imageArea/area(polyInt);
                    normU = imageArea/area(polyUn);
                    
                    % Second: compute the time series associated to each event and to the entire cluster
                    for EvI = 1:app.CCregions.eventN(ClI)
                        % Loop on all events in the list
                        eventI = app.CCregions.eventList{ClI}(EvI);
                        
                        % Now use the bounding box of each event to create the
                        % DeltaF/F time series for each event.
                        deltaT = app.eventFeatures.Duration(eventI);
                        start = ceil(app.eventFeatures.BoundingBox(eventI,3));
                        % extract the frames relative to the event and multiply it by the binary mask
                        tmpDF = binMskI .* squeeze(app.parentApp.dataOut.deltaF(:,:,1,start:start+deltaT-1));
                        % compute the meand DF/F in the ROI during the event
                        tmpDFmean = squeeze(mean(mean(tmpDF)));
                        app.CCregions.deltaFI(ClI,start:start+deltaT-1) = normI*tmpDFmean;
                        tmpDF = binMskU .* squeeze(app.parentApp.dataOut.deltaF(:,:,1,start:start+deltaT-1));
                        % compute the meand DF/F in the ROI during the event
                        tmpDFmean = squeeze(mean(mean(tmpDF)));
                        app.CCregions.deltaFU(ClI,start:start+deltaT-1) = normU*tmpDFmean;
                    end
                    % Compute the time series of the event ROI of the
                    % entire data
                    tmpDF = binMskI .* squeeze(app.parentApp.dataOut.deltaF);
                    app.CCregions.deltaFIall(ClI,:) = normI*squeeze(mean(mean(tmpDF)));
                    tmpDF = binMskU .* squeeze(app.parentApp.dataOut.deltaF);
                    app.CCregions.deltaFUall(ClI,:) = normU*squeeze(mean(mean(tmpDF)));
                    
                    app.eventFeatures.MeanIntensity(eventI); %
                    
                    
                end
                
                % Create the app.CC.sortI. index arrays
                % These array hold clusters sorted by a specific feature
                % sortInEvents : sorted by decreasing number of events
                % sortIarea : sorted by decreasing union area
                [tmp,app.CC.sortInEvents] = sort(app.CCregions.eventN,'descend');
                [tmp,app.CC.sortIarea] = sort(app.CCregions.areaU,'descend');
                % Rocco, Jan 2021
                %             [tmp,app.CC.sortIintCC] = sort(app.CCregions.intCC,'descend');
                % Plot the time series according to the selected preferences
                plotTimeSeries (app);
            end
            
            
            %             *
            %
            % March 1, 2021. Include discarded events.
            % Since single events survived many previous filterings anyhow, I
            % wandered what happens if we retain them for the
            % crosscorrelation analysis. In order to do so, I'm simply
            % creating a second table, which will comprehend all the single
            % discarded events, and which will be concatenated vertically
            % to the pr-eexisting one.
            %
            % I think that this function could be useful in particular when
            % time resolution (i.e. number of frames) is not that high.
            
            
            if app.includeDiscardedEventsCB.Value
                
                disp('Clusters have been correctly extracted.')
                disp('Computing discarded events. Please wait...')
                
                % Somewhere there might be some kind of error with the event in cluster counter. By the way, not so important...
                % disp(length(ind))
                % disp(length(ind)+app.nEvInClusters)
                
                % Find indices of events which do not belong to any cluster
                isClTmp = app.eventFeatures.ClusterId;
                [discardedIndices, ~] = find(isClTmp == 0);
                % How many events remain?
                nDiscardedEvents = length(discardedIndices);
                % Create a second table which mimics the first one. The
                % variables have to match the ones of the first table, since we
                % want to concatenate them vertically.
                eventListDE = cell(nDiscardedEvents,1);
                eventNDE = ones(nDiscardedEvents,1); % #3: Always 1 event, no other things needed
                eventSIDE = cell(nDiscardedEvents,1);
                clusterPolyUDE = cell(nDiscardedEvents,1);
                clusterPolyIDE = cell(nDiscardedEvents,1);
                centroidUDE = zeros(nDiscardedEvents,2);
                centroidIDE = zeros(nDiscardedEvents,2);
                indPolyUDE = cell(nDiscardedEvents,1);
                indPolyIDE = cell(nDiscardedEvents,1);
                areaUDE = zeros(nDiscardedEvents,1);
                areaIDE = zeros(nDiscardedEvents,1);
                deltaFIDE = zeros(nDiscardedEvents,app.parentApp.metadata.frames);
                deltaFUDE = deltaFIDE;
                deltaFIallDE = deltaFIDE;
                deltaFUallDE = deltaFIDE;
                timeLineDE = zeros(nDiscardedEvents,app.parentApp.metadata.frames);
                intCCDE = zeros(nDiscardedEvents,1);
                totCCDE = zeros(nDiscardedEvents,1);
                
                % Generate table
                discardedEventsTable = table(eventListDE,eventSIDE,eventNDE,clusterPolyUDE,clusterPolyIDE,centroidUDE,centroidIDE,...
                    indPolyUDE,indPolyIDE,areaUDE,areaIDE,deltaFIDE,deltaFUDE,deltaFIallDE,deltaFUallDE,timeLineDE,intCCDE,totCCDE,...
                    'VariableNames',{'eventList','eventSI','eventN','clusterPolyU','clusterPolyI',...
                    'centroidU','centroidI','indexesU','indexesI','areaU','areaI','deltaFI','deltaFU',...
                    'deltaFIall','deltaFUall','timeLine','intCC','totCC'});
                
                % I'm going to fill these columns with proper values. In
                % some cases, like in the SI column, things will be a little
                % different. Just to be sure, I'm making U and I data equal
                % within the for loop (row-by-row). Maybe I will change that...
                
                % This will be needed if no clusters were found.
                im = app.parentApp.dataOut.deltaF(:,:,1,1);     % Grab the first image of the stack for the mask creation
                
                % #2 eventSI is put 1. Alternatively, I could put NaN...
                discardedEventsTable{:,2} = {1};
                NaNVertCounter = 0; % Created for debug reasons, not important.
                for i = 1:nDiscardedEvents
                    
                    % #1 eventList
                    discardedEventsTable{i,1} = {discardedIndices(i)};
                    
                    % #2 , #3 eventSI & event N. They have been filled with 1s
                    
                    % #4 , #5: clusterPolyU & clusterPolyI
                    % Generate polyshape in the same way in which it occurs for
                    % clusters. Again, consider footprints to generate the
                    % figure.
                    p_tmp = polyshape(cell2mat(tmpFootprint(discardedIndices(i))),'Simplify',false);
                    % Polyshape output will suffer from the same problems
                    % which were observed with clusters: sometimes, some vertex
                    % is NaN. If you don't eliminate these NaN vertices,
                    % all the resulting series will be null and therefore
                    % will affect crosscorrelation analysis.
                    if ~isempty(find(isnan(p_tmp.Vertices), 1))
                        A = p_tmp.Vertices;
                        A(~any(~isnan(A), 2),:) = [];
                        % Update polyshape deprived of NaN vertices
                        p_tmp = polyshape(A,'Simplify',false);
                        % Also, add a counter for debug reasons...
                        % It will disp() all the instances in which this
                        % correction was needed
                        NaNVertCounter = NaNVertCounter + 1;
                    end
                    % Add poly to table in clusterpolyU column
                    discardedEventsTable{i,4} = {p_tmp};
                    % And, of course, clusterpolyI value shall be the same...
                    discardedEventsTable{i,5} = discardedEventsTable{i,4};
                    
                    % #6 , #7: centroidU & centroidI
                    % Centroid is calculated in the same way as for clusters:
                    % using mean(). Of course, again, union and intersection
                    % are equal.
                    discardedEventsTable.centroidU(i,1:2) = mean(discardedEventsTable.clusterPolyU{i}.Vertices);
                    discardedEventsTable.centroidI(i,1:2) = discardedEventsTable.centroidU(i,1:2);
                    
                    % #8 , #9: indexesU & indexesI
                    % Keep them zeros()
                    
                    % #10 , #11: areaU & areaI
                    % As regards the area, actually I could get it directly by
                    % the xyArea column of eventFeatures table. Although, I
                    % prefer to recalculate it here, in a way that allows to
                    % have a result which is more 'similar' to the calculations
                    % which were made for clusters.
                    % Note that in eventFeatures original table, there is also
                    % information about 3d surface. But that's not important
                    % and is not used here.
                    discardedEventsTable.areaU(i) = area(p_tmp);
                    discardedEventsTable.areaI(i) = discardedEventsTable.areaU(i);
                    
                    % Computation of time series.
                    norm_d = imageArea/area(p_tmp);
                    
                    eventI_d = discardedEventsTable.eventList{i};
                    % Use the bounding box of each event to create the
                    % DeltaF/F time series for each event.
                    deltaT = app.eventFeatures.Duration(eventI_d);
                    start = ceil(app.eventFeatures.BoundingBox(eventI_d,3));
                    % Mask is single
                    binMskSingleEvent = roipoly(im,p_tmp.Vertices(:,1),p_tmp.Vertices(:,2));
                    
                    % Extract the frames relative to the event and multiply it by the binary mask
                    tmpDF = binMskSingleEvent .* squeeze(app.parentApp.dataOut.deltaF(:,:,1,start:start+deltaT-1));
                    % compute the meand DF/F in the ROI during the event
                    tmpDFmean = squeeze(mean(mean(tmpDF)));
                    discardedEventsTable.deltaFI(i,start:start+deltaT-1) = norm_d * tmpDFmean;
                    % Copy union
                    discardedEventsTable.deltaFU(i,start:start+deltaT-1) = discardedEventsTable.deltaFI(i,start:start+deltaT-1);
                    % Extract DF 'all'
                    % Compute the time series of the event ROI of the
                    % entire data
                    tmpDF = binMskSingleEvent .* squeeze(app.parentApp.dataOut.deltaF);
                    discardedEventsTable.deltaFIall(i,:) = norm_d*squeeze(mean(mean(tmpDF)));
                    % Copy union 'all'
                    discardedEventsTable.deltaFUall(i,:) = discardedEventsTable.deltaFIall(i,:);
                end
                
                % Up to this point, a second table has been created, which contains all the
                % discarded events' parameters.
                % Now, concatenate vertically the two tables and refresh the
                % information carried by app.CCregions.
                if app.nClusters ~= 0
                    app.CCregions = [app.CCregions ; discardedEventsTable];
                else
                    % In some rare cases, only single events were found,
                    % and no cluster is already present. In these cases,
                    % just fill app.CCregions property
                    app.CCregions = discardedEventsTable;
                    % Refresh also text label
                    app.clusterTxt.Text = sprintf('0 clusters found; single events only.');
                    
                end
                % Create the app.CC.sortI. index arrays
                % These array hold clusters sorted by a specific feature
                % sortInEvents : sorted by decreasing number of events
                % sortIarea : sorted by decreasing union area
                [tmp,app.CC.sortInEvents] = sort(app.CCregions.eventN,'descend');
                [tmp,app.CC.sortIarea] = sort(app.CCregions.areaU,'descend');
                % Rocco, Jan 2021
                %             [tmp,app.CC.sortIintCC] = sort(app.CCregions.intCC,'descend');
                % Plot the time series according to the selected preferences
                plotTimeSeries (app);
                
                % Also, refresh nCluster in order to give the correct
                % dependencies to crosscorrelation app
                app.nClusters = app.nClusters + nDiscardedEvents;
                
                % Display an output, for the moment in command window
                disp('Total number of traces (clusters + discarded events): '); disp(app.nClusters);
                disp(' Discarded events which had NaN vertices: ');  disp(NaNVertCounter);
                
            end
            
            
            %**********************
            % JULY 2021 - CLUSTER REFINEMENT
            if app.MergeClustersCB.Value
                % July, 2021, 03:19 a.m..
                % RG
                % Sometimes, due to the cluster research pipeline (which
                % starts from randomly-placed events), the same 'functional'
                % area might be splitted in 2 or more clusters that overlap
                % more than the SI states. Merge them.
                N=2;
                for rep=1:N % Repeat this process more times to clean all the overlapping clusters
                    disp('REP:');disp(rep);
                    CL = app.CCregions; % This table will replace the original one
                    l = height(app.CCregions);
                    deletedRows = [];
                    for i=1:height(app.CCregions)-1
                        % Here, I'm basically repeating the same concept as seen
                        % before. I'm comparing intersection and union areas, but
                        % this time not of single events, but of already-formed
                        % clusters. I want to merge them.
                        mother = CL.clusterPolyU{i};
                        for j=i+1:height(app.CCregions)
                            if ismember(j,deletedRows) == 1 % Do not process already merged clusters.
                                continue
                            end
                            mother = CL.clusterPolyU{i}; % Mother could have been changed!
                            child = app.CCregions.clusterPolyU{j};
                            polyInt = intersect(mother, child,'KeepCollinearPoints',true);
                            polyUn = union(mother, child,'KeepCollinearPoints',true);
                            SI = area(polyInt)/area(polyUn);
                            SIm = area(polyInt)/area(mother);
                            SIc = area(polyInt)/area(child);
                            if SI >= app.SIthr.Value || SIm >= app.SIthr.Value || SIc >= app.SIthr.Value % Same thing as SI seen before.
                                % If some clusters are still overlapping, then
                                % merge them.
                                %                                 disp('must be merged:');disp(i);disp(j); % Output
                                %
                                % Under some rare conditions, poly vertices end to be NaN.
                                if ~isempty(find(isnan(polyInt.Vertices), 1))
                                    A = polyInt.Vertices;
                                    A(~any(~isnan(A), 2),:)=[];
                                    % Generate new polyshape which DOES NOT SIMPLIFY these new vertices
                                    polyInt = polyshape(A,'Simplify',false);
                                end
                                % Do the same for Union
                                if ~isempty(find(isnan(polyUn.Vertices), 1))
                                    A = polyUn.Vertices;
                                    A(~any(~isnan(A), 2),:)=[];
                                    %Generate new polyshape which DOES NOT SIMPLIFY new vertices
                                    polyUn = polyshape(A,'Simplify',false);
                                end
                                %
                                % Recalculate again all the columns
                                CL.eventList{i} = [CL.eventList{i},CL.eventList{j}];
                                CL.eventSI{i} = [CL.eventSI{i}, CL.eventSI{j}]; % Not important
                                CL.eventN(i) = CL.eventN(i) + CL.eventN(j);
                                CL.clusterPolyU{i} = polyUn;
                                CL.clusterPolyI{i} = polyInt; % Intersection is useless, no matter what fills here
                                CL.centroidU(i,1:2) = mean(polyUn.Vertices);
                                CL.centroidI(i,1:2) = mean(polyInt.Vertices);
                                CL.areaU(i) = area(polyUn);
                                CL.areaI(i) = area(polyInt);
                                % Make sure to reset preexisting time
                                % series!
                                CL.deltaFI(i,:) = 0;
                                CL.deltaFU(i,:) = 0;
                                CL.deltaFIall(i,:) = 0;
                                CL.deltaFUall(i,:) = 0;
                                normI = imageArea/area(polyInt);
                                normU = imageArea/area(polyUn);
                                im = app.parentApp.dataOut.deltaF(:,:,1,1); % Grab the first image of the stack for the mask creation
                                binMskU = roipoly(im,polyUn.Vertices(:,1),polyUn.Vertices(:,2));
                                binMskI = roipoly(im,polyInt.Vertices(:,1),polyInt.Vertices(:,2));
                                % Again, time series associated to each event and to the entire cluster
                                for EvI = 1:CL.eventN(i)
                                    % Loop on all events in the list
                                    eventI = CL.eventList{i}(EvI);
                                    % Now use the bounding box of each event to create the
                                    % DeltaF/F time series for each event.
                                    deltaT = app.eventFeatures.Duration(eventI);
                                    start = ceil(app.eventFeatures.BoundingBox(eventI,3));
                                    % extract the frames relative to the event and multiply it by the binary mask
                                    tmpDF = binMskI .* squeeze(app.parentApp.dataOut.deltaF(:,:,1,start:start+deltaT-1));
                                    % compute the meand DF/F in the ROI during the event
                                    tmpDFmean = squeeze(mean(mean(tmpDF)));
                                    CL.deltaFI(i,start:start+deltaT-1) = normI*tmpDFmean;
                                    tmpDF = binMskU .* squeeze(app.parentApp.dataOut.deltaF(:,:,1,start:start+deltaT-1));
                                    % compute the meand DF/F in the ROI during the event
                                    tmpDFmean = squeeze(mean(mean(tmpDF)));
                                    CL.deltaFU(i,start:start+deltaT-1) = normU*tmpDFmean;
                                end
                                % Compute the time series of the event ROI of the
                                % entire data
                                tmpDF = binMskI .* squeeze(app.parentApp.dataOut.deltaF);
                                CL.deltaFIall(i,:) = normI*squeeze(mean(mean(tmpDF)));
                                tmpDF = binMskU .* squeeze(app.parentApp.dataOut.deltaF);
                                CL.deltaFUall(i,:) = normU*squeeze(mean(mean(tmpDF)));
                                %!!!
                                % Importantly, now delete the row belonging to the
                                % merged cluster
                                deletedRows = [deletedRows,j]; % Add the index of row that needs to be deleted
                                %!!!
                            end
                        end
                    end
                    % Finally, delete old rows starting from the bottom
                    deletedRows = sort(deletedRows,'descend');
                    CL(deletedRows(:),:) = [];
                    % Now substitute old cluster table with CL refined table
                    app.CCregions = CL ;
                end
                % Assign new ClusterIds to eventFeatures table
                for i=1:height(app.CCregions)
                    events=cell2mat(app.CCregions.eventList(i)); % Take array of events belonging to each cluster
                    app.eventFeatures(events(:),11) = {i}; % Reassign the correct clusterId (column 11 of eventFeatures table)
                end
                % Refresh output
                [tmp,app.CC.sortInEvents] = sort(app.CCregions.eventN,'descend');
                [tmp,app.CC.sortIarea] = sort(app.CCregions.areaU,'descend');
                app.nClusters = height(app.CCregions);
                isClTmp = app.eventFeatures.ClusterId;
                app.nEvInClusters = length(find(isClTmp ~= 0));
                
                app.clusterTxt.Text = sprintf('%g events in %g clusters',app.nEvInClusters,app.nClusters);
                
                
                disp('Cluster refinement: Done!')
            end
            
            %**********************
            
            % March 23. Save backup of cluster table. Needed since now
            % clusters can be deleted on the basis of their global
            % crosscorrelation score.
            app.NotExcluded = app.CCregions;
            
            app.SpCorrelationBt.BackgroundColor = [0.96,0.96,0.96];
            drawnow
        end

        % Button pushed function: computeAndSaveDistributions
        function exportDistributions(app, event)
            % Dec 2020
            % Compute with the present limits the event distributions
            % Save everything at the end
            
            % Compute distribution of event volumes
            left = app.plotsOut.volume.fromDistr;
            right = app.plotsOut.volume.toDistr;
            bins = app.plotsOut.volume.bins;
            tmp = app.eventFeatures.Volume;
            % Generate the bin limits
            edges = linspace (left,right,bins+1);
            centers = (edges(2:bins+1)+edges(1:bins))/2;
            app.plotsOut.spectra.volume.counts = histcounts(tmp,edges,'Normalization','probability');
            app.plotsOut.spectra.volume.cnt = centers;
            app.plotsOut.spectra.volume.left = left;
            app.plotsOut.spectra.volume.right = right;
            app.plotsOut.spectra.volume.bins = bins;
            
            % compute also the distribution of the Log10 of the feature
            tmp = log10(tmp);
            left = log10(left);
            right = log10(right);
            if left<=0
                left = 1;
            end
            
            edges = linspace (left,right,bins+1);
            centers = (edges(2:bins+1)+edges(1:bins))/2;
            app.plotsOut.spectra.L10volume.counts = histcounts(tmp,edges,'Normalization','probability');
            app.plotsOut.spectra.L10volume.cnt = centers;
            app.plotsOut.spectra.L10volume.left = left;
            app.plotsOut.spectra.L10volume.right = right;
            app.plotsOut.spectra.L10volume.bins = bins;
            
            % Compute distribution of event Surface
            left = app.plotsOut.surface.fromDistr;
            right = app.plotsOut.surface.toDistr;
            bins = app.plotsOut.surface.bins;
            tmp = app.eventFeatures.xyArea;
            % Generate the bin limits
            edges = linspace (left,right,bins+1);
            centers = (edges(2:bins+1)+edges(1:bins))/2;
            app.plotsOut.spectra.surface.counts = histcounts(tmp,edges,'Normalization','probability');
            app.plotsOut.spectra.surface.cnt = centers;
            app.plotsOut.spectra.surface.left = left;
            app.plotsOut.spectra.surface.right = right;
            app.plotsOut.spectra.surface.bins = bins;
            
            % compute also the distribution of the Log10 of the feature
            tmp = log10(tmp);
            left = log10(left);
            right = log10(right);
            if left<=0
                left = 1;
            end
            edges = linspace (left,right,bins+1);
            centers = (edges(2:bins+1)+edges(1:bins))/2;
            app.plotsOut.spectra.L10surface.counts = histcounts(tmp,edges,'Normalization','probability');
            app.plotsOut.spectra.L10surface.cnt = centers;
            app.plotsOut.spectra.L10surface.left = left;
            app.plotsOut.spectra.L10surface.right = right;
            app.plotsOut.spectra.L10surface.bins = bins;
            
            % Compute distribution of event Duration
            left = app.plotsOut.duration.fromDistr;
            right = app.plotsOut.duration.toDistr;
            bins = app.plotsOut.duration.bins;
            tmp = app.eventFeatures.Duration;
            % Generate the bin limits
            edges = linspace (left,right,bins+1);
            centers = (edges(2:bins+1)+edges(1:bins))/2;
            app.plotsOut.spectra.duration.counts = histcounts(tmp,edges,'Normalization','probability');
            app.plotsOut.spectra.duration.cnt = centers;
            app.plotsOut.spectra.duration.left = left;
            app.plotsOut.spectra.duration.right = right;
            app.plotsOut.spectra.duration.bins = bins;
            
            % compute also the distribution of the Log10 of the feature
            tmp = log10(tmp);
            left = log10(left);
            right = log10(right);
            if left<=0
                left = 1;
            end
            edges = linspace (left,right,bins+1);
            centers = (edges(2:bins+1)+edges(1:bins))/2;
            app.plotsOut.spectra.L10duration.counts = histcounts(tmp,edges,'Normalization','probability');
            app.plotsOut.spectra.L10duration.cnt = centers;
            app.plotsOut.spectra.L10duration.left = left;
            app.plotsOut.spectra.L10duration.right = right;
            app.plotsOut.spectra.L10duration.bins = bins;
            
            % Compute distribution of event mean DF/F
            left = app.plotsOut.deltaF.fromDistr;
            right = app.plotsOut.deltaF.toDistr;
            bins = app.plotsOut.deltaF.bins;
            tmp = app.eventFeatures.MeanIntensity;
            % Generate the bin limits
            edges = linspace (left,right,bins+1);
            centers = (edges(2:bins+1)+edges(1:bins))/2;
            app.plotsOut.spectra.deltaF.counts = histcounts(tmp,edges,'Normalization','probability');
            app.plotsOut.spectra.deltaF.cnt = centers;
            app.plotsOut.spectra.deltaF.left = left;
            app.plotsOut.spectra.deltaF.right = right;
            app.plotsOut.spectra.deltaF.bins = bins;
            
            % compute also the distribution of the Log10 of the feature
            tmp = log10(tmp);
            left = log10(left);
            right = log10(right);
            if left<=0
                left = 0.1;
            end
            edges = linspace (left,right,bins+1);
            centers = (edges(2:bins+1)+edges(1:bins))/2;
            app.plotsOut.spectra.L10deltaF.counts = histcounts(tmp,edges,'Normalization','probability');
            app.plotsOut.spectra.L10deltaF.cnt = centers;
            app.plotsOut.spectra.L10deltaF.left = left;
            app.plotsOut.spectra.L10deltaF.right = right;
            app.plotsOut.spectra.L10deltaF.bins = bins;
            
            % The spectral analysis of the events is contained in the app.plotsOut.spectra structure
            % Save distributions
            fileOut = [app.parentApp.dataPath 'eventDistributions.mat'];
            tmp = app.plotsOut.spectra;            % That is the way it is. Cryptic note, Dec 2020.
            save(fileOut,'tmp');
            
        end

        % Callback function: TSselection, showClusterSpinner
        function changeTraceSource(app, event)
            value = app.showClusterSpinner.Value;
            plotTimeSeries(app)
            multiPlot(app,event)
        end

        % Callback function: FillCB, boundaryLineW, framesCB, logMin, 
        % ...and 1 other component
        function multiPlot(app, event)
            % December 2020. GMR
            % Draw the multi modal window according to the selected option
            selectedButton = app.multiPlotMode.SelectedObject;
            
            % Plot the image background
            cla (app.multiDisplay)
            hold(app.multiDisplay, 'on' )
            
            if app.framesCB.Value
                tmp = squeeze(app.parentApp.dataOut.workImages(:,:,1,app.currentFrame));
                imagesc(app.multiDisplay,log10(tmp));
            else
                imagesc(app.multiDisplay,log10(app.parentApp.mdImage));
            end
            set(app.multiDisplay,'Colormap',gray);
            set(app.multiDisplay,'XTick',[]);
            set(app.multiDisplay,'YTick',[]);
            set(app.multiDisplay,'XLim',[0 app.parentApp.WIcolumns]);
            set(app.multiDisplay,'YLim',[0 app.parentApp.WIrows]);
            set(app.multiDisplay,'CLim',[app.logMin.Value,app.logMax.Value]);
            
            switch app.multiPlotMode.SelectedObject.Text
                case 'Boundaries'
                    nevnts = length(app.eventFeatures.xyArea);
                    for i=1:nevnts
                        plot(app.multiDisplay,app.eventFeatures.Footprint{i}(:,1),...
                            app.eventFeatures.Footprint{i}(:,2),'LineWidth',app.boundaryLineW.Value);
                    end
                    
                case 'Clusters'
                    trN = app.showClusterSpinner.Value;
                    if trN>0
                        switch app.sortingCriteriaRB.SelectedObject.Text
                            case 'No sorting'
                                % Do nothing
                            case 'Area'
                                trN = app.CC.sortIarea(trN);
                            case 'n Events'
                                trN = app.CC.sortInEvents(trN);
                            case 'CC'
                                trN = app.CC.sortIintCC(trN);
                        end
                    end
                    
                    switch trN
                        case 0
                            % Show all clusters
                            for ClI=1:app.nClusters
                                
                                % Display clusters as polyshape vertices or
                                % as polyshape itself. You can control the
                                % graphical outputs of polyshape easily
                                if app.FillCB.Value
                                    randRgb = [rand(3,1)];
                                    plot(app.multiDisplay,app.CCregions.clusterPolyU{ClI},...
                                        'LineWidth',app.boundaryLineW.Value,'FaceAlpha',0.59, 'EdgeColor',randRgb,'FaceColor',randRgb);
                                else
                                    plot(app.multiDisplay,app.CCregions.clusterPolyU{ClI}.Vertices(:,1),...
                                        app.CCregions.clusterPolyU{ClI}.Vertices(:,2),...
                                        'LineWidth',app.boundaryLineW.Value);
                                end
                            end
                            % Show also the edges if the option is selected
                            if app.NNlinkCB.Value
                                for ClI=1:app.nClusters
                                    [p1x,p1y] = centroid (app.CCregions.clusterPolyU{ClI});
                                    NNi = app.CC.NNind(ClI);
                                    [p2x,p2y] = centroid (app.CCregions.clusterPolyU{NNi});
                                    plot(app.multiDisplay,[p1x,p2x],[p1y,p2y],'y',...
                                        'LineWidth',app.boundaryLineW.Value/2);
                                end
                            end
                        otherwise
                            plot(app.multiDisplay,app.CCregions.clusterPolyU{trN}.Vertices(:,1),...
                                app.CCregions.clusterPolyU{trN}.Vertices(:,2),'r',...
                                'LineWidth',app.boundaryLineW.Value);
                            if app.NNlinkCB.Value
                                [p1x,p1y] = centroid (app.CCregions.clusterPolyU{trN});
                                NNi = app.CC.NNind(trN);
                                [p2x,p2y] = centroid (app.CCregions.clusterPolyU{NNi});
                                plot(app.multiDisplay,[p1x,p2x],[p1y,p2y],'y',...
                                    'LineWidth',app.boundaryLineW.Value/2);
                                plot(app.multiDisplay,app.CCregions.clusterPolyU{NNi}.Vertices(:,1),...
                                    app.CCregions.clusterPolyU{NNi}.Vertices(:,2),'g',...
                                    'LineWidth',app.boundaryLineW.Value);
                            end
                    end
            end
            % flip the Y axes
            set(app.multiDisplay,'Ydir','reverse');
            hold(app.multiDisplay,'off');
            
        end

        % Button pushed function: CCmatrixBt
        function computeCCmatrix(app, event)
            % A new GUI for the analysis of CC matrices...
            app.BinMapCCMatrix = BinMapCCMatrix(app);
            app.BinMapCCMatrix.ClustercrosscorrelationUIFigure.Name = ...
                append('Cluster Crosscorrelation! ', sprintf(app.parentApp.fileIn));
        end

        % Button pushed function: ClusterCCScoreBT
        function ClusterCCScore(app, event)
            % March 15, 2021. Rg.
            % Display all the clusters filled with different colors. Colors
            % are indicative of the total crosscorrelation score, which is
            % the sum of all the crosscorrelations computed for that cluster.
            %
            % This view is particularly pretty if you use it in combination
            % with the 'grid' option -- possibily with a small bin size.
            %
            if ~isempty(app.CC.CCmatrix)
                figure
                pbaspect([1 1 1])
                daspect([1 1 1])
                title('Cluster cross-correlation "heatmap"')
                subtitle('Color is the sum of all cross-correlations')
                set(gca,'Ydir','reverse');
                set(gca,'Color','k')
                colormap jet
                colorbar
                hold on
                
                
                %                 tmp = squeeze(app.parentApp.dataOut.workImages(:,:,1,app.currentFrame));
                %                 imagesc(log10(tmp));
                %                 aa=log10(tmp);
                
                if ~app.gCCNormalizeCB.Value
                    
                    % The total crosscorrelation scores are stored as a column vector
                    % into app.CCregions table. I'm using fill() option to fill the
                    % polygons with the corresponding value.
                    for ClI=1:app.nClusters
                        fill(app.CCregions.clusterPolyU{ClI}.Vertices(:,1),...
                            app.CCregions.clusterPolyU{ClI}.Vertices(:,2), app.CCregions.totCC(ClI),... % Fill with score value
                            'EdgeAlpha',0) % Also, don't show borders (useful for smaller bins)
                        % Oct 2021, show relationship with soma. Take, instead of totCC, the values of the first row of an area-sorted matrix
                        
                        
                        %
                        %                         fill(app.CCregions.clusterPolyU{ClI}.Vertices(:,1),...
                        %                             app.CCregions.clusterPolyU{ClI}.Vertices(:,2), app.CC.CCmatrix(ClI,1),... % Fill with score value
                        %                             'EdgeAlpha',0) % Also, don't show borders (useful for smaller bins)
                    end
                    
                else
                    
                    % Normalize values between 0 and 1. Also, fix clims of the
                    % colormap at the extreme values. Useful for comparison.
                    A = app.CCregions.totCC;
                    normA = A - min(A(:));
                    normA = normA ./ max(normA(:));
                    set(gca,'CLim',[0,1])
                    for ClI=1:app.nClusters
                        fill(app.CCregions.clusterPolyU{ClI}.Vertices(:,1),...
                            app.CCregions.clusterPolyU{ClI}.Vertices(:,2), normA(ClI),... % Fill with score value
                            'EdgeAlpha',0) % Also, don't show borders (useful for smaller bins)
                    end
                    
                end
                
                hold off
            end
            
        end

        % Value changed function: gCCExcludeCB
        function ExcludeClustersByGlobalCC(app, event)
            % March, 23, 2021. Rg.
            
            % Delete clusters that are globally anticorrelated with the
            % others. Useful to find 'interesting' regions among grid
            % analysis. Maybe I colud create a mask out of it?
            
            value = app.gCCExcludeCB.Value;
            if value
                [I,~] = find(app.CCregions.totCC < app.gCCExcludeEF.Value); % Find globally anticorrelated domains
                app.CCregions(I,:) = []; % Remove from table
                app.nClusters = height(app.CCregions); % Update
            else
                %                 app.CCregions = app.NotExcluded; % Backup was taken right after cluster extraction
                % Previous global score will be lost, hence CC shall be
                % computed again -- or, alternatively, you could just
                % re-pick again the matrix of interest with the 'switch'
                % button in BinMapCCMatrix child.
                
            end
        end

        % Button pushed function: AVRatiosBT
        function Area_Volume_Ratios(app, event)
            % Jun 27, 2021, RG
            % This callback creates a table with the surface and volumetric information
            % about the overall calcium fluctuations, with respect to the total area of the aquisition.
            % Just to be sure, I'm going to compute surface and volume ratios both for
            % events and clusters.
            % Probably, the information about clusters is the one we ought
            % to use.
            
            % Importantly, in all the following computations I am always looking
            % exactly for 1s or 0s. This is
            % needed in order to avoid to take into account NaNs. NaNs are
            % indeed created whenever there is a preprocessing crop or a creation
            % of a ROI in Image Gateway, and they are not part of the area.
            
            % To save anything Image Gateway main folder is needed.
            
            app.AVRatiosBT.BackgroundColor = [0 0 1];
            drawnow;
            
            % EF string will be added before file format. This is to
            % generate automatically more filenames starting from the same
            % file. Useful e.g. for multiple ROIs
            fn = string(app.FilenameEF.Value);
            
            % #1 First table: Export event features table
            EF = app.eventFeatures;
            % Add two columns in which the surface and the volume are
            % converted in um^2 and sec*um^2. This conversion will allow to
            % compare data coming from acquisitions with different um/pixel.
            % Also, this information will be useful for the computation of
            % the volume ratio of the clusters (see 3d table).
            % I'm also multiplying by the binning
            b = app.parentApp.binTxt.Value;
            EF.xyAreaMicron2 = EF.xyArea .* (app.umpxEF.Value^2) .* (b^2);
            EF.VolumeMicron2Sec = EF.Volume .* (app.umpxEF.Value^2) .* (b^2); % This is in terms of frames. It will change, if necessary...
            % Save with proper filename
            str = string(app.parentApp.fileIn);
            str = strrep(str,'.tif','');
            str = append('EventFeatures_',str,fn,'.mat');
            %% Edit for CodeOcean format. Save results in appropriate folder.
            outputFolderTmp1=pwd;
            outputFolderTmp2=fileparts(outputFolderTmp1);

            outputFolderParent=append(outputFolderTmp2,filesep,'results',filesep);
            if exist(outputFolderParent, 'dir') ~= 7
                mkdir(outputFolderParent);
            end
            outputFolder=append(outputFolderParent,'EventsAndClusters',filesep);
            if exist(outputFolder, 'dir') ~= 7
                mkdir(outputFolder);
            end
            str = append(outputFolder,str); % Save .mat in dedicated folder
            save(str,'EF');
            
            % #2 Second table: Export clusters table
            CL = app.CCregions;
            % Again, compute area and volume in microns.
            % We are consindering only the union method
            CL.xyAreaUMicron2 = CL.areaU .* (app.umpxEF.Value^2) .* (b^2);
            % Compute volume of each cluster. To do so, sum the volumes
            % (which, due to topological connectivity extraction, do not overlap) of the events belonging to each
            % cluster.
            L = height(CL); % Number of clusters
            CL.VolumeMicron2Sec = zeros(L,1); % Create new column
            for i = 1:L
                eventsInsideCluster = CL.eventList{i}; % Take indices of events in the cluster
                eventsVol = EF.VolumeMicron2Sec;
                clusterVol =sum(eventsVol(eventsInsideCluster)); % Sum their micron volume
                CL.VolumeMicron2Sec(i) = clusterVol;
            end
            % Save with proper filename
            str = string(app.parentApp.fileIn);
            str = strrep(str,'.tif','');
            str = append('Clusters_',str,fn,'.mat');
            %% Edit for CodeOcean format. Save results in appropriate folder.
            str = append(outputFolder,str); % Same folder as for events
            save(str,'CL');
            
            % #3 Export surface and volumetric ratios. These ratios are computed mostly
            % starting from the binary stack in Image Gateway and not from the area/volume
            % info in events and cluster features (in my
            % opinion, this allows to have a more precise measurement). I
            % will save these ratios in a third .mat file.
            % I decided, for the sake of completeness, to take into account
            % 4 ratios, related to the surface and volumetric aspects for
            % both events and clusters.
            % First, compute total surface and volume of the ROI
            binSource = squeeze(app.parentApp.erodedBinaryImages(:,:,1,:));
            % Total surface
            tmp = app.parentApp.mdImage;
            tmp(tmp == 1) = NaN;
            ROIArea = tmp;
            expTotalArea = numel(find((~isnan(ROIArea))));
            % Total volume
            expTotalVolume = expTotalArea * app.parentApp.metadata.frames;
            %
            % #3.a EVENTS SURFACE
            % Since events are mostly overlapping, I cannot just sum their areas.
            % I'm plotting the polygons of
            % each event (all together, hold on) to obtain the total projection occupied
            % by them. Points within projection will be 1; the other points will be 0;
            % outside the ROI there will be NaN.
            sz = size((binSource),1); % Size of the x-y edge of the acquisition
            eftemplate = zeros(sz,sz) .* ROIArea;
            l = length(EF.Footprint);
            for i=1:l
                % Remove NaN vertices (if present)
                vx = EF.Footprint{i,1}(:,1);
                vx = vx(~isnan(vx));
                vy = EF.Footprint{i,1}(:,2);
                vy = vy(~isnan(vy));
                % Create binary mask for each polygon
                tmp = poly2mask(vx,vy,sz,sz);
                eftemplate = eftemplate + tmp; % Add each footprint to template
            end
            % Calculate the '1/0' ratio
            eftemplate(eftemplate >= 1) = 1;
            evOnes_area = numel(find(eftemplate == 1));
            evZeros_area = numel(find(eftemplate == 0));
            evRatio_area = evOnes_area/expTotalArea;
            %
            % #3.b) EVENTS VOLUME
            % You can just sum the volume values from the event table.
            % We can do this because by definition
            % single events volumes are not overlapped (only their surface
            % might be).
            evOnes_vol = sum(EF.Volume); % Sum these volumes to get the volumes of the clusters
            evRatio_vol = evOnes_vol / expTotalVolume; % Ratio
            %
            % #3.c) CLUSTERS SURFACE
            % Since clusters are overlapping, I cannot just sum their areas.
            % I'm plotting the polygons of
            % each cluster (all together, hold on) to obtain the total projection occupied
            % by them. Points within projection will be 1; the other points will be 0;
            % outside the ROI there will be NaN.
            sz = size((binSource),1); % Size of the x-y edge of the acquisition
            cltemplate = zeros(sz,sz) .* ROIArea;
            cl = app.CCregions.clusterPolyU; % Use clusters computed with 'union'
            l = length(cl);
            for i=1:l
                % Create binary mask for each polygon
                % Remove NaN vertices first (which are present if polygons
                % has strange shapes e.g., holes in it
                vx = cl{i,1}.Vertices(:,1);
                vx = vx(~isnan(vx));
                vy = cl{i,1}.Vertices(:,2);
                vy = vy(~isnan(vy));
                tmp = poly2mask(vx,vy,sz,sz);
                cltemplate = cltemplate + tmp; % Add each footprint to template
            end
            % Calculate the '1/0' ratio
            cltemplate(cltemplate >= 1) = 1;
            clOnes_area = numel(find(cltemplate == 1));
            clZeros_area = numel(find(cltemplate == 0));
            clRatio_area = clOnes_area/expTotalArea; % This ratio should be smaller to the one of the surface of single events
            %
            % #3.d) CLUSTERS VOLUME
            % The calculation of the volume occupied by clusters is simple.
            % You can just sum the volumes of the single events which were
            % assigned to a clusters. We can do this because by definition
            % single events volumes are not overlapped (only their surface
            % might be).
            a = EF; % Events
            b = find(a.ClusterId > 0); % Indices of events in clusters. [Don't remove find()].
            c = a(b,:); % New table with only events in clusters
            clOnes_vol = sum(c.Volume); % Sum these volumes to get the volumes of the clusters
            clRatio_vol = clOnes_vol / expTotalVolume; % Ratio
            %
            % Put the ratios in a table.
            SVR = table(evRatio_area, evRatio_vol, clRatio_area, clRatio_vol,expTotalArea,expTotalVolume,app.umpxEF.Value,...
                'VariableNames',{'Events surface ratio','Events volume ratio','Clusters surface ratio','Clusters volume ratio','Total surface','Total volume','um/pix Ratio'});
            % Export area-volume ratios table
            str = string(app.parentApp.fileIn);
            str = strrep(str,'.tif','');
            str = append('A-V-Ratios_',str,fn,'.mat'); % Prefix
            %% Edit for CodeOcean format. Save results in appropriate folder.
            str = append(outputFolder,str); % Save .mat in dedicated folder
            save(str,'SVR');
            
            % Done!
            disp('Done! All the features were saved.')
            app.AVRatiosBT.BackgroundColor = [0.99,0.92,0.80];
        end

        % Button pushed function: defineSomaBT
        function defineSoma(app, event)
            % June 2021. Define cellular body coordinates and add the
            % values of the distance to it as an additional column of
            % app.CCregions table
            
            % First method: select the soma as a point:
            % Select and save soma coordinates
            img = log10(app.parentApp.mdImage);
            f = figure;
            imagesc(img);
            [app.xSoma, app.ySoma] = getpts; % Important: double click is needed!
            close(f);
            % Compute distance for each cluster centroid
            a = app.CCregions.centroidU;
            dist = sqrt((a(:,1)-app.xSoma).^2+(a(:,2)-app.ySoma).^2);
            
            %             % Second method: select the soma as a polygon
            %             p = drawpolygon(app.multiDisplay); % Draw soma boundaries on in-app boundaries axes
            %             app.xSoma = p.Position(:,1);
            %             app.ySoma = p.Position(:,2);
            %             dist = zeros(app.nClusters,1);
            %             for i=1:app.nClusters % Could not do that with arrays
            %                 % Coordinates of cluster centroid
            %                 cx = app.CCregions.centroidU(i,1);
            %                 cy = app.CCregions.centroidU(i,2);
            %                 % Compute poly-point distance with external function
            %                 tmp_minDist = p_poly_dist(cx,cy,app.xSoma,app.ySoma);
            %                 % Add to distance array
            %                 dist(i) = tmp_minDist;
            %             end
            
            % If um/px ratio is available, express the distance in terms of
            % microns (otherwise just multiply by 1). Also, consider
            % previous binning
            dist = dist.*app.umpxEF.Value.*app.parentApp.binTxt.Value;
            
            % Add distance column to app.CCregions
            app.CCregions.somaDistance = dist;
            
            % Done
            app.defineSomaBT.BackgroundColor = [0.99,0.92,0.80];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.extractDomains = uifigure('Visible', 'off');
            app.extractDomains.Name = 'applicationBinaryMap';
            app.uipanel3 = uipanel(app.extractDomains);
            app.uipanel3.Title = 'Events and domain finder.';
            app.uipanel5 = uipanel(app.uipanel3);
            app.uipanel5.Title = '2) Features Extraction';
            app.featureViewer = uiaxes(app.uipanel5);
            app.uipanel7 = uipanel(app.uipanel5);
            app.uipanel7.Title = 'Features';
            app.XvolumeCB = uicheckbox(app.uipanel7);
            app.XvolumeCB.ValueChangedFcn = createCallbackFcn(app, @plotScatter, true);
            app.XvolumeCB.Text = '  Volume';
            app.XdeltaFCB = uicheckbox(app.uipanel7);
            app.XdeltaFCB.ValueChangedFcn = createCallbackFcn(app, @plotScatter, true);
            app.XdeltaFCB.Text = '  DeltaF/F';
            app.XdurationCB = uicheckbox(app.uipanel7);
            app.XdurationCB.ValueChangedFcn = createCallbackFcn(app, @plotScatter, true);
            app.XdurationCB.Text = '  Duration';
            app.XsurfaceCB = uicheckbox(app.uipanel7);
            app.XsurfaceCB.ValueChangedFcn = createCallbackFcn(app, @plotScatter, true);
            app.XsurfaceCB.Text = '  Surface';
            app.XYLabel = uilabel(app.uipanel7);
            app.XYLabel.Text = 'X                     Y';
            app.YvolumeCB = uicheckbox(app.uipanel7);
            app.YvolumeCB.ValueChangedFcn = createCallbackFcn(app, @plotScatter, true);
            app.YvolumeCB.Text = '';
            app.YdeltaFCB = uicheckbox(app.uipanel7);
            app.YdeltaFCB.ValueChangedFcn = createCallbackFcn(app, @plotScatter, true);
            app.YdeltaFCB.Text = '';
            app.YdurationCB = uicheckbox(app.uipanel7);
            app.YdurationCB.ValueChangedFcn = createCallbackFcn(app, @plotScatter, true);
            app.YdurationCB.Text = '';
            app.YsurfaceCB = uicheckbox(app.uipanel7);
            app.YsurfaceCB.ValueChangedFcn = createCallbackFcn(app, @plotScatter, true);
            app.YsurfaceCB.Text = '';
            app.Ylog10 = uicheckbox(app.uipanel7);
            app.Ylog10.ValueChangedFcn = createCallbackFcn(app, @plotScatter, true);
            app.Ylog10.Text = '';
            app.Xlog10 = uicheckbox(app.uipanel7);
            app.Xlog10.ValueChangedFcn = createCallbackFcn(app, @plotScatter, true);
            app.Xlog10.Text = ' Log   Log';
            app.extractFeatures = uibutton(app.uipanel5, 'push');
            app.extractFeatures.ButtonPushedFcn = createCallbackFcn(app, @extractFeatures_Callback, true);
            app.extractFeatures.Tooltip = {'Analyze the connected binary stack to compute the geometrical properties of all identied events.'};
            app.extractFeatures.Text = 'Feature Extraction';
            app.ExportFeaturesButton = uibutton(app.uipanel5, 'push');
            app.ExportFeaturesButton.ButtonPushedFcn = createCallbackFcn(app, @exportfeaturetab, true);
            app.ExportFeaturesButton.Tooltip = {'Export '};
            app.ExportFeaturesButton.Text = 'Export Features';
            app.selectSource4FeaturesBT = uibuttongroup(app.uipanel5);
            app.selectSource4FeaturesBT.Tooltip = {'Select whether analyzing all the events identified in the binary stack or only the events which size is included in the size limit defined in the Event Segmentation panel'};
            app.selectSource4FeaturesBT.Title = 'Select Source';
            app.AlleventsBt = uiradiobutton(app.selectSource4FeaturesBT);
            app.AlleventsBt.Text = 'All events';
            app.FilteredBt = uiradiobutton(app.selectSource4FeaturesBT);
            app.FilteredBt.Text = 'Filtered';
            app.FilteredBt.Value = true;
            app.nEventsFltTxt = uilabel(app.uipanel5);
            app.nEventsFltTxt.Text = '';
            app.nEventsTxt = uilabel(app.uipanel5);
            app.nEventsTxt.Text = '';
            app.ScramblecontrolPanel = uipanel(app.uipanel5);
            app.ScramblecontrolPanel.Title = 'Scramble control';
            app.ScrambleEventsRotateCB = uicheckbox(app.ScramblecontrolPanel);
            app.ScrambleEventsRotateCB.Text = 'Rotate 90°';
            app.ScrambleEventsRotateSP = uispinner(app.ScramblecontrolPanel);
            app.ScrambleEventsRotateSP.Limits = [1 3];
            app.ScrambleEventsRotateSP.RoundFractionalValues = 'on';
            app.ScrambleEventsRotateSP.Value = 1;
            app.displayPanel = uipanel(app.uipanel3);
            app.displayPanel.Title = 'Display';
            app.uibuttongroup2 = uibuttongroup(app.displayPanel);
            app.uibuttongroup2.Title = 'Source';
            app.BMdFflag = uiradiobutton(app.uibuttongroup2);
            app.BMdFflag.Text = 'B*DF/F';
            app.clustflag = uiradiobutton(app.uibuttongroup2);
            app.clustflag.Text = 'Clu';
            app.clustflag.Value = true;
            app.displaythreed = uibutton(app.uibuttongroup2, 'push');
            app.displaythreed.ButtonPushedFcn = createCallbackFcn(app, @displaythreed_Callback, true);
            app.displaythreed.Text = '3-Display';
            app.currentFrameTxt = uilabel(app.displayPanel);
            app.currentFrameTxt.Text = 'Current frame:';
            app.back1frameBM = uibutton(app.displayPanel, 'push');
            app.back1frameBM.ButtonPushedFcn = createCallbackFcn(app, @back1frameBM_Callback, true);
            app.back1frameBM.Tooltip = 'Move the current frame';
            app.back1frameBM.Text = '<';
            app.advace1frameBM = uibutton(app.displayPanel, 'push');
            app.advace1frameBM.ButtonPushedFcn = createCallbackFcn(app, @advance1frameBM_Callback, true);
            app.advace1frameBM.Tooltip = 'Move the current frame';
            app.advace1frameBM.Text = '>';
            app.backToTheBeginning = uibutton(app.displayPanel, 'push');
            app.backToTheBeginning.ButtonPushedFcn = createCallbackFcn(app, @backToStart, true);
            app.backToTheBeginning.Tooltip = {'Move to the beginning of the stack'};
            app.backToTheBeginning.Text = '||<';
            app.untilTheEnd = uibutton(app.displayPanel, 'push');
            app.untilTheEnd.ButtonPushedFcn = createCallbackFcn(app, @advanceToTheEnd, true);
            app.untilTheEnd.Tooltip = {'Move to the end of the stack'};
            app.untilTheEnd.Text = '>||';
            app.back20frames = uibutton(app.displayPanel, 'push');
            app.back20frames.ButtonPushedFcn = createCallbackFcn(app, @back20F, true);
            app.back20frames.Tooltip = 'Move the current frame';
            app.back20frames.Text = '<<';
            app.advace20frames = uibutton(app.displayPanel, 'push');
            app.advace20frames.ButtonPushedFcn = createCallbackFcn(app, @advance20F, true);
            app.advace20frames.Tooltip = 'Move the current frame';
            app.advace20frames.Text = '>>';
            app.ShowclusterSpinnerLabel = uilabel(app.displayPanel);
            app.ShowclusterSpinnerLabel.Text = 'Show cluster';
            app.showClusterSpinner = uispinner(app.displayPanel);
            app.showClusterSpinner.Limits = [0 Inf];
            app.showClusterSpinner.ValueDisplayFormat = '%g';
            app.showClusterSpinner.ValueChangedFcn = createCallbackFcn(app, @changeTraceSource, true);
            app.BoundarywidthEditFieldLabel = uilabel(app.displayPanel);
            app.BoundarywidthEditFieldLabel.Text = 'Boundary width:';
            app.boundaryLineW = uieditfield(app.displayPanel, 'numeric');
            app.boundaryLineW.Limits = [0 5];
            app.boundaryLineW.ValueDisplayFormat = '%.0f';
            app.boundaryLineW.ValueChangedFcn = createCallbackFcn(app, @multiPlot, true);
            app.boundaryLineW.Value = 1;
            app.InvertframesCheckBox = uicheckbox(app.displayPanel);
            app.InvertframesCheckBox.Text = 'Invert frames';
            app.LogminmaxEditFieldLabel = uilabel(app.displayPanel);
            app.LogminmaxEditFieldLabel.Tooltip = {'Min and Max values for the log10 of fluorescence. These limits apply to the Frames window.'};
            app.LogminmaxEditFieldLabel.Text = 'Log min/max:';
            app.logMin = uieditfield(app.displayPanel, 'numeric');
            app.logMin.Limits = [1 10];
            app.logMin.ValueDisplayFormat = '%.1f';
            app.logMin.ValueChangedFcn = createCallbackFcn(app, @multiPlot, true);
            app.logMin.Tooltip = {'Min and Max values for the log10 of fluorescence. These limits apply to the Frames window.'};
            app.logMin.Value = 1.3;
            app.logMax = uieditfield(app.displayPanel, 'numeric');
            app.logMax.ValueDisplayFormat = '%.1f';
            app.logMax.Tooltip = {'Min and Max values for the log10 of fluorescence. These limits apply to the Frames window.'};
            app.logMax.Value = 4;
            app.ClusterCCScoreBT = uibutton(app.displayPanel, 'push');
            app.ClusterCCScoreBT.ButtonPushedFcn = createCallbackFcn(app, @ClusterCCScore, true);
            app.ClusterCCScoreBT.Text = 'Global CC score';
            app.gCCExcludeCB = uicheckbox(app.displayPanel);
            app.gCCExcludeCB.ValueChangedFcn = createCallbackFcn(app, @ExcludeClustersByGlobalCC, true);
            app.gCCExcludeCB.Text = 'Exclude gCC < ';
            app.gCCExcludeEF = uieditfield(app.displayPanel, 'numeric');
            app.gCCNormalizeCB = uicheckbox(app.displayPanel);
            app.gCCNormalizeCB.Text = ' [0,1]';
            app.FillCB = uicheckbox(app.displayPanel);
            app.FillCB.ValueChangedFcn = createCallbackFcn(app, @multiPlot, true);
            app.FillCB.Text = 'Fill';
            app.FillCB.Value = true;
            app.clusterPanel = uipanel(app.uipanel3);
            app.clusterPanel.Title = '1) Event Segmentation';
            app.clusterAxis = uiaxes(app.clusterPanel);
            app.clusterAxis.XTick = [];
            app.clusterAxis.YTick = [];
            app.ExportButton = uibutton(app.clusterPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @exportcluster2, true);
            app.ExportButton.Text = 'Export';
            app.applyclust = uibutton(app.clusterPanel, 'push');
            app.applyclust.ButtonPushedFcn = createCallbackFcn(app, @applyClust_Callback, true);
            app.applyclust.Tooltip = {'Analyze the binary file  and extract the topologically connected domains using the parameters defined in this panel.'};
            app.applyclust.Text = 'Compute';
            app.text6 = uilabel(app.clusterPanel);
            app.text6.Tooltip = {'Further filtering of event size. '};
            app.text6.Text = 'Min & Max volume of domains (Voxels)';
            app.minptsdb = uieditfield(app.clusterPanel, 'text');
            app.minptsdb.Tooltip = {'Further filtering of event size. '};
            app.minptsdb.Value = '24';
            app.maxptsdb = uieditfield(app.clusterPanel, 'text');
            app.maxptsdb.Tooltip = {'Further filtering of event size. '};
            app.maxptsdb.Value = '10000000000';
            app.SourceBtGroup = uibuttongroup(app.clusterPanel);
            app.SourceBtGroup.Tooltip = {'Select the source binary stack: Raw, entire binarized data including isolated voxels; Filtered: includes only the voxels that satisfy the filtering conditions (minimum event surface and duration)  defined in Image Gateway'};
            app.SourceBtGroup.Title = 'Source';
            app.RawBt = uiradiobutton(app.SourceBtGroup);
            app.RawBt.Text = 'Raw';
            app.filteredBt = uiradiobutton(app.SourceBtGroup);
            app.filteredBt.Text = 'Filtered';
            app.filteredBt.Value = true;
            app.connectivityBtGroup = uibuttongroup(app.clusterPanel);
            app.connectivityBtGroup.Tooltip = {'Degree of connectivity. Voxels are connected if theiy share a face (6 degree of connectivity), faces and edges (18), faces, edges and vertixes (26).'};
            app.connectivityBtGroup.Title = 'Connectivity';
            app.px6Bt = uiradiobutton(app.connectivityBtGroup);
            app.px6Bt.Text = '6 degree';
            app.px18Bt = uiradiobutton(app.connectivityBtGroup);
            app.px18Bt.Text = '18 degree';
            app.px26Bt = uiradiobutton(app.connectivityBtGroup);
            app.px26Bt.Text = '26 degree';
            app.px26Bt.Value = true;
            app.displaySelect = uibuttongroup(app.clusterPanel);
            app.displaySelect.SelectionChangedFcn = createCallbackFcn(app, @redoDisplay, true);
            app.displaySelect.Title = 'Display';
            app.dislayRaw = uiradiobutton(app.displaySelect);
            app.dislayRaw.Text = 'Raw';
            app.displayFlt = uiradiobutton(app.displaySelect);
            app.displayFlt.Text = 'Filtered';
            app.displayFlt.Value = true;
            app.debugGridCB = uicheckbox(app.clusterPanel);
            app.debugGridCB.Text = 'Grid';
            app.debugDrawCB = uicheckbox(app.clusterPanel);
            app.debugDrawCB.Text = 'Draw (debug)';
            app.debugABNormalCB = uicheckbox(app.clusterPanel);
            app.debugABNormalCB.Enable = 'off';
            app.debugABNormalCB.Visible = 'off';
            app.debugABNormalCB.Text = 'AB-Normal mask';
            app.uipanel2 = uipanel(app.uipanel3);
            app.uipanel2.Title = 'Frames, Boundaries and Clusters';
            app.multiDisplay = uiaxes(app.uipanel2);
            app.multiDisplay.XTick = [];
            app.multiDisplay.YTick = [];
            app.multiPlotMode = uibuttongroup(app.uipanel2);
            app.multiPlotMode.SelectionChangedFcn = createCallbackFcn(app, @multiPlot, true);
            app.BoundariesButton = uiradiobutton(app.multiPlotMode);
            app.BoundariesButton.Text = 'Boundaries';
            app.BoundariesButton.Value = true;
            app.ClustersButton = uiradiobutton(app.multiPlotMode);
            app.ClustersButton.Text = 'Clusters';
            app.framesCB = uicheckbox(app.multiPlotMode);
            app.framesCB.ValueChangedFcn = createCallbackFcn(app, @multiPlot, true);
            app.framesCB.Tooltip = {'Visualize frames as background'};
            app.framesCB.Text = 'Frames';
            app.NNlinkCB = uicheckbox(app.multiPlotMode);
            app.NNlinkCB.Tooltip = {'Visualize the edge from each cluster to ists nearest neighbour (largest CC)'};
            app.NNlinkCB.Text = 'NN';
            app.uipanel13 = uipanel(app.uipanel3);
            app.uipanel13.Title = 'Features Distributions';
            app.distributions = uiaxes(app.uipanel13);
            app.uibuttongroup4 = uibuttongroup(app.uipanel13);
            app.xLogScale = uicheckbox(app.uibuttongroup4);
            app.xLogScale.ValueChangedFcn = createCallbackFcn(app, @computeDistributions, true);
            app.xLogScale.Text = 'X Log';
            app.xLogScale.Value = true;
            app.plotdistr = uibutton(app.uibuttongroup4, 'push');
            app.plotdistr.ButtonPushedFcn = createCallbackFcn(app, @computeDistributions, true);
            app.plotdistr.Text = 'Compute';
            app.minMaxSurfaceTxt = uilabel(app.uibuttongroup4);
            app.minMaxSurfaceTxt.Text = '';
            app.minMaxVolumeTxt = uilabel(app.uibuttongroup4);
            app.minMaxVolumeTxt.Text = '';
            app.minMaxDurationTxt = uilabel(app.uibuttongroup4);
            app.minMaxDurationTxt.Text = '';
            app.selectDistr = uibuttongroup(app.uibuttongroup4);
            app.selectDistr.SelectionChangedFcn = createCallbackFcn(app, @selectFeatureDistribution, true);
            app.volumeBt = uiradiobutton(app.selectDistr);
            app.volumeBt.Text = 'Volume';
            app.volumeBt.Value = true;
            app.surfaceBt = uiradiobutton(app.selectDistr);
            app.surfaceBt.Text = 'Surface';
            app.durationBt = uiradiobutton(app.selectDistr);
            app.durationBt.Text = 'Duration';
            app.deltaFBt = uiradiobutton(app.selectDistr);
            app.deltaFBt.Text = 'DeltaF/F';
            app.deltaFBt_2 = uiradiobutton(app.selectDistr);
            app.deltaFBt_2.Text = 'N Events';
            app.deltaFBt_3 = uiradiobutton(app.selectDistr);
            app.deltaFBt_3.Text = 'Area';
            app.deltaFBt_4 = uiradiobutton(app.selectDistr);
            app.deltaFBt_4.Text = 'CC value';
            app.DistanceBt = uiradiobutton(app.selectDistr);
            app.DistanceBt.Text = 'Distance';
            app.yLogScale = uicheckbox(app.uibuttongroup4);
            app.yLogScale.ValueChangedFcn = createCallbackFcn(app, @distYaxesLog, true);
            app.yLogScale.Text = 'Y Log';
            app.yLogScale.Value = true;
            app.FromEditFieldLabel = uilabel(app.uibuttongroup4);
            app.FromEditFieldLabel.Text = 'From:';
            app.fromDistr = uieditfield(app.uibuttongroup4, 'numeric');
            app.fromDistr.ValueChangedFcn = createCallbackFcn(app, @changeFrom, true);
            app.ToLabel = uilabel(app.uibuttongroup4);
            app.ToLabel.Text = 'To:';
            app.toDistr = uieditfield(app.uibuttongroup4, 'numeric');
            app.toDistr.ValueChangedFcn = createCallbackFcn(app, @changeTo, true);
            app.toDistr.Value = 1000;
            app.minMaxDeltaF = uilabel(app.uibuttongroup4);
            app.minMaxDeltaF.Text = '';
            app.BinsLabel = uilabel(app.uibuttongroup4);
            app.BinsLabel.Text = 'Bins:';
            app.binDistr = uieditfield(app.uibuttongroup4, 'numeric');
            app.binDistr.ValueChangedFcn = createCallbackFcn(app, @changeBin, true);
            app.binDistr.Value = 40;
            app.computeAndSaveDistributions = uibutton(app.uibuttongroup4, 'push');
            app.computeAndSaveDistributions.ButtonPushedFcn = createCallbackFcn(app, @exportDistributions, true);
            app.computeAndSaveDistributions.Tooltip = {'Compute all distributions with the present parameters and save them to the data directory'};
            app.computeAndSaveDistributions.Text = 'Save';
            app.AVRatiosBT = uibutton(app.uibuttongroup4, 'push');
            app.AVRatiosBT.ButtonPushedFcn = createCallbackFcn(app, @Area_Volume_Ratios, true);
            app.AVRatiosBT.Text = 'Save all';
            app.defineSomaBT = uibutton(app.uibuttongroup4, 'push');
            app.defineSomaBT.ButtonPushedFcn = createCallbackFcn(app, @defineSoma, true);
            app.defineSomaBT.Text = 'Soma distance';
            app.FilenameEF = uieditfield(app.uibuttongroup4, 'text');
            app.FilenameEF.Value = '-';
            app.mpxLabel = uilabel(app.uibuttongroup4);
            app.mpxLabel.Text = 'μm/px:';
            app.umpxEF = uieditfield(app.uibuttongroup4, 'numeric');
            app.umpxEF.Value = 0.209;
            app.nameLabel = uilabel(app.uibuttongroup4);
            app.nameLabel.Text = 'name:';
            app.ClusterIdentificationPanel = uipanel(app.uipanel3);
            app.ClusterIdentificationPanel.Title = '3) Cluster Identification';
            app.timeSeries = uiaxes(app.ClusterIdentificationPanel);
            app.timeSeries.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.timeSeries.YTickLabel = '';
            app.SpCorrelationBt = uibutton(app.ClusterIdentificationPanel, 'push');
            app.SpCorrelationBt.ButtonPushedFcn = createCallbackFcn(app, @computeSpatialCorrelation, true);
            app.SpCorrelationBt.Tooltip = {'Compute spatial correlation matrix'};
            app.SpCorrelationBt.Text = 'Clusters';
            app.TolleranceSILabel = uilabel(app.ClusterIdentificationPanel);
            app.TolleranceSILabel.Text = 'Tollerance (SI):';
            app.SIthr = uieditfield(app.ClusterIdentificationPanel, 'numeric');
            app.SIthr.ValueDisplayFormat = '%5.2g';
            app.SIthr.Tooltip = {'Events belongs to the same cluster when their Similarity Index (SI) il larger than this threshold. The SI between two events is computed as the ratio betwen the intersection of their boundaries and their union. The SI = 1 for two identical events and it is strictly larger than 0. '};
            app.SIthr.Value = 0.2;
            app.clusterTxt = uilabel(app.ClusterIdentificationPanel);
            app.clusterTxt.Text = 'Clusters: //';
            app.TSselection = uibuttongroup(app.ClusterIdentificationPanel);
            app.TSselection.SelectionChangedFcn = createCallbackFcn(app, @changeTraceSource, true);
            app.DFintersectionButton = uiradiobutton(app.TSselection);
            app.DFintersectionButton.Enable = 'off';
            app.DFintersectionButton.Visible = 'off';
            app.DFintersectionButton.Text = 'DF intersection';
            app.DFunionButton = uiradiobutton(app.TSselection);
            app.DFunionButton.Text = 'DF union';
            app.DFIallButton = uiradiobutton(app.TSselection);
            app.DFIallButton.Enable = 'off';
            app.DFIallButton.Visible = 'off';
            app.DFIallButton.Text = 'DF I all';
            app.DFUallButton = uiradiobutton(app.TSselection);
            app.DFUallButton.Text = 'DF U all';
            app.DFUallButton.Value = true;
            app.CCmatrixBt = uibutton(app.ClusterIdentificationPanel, 'push');
            app.CCmatrixBt.ButtonPushedFcn = createCallbackFcn(app, @computeCCmatrix, true);
            app.CCmatrixBt.Text = 'CC matrix';
            app.sortingCriteriaRB = uibuttongroup(app.ClusterIdentificationPanel);
            app.sortingCriteriaRB.Tooltip = {'Criteria for clusetr sorting'};
            app.sortingCriteriaRB.Title = 'Sorting criteria';
            app.NosortingButton = uiradiobutton(app.sortingCriteriaRB);
            app.NosortingButton.Text = 'No sorting';
            app.NosortingButton.Value = true;
            app.nEventsButton = uiradiobutton(app.sortingCriteriaRB);
            app.nEventsButton.Text = 'n Events';
            app.AreaButton = uiradiobutton(app.sortingCriteriaRB);
            app.AreaButton.Text = 'Area';
            app.CCButton = uiradiobutton(app.sortingCriteriaRB);
            app.CCButton.Text = 'CC';
            app.maxDF = uieditfield(app.ClusterIdentificationPanel, 'numeric');
            app.maxDF.ValueDisplayFormat = '%.0f';
            app.maxDF.Value = 10;
            app.minDF = uieditfield(app.ClusterIdentificationPanel, 'numeric');
            app.minDF.ValueDisplayFormat = '%.0f';
            app.minDF.Value = -10;
            app.includeDiscardedEventsCB = uicheckbox(app.ClusterIdentificationPanel);
            app.includeDiscardedEventsCB.Text = 'Include single events';
            app.includeDiscardedEventsCB.Value = true;
            app.RhoEditFieldLabel = uilabel(app.ClusterIdentificationPanel);
            app.RhoEditFieldLabel.Text = 'Rho:';
            app.RhoMultiplierEF = uieditfield(app.ClusterIdentificationPanel, 'numeric');
            app.RhoMultiplierEF.Value = 2;
            app.MergeClustersCB = uicheckbox(app.ClusterIdentificationPanel);
            app.MergeClustersCB.Text = 'Refine and merge clusters';
            app.MergeClustersCB.Value = true;
            % Show the figure after all components are created
            app.extractDomains.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = applicationBinaryMap_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.extractDomains)

            % Execute the startup function
            runStartupFcn(app, @(app)applicationBinaryMap_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.extractDomains)
        end
    end
end